classdef MyDaq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DaqFigure                       matlab.ui.Figure
        MainMenu                        matlab.ui.container.Menu
        NewtracebehaviorMenu            matlab.ui.container.Menu
        AddnewMenu                      matlab.ui.container.Menu
        ReplaceexistingMenu             matlab.ui.container.Menu
        AddtoaverageMenu                matlab.ui.container.Menu
        UpdatelistofDAQprogramsMenu     matlab.ui.container.Menu
        UpdatelistofanalysisroutinesMenu  matlab.ui.container.Menu
        PlotoptionsMenu                 matlab.ui.container.Menu
        ResizeableMenu                  matlab.ui.container.Menu
        LogscalexMenu                   matlab.ui.container.Menu
        LogscaleyMenu                   matlab.ui.container.Menu
        xgridMenu                       matlab.ui.container.Menu
        ygridMenu                       matlab.ui.container.Menu
        xminorgridMenu                  matlab.ui.container.Menu
        yminorgridMenu                  matlab.ui.container.Menu
        Axes                            matlab.ui.control.UIAxes
        MainPanel                       matlab.ui.container.Panel
        TraceTree                       matlab.ui.container.Tree
        InstrumenttracesNode            matlab.ui.container.TreeNode
        cavitytransmissionspectrumNode  matlab.ui.container.TreeNode
        AnalysistracesNode              matlab.ui.container.TreeNode
        Fit1Node                        matlab.ui.container.TreeNode
        Fit2Node                        matlab.ui.container.TreeNode
        TracePanel                      matlab.ui.container.Panel
        DisplayCheckBox                 matlab.ui.control.CheckBox
        SaveButton                      matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        ColorButton                     matlab.ui.control.Button
        ColorSampleLabel                matlab.ui.control.Label
        LoadtraceButton                 matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        AcquisitioninstrumentDropDownLabel  matlab.ui.control.Label
        AcquisitioninstrumentDropDown   matlab.ui.control.DropDown
        MeasurementsessionnameEditFieldLabel  matlab.ui.control.Label
        SessionNameEditField            matlab.ui.control.EditField
        AnalysisroutineDropDownLabel    matlab.ui.control.Label
        AnalysisroutineDropDown         matlab.ui.control.DropDown
        MeasurementbasedirectoryEditFieldLabel  matlab.ui.control.Label
        BaseDirectoryEditField          matlab.ui.control.EditField
    end

    properties (Access = public)
        max_trace_no = 100      % Maximum number of stored traces 
        
        default_file_ext             = '.txt'
        trace_disp_icon_filename     = 'trace_disp_icon.png'
        trace_not_disp_icon_filename = 'trace_not_disp_icon.png'
    end
    
    properties (GetAccess = public, SetAccess = private)
        
        % Object to fascilitate the implementation of user interactions
        GuiSync     MyGuiSync
        
        % The information about data acquisition programs is stored as an
        % array of program descriptors
        AcqProgramList      MyProgramDescriptor
        
        % The information about analysis routines is stored as a simple 
        % structure array
        AnalysisRoutineList     struct
        Listeners = struct()
        
        % List of presently active analysis routine objects. The two last
        % fields in the structure array are needed to implement fit trace
        % replacement if the fitted data is unchanged.
        RunningAnalysisRoutines = struct( ...
            'Instance',  {}, ...    % Object that represents the routine
            'Listener',  {})        % Listener to NewFit event
    end
    
    properties (Access = private) 
        original_axes_position = []
        original_figure_position = []
        original_main_panel_position = []
    end
    
    methods (Access = private)
        
        % Add new trace to DAQ, store it under the appropriate node and plot it 
        function addTraceNode(app, Trace, ParentNode, name)
            ExistingNodes = ParentNode.Children;
            
            % Add a node to the trace display tree
            NewNode = uitreenode(ParentNode, 'NodeData', Trace);
            NewNode.Text = name;
            
            try 
                
                % Append new trace to the axes
                hold(app.Axes, 'on');
                plot(Trace, app.Axes);
                
                % Indicate the trace display status
                NewNode.Icon = app.trace_disp_icon_filename;
            catch ME
                
                warning(ME.message);
                NewNode.Icon = app.trace_not_disp_icon_filename;
            end
            
            % Expand to make it more apparent that the first trace is aquired
            if isempty(ExistingNodes)          
                expand(ParentNode)
            end
        end
        
        function acquireNewData(app, ~, EventData)
            
            % Get the currently selected instrument
            ind = app.AcquisitioninstrumentDropDown.Value;
            
            if ind == 0
                
                % No application selected
                return
            end
            
            curr_instr_name = app.AcqProgramList(ind).name;
            
            if ~strcmp(EventData.src_name, curr_instr_name)
                
                % Return if the data does not originates from the currently
                % selected instrument
                return
            end
            
            ExistingNodes = app.InstrumenttracesNode.Children;
            
            % Copy data from the source instrument
            NewTrace = copy(EventData.Trace);
            
            if isprop(EventData, 'trace_name') && ~isempty(EventData.trace_name)
                
                % Use the trace name specified in EventData
                trace_name = EventData.trace_name;
            else
                
                % Generate a name for the trace
                trace_name = [EventData.src_name '_trace'];
            end
            
            if ~isempty(ExistingNodes)
                
                % Make the name unique among the nodes
                trace_name = matlab.lang.makeUniqueStrings(trace_name, ...
                    {ExistingNodes.Text});
            end
            
            addTraceNode(app, NewTrace, app.InstrumenttracesNode, trace_name);
            
            % If the save flag is on in EventData, save the new trace
            % making sure that unique filename is generated in order
            % not to prompt the owerwrite dialog
            if isprop(EventData, 'save') && EventData.save
                saveTrace(app, NewNode, 'create_unique_name', true);
            end
        end
    
        function acquireNewAnalysisTrace(app, Src, EventData)
                
            % Copy data from the source object
            NewTrace = copy(EventData.Trace);
            
            % Create a new node with unique name           
            ExistingNodes = app.AnalysistracesNode.Children;
            
            % Generate a name for the trace
            try 
                
                % Try to find out the node that the raw data belongs to
                ind = arrayfun(@(x)isequal(x.NodeData, Src.Data), app.InstrumenttracesNode.Children);
                assert(nnz(ind) == 1, 'Raw fit data is not found among instrument trace nodes.');
                data_name = app.InstrumenttracesNode.Children(ind).Text;
            catch ME
                warning(ME.message);
                data_name = '';
            end
            trace_name = [data_name '_fit'];
            
            if ~isempty(ExistingNodes)
            
                % Ensure the name to be unique
                trace_name = matlab.lang.makeUniqueStrings(trace_name, ...
                    {ExistingNodes.Text});
            end
            
            addTraceNode(app, NewTrace, app.AnalysistracesNode, trace_name);
        end
    
        function saveTrace(app, TraceNode, varargin) 
            p = inputParser();
            
            % If file already exists, generate a uinique name rather than
            % show user the overwrite dialog.
            addParameter(p, 'create_unique_name', false, @islogical);
            parse(p,varargin{:});
            
            save_dir = createSessionPath(app.BaseDirectoryEditField.Value, ...
                app.SessionNameEditField.Value);
            
            % Append an ending to the file name if necessary
            filename = TraceNode.Text;
            [~, ~, ext] = fileparts(filename);
            
            if isempty(ext) || any(isspace(ext)) || ...
                    length(ext)<2 || length(ext)>5
                
                % Add default file extension
                filename = [filename, app.default_file_ext];
            end
            
            fullfilename = fullfile(save_dir, filename);
            
            if p.Results.create_unique_name && exist(fullfilename, 'file')~=0
                fullfilename = createUniqueFileName(fullfilename); 
            end
            
            % Save in readable format using the method of MyTrace
            save(TraceNode.NodeData, fullfilename)
        end
    
        % Callbacks that are shared between several events 
        function updateTracePanel(app)
            Sn = app.TraceTree.SelectedNodes;
            
            % Enable the trace panel only if a node corresponsing to
            % a trace is selected, not a top-level group node
            is_trace_node = ~isempty(Sn) && ...
                ~isequal(Sn, app.InstrumenttracesNode) && ...
                ~isequal(Sn, app.AnalysistracesNode);
            
            for i = 1:length(app.TracePanel.Children)
                setIfChanged(app.TracePanel.Children(i), 'Enable', is_trace_node);
            end
            
            if ~is_trace_node
                return
            end
            
            if ~isempty(Sn.NodeData.file_name)
                app.StatusLabel.Text = 'Saved';
                app.StatusLabel.FontColor = MyAppColors.ok();
            else
                app.StatusLabel.Text = 'Not saved';
                app.StatusLabel.FontColor = MyAppColors.warning();
            end
            
            Ln = getLine(Sn.NodeData, app.Axes);
            if ~isempty(Ln)
                app.ColorSampleLabel.BackgroundColor = Ln.Color;
                app.DisplayCheckBox.Value = strcmpi(Ln.Visible, 'on');
            end
        end
    
        function deleteSelectedTraceNode(app)
            Sn = app.TraceTree.SelectedNodes;
            
            if isempty(Sn) || isequal(Sn.Parent, app.TraceTree)
                
                % No node or a top-level node is selected
                return
            end
            
            ParentNode = Sn.Parent;
            
            % Find the index of the selected node in its sub-category
            ind = arrayfun(@(x)isequal(x, Sn), ParentNode.Children);
            ind = find(ind, 1, 'first');
            
            % Delete the previously selected node
            delete(Sn.NodeData);
            delete(Sn);
            
            % Change selection to the next node
            if length(ParentNode.Children) >= ind
                app.TraceTree.SelectedNodes = ParentNode.Children(ind);
            elseif ind-1 >= 1 && length(ParentNode.Children) >= ind-1
                app.TraceTree.SelectedNodes = ParentNode.Children(ind-1);
            end
            
            updateTracePanel(app);
        end
        
        function initAnalysisRoutineList(app)
            try
                
                % First, delete any instance of the analysis routines that
                % are presently running
                for i = 1:length(app.RunningAnalysisRoutines)
                    Inst = app.RunningAnalysisRoutines(i).Instance;
                    if ~isempty(Inst)
                        delete(Inst);
                    end
                end
                
                % Update the analysis drop down
                app.AnalysisRoutineList = getLocalSettings('AnalysisRoutineList');
                len = length(app.AnalysisRoutineList);
                
                app.AnalysisroutineDropDown.Items = [{'Select ...'}, ...
                    {app.AnalysisRoutineList.title}];
                app.AnalysisroutineDropDown.ItemsData = 0:len;
                
                % Initialize an empty array of appropriate size for the storage
                % of analysis routine instances 
                app.RunningAnalysisRoutines(len).Instance = [];
            catch ME
                warning(['Could not initialize the list of analysis routines. Error: ' ...
                    ME.message])
            end
        end
    
        function initDaqPrograms(app)
            try
                
                % The list of instruments is populated from the list of run files.
                FullProgList = getIcPrograms();
                
                % Select those programs that are enabled and can produce traces.
                ind = [FullProgList.enabled] & [FullProgList.data_source];
                app.AcqProgramList = FullProgList(ind);
                
                app.AcquisitioninstrumentDropDown.Items = [{'Select ...'}, ...
                    {app.AcqProgramList.title}];
                app.AcquisitioninstrumentDropDown.ItemsData = 0:length(app.AcqProgramList);
            catch ME
                warning(['Could not initialize the list of data acquisition programs. Error: ' ...
                    ME.message])
            end
        end
    
        function createLinks(app)
            addLink(app.GuiSync, app.ResizeableMenu, 'DaqFigure.Resize');
            addLink(app.GuiSync, app.LogscalexMenu, 'Axes.XScale', 'map', {{'log', 'linear'}, {'on', 'off'}});
            addLink(app.GuiSync, app.LogscaleyMenu, 'Axes.YScale', 'map', {{'log', 'linear'}, {'on', 'off'}});
            addLink(app.GuiSync, app.xgridMenu,  'Axes.XGrid');
            addLink(app.GuiSync, app.ygridMenu,  'Axes.YGrid');
            addLink(app.GuiSync, app.xminorgridMenu,  'Axes.XMinorGrid');
            addLink(app.GuiSync, app.yminorgridMenu,  'Axes.YMinorGrid');
        end
    
        % Functions declared in separate files. These never explicitly
        % refer to the elements of GUI.
        deleteListeners(app, obj_name)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Store the original axes position for future resizing
            app.original_axes_position = app.Axes.OuterPosition;
            app.original_figure_position = app.DaqFigure.Position;
            app.original_main_panel_position = app.MainPanel.Position;
            
            % Delete dummy trace nodes
            delete(app.AnalysistracesNode.Children);
            delete(app.InstrumenttracesNode.Children);
            
            % Read local settings
            try
                dir = getLocalSettings('measurement_base_dir');
                app.BaseDirectoryEditField.Value = dir;
            catch ME
                warning(ME.message)
            end
            
            initDaqPrograms(app);
            initAnalysisRoutineList(app);
            
            try
                C = MyCollector.instance();
                app.Listeners.Collector.NewDataWithHeaders = ...
                    addlistener(C, 'NewDataWithHeaders', @app.acquireNewData);
            catch ME
                warning(['Could not create a listener for the collector, ' ...
                    'the application will not be able to acquire new traces. Error:\n' ...
                    ME.message])
            end
            
            updateTracePanel(app);
            
            app.GuiSync = MyGuiSync(app);
            createLinks(app);
        end

        % Size changed function: DaqFigure
        function DaqFigureSizeChanged(app, event)
            fig_position = app.DaqFigure.Position;
            
            if isempty(app.original_axes_position) || ...
                    isempty(app.original_figure_position)
                return
            end
            
            % Resize width and hight of axes so that the top left corner
            % remains where it was and the distances between the axes and
            % the figure boundaries are constant
            x = app.original_axes_position(1);
            y = app.original_axes_position(2);
            
            dx = app.original_figure_position(3)- ...
                (app.original_axes_position(1)+app.original_axes_position(3));
            dy = app.original_figure_position(4)- ...
                (app.original_axes_position(2)+app.original_axes_position(4));
            
            w = fig_position(3)-x-dx;
            h = fig_position(4)-y-dy;
            
            app.Axes.OuterPosition = [x, y, w, h];
            
            % Move the analysis panel to the top right
            dy = app.original_main_panel_position(4)- ...
                (app.original_main_panel_position(2)+ ...
                app.original_main_panel_position(4));
            
            w = app.MainPanel.Position(3);
            h = app.MainPanel.Position(4);
            x = fig_position(3)-w;
            y = fig_position(4)-h-dy;
            
            app.MainPanel.Position=[x, y, w, h];
        end

        % Close request function: DaqFigure
        function DaqFigureCloseRequest(app, event)
            if ~isempty(fieldnames(app.Listeners))
                cellfun(@(x) deleteListeners(app, x), fieldnames(app.Listeners));
            end
            
            delete(app)
        end

        % Value changed function: AcquisitioninstrumentDropDown
        function AcquisitioninstrumentDropDownValueChanged(app, event)
            ind = app.AcquisitioninstrumentDropDown.Value;
            
            if ind == 0
                
                % No instrument selected
                return
            end
            
            try
                eval(app.AcqProgramList(ind).run_expr);
            catch ME
                errordlg(sprintf('An error occured while attempting to run %s: %s',...
                    app.AcqProgramList(ind).name), ME.message)
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            deleteSelectedTraceNode(app);
        end

        % Selection changed function: TraceTree
        function TraceTreeSelectionChanged(app, event)
            updateTracePanel(app);
            
            % Push new data to all open fits if an instrument trace node is
            % selected
            Sn = app.TraceTree.SelectedNodes;
            if ~isempty(Sn) && isequal(Sn.Parent, app.InstrumenttracesNode)
                for i = 1:length(app.RunningAnalysisRoutines)
                    Inst = app.RunningAnalysisRoutines(i).Instance;
                    if ~isempty(Inst) && isvalid(Inst)
                        Inst.Data = Sn.NodeData;
                    end
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveTrace(app, app.TraceTree.SelectedNodes);
            
            app.StatusLabel.Text = 'Saved';
            app.StatusLabel.FontColor = MyAppColors.ok();
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            color = uisetcolor(app.ColorSampleLabel.BackgroundColor);
            
            % Set the new color of line of the selected trace
            Ln = getLine(app.TraceTree.SelectedNodes.NodeData, app.Axes);
            
            Ln.Color = color;
            app.ColorSampleLabel.BackgroundColor = Ln.Color;
            
            % return focus to the DAQ window
            setFocus(app.DaqFigure);
        end

        % Value changed function: DisplayCheckBox
        function DisplayCheckBoxValueChanged(app, event)
            value = app.DisplayCheckBox.Value;
            
            % Set the visibility of selected line
            Ln = getLine(app.TraceTree.SelectedNodes.NodeData, app.Axes);
            Ln.Visible = value;
            
            % Change the node icon
            if value
                app.TraceTree.SelectedNodes.Icon = 'trace_disp_icon.png';
            else
                app.TraceTree.SelectedNodes.Icon = 'trace_not_disp_icon.png';
            end
        end

        % Window key press function: DaqFigure
        function DaqFigureWindowKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'delete'
                    deleteSelectedTraceNode(app);
                case 's'
                    
                    % Save trace when ctrl+s is pressed
                    if ismember('control', event.Modifier)
                        Sn = app.TraceTree.SelectedNodes;
                        
                        if ~isempty(Sn)
                            saveTrace(app, app.TraceTree.SelectedNodes);
                
                            app.StatusLabel.Text = 'Saved';
                            app.StatusLabel.FontColor = MyAppColors.ok();
                        end
                    end
            end
        end

        % Value changed function: AnalysisroutineDropDown
        function AnalysisroutineDropDownValueChanged(app, event)
            Sn = app.TraceTree.SelectedNodes;
            
            if isempty(Sn) || ~isequal(Sn.Parent, app.InstrumenttracesNode)
                
                % No instrument trace selected 
                return
            end
                
            % Start a new instance of analysis routine of the selected
            % type
            ind = app.AnalysisroutineDropDown.Value;
            
            if ind == 0
                
                % No analysis routine selected
                return
            end
            
            ExistingInstance = app.RunningAnalysisRoutines(ind).Instance;
            
            if isempty(ExistingInstance) || ~isvalid(ExistingInstance)
                ar_func = app.AnalysisRoutineList(ind).function;
                
                try
                    
                    % Run the analysis routine supplying the handles of data
                    % trace and axes for interactive plotting
                    Ar = feval(ar_func, 'Data', Sn.NodeData, 'Axes', app.Axes);
                catch ME
                    warning(['Analysis routine ''' app.AnalysisRoutineList(ind).title ...
                        ''' could not be started. Error: ' ME.message])
                    return
                end
                
                if ~isa(Ar, 'MyAnalysisRoutine')
                    delete(Ar);
                    error('A valid analysis routine must be a subclass of MyAnalysisRoutine');
                end
                
                % Add the analysis routine object to the cleanup list
                app.RunningAnalysisRoutines(ind).Instance = Ar;
                
                app.RunningAnalysisRoutines(ind).Listener = ...
                    addlistener(Ar, 'NewAnalysisTrace', @app.acquireNewAnalysisTrace);
            else
                
                setFocus(ExistingInstance);
            end
        end

        % Menu selected function: UpdatelistofanalysisroutinesMenu
        function UpdatelistofanalysisroutinesMenuSelected(app, event)
            initAnalysisRoutineList(app);
        end

        % Menu selected function: UpdatelistofDAQprogramsMenu
        function UpdatelistofDAQprogramsMenuSelected(app, event)
            initDaqPrograms(app);
        end

        % Button pushed function: LoadtraceButton
        function LoadtraceButtonPushed(app, event)
            
            try
                default_dir = getLocalSettings('measurement_base_dir');
            catch ME
                warning(ME.message);
                default_dir = '';
            end
            
            [fn, path] = uigetfile('*.*', 'Select a MyTrace file', default_dir);
            
            if fn == 0
                
                % No file was choosen
                return
            end
            
            NewTrace = MyTrace.load(fullfile(path, fn));
            
            addTraceNode(app, NewTrace, app.InstrumenttracesNode, fn);
            
            % Return focus to the DAQ figure, as it was pushed behind
            % the main MATLAB window by the dialog
            setFocus(app.DaqFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DaqFigure = uifigure('Visible', 'off');
            app.DaqFigure.Name = 'DAQ';
            app.DaqFigure.CloseRequestFcn = createCallbackFcn(app, @DaqFigureCloseRequest, true);
            app.DaqFigure.SizeChangedFcn = createCallbackFcn(app, @DaqFigureSizeChanged, true);
            app.DaqFigure.WindowKeyPressFcn = createCallbackFcn(app, @DaqFigureWindowKeyPress, true);
            app.MainMenu = uimenu(app.DaqFigure);
            app.MainMenu.Text = 'Main';
            app.NewtracebehaviorMenu = uimenu(app.MainMenu);
            app.NewtracebehaviorMenu.Text = 'New trace behavior';
            app.AddnewMenu = uimenu(app.NewtracebehaviorMenu);
            app.AddnewMenu.Checked = 'on';
            app.AddnewMenu.Text = 'Add new';
            app.ReplaceexistingMenu = uimenu(app.NewtracebehaviorMenu);
            app.ReplaceexistingMenu.Text = 'Replace existing';
            app.AddtoaverageMenu = uimenu(app.NewtracebehaviorMenu);
            app.AddtoaverageMenu.Text = 'Add to average';
            app.UpdatelistofDAQprogramsMenu = uimenu(app.MainMenu);
            app.UpdatelistofDAQprogramsMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdatelistofDAQprogramsMenuSelected, true);
            app.UpdatelistofDAQprogramsMenu.Text = 'Update list of DAQ programs';
            app.UpdatelistofanalysisroutinesMenu = uimenu(app.MainMenu);
            app.UpdatelistofanalysisroutinesMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdatelistofanalysisroutinesMenuSelected, true);
            app.UpdatelistofanalysisroutinesMenu.Text = 'Update list of analysis routines';
            app.PlotoptionsMenu = uimenu(app.DaqFigure);
            app.PlotoptionsMenu.Text = 'Plot options';
            app.ResizeableMenu = uimenu(app.PlotoptionsMenu);
            app.ResizeableMenu.Text = 'Resizeable';
            app.LogscalexMenu = uimenu(app.PlotoptionsMenu);
            app.LogscalexMenu.Text = 'Log scale x';
            app.LogscaleyMenu = uimenu(app.PlotoptionsMenu);
            app.LogscaleyMenu.Text = 'Log scale y';
            app.xgridMenu = uimenu(app.PlotoptionsMenu);
            app.xgridMenu.Text = 'x grid';
            app.ygridMenu = uimenu(app.PlotoptionsMenu);
            app.ygridMenu.Text = 'y grid';
            app.xminorgridMenu = uimenu(app.PlotoptionsMenu);
            app.xminorgridMenu.Text = 'x minor grid';
            app.yminorgridMenu = uimenu(app.PlotoptionsMenu);
            app.yminorgridMenu.Text = 'y minor grid';
            app.Axes = uiaxes(app.DaqFigure);
            title(app.Axes, '')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            app.MainPanel = uipanel(app.DaqFigure);
            app.TraceTree = uitree(app.MainPanel);
            app.TraceTree.SelectionChangedFcn = createCallbackFcn(app, @TraceTreeSelectionChanged, true);
            app.TraceTree.Editable = 'on';
            app.InstrumenttracesNode = uitreenode(app.TraceTree);
            app.InstrumenttracesNode.Text = 'Instrument traces';
            app.cavitytransmissionspectrumNode = uitreenode(app.InstrumenttracesNode);
            app.cavitytransmissionspectrumNode.Icon = 'trace_disp_icon.png';
            app.cavitytransmissionspectrumNode.Text = '1. cavity transmission spectrum';
            app.AnalysistracesNode = uitreenode(app.TraceTree);
            app.AnalysistracesNode.Text = 'Analysis traces';
            app.Fit1Node = uitreenode(app.AnalysistracesNode);
            app.Fit1Node.Text = 'Fit1';
            app.Fit2Node = uitreenode(app.AnalysistracesNode);
            app.Fit2Node.Text = 'Fit2';
            app.TracePanel = uipanel(app.MainPanel);
            app.TracePanel.Title = 'Trace';
            app.DisplayCheckBox = uicheckbox(app.TracePanel);
            app.DisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayCheckBoxValueChanged, true);
            app.DisplayCheckBox.Text = 'Display';
            app.SaveButton = uibutton(app.TracePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DeleteButton = uibutton(app.TracePanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.StatusLabel = uilabel(app.TracePanel);
            app.StatusLabel.Text = 'Status';
            app.ColorButton = uibutton(app.TracePanel, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Color';
            app.ColorSampleLabel = uilabel(app.TracePanel);
            app.ColorSampleLabel.Text = '';
            app.LoadtraceButton = uibutton(app.MainPanel, 'push');
            app.LoadtraceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtraceButtonPushed, true);
            app.LoadtraceButton.Text = 'Load trace';
            app.BrowseButton = uibutton(app.MainPanel, 'push');
            app.BrowseButton.Text = 'Browse';
            app.AcquisitioninstrumentDropDownLabel = uilabel(app.MainPanel);
            app.AcquisitioninstrumentDropDownLabel.Text = 'Acquisition instrument';
            app.AcquisitioninstrumentDropDown = uidropdown(app.MainPanel);
            app.AcquisitioninstrumentDropDown.Items = {'Select ...', 'Dpo4034Nano1', 'Option 3', 'Option 4'};
            app.AcquisitioninstrumentDropDown.ValueChangedFcn = createCallbackFcn(app, @AcquisitioninstrumentDropDownValueChanged, true);
            app.AcquisitioninstrumentDropDown.Value = 'Dpo4034Nano1';
            app.MeasurementsessionnameEditFieldLabel = uilabel(app.MainPanel);
            app.MeasurementsessionnameEditFieldLabel.Text = 'Measurement session name';
            app.SessionNameEditField = uieditfield(app.MainPanel, 'text');
            app.SessionNameEditField.Value = 'Placeholder';
            app.AnalysisroutineDropDownLabel = uilabel(app.MainPanel);
            app.AnalysisroutineDropDownLabel.Text = 'Analysis routine';
            app.AnalysisroutineDropDown = uidropdown(app.MainPanel);
            app.AnalysisroutineDropDown.Items = {'Select ...', 'Option 2', 'Option 3', 'Option 4'};
            app.AnalysisroutineDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisroutineDropDownValueChanged, true);
            app.AnalysisroutineDropDown.Value = 'Select ...';
            app.MeasurementbasedirectoryEditFieldLabel = uilabel(app.MainPanel);
            app.MeasurementbasedirectoryEditFieldLabel.Text = 'Measurement base directory';
            app.BaseDirectoryEditField = uieditfield(app.MainPanel, 'text');
            % Show the figure after all components are created
            app.DaqFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyDaq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DaqFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DaqFigure)
        end
    end
end