classdef MyDaq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DaqFigure                       matlab.ui.Figure
        MainMenu                        matlab.ui.container.Menu
        OpenDAQmenuMenu                 matlab.ui.container.Menu
        UpdatethelistofDAQprogramsMenu  matlab.ui.container.Menu
        UpdatethelistofanalysisroutinesMenu  matlab.ui.container.Menu
        ReconnectCollectorMenu          matlab.ui.container.Menu
        PlotoptionsMenu                 matlab.ui.container.Menu
        ResizeableMenu                  matlab.ui.container.Menu
        LogscalexMenu                   matlab.ui.container.Menu
        LogscaleyMenu                   matlab.ui.container.Menu
        GridxMenu                       matlab.ui.container.Menu
        GridyMenu                       matlab.ui.container.Menu
        MinorgridxMenu                  matlab.ui.container.Menu
        MinorgridyMenu                  matlab.ui.container.Menu
        LegendMenu                      matlab.ui.container.Menu
        FontsizeMenu                    matlab.ui.container.Menu
        TraceMenu                       matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        NewtracenameMenu                matlab.ui.container.Menu
        FrominstrumentMenu              matlab.ui.container.Menu
        UserdefinedMenu                 matlab.ui.container.Menu
        AutosaveMenu                    matlab.ui.container.Menu
        Axes                            matlab.ui.control.UIAxes
        MainPanel                       matlab.ui.container.Panel
        DropDownLabel                   matlab.ui.control.Label
        AcquisitionInstrumentDropDown   matlab.ui.control.DropDown
        DropDownLabel_2                 matlab.ui.control.Label
        AnalysisRoutineDropDown         matlab.ui.control.DropDown
        TraceTree                       matlab.ui.container.Tree
        DatatracesNode                  matlab.ui.container.TreeNode
        cavitytransmissionspectrumNode  matlab.ui.container.TreeNode
        AnalysistracesNode              matlab.ui.container.TreeNode
        Fit1Node                        matlab.ui.container.TreeNode
        Fit2Node                        matlab.ui.container.TreeNode
        Node7                           matlab.ui.container.TreeNode
        Node8                           matlab.ui.container.TreeNode
        Node9                           matlab.ui.container.TreeNode
        SelectedTracePanel              matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        savestatusLabel                 matlab.ui.control.Label
        ShowmetadataButton              matlab.ui.control.Button
        DisplayCheckBox                 matlab.ui.control.CheckBox
        ColorButton                     matlab.ui.control.Button
        AcquisitioninstrumentButton     matlab.ui.control.Button
        AnalysisroutineButton           matlab.ui.control.Button
        MeasurementsessionnameEditFieldLabel  matlab.ui.control.Label
        SessionNameEditField            matlab.ui.control.EditField
        WorkingdirectoryEditFieldLabel  matlab.ui.control.Label
        WorkingdirectoryEditField       matlab.ui.control.EditField
        OpenButton                      matlab.ui.control.Button
    end

    properties (Access = public)
        default_file_ext             = '.txt'
        trace_disp_icon_filename     = 'trace_disp_icon.png'
        trace_not_disp_icon_filename = 'trace_not_disp_icon.png'
        
        % Handle of the collector for easy reference
        Collector
    end
    
    properties (GetAccess = public, SetAccess = private)
        
        % The information about data acquisition programs is stored as an
        % array of program descriptors
        AcqProgramList          MyProgramDescriptor
        
        % The information about analysis routines is stored as a simple 
        % structure array
        AnalysisRoutineList     struct
        
        % List of presently active analysis routine objects. 
        RunningAnalysisRoutines = struct( ...
            'Instance',  {})                % Object that represents the routine
        
        Listeners = struct( ...
            'NewInstrumentTrace',   event.listener.empty(), ... % New data from an instrument object
            'NewAnalysisTrace',     event.listener.empty())     % New data from an analysis routine
    end
    
    properties (Access = private) 
        original_axes_position = []
        original_figure_position = []
        original_main_panel_position = []
        
        % User-defined instrument trace name
        user_trace_name = ''
    end
    
    methods (Access = public)
        
        % Recolor Daq according to the color scheme from the settings
        function applyLocalColorScheme(app)
            
            % This method provides luxury functionality and therefore it never
            % throws errors if something does not work
            try
                
                % Load the color scheme from local settings
                S = getLocalSettings('DaqColorScheme');
                
                % Apply the color scheme to the DAQ itself and to all the
                % analysis routines
                S.colorSchemeFcn(findFigure(app));
                
                for i = 1:length(app.RunningAnalysisRoutines)
                    Inst = app.RunningAnalysisRoutines(i).Instance;
                
                    if ~isempty(Inst) && isvalid(Inst) && ~isa(Inst, 'MyFit')
                        S.colorSchemeFcn(findFigure(Inst));
                    end
                end
            catch ME
                warning(['Could not apply color scheme. Error: ' ME.message])
            end
        end
    end
    
    methods (Access = private)
        
        % Add new trace to DAQ, store it under the appropriate node and plot it 
        function NewNode = addTraceNode(app, Trace, ParentNode, name)
            
            % Check if we are addiving the very first trace to DAQ
            is_first_node = isempty(app.DatatracesNode.Children) && ...
                isempty(app.AnalysistracesNode.Children);
            
            % We also need to check if the first trace is added to the
            % particular parent node
            is_first_subnode = isempty(ParentNode.Children);
            
            if is_first_node
                
                % Reset the order of colors which are assigned to new traces
                app.Axes.ColorOrderIndex = 1;
            end
            
            if ~is_first_subnode
                existing_names = {ParentNode.Children.Text};
            else
                existing_names = {};
            end
            
            % Make the name unique among the subnodes
            name = matlab.lang.makeUniqueStrings(name, ...
                existing_names);
            
            % Add a node to the trace display tree
            NewNode = uitreenode(ParentNode, 'NodeData', Trace);
            NewNode.Text = name;
            
            try 
                
                % Append new trace to the axes
                plot(Trace, app.Axes, 'DisplayName', name);
                
                % Indicate the trace display status
                NewNode.Icon = app.trace_disp_icon_filename;
            catch ME
                
                warning(ME.message);
                NewNode.Icon = app.trace_not_disp_icon_filename;
            end
            
            if is_first_subnode
                
                % Expand to make it more apparent that the first trace 
                % is added to the parent node
                expand(ParentNode)
            end
            
            if is_first_node
                
                % Select if the added trace is very first in DAQ
                setTraceSelection(app, NewNode)
            end
        end
        
        function f = createNewInstrDataCb(app, instr_name)
            function acquireNewData(~, EventData)
                
                % First create metadata for the newly acquired traces
                
                AcqMdt = MyMetadata.acq(instr_name);
                
                try 
                    InstrSettingsMdt = readInstrumentSettings(app.Collector);
                catch ME
                    warning(['Could not read instrument settings from ' ...
                        'Collector. Error: ' ME.message])
                    InstrSettingsMdt = MyMetadata.empty();
                end
                
                % Make the full metadata
                Mdt = [AcqMdt, InstrSettingsMdt];
                
                % Generate a name base for the new traces
                if strcmpi(app.FrominstrumentMenu.Checked, 'on')
                    trace_name_base = [instr_name '_trace'];
                else
                    trace_name_base = app.user_trace_name;
                end
                
                % Add new nodes containing the acquired traces
                for i = 1:length(EventData.traces)
                    try
                        
                        % Add optional tags from the event data and ensure that the
                        % full generated names are unique
                        trace_name = [trace_name_base, EventData.trace_tags{i}];
                    catch
                        trace_name = trace_name_base;
                    end
                    
                    % Copy metadata to the trace and add it as a new node
                    TmpTrace = copy(EventData.traces{i});
                    TmpTrace.UserMetadata = [copy(Mdt), TmpTrace.UserMetadata];
                    
                    NewNode = addTraceNode(app, TmpTrace, ...
                        app.DatatracesNode, trace_name);
                    
                    % Optionally save the trace
                    if strcmpi(app.AutosaveMenu.Checked, 'on')
                        saveTrace(app, NewNode, 'create_unique_name', true);
                    end
                end
            end
            
            f = @acquireNewData;
        end
        
        % Start the instrument selected in the AcquisitionInstrumentDropDown
        % and set up a new listener for its NewData event.
        function runSelectedInstrument(app)
            ind = app.AcquisitionInstrumentDropDown.Value;
            
            if ind == 0
                
                % No instrument selected
                return
            end

            name = app.AcqProgramList(ind).name;
            
            try
                Instr = eval(app.AcqProgramList(ind).run_expr);
            catch ME
                uialert(f, sprintf('An error occured while attempting to run %s: %s',...
                    name, ME.message), 'Warning', 'Icon', 'warning')
            end
            
            % Replace listener
            try
                delete(app.Listeners.NewInstrumentTrace)
            catch ME
                warning(['Could not delete the listener for the exising instrument ' ...
                    'NewData event. Error: ' ME.message])
            end
            
            app.Listeners.NewInstrumentTrace = addlistener(Instr, 'NewData', createNewInstrDataCb(app, name));
        end
    
        % Start new or bring into focus existing analysis routine
        % ind - index in AnalysisRoutineList
        function runAnalysisRoutine(app, ind)
            if ind == 0
                
                % No analysis routine selected
                return
            end
            
            Ar = app.RunningAnalysisRoutines(ind).Instance;
            
            if isempty(Ar) || ~isvalid(Ar)
                Sn = app.TraceTree.SelectedNodes;
            
                if isempty(Sn) || isequal(Sn.Parent, app.TraceTree)
                    
                    % No trace selected. We can start the routine anyhow 
                    % and push data to it later.
                    Data = MyTrace();
                    data_name = '';
                else
                    Data = Sn.NodeData;
                    data_name = Sn.Text;
                end
            
                ar_func = app.AnalysisRoutineList(ind).function;
                
                try
                    
                    % Run the analysis routine supplying the handles of data
                    % trace and axes for interactive plotting
                    Ar = feval(ar_func, 'Data', Data, 'Axes', app.Axes, 'enable_gui', true);
                catch ME
                    warning(['Analysis routine ''' app.AnalysisRoutineList(ind).title ...
                        ''' could not be started. Error: ' ME.message])
                    return
                end
                
                try
                    MyAnalysisRoutine.validate(Ar);
                catch ME
                    delete(Ar);
                    error(['Object created by ' ar_func 'is not a valid analysis routine. ' ME.message]);
                end
                
                % Optionally supply the trace name
                if isprop(Ar, 'data_name')
                    Ar.data_name = data_name;
                end
                
                % Add the analysis routine object to the cleanup list
                app.RunningAnalysisRoutines(ind).Instance = Ar;
                
                Fig = findFigure(Ar);
                
                if ~isa(Ar, 'MyFit')
                    
                    % We let fits define their own color scheme
                    try
                        
                        % Recolor the GUI
                        S = getLocalSettings('DaqColorScheme');
                        S.colorSchemeFcn(Fig);
                    catch ME
                        warning(ME.message)
                    end
                end
                
                centerFigure(Fig);
                
                % Replace NewProcessedData listener
                try
                    delete(app.Listeners.NewAnalysisTrace)
                catch ME
                    warning(['Could not delete the existing listener for ' ...
                        'NewProcessedData event. Error: ' ME.message])
                end
                
                app.Listeners.NewAnalysisTrace = addlistener(Ar, 'NewProcessedData', ...
                    @app.acquireNewAnalysisData);
            else
                
                setFocus(Ar);
            end
        end
    
        function name = findTraceName(app, AnTrace)
            if isempty(AnTrace) || ~isvalid(AnTrace)
                warning('Raw data is not found. Analysis trace is not valid.')
                name = '';
                return
            end
            
            TraceNodes = [app.DatatracesNode.Children; app.AnalysistracesNode.Children];
            
            for i = 1:length(TraceNodes)
                Tr = TraceNodes(i).NodeData;
                
                if (length(Tr.x) == length(AnTrace.x)) && (length(Tr.y) == length(AnTrace.y)) && ...
                        all(Tr.x == AnTrace.x) && all(Tr.y == AnTrace.y)
                    name = TraceNodes(i).Text;
                    return
                end
            end
            
            warning('Raw data is not found in the trace nodes.')
            name = '';
        end
    
        function acquireNewAnalysisData(app, Src, EventData)
            
            % Generate name for the trace from the name of data
            data_name = findTraceName(app, Src.Data);
            
            [trace_name_base, ~] = splitFilename(data_name);
            
            % Add new nodes containing the acquired traces
            for i = 1:length(EventData.traces)
                try
                    
                    % Add optional tags from the event data and ensure that the
                    % full generated names are unique
                    trace_name = [trace_name_base, EventData.trace_tags{i}];
                catch
                    trace_name = trace_name_base;
                end
                
                addTraceNode(app, copy(EventData.traces{i}), ...
                    app.AnalysistracesNode, trace_name);
            end
        end
    
        function saveTrace(app, TraceNode, varargin) 
            p = inputParser();
            
            % If file already exists, generate a uinique name rather than
            % show user the overwrite dialog.
            addParameter(p, 'create_unique_name', false, @islogical);
            parse(p,varargin{:});
            
            save_dir = app.WorkingdirectoryEditField.Value;
            if isempty(save_dir)
                 message = ['Please indicate working directory in order ' ...
                    'to save the trace'];
                uialert(app.DaqFigure, message, 'Warning', 'Icon', 'warning');
                return
            end
            
            % Append an ending to the file name if necessary
            filename = TraceNode.Text;
            [~, ext] = splitFilename(filename);
            
            if isempty(ext)
                
                % Add default file extension
                filename = [filename, app.default_file_ext];
            end
            
            fullfilename = fullfile(save_dir, filename);
            
            if p.Results.create_unique_name && exist(fullfilename, 'file')~=0
                fullfilename = createUniqueFileName(fullfilename); 
            end
            
            % Save in readable format using the method of MyTrace
            save(TraceNode.NodeData, fullfilename)
        end
        
        function setTraceSelection(app, Sn)
            % Sn = "Selected node"
            
            if nargin() == 1
                
                % Selected node is not provided explicitly
                Sn = app.TraceTree.SelectedNodes;
            else
                
                % Update the actual node selection (this step is skipped if the selection 
                % was updated using the GUI)
                try 
                    
                    % New selected node must be a child node of the trace
                    % tree, otherwise the code below fails
                    app.TraceTree.SelectedNodes = Sn;
                catch ME
                    Warning(['Could not update the trace node selection. Error: ' ME.message])
                end
            end
            
            updateTracePanel(app);
            
            if isempty(Sn) || isequal(Sn.Parent, app.TraceTree)
                
                % Do nothing if no node is selected or the selected node is
                % at the top level
                return
            end
            
            % Push new data to all open fits if an instrument trace node is
            % selected
            Trace = Sn.NodeData;
            trace_name = Sn.Text;
            
            for i = 1:length(app.RunningAnalysisRoutines)
                Inst = app.RunningAnalysisRoutines(i).Instance;
                
                if ~isempty(Inst) && isvalid(Inst)
                    Inst.Data = Trace;
                    
                    % Optionally supply the trace name
                    if isprop(Inst, 'data_name')
                        Inst.data_name = trace_name;
                    end
                end
            end
            
            % Update axes labels
            makeLabels(Trace, app.Axes);
        end
    
        % Callbacks that are shared between several events 
        function updateTracePanel(app)
            Sn = app.TraceTree.SelectedNodes;
            
            % Enable the trace panel only if a node corresponsing to
            % a trace is selected, not a top-level group node
            is_trace_node = ~isempty(Sn) && ...
                ~isequal(Sn, app.DatatracesNode) && ...
                ~isequal(Sn, app.AnalysistracesNode);
            
            for i = 1:length(app.SelectedTracePanel.Children)
                setIfChanged(app.SelectedTracePanel.Children(i), 'Enable', is_trace_node);
            end
            
            if ~is_trace_node
                return
            end
            
            Trace = Sn.NodeData;
            
            if ~isempty(Trace.file_name)
                app.savestatusLabel.Text = 'Saved';
                app.savestatusLabel.FontColor = MyAppColors.ok();
            else
                app.savestatusLabel.Text = 'Not saved';
                app.savestatusLabel.FontColor = MyAppColors.warning();
            end
            
            Ln = getLine(Trace, app.Axes);
            if ~isempty(Ln)
                app.ColorButton.BackgroundColor = Ln.Color;
                app.DisplayCheckBox.Value = strcmpi(Ln.Visible, 'on');
            end
        end
    
        function updatePlotMenu(app)
            setIfChanged(app.ResizeableMenu, 'Checked', app.DaqFigure.Resize);
            setIfChanged(app.GridxMenu,      'Checked', app.Axes.XGrid);
            setIfChanged(app.GridyMenu,      'Checked', app.Axes.YGrid);
            setIfChanged(app.MinorgridxMenu, 'Checked', app.Axes.XMinorGrid);
            setIfChanged(app.MinorgridyMenu, 'Checked', app.Axes.YMinorGrid);
            setIfChanged(app.LogscalexMenu,  'Checked', strcmpi(app.Axes.XScale, 'log'));
            setIfChanged(app.LogscaleyMenu,  'Checked', strcmpi(app.Axes.YScale, 'log'));
            setIfChanged(app.LegendMenu,     'Checked', app.Axes.Legend.Visible);
        end
    
        function deleteSelectedTraceNode(app)
            Sn = app.TraceTree.SelectedNodes;
            
            if isempty(Sn) || isequal(Sn.Parent, app.TraceTree)
                
                % No node or a top-level node is selected
                return
            end
            
            ParentNode = Sn.Parent;
            
            % Find the index of the selected node in its sub-category
            ind = arrayfun(@(x)isequal(x, Sn), ParentNode.Children);
            ind = find(ind, 1, 'first');
            
            % Delete the previously selected node
            delete(Sn.NodeData);
            delete(Sn);
            
            % Change selection to the next node
            if length(ParentNode.Children) >= ind
                setTraceSelection(app, ParentNode.Children(ind));
            elseif ind-1 >= 1 && length(ParentNode.Children) >= ind-1
                setTraceSelection(app, ParentNode.Children(ind-1));
            end
        end
    
        function is_visible = setTraceVisibility(app, Node, state)
            
            % The value of state input can be one of the following: 
            % true, false or 'toggle'
            Trace = Node.NodeData;
            
            % Set the visibility of selected line
            Ln = getLine(Trace, app.Axes);
            
            if isequal(state, 'toggle')
                if strcmpi(Ln.Visible, 'on')
                    new_state = 'off';
                else
                    new_state = 'on';
                end
            else
                new_state = state;
            end
            
            % Exclude invisible lines from the legend
            Ln.Visible = new_state;
            Ln.Annotation.LegendInformation.IconDisplayStyle = new_state;
            
            is_visible = strcmpi(Ln.Visible, 'on');
            
            % Change the node icon
            if is_visible
                Node.Icon = 'trace_disp_icon.png';
            else
                Node.Icon = 'trace_not_disp_icon.png';
            end
        end
    
        function initAnalysisRoutineList(app)
            try
                
                % First, delete any instance of the analysis routines that
                % are presently running
                for i = 1:length(app.RunningAnalysisRoutines)
                    Inst = app.RunningAnalysisRoutines(i).Instance;
                    if ~isempty(Inst)
                        delete(Inst);
                    end
                end
                
                try
                    app.AnalysisRoutineList = getLocalSettings('AnalysisRoutineList');
                catch
                    
                    disp('No analysis routines found in the local settings')
                    return
                end
                
                % Update the analysis drop down
                len = length(app.AnalysisRoutineList);
                
                app.AnalysisRoutineDropDown.Items = [{'Select ...'}, ...
                    {app.AnalysisRoutineList.title}];
                app.AnalysisRoutineDropDown.ItemsData = 0:len;
                
                % Initialize an empty array of appropriate size for the storage
                % of analysis routine instances 
                app.RunningAnalysisRoutines(len).Instance = [];
            catch ME
                warning(['Could not initialize the list of analysis routines. Error: ' ...
                    ME.message])
            end
        end
    
        function initDaqPrograms(app)
            try
                try
                    
                    % The list of instruments is populated from the list of run files.
                    FullProgList = getIcPrograms();
                catch
                    
                    disp('No instruments found in the local settings')
                    return
                end
                
                % Select those programs that are enabled and can produce traces.
                ind = [FullProgList.enabled] & [FullProgList.data_source];
                app.AcqProgramList = FullProgList(ind);
                
                app.AcquisitionInstrumentDropDown.Items = [{'Select ...'}, ...
                    {app.AcqProgramList.title}];
                app.AcquisitionInstrumentDropDown.ItemsData = 0:length(app.AcqProgramList);
            catch ME
                warning(['Could not initialize the list of data acquisition programs. Error: ' ...
                    ME.message])
            end
        end
        
        function connectCollector(app)
            try
                C = MyCollector.instance();
                
                % Add this app to the collector
                name = matlab.lang.makeUniqueStrings(class(app), C.running_apps);
                addApp(C, app, name);
                
                app.Collector = C;
            catch ME
                warning(['Could not connect collector. Error:\n' ME.message])
            end
        end
    
        % Change the 'Checked' property of menu between 'on' and 'off'
        function toggleMenu(~, Menu)
            if strcmpi(Menu.Checked, 'on')
                Menu.Checked = 'off';
            else
                Menu.Checked = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Store the original axes position for future resizing
            app.original_axes_position = app.Axes.OuterPosition;
            app.original_figure_position = app.DaqFigure.Position;
            app.original_main_panel_position = app.MainPanel.Position;
            
            % Necessary for proper resizing behavior
            app.Axes.PlotBoxAspectRatioMode = 'auto';
            
            % Create legend that is hidden by default
            Leg = legend(app.Axes);
            Leg.Visible = 'off';
            Leg.Interpreter = 'none';
            
            % Add new traces to Axes without deleting previous data
            hold(app.Axes, 'on');

            % Delete dummy trace nodes
            delete(app.AnalysistracesNode.Children);
            delete(app.DatatracesNode.Children);
            
            connectCollector(app);
            
            app.SessionNameEditField.Value = app.Collector.session_name;
            
            try
                path = createSessionPath();
            catch
                
                % Session path could not be created
                app.SessionNameEditField.Enable = 'off';
                path = fullfile('C:', app.SessionNameEditField.Value);
            end
            app.WorkingdirectoryEditField.Value = path;
            
            initDaqPrograms(app);
            initAnalysisRoutineList(app);
            
            updateTracePanel(app);
            
            applyLocalColorScheme(app);
            centerFigure(app.DaqFigure);
        end

        % Size changed function: DaqFigure
        function DaqFigureSizeChanged(app, event)
            fig_position = app.DaqFigure.Position;
            
            if isempty(app.original_axes_position) || ...
                    isempty(app.original_figure_position)
                return
            end
            
            % Resize width and hight of axes so that the top left corner
            % remains where it was and the distances between the axes and
            % the figure boundaries are constant
            x = app.original_axes_position(1);
            y = app.original_axes_position(2);
            
            dx = app.original_figure_position(3)- ...
                (app.original_axes_position(1)+app.original_axes_position(3));
            dy = app.original_figure_position(4)- ...
                (app.original_axes_position(2)+app.original_axes_position(4));
            
            w = fig_position(3)-x-dx;
            h = fig_position(4)-y-dy;
            
            app.Axes.OuterPosition = [x, y, w, h];
            
            % Move the analysis panel to the top right
            dy = app.original_main_panel_position(4)- ...
                (app.original_main_panel_position(2)+ ...
                app.original_main_panel_position(4));
            
            w = app.MainPanel.Position(3);
            h = app.MainPanel.Position(4);
            x = fig_position(3)-w;
            y = fig_position(4)-h-dy;
            
            app.MainPanel.Position=[x, y, w, h];
        end

        % Close request function: DaqFigure
        function DaqFigureCloseRequest(app, event)
            fn = fieldnames(app.Listeners);
            
            for i = 1:length(fn)
                try
                    delete(app.Listeners.(fn{i}))
                catch
                end
            end
            
            % Delete all the analysis routine objects and associated
            % listeners
            for i = 1:length(app.RunningAnalysisRoutines)
                Rs = app.RunningAnalysisRoutines(i);
                
                if ~isempty(Rs.Instance) && isvalid(Rs.Instance)
                    delete(Rs.Instance);
                end
            end
            
            delete(app)
        end

        % Value changed function: AcquisitionInstrumentDropDown
        function AcquisitionInstrumentDropDownValueChanged(app, event)
            runSelectedInstrument(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            deleteSelectedTraceNode(app);
        end

        % Selection changed function: TraceTree
        function TraceTreeSelectionChanged(app, event)
            setTraceSelection(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveTrace(app, app.TraceTree.SelectedNodes);
            
            app.savestatusLabel.Text = 'Saved';
            app.savestatusLabel.FontColor = MyAppColors.ok();
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            color = uisetcolor(app.ColorButton.BackgroundColor);
            
            % Set the new color of line of the selected trace
            Ln = getLine(app.TraceTree.SelectedNodes.NodeData, app.Axes);
            
            Ln.Color = color;
            app.ColorButton.BackgroundColor = Ln.Color;
            
            % return focus to the DAQ window
            setFocus(app.DaqFigure);
        end

        % Value changed function: DisplayCheckBox
        function DisplayCheckBoxValueChanged(app, event)
            value = app.DisplayCheckBox.Value;
            
            SelNode = app.TraceTree.SelectedNodes;
            setTraceVisibility(app, SelNode, value);
        end

        % Window key press function: DaqFigure
        function DaqFigureWindowKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'delete'
                    deleteSelectedTraceNode(app);
                case 's'
                    
                    % Save trace when Ctrl+s is pressed
                    if ismember('control', event.Modifier)
                        Sn = app.TraceTree.SelectedNodes;
                        
                        if ~isempty(Sn)
                            saveTrace(app, app.TraceTree.SelectedNodes);
                
                            app.savestatusLabel.Text = 'Saved';
                            app.savestatusLabel.FontColor = MyAppColors.ok();
                        end
                    end
                case 'x'
                    
                    % Change the visiblity of trace if Ctrl+x is pressed
                    if ismember('control', event.Modifier)
                        Sn = app.TraceTree.SelectedNodes;
                        
                        if ~isempty(Sn)
                            app.DisplayCheckBox.Value = ...
                                setTraceVisibility(app, app.TraceTree.SelectedNodes, 'toggle');
                        end
                    end
                case 'r'
                    
                    % Replot the selected trace to bring it on top
                    if ismember('control', event.Modifier)
                        Sn = app.TraceTree.SelectedNodes;
                        
                        if ~isempty(Sn) && ~isempty(Sn.NodeData)
                            Trace = Sn.NodeData;
                            
                            % Delete existing line and plot again with the
                            % same color
                            Line = getLine(Trace, app.Axes);
                            color = Line.Color;
                            
                            deleteLine(Trace, app.Axes);
                            
                            NewLine = plot(Trace, app.Axes, 'DisplayName', Sn.Text);
                            NewLine.Color = color;
                        end
                    end
            end
        end

        % Value changed function: AnalysisRoutineDropDown
        function AnalysisRoutineDropDownValueChanged(app, event)
            
            % Start a new instance of analysis routine of the selected
            % type
            ind = app.AnalysisRoutineDropDown.Value;
            runAnalysisRoutine(app, ind);
        end

        % Menu selected function: 
        % UpdatethelistofanalysisroutinesMenu
        function UpdatethelistofanalysisroutinesMenuSelected(app, event)
            initAnalysisRoutineList(app);
        end

        % Menu selected function: UpdatethelistofDAQprogramsMenu
        function UpdatethelistofDAQprogramsMenuSelected(app, event)
            initDaqPrograms(app);
        end

        % Menu selected function: LoadMenu
        function LoadtraceButtonPushed(app, event)
            default_dir = app.WorkingdirectoryEditField.Value;
            
            if ~exist(default_dir, 'dir')
                try
                    default_dir = getLocalSettings('measurement_base_dir');
                catch 
                end
            end
            
            [fn, path] = uigetfile('*.*', 'Select a MyTrace file', default_dir, ...
                'MultiSelect', 'on');
            
            if isequal(fn, 0)
                
                % No file was choosen. Focus the DAQ figure and return. 
                setFocus(app.DaqFigure);
                return
            end
            
            % For uniformity, convert to cell even if only one file was selected
            if ~iscell(fn)
                fn = {fn};
            end
            
            for i = 1:length(fn)
                
                % Load the selected traces one by one
                NewTrace = MyTrace.load(fullfile(path, fn{i}));
                
                [name, ext] = splitFilename(fn{i});
                if ~strcmpi(ext, app.default_file_ext)
                    
                    % Keep the file extension in the name only if 
                    % it is not equal to the default
                    name = fn{i};
                end
               
                addTraceNode(app, NewTrace, app.DatatracesNode, name);
            end
            
            % Return focus to the DAQ figure, as it was pushed behind
            % the main MATLAB window by the dialog
            setFocus(app.DaqFigure);
        end

        % Menu selected function: PlotoptionsMenu
        function PlotoptionsMenuSelected(app, event)
            updatePlotMenu(app);
        end

        % Menu selected function: ResizeableMenu
        function ResizeableMenuSelected(app, event)
            toggleMenu(app, app.ResizeableMenu); % Menu state must be switched manually
            setIfChanged(app.DaqFigure, 'Resize', app.ResizeableMenu.Checked);
        end

        % Menu selected function: LogscalexMenu
        function LogscalexMenuSelected(app, event)
            toggleMenu(app, app.LogscalexMenu); % Menu state must be switched manually
            
            if strcmpi(app.LogscalexMenu.Checked, 'on')
                setIfChanged(app.Axes, 'XScale', 'log');
            else
                setIfChanged(app.Axes, 'XScale', 'linear');
            end
        end

        % Menu selected function: LogscaleyMenu
        function LogscaleyMenuSelected(app, event)
            toggleMenu(app, app.LogscaleyMenu);
            
            if strcmpi(app.LogscaleyMenu.Checked, 'on')
                setIfChanged(app.Axes, 'YScale', 'log');
            else
                setIfChanged(app.Axes, 'YScale', 'linear');
            end
        end

        % Menu selected function: GridxMenu
        function GridxMenuSelected(app, event)
            toggleMenu(app, app.GridxMenu);
            setIfChanged(app.Axes, 'XGrid', app.GridxMenu.Checked);
        end

        % Menu selected function: GridyMenu
        function GridyMenuSelected(app, event)
            toggleMenu(app, app.GridyMenu);
            setIfChanged(app.Axes, 'YGrid', app.GridyMenu.Checked);
        end

        % Menu selected function: MinorgridxMenu
        function MinorgridxMenuSelected(app, event)
            toggleMenu(app, app.MinorgridxMenu);
            setIfChanged(app.Axes, 'XMinorGrid', app.MinorgridxMenu.Checked);
        end

        % Menu selected function: MinorgridyMenu
        function MinorgridyMenuSelected(app, event)
            toggleMenu(app, app.MinorgridyMenu);
            setIfChanged(app.Axes, 'YMinorGrid', app.MinorgridyMenu.Checked);
        end

        % Menu selected function: LegendMenu
        function LegendMenuSelected(app, event)
            toggleMenu(app, app.LegendMenu);
            setIfChanged(app.Axes.Legend, 'Visible', app.LegendMenu.Checked);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            default_dir = app.WorkingdirectoryEditField.Value;
            
            if ~exist(default_dir, 'dir')
                try
                    default_dir = getLocalSettings('measurement_base_dir');
                catch
                end
            end
            
            dir = uigetdir(default_dir);
            
            if dir == 0
                
                % No path was choosen
                setFocus(app.DaqFigure);
                return
            end
            
            % Split the full name of the choosen directory, regard the last
            % part of the path as session name and the rest as base folder
            [~, session_name] = splitSessionPath(dir);

            app.SessionNameEditField.Value = session_name;
            app.WorkingdirectoryEditField.Value = dir;
            
            % Return focus to the DAQ figure, as it was pushed behind
            % the main MATLAB window by the dialog
            setFocus(app.DaqFigure);
        end

        % Menu selected function: ReconnectCollectorMenu
        function ReconnectCollectorMenuSelected(app, event)
            connectCollector(app);
        end

        % Node text changed function: TraceTree
        function TraceTreeNodeTextChanged(app, event)
            Trace = event.Node.NodeData;
            
            if isempty(Trace)
                return
            end
            
            % Update the trace label in legend
            Line = getLine(Trace, app.Axes);
            Line.DisplayName = event.Node.Text;
        end

        % Button pushed function: ShowmetadataButton
        function ShowmetadataButtonPushed(app, event)
            Sn = app.TraceTree.SelectedNodes;
            if isempty(Sn) || isequal(Sn.Parent, app.TraceTree)
                
                % Do nothing if no node is selected or the selected node is
                % at the top level
                return
            end
            
            Trace = Sn.NodeData;
            
            if isempty(Trace)
                return
            end
            
            % Print and show the user metadata of the selected trace
            message = mdt2str(Trace.UserMetadata);
            uialert(app.DaqFigure, message, 'Metadata', 'Icon', 'info');
        end

        % Button pushed function: AcquisitioninstrumentButton
        function AcquisitioninstrumentButtonPushed(app, event)
            runSelectedInstrument(app);
        end

        % Button pushed function: AnalysisroutineButton
        function AnalysisroutineButtonPushed(app, event)
            
            % Set focus to the currently selected analysis routine
            ind = app.AnalysisRoutineDropDown.Value;
            runAnalysisRoutine(app, ind);
        end

        % Menu selected function: FrominstrumentMenu
        function FrominstrumentMenuSelected(app, event)
            app.FrominstrumentMenu.Checked = 'on';
            app.UserdefinedMenu.Checked = 'off';
        end

        % Menu selected function: UserdefinedMenu
        function UserdefinedMenuSelected(app, event)
            if strcmpi(app.UserdefinedMenu.Checked, 'on') || isempty(app.user_trace_name)
                
                % If the menue is already checked, invoke a dialog to enter
                % the name
                out = inputdlg('Enter the name that will be assigned to new traces', ...
                    'Trace name', [1 50], {app.user_trace_name});
                
                if ~isempty(out)
                    app.user_trace_name = out{1};
                    app.UserdefinedMenu.Text = ['User defined: ' app.user_trace_name];
                end
                
                % Return focus to the main figure
                setFocus(app.DaqFigure);
            end
            
            % Check User defined and uncheck other options
            app.FrominstrumentMenu.Checked = 'off';
            app.UserdefinedMenu.Checked = 'on';
        end

        % Menu selected function: AutosaveMenu
        function AutosaveMenuSelected(app, event)
            toggleMenu(app, app.AutosaveMenu);
        end

        % Menu selected function: OpenDAQmenuMenu
        function OpenDAQmenuMenuSelected(app, event)
            MyDaqMenu();
        end

        % Value changed function: SessionNameEditField
        function SessionNameEditFieldValueChanged(app, event)
            value = app.SessionNameEditField.Value;
            
            try
                app.Collector.session_name = value;
            catch ME
                warning(ME.message)
            end
            
            % Update the full path
            app.WorkingdirectoryEditField.Value = createSessionPath();
        end

        % Menu selected function: FontsizeMenu
        function FontsizeMenuSelected(app, event)
            
            % Invoke a dilog for changing the font size in the main plot
            answer = inputdlg({'Enter font size:'}, 'Axes font', [1, 35], ...
                {sprintf('%i', app.Axes.FontSize)});
            
            if ~isempty(answer)
                app.Axes.FontSize = sscanf(answer{1}, '%i');
            end
            
            % Return focus to the Daq figure
            setFocus(app.DaqFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DaqFigure = uifigure('Visible', 'off');
            app.DaqFigure.Name = 'DAQ';
            app.DaqFigure.CloseRequestFcn = createCallbackFcn(app, @DaqFigureCloseRequest, true);
            app.DaqFigure.SizeChangedFcn = createCallbackFcn(app, @DaqFigureSizeChanged, true);
            app.DaqFigure.WindowKeyPressFcn = createCallbackFcn(app, @DaqFigureWindowKeyPress, true);
            app.MainMenu = uimenu(app.DaqFigure);
            app.MainMenu.Text = 'Main';
            app.OpenDAQmenuMenu = uimenu(app.MainMenu);
            app.OpenDAQmenuMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDAQmenuMenuSelected, true);
            app.OpenDAQmenuMenu.Text = 'Open DAQ menu';
            app.UpdatethelistofDAQprogramsMenu = uimenu(app.MainMenu);
            app.UpdatethelistofDAQprogramsMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdatethelistofDAQprogramsMenuSelected, true);
            app.UpdatethelistofDAQprogramsMenu.Text = 'Update the list of DAQ programs';
            app.UpdatethelistofanalysisroutinesMenu = uimenu(app.MainMenu);
            app.UpdatethelistofanalysisroutinesMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdatethelistofanalysisroutinesMenuSelected, true);
            app.UpdatethelistofanalysisroutinesMenu.Text = 'Update the list of analysis routines';
            app.ReconnectCollectorMenu = uimenu(app.MainMenu);
            app.ReconnectCollectorMenu.MenuSelectedFcn = createCallbackFcn(app, @ReconnectCollectorMenuSelected, true);
            app.ReconnectCollectorMenu.Text = 'Reconnect Collector';
            app.PlotoptionsMenu = uimenu(app.DaqFigure);
            app.PlotoptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotoptionsMenuSelected, true);
            app.PlotoptionsMenu.Text = 'Plot options';
            app.ResizeableMenu = uimenu(app.PlotoptionsMenu);
            app.ResizeableMenu.MenuSelectedFcn = createCallbackFcn(app, @ResizeableMenuSelected, true);
            app.ResizeableMenu.Text = 'Resizeable';
            app.LogscalexMenu = uimenu(app.PlotoptionsMenu);
            app.LogscalexMenu.MenuSelectedFcn = createCallbackFcn(app, @LogscalexMenuSelected, true);
            app.LogscalexMenu.Text = 'Log scale x';
            app.LogscaleyMenu = uimenu(app.PlotoptionsMenu);
            app.LogscaleyMenu.MenuSelectedFcn = createCallbackFcn(app, @LogscaleyMenuSelected, true);
            app.LogscaleyMenu.Text = 'Log scale y';
            app.GridxMenu = uimenu(app.PlotoptionsMenu);
            app.GridxMenu.MenuSelectedFcn = createCallbackFcn(app, @GridxMenuSelected, true);
            app.GridxMenu.Text = 'Grid x';
            app.GridyMenu = uimenu(app.PlotoptionsMenu);
            app.GridyMenu.MenuSelectedFcn = createCallbackFcn(app, @GridyMenuSelected, true);
            app.GridyMenu.Text = 'Grid y';
            app.MinorgridxMenu = uimenu(app.PlotoptionsMenu);
            app.MinorgridxMenu.MenuSelectedFcn = createCallbackFcn(app, @MinorgridxMenuSelected, true);
            app.MinorgridxMenu.Text = 'Minor grid x';
            app.MinorgridyMenu = uimenu(app.PlotoptionsMenu);
            app.MinorgridyMenu.MenuSelectedFcn = createCallbackFcn(app, @MinorgridyMenuSelected, true);
            app.MinorgridyMenu.Text = 'Minor grid y';
            app.LegendMenu = uimenu(app.PlotoptionsMenu);
            app.LegendMenu.MenuSelectedFcn = createCallbackFcn(app, @LegendMenuSelected, true);
            app.LegendMenu.Text = 'Legend';
            app.FontsizeMenu = uimenu(app.PlotoptionsMenu);
            app.FontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontsizeMenuSelected, true);
            app.FontsizeMenu.Text = 'Font size';
            app.TraceMenu = uimenu(app.DaqFigure);
            app.TraceMenu.Text = 'Trace';
            app.LoadMenu = uimenu(app.TraceMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadtraceButtonPushed, true);
            app.LoadMenu.Separator = 'on';
            app.LoadMenu.Text = 'Load';
            app.NewtracenameMenu = uimenu(app.TraceMenu);
            app.NewtracenameMenu.Tooltip = {'Choose the way default trace names are generated'};
            app.NewtracenameMenu.Separator = 'on';
            app.NewtracenameMenu.Text = 'New trace name';
            app.FrominstrumentMenu = uimenu(app.NewtracenameMenu);
            app.FrominstrumentMenu.MenuSelectedFcn = createCallbackFcn(app, @FrominstrumentMenuSelected, true);
            app.FrominstrumentMenu.Checked = 'on';
            app.FrominstrumentMenu.Text = 'From instrument';
            app.UserdefinedMenu = uimenu(app.NewtracenameMenu);
            app.UserdefinedMenu.MenuSelectedFcn = createCallbackFcn(app, @UserdefinedMenuSelected, true);
            app.UserdefinedMenu.Tooltip = {'Click twice to edit  the name'};
            app.UserdefinedMenu.Text = 'User defined: ';
            app.AutosaveMenu = uimenu(app.TraceMenu);
            app.AutosaveMenu.MenuSelectedFcn = createCallbackFcn(app, @AutosaveMenuSelected, true);
            app.AutosaveMenu.Tooltip = {'Automatically save new tinstrument traces as they are acquired'};
            app.AutosaveMenu.Text = 'Auto save';
            app.Axes = uiaxes(app.DaqFigure);
            title(app.Axes, '')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            app.MainPanel = uipanel(app.DaqFigure);
            app.DropDownLabel = uilabel(app.MainPanel);
            app.DropDownLabel.Text = '';
            app.AcquisitionInstrumentDropDown = uidropdown(app.MainPanel);
            app.AcquisitionInstrumentDropDown.Items = {'Select ...', 'Dpo4034Nano1', 'Option 3', 'Option 4'};
            app.AcquisitionInstrumentDropDown.ValueChangedFcn = createCallbackFcn(app, @AcquisitionInstrumentDropDownValueChanged, true);
            app.AcquisitionInstrumentDropDown.Value = 'Dpo4034Nano1';
            app.DropDownLabel_2 = uilabel(app.MainPanel);
            app.DropDownLabel_2.Text = '';
            app.AnalysisRoutineDropDown = uidropdown(app.MainPanel);
            app.AnalysisRoutineDropDown.Items = {'Select ...', 'Option 2', 'Option 3', 'Option 4'};
            app.AnalysisRoutineDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisRoutineDropDownValueChanged, true);
            app.AnalysisRoutineDropDown.Value = 'Select ...';
            app.TraceTree = uitree(app.MainPanel);
            app.TraceTree.SelectionChangedFcn = createCallbackFcn(app, @TraceTreeSelectionChanged, true);
            app.TraceTree.NodeTextChangedFcn = createCallbackFcn(app, @TraceTreeNodeTextChanged, true);
            app.TraceTree.Editable = 'on';
            app.TraceTree.Tooltip = {'Keyboard shortcuts:'; 'del       - delete trace'; 'Ctrl+s  - save'; 'Ctrl+x  - hide/show'; 'Ctrl+r   - replot'};
            app.DatatracesNode = uitreenode(app.TraceTree);
            app.DatatracesNode.Text = 'Data traces';
            app.cavitytransmissionspectrumNode = uitreenode(app.DatatracesNode);
            app.cavitytransmissionspectrumNode.Icon = 'trace_disp_icon.png';
            app.cavitytransmissionspectrumNode.Text = '1. cavity transmission spectrum';
            app.AnalysistracesNode = uitreenode(app.TraceTree);
            app.AnalysistracesNode.Text = 'Analysis traces';
            app.Fit1Node = uitreenode(app.AnalysistracesNode);
            app.Fit1Node.Text = 'Fit1';
            app.Fit2Node = uitreenode(app.AnalysistracesNode);
            app.Fit2Node.Text = 'Fit2';
            app.Node7 = uitreenode(app.AnalysistracesNode);
            app.Node7.Text = 'Node7';
            app.Node8 = uitreenode(app.AnalysistracesNode);
            app.Node8.Text = 'Node8';
            app.Node9 = uitreenode(app.AnalysistracesNode);
            app.Node9.Text = 'Node9';
            app.SelectedTracePanel = uipanel(app.MainPanel);
            app.SelectedTracePanel.Tooltip = {'Selected trace controls'};
            app.SaveButton = uibutton(app.SelectedTracePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DeleteButton = uibutton(app.SelectedTracePanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.savestatusLabel = uilabel(app.SelectedTracePanel);
            app.savestatusLabel.Text = 'save status';
            app.ShowmetadataButton = uibutton(app.SelectedTracePanel, 'push');
            app.ShowmetadataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowmetadataButtonPushed, true);
            app.ShowmetadataButton.Text = 'Show metadata';
            app.DisplayCheckBox = uicheckbox(app.SelectedTracePanel);
            app.DisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayCheckBoxValueChanged, true);
            app.DisplayCheckBox.Text = 'Display';
            app.ColorButton = uibutton(app.SelectedTracePanel, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = '';
            app.AcquisitioninstrumentButton = uibutton(app.MainPanel, 'push');
            app.AcquisitioninstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @AcquisitioninstrumentButtonPushed, true);
            app.AcquisitioninstrumentButton.Text = 'Acquisition instrument';
            app.AnalysisroutineButton = uibutton(app.MainPanel, 'push');
            app.AnalysisroutineButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisroutineButtonPushed, true);
            app.AnalysisroutineButton.Text = 'Analysis routine';
            app.MeasurementsessionnameEditFieldLabel = uilabel(app.MainPanel);
            app.MeasurementsessionnameEditFieldLabel.Text = 'Measurement session name';
            app.SessionNameEditField = uieditfield(app.MainPanel, 'text');
            app.SessionNameEditField.ValueChangedFcn = createCallbackFcn(app, @SessionNameEditFieldValueChanged, true);
            app.WorkingdirectoryEditFieldLabel = uilabel(app.DaqFigure);
            app.WorkingdirectoryEditFieldLabel.Text = 'Working directory';
            app.WorkingdirectoryEditField = uieditfield(app.DaqFigure, 'text');
            app.OpenButton = uibutton(app.DaqFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Select an existing folder'};
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.DaqFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyDaq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DaqFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DaqFigure)
        end
    end
end