classdef MyDaqMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DaqMenuFigure                  matlab.ui.Figure
        MainTabGroup                   matlab.ui.container.TabGroup
        SessionTab                     matlab.ui.container.Tab
        AllProgramsPanel               matlab.ui.container.Panel
        RunButton                      matlab.ui.control.Button
        InstrumentmanagerButton        matlab.ui.control.Button
        ReloadlistButton               matlab.ui.control.Button
        RuninbackgroundButton          matlab.ui.control.Button
        AllprogramsLabel               matlab.ui.control.Label
        ProgramListBox                 matlab.ui.control.ListBox
        ActiveProgramsPanel            matlab.ui.container.Panel
        InstrumentList                 matlab.ui.control.ListBox
        PresentlyactiveLabel           matlab.ui.control.Label
        DeleteinstrumentButton         matlab.ui.control.Button
        CloseGUIButton                 matlab.ui.control.Button
        OpennewDAQButton               matlab.ui.control.Button
        SavesessionButton              matlab.ui.control.Button
        LoadsessionButton              matlab.ui.control.Button
        ShowMetadataButton             matlab.ui.control.Button
        SettingsTab                    matlab.ui.container.Tab
        MeasurementbasedirectoryEditFieldLabel  matlab.ui.control.Label
        MeasBaseDirEditField           matlab.ui.control.EditField
        MeasBaseDirBrowseButton        matlab.ui.control.Button
        InstrumentcolorschemeDropDownLabel  matlab.ui.control.Label
        InstrumentcolorschemeDropDown  matlab.ui.control.DropDown
        DefaultlogdirectoryEditFieldLabel  matlab.ui.control.Label
        DefaultLogDirEditField         matlab.ui.control.EditField
        DefaultLogDirBrowseButton      matlab.ui.control.Button
        DAQcolorschemeDropDownLabel    matlab.ui.control.Label
        DAQcolorschemeDropDown         matlab.ui.control.DropDown
        AnalysisRoutineTable           matlab.ui.control.Table
        AnalysisroutinesLabel          matlab.ui.control.Label
        LocalbasedirectoryEditFieldLabel  matlab.ui.control.Label
        LocalbasedirEditField          matlab.ui.control.EditField
        AddAnalysisRoutineButton       matlab.ui.control.Button
        RemoveAnalysisRoutineButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        ProgList     MyProgramDescriptor
        Collector
    end
    
    properties (Access = private)
        Listeners = struct()
    end
    
    methods (Access = private)

        function updateProgramList(app)
            
            try
            
                % Reload the list of local instrument control programs
                FullProgList = getIcPrograms();
            catch
                
                % Return if the instrument list has not yet been
                % initialized
                return
            end
            
            ind = [FullProgList.enabled];
            
            app.ProgList = FullProgList(ind);
            
            app.ProgramListBox.Items = {app.ProgList.title};
            app.ProgramListBox.ItemsData = 1:length(app.ProgList);
            
            if isempty(app.ProgramListBox.Value) && ~isempty(app.ProgList)
                app.ProgramListBox.Value = 1;
            end
            
            % Enable state of the run buttons depends on the current selection in the list
            updateRunButtonsAccess(app);
        end
        
        function updateInstrList(app)
            app.InstrumentList.Items = app.Collector.running_instruments;
            
            if isempty(app.InstrumentList.Items) || isempty(app.InstrumentList.Value)
                app.DeleteinstrumentButton.Enable = 'off';
                app.CloseGUIButton.Enable = 'off';
            else
                app.DeleteinstrumentButton.Enable = 'on';
                app.CloseGUIButton.Enable = 'on';
            end
        end
    
        function updateLocalSettingsDisp(app)
            S = getLocalSettings();
                    
            try
                app.MeasBaseDirEditField.Value = S.measurement_base_dir;
            catch
            end
            
            try
                app.DefaultLogDirEditField.Value = S.default_log_dir;
            catch
            end
            
            try
                app.InstrumentcolorschemeDropDown.Value = S.ColorScheme.name;
            catch
            end
            
            try
                app.DAQcolorschemeDropDown.Value = S.DaqColorScheme.name;
            catch
            end
            
            try
                app.LocalbasedirEditField.Value = getLocalBaseDir();
            catch
            end
            
            try
                
                % Ensure column format for proper conversion to cell
                List = S.AnalysisRoutineList(:);
                
                % Display the values of structure fields in the table
                app.AnalysisRoutineTable.Data = struct2cell(List)';
            catch
            end
        end
    
        function saveAnalysisRoutineList(app)
            
            % Convert the table data to structure array
            S = cell2struct(app.AnalysisRoutineTable.Data', {'title', 'function'});
            
            % Save the array
            setLocalSettings('AnalysisRoutineList', S);
        end
        
        % Delete all the stored listeners
        function deleteListeners(app)
            fn = fieldnames(app.Listeners);
            for i = 1:length(fn)
                obj_name = fn{i};
                
                event_names = fieldnames(app.Listeners.(obj_name));
                for j = 1:length(event_names)
                    delete(app.Listeners.(obj_name).(event_names{j}));
                end
            end
        end
    
        function updateRunButtonsAccess(app)
            ind = app.ProgramListBox.Value;
            
            if ~isempty(ind)
                
                if isempty(app.ProgList(ind).run_expr)
                    app.RunButton.Enable = 'off';
                else
                    app.RunButton.Enable = 'on';
                end
                
                if isempty(app.ProgList(ind).run_bg_expr)
                    app.RuninbackgroundButton.Enable = 'off';
                else
                    app.RuninbackgroundButton.Enable = 'on';
                end
            else
                app.RunButton.Enable = 'off';
                app.RuninbackgroundButton.Enable = 'off';
            end
        end
        
        function collectorDeletedCallback(app, ~, ~)
            app.InstrumentList.Enable = 'off';
            app.DeleteinstrumentButton.Enable = 'off';
        end
        
        function deleteSelectedInstr(app)
            
            % Delete selected instrument from the running instruments list
            name = app.InstrumentList.Value;
            if ~isempty(name)
                Instr = getInstrument(app.Collector, name);
                delete(Instr);
                updateInstrList(app);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                
                % Create an app instance only if it is not running already
                runSingletonApp(app);
            catch
                return
            end
            
            % Get the unique instance of Collector and initialize its GUI tab
            app.Collector = MyCollector.instance();
            updateInstrList(app);
            
            app.Listeners.Collector.InstrChange = ...
                addlistener(app.Collector, 'InstrList', 'PostSet', @(~,~) updateInstrList(app));
            
            app.Listeners.Collector.Deletion = ...
                addlistener(app.Collector, 'ObjectBeingDestroyed', @app.collectorDeletedCallback);
            
            updateProgramList(app);
        end

        % Close request function: DaqMenuFigure
        function DaqMenuFigureCloseRequest(app, event)
            deleteListeners(app);
            delete(app);
        end

        % Selection change function: MainTabGroup
        function MainTabGroupSelectionChanged(app, event)
            selectedTab = app.MainTabGroup.SelectedTab;
            
            switch selectedTab
                case app.SessionTab
                    updateProgramList(app);
                case app.SettingsTab
                    updateLocalSettingsDisp(app);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            ind = app.ProgramListBox.Value;
            if ~isempty(ind)
                eval([app.ProgList(ind).run_expr,';']);
            end
        end

        % Button pushed function: ReloadlistButton
        function ReloadlistButtonPushed(app, event)
            updateProgramList(app);
        end

        % Button pushed function: InstrumentmanagerButton
        function InstrumentmanagerButtonPushed(app, event)
            MyInstrumentManager();
        end

        % Button pushed function: MeasBaseDirBrowseButton
        function MeasBaseDirBrowseButtonPushed(app, event)
            dir_name = uigetdir(app.MeasBaseDirEditField.Value);
            
            if dir_name~=0
                
                % if a new file was choosen
                app.MeasBaseDirEditField.Value = dir_name;
                setLocalSettings('measurement_base_dir', dir_name);
            end
            
            % Bring focus back to the app window
            setFocus(app);
        end

        % Value changed function: MeasBaseDirEditField
        function MeasBaseDirEditFieldValueChanged(app, event)
            value = app.MeasBaseDirEditField.Value;
            setLocalSettings('measurement_base_dir', value);
        end

        % Value changed function: ProgramListBox
        function ProgramListBoxValueChanged(app, event)
            updateRunButtonsAccess(app);
        end

        % Button pushed function: RuninbackgroundButton
        function RuninbackgroundButtonPushed(app, event)
            ind = app.ProgramListBox.Value;
            if ~isempty(ind)
                eval([app.ProgList(ind).run_bg_expr,';']);
            end
        end

        % Button pushed function: DeleteinstrumentButton
        function DeleteinstrumentButtonPushed(app, event)
            deleteSelectedInstr(app);
        end

        % Value changed function: InstrumentcolorschemeDropDown
        function InstrumentcolorschemeDropDownValueChanged(app, event)
            S = struct();
            S.name = lower(app.InstrumentcolorschemeDropDown.Value);
            S.colorSchemeFcn = MyAppColors.createColorSchemeFcn(S.name);
            
            try
                
                % Recolor running apps, except MyDaqs
                app_names = app.Collector.running_apps;
                
                for i = 1:length(app_names)
                    RunningApp = getApp(app.Collector, app_names{i});
                    if ~isa(RunningApp, 'MyDaq')
                        S.colorSchemeFcn(findFigure(RunningApp));
                    end
                end
            catch ME
                warning(['Could not recolor the running apps. Error: ' ME.message]);
            end
            
            try
                
                % Recolor the GUIs of all running instruments
                instr_names = app.Collector.running_instruments;
                
                for i = 1:length(instr_names)
                    Instr = getInstrument(app.Collector, instr_names{i});
                    Fig = findFigure(Instr);
                    if ~isempty(Fig)
                        S.colorSchemeFcn(Fig);
                    end
                end
            catch ME
                warning(['Could not recolor the instrument GUIs. Error: ' ME.message]);
            end
            
            % Save the new color scheme
            setLocalSettings('ColorScheme', S);
        end

        % Value changed function: DAQcolorschemeDropDown
        function DAQcolorschemeDropDownValueChanged(app, event)
            S = struct();
            S.name = lower(app.DAQcolorschemeDropDown.Value);
            S.colorSchemeFcn = MyAppColors.createColorSchemeFcn(S.name);
            
            % Save the new color scheme
            setLocalSettings('DaqColorScheme', S);
            
            try
                
                % Recolor running MyDaqs
                app_names = app.Collector.running_apps;
                
                for i = 1:length(app_names)
                    RunningApp = getApp(app.Collector, app_names{i});
                    if isa(RunningApp, 'MyDaq')
                        
                        % Here the function applyLocalColorScheme is a method of
                        % MyDaq in this case
                        applyLocalColorScheme(RunningApp);
                    end
                end
            catch ME
                warning(['Could not recolor MyDaq apps. Error: ' ME.message]);
            end
        end

        % Value changed function: DefaultLogDirEditField
        function DefaultLogDirEditFieldValueChanged(app, event)
            value = app.DefaultLogDirEditField.Value;
            setLocalSettings('default_log_dir', value);
        end

        % Button pushed function: DefaultLogDirBrowseButton
        function DefaultLogDirBrowseButtonPushed(app, event)
            dir_name = uigetdir(app.DefaultLogDirEditField.Value);
            
            if dir_name ~= 0
                
                % if a new file was choosen
                app.DefaultLogDirEditField.Value = dir_name;
                setLocalSettings('default_log_dir', dir_name);
            end
            
            % Bring focus back to the app window
            setFocus(app);
        end

        % Button pushed function: LoadsessionButton
        function LoadsessionButtonPushed(app, event)
            try
                default_dir = getLocalSettings('measurement_base_dir');
            catch
                default_dir = '';
            end
            
            [file_name, path] = uigetfile('*.*', '', default_dir);
            
            if file_name ~= 0
                runSession(fullfile(path, file_name));
            end
        end

        % Button pushed function: SavesessionButton
        function SavesessionButtonPushed(app, event)
            try
                default_dir = getLocalSettings('measurement_base_dir');
            catch
                default_dir = '';
            end
            
            [file_name, path] = uiputfile('*.*', 'Save information about session', ...
                fullfile(default_dir, 'MeasurementSession.txt'));
            
            if file_name ~= 0
                
                % Read instrument settings and the information about
                % the session from Collector
                InstrMdt = readInstrumentSettings(app.Collector);
                CollectorMdt = getMetadata(app.Collector);
                
                save([InstrMdt, CollectorMdt], fullfile(path, file_name), 'overwrite', true); %#ok<USENS> 
                
                disp(['Session info is saved to ' fullfile(path,file_name)])
            end
            
            setFocus(app.DaqMenuFigure);
        end

        % Button pushed function: AddAnalysisRoutineButton
        function AddAnalysisRoutineButtonPushed(app, event)
            if isempty(app.AnalysisRoutineTable.Data)
                app.AnalysisRoutineTable.Data = {'AnalysisRoutine', 'FunctionName'};
            else
                title = matlab.lang.makeUniqueStrings('AnalysisRoutine', ...
                    app.AnalysisRoutineTable.Data(:, 1));
                
                func_name = matlab.lang.makeUniqueStrings('FunctionName', ...
                    app.AnalysisRoutineTable.Data(:, 2));
                
                app.AnalysisRoutineTable.Data(end+1, :) = {title, func_name};
            end
            
            saveAnalysisRoutineList(app);
        end

        % Button pushed function: RemoveAnalysisRoutineButton
        function RemoveAnalysisRoutineButtonPushed(app, event)
            try 
                sel_ind = app.AnalysisRoutineTable.UserData.selected_indices;
                app.AnalysisRoutineTable.Data(sel_ind(:, 1), :) = [];
                
                saveAnalysisRoutineList(app);
            catch
                
                % The statements above can fail for a few reasons,
                % including the selection being empty or the table data
                % being empty, we do nothing in such case. 
            end
        end

        % Cell selection callback: AnalysisRoutineTable
        function AnalysisRoutineTableCellSelection(app, event)
            
            % Store the selected indices under UserData
            app.AnalysisRoutineTable.UserData.selected_indices = event.Indices;
        end

        % Cell edit callback: AnalysisRoutineTable
        function AnalysisRoutineTableCellEdit(app, event)
            saveAnalysisRoutineList(app);
        end

        % Button pushed function: OpennewDAQButton
        function OpennewDAQButtonPushed(app, event)
            MyDaq();
        end

        % Value changed function: InstrumentList
        function InstrumentListValueChanged(app, event)
            updateInstrList(app);
        end

        % Button pushed function: ShowMetadataButton
        function ShowMetadataButtonPushed(app, event)
            % Read instrument settings and the information about
            % the session from Collector
            InstrMdt = readInstrumentSettings(app.Collector);
            CollectorMdt = getMetadata(app.Collector);
            
            message = mdt2str([InstrMdt, CollectorMdt]);
            uialert(app.DaqMenuFigure, message, 'Metadata', 'Icon', 'info');
        end

        % Window key press function: DaqMenuFigure
        function DaqMenuFigureWindowKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'delete'
                    if isequal(app.MainTabGroup.SelectedTab, app.SessionTab)
                        deleteSelectedInstr(app);
                    end
                case 'return'
                    if ismember('control', event.Modifier)
                        
                        % Run in background if Ctrl+Enter is pressed
                        ind = app.ProgramListBox.Value;
                        if ~isempty(ind)
                            eval([app.ProgList(ind).run_bg_expr,';']);
                        end
                    else
                        
                        % Simply run otherwise
                        ind = app.ProgramListBox.Value;
                        if ~isempty(ind)
                            eval([app.ProgList(ind).run_expr,';']);
                        end
                    end
            end
        end

        % Button pushed function: CloseGUIButton
        function CloseGUIButtonPushed(app, event)
            
            % Close the GUI of selected instrument
            name = app.InstrumentList.Value;
            if ~isempty(name)
                Instr = getInstrument(app.Collector, name);
                
                try
                    closeGui(Instr);
                catch ME
                    warning(ME.message);
                end
                
                updateInstrList(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DaqMenuFigure = uifigure('Visible', 'off');
            app.DaqMenuFigure.Name = 'Data acquisition menu';
            app.DaqMenuFigure.CloseRequestFcn = createCallbackFcn(app, @DaqMenuFigureCloseRequest, true);
            app.DaqMenuFigure.WindowKeyPressFcn = createCallbackFcn(app, @DaqMenuFigureWindowKeyPress, true);
            app.MainTabGroup = uitabgroup(app.DaqMenuFigure);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MainTabGroupSelectionChanged, true);
            app.SessionTab = uitab(app.MainTabGroup);
            app.SessionTab.Title = 'Session';
            app.AllProgramsPanel = uipanel(app.SessionTab);
            app.RunButton = uibutton(app.AllProgramsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Start selected instrument'};
            app.RunButton.Text = 'Run';
            app.InstrumentmanagerButton = uibutton(app.AllProgramsPanel, 'push');
            app.InstrumentmanagerButton.ButtonPushedFcn = createCallbackFcn(app, @InstrumentmanagerButtonPushed, true);
            app.InstrumentmanagerButton.Text = 'Instrument manager';
            app.ReloadlistButton = uibutton(app.AllProgramsPanel, 'push');
            app.ReloadlistButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadlistButtonPushed, true);
            app.ReloadlistButton.Text = 'Reload list';
            app.RuninbackgroundButton = uibutton(app.AllProgramsPanel, 'push');
            app.RuninbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @RuninbackgroundButtonPushed, true);
            app.RuninbackgroundButton.Tooltip = {'Start selected instrument without GUI'};
            app.RuninbackgroundButton.Text = 'Run in background';
            app.AllprogramsLabel = uilabel(app.AllProgramsPanel);
            app.AllprogramsLabel.Text = 'All programs';
            app.ProgramListBox = uilistbox(app.AllProgramsPanel);
            app.ProgramListBox.Items = {};
            app.ProgramListBox.ValueChangedFcn = createCallbackFcn(app, @ProgramListBoxValueChanged, true);
            app.ProgramListBox.Tooltip = {'Enter - run'; 'Ctrl+Enter - run in background'};
            app.ProgramListBox.Value = {};
            app.ActiveProgramsPanel = uipanel(app.SessionTab);
            app.InstrumentList = uilistbox(app.ActiveProgramsPanel);
            app.InstrumentList.Items = {};
            app.InstrumentList.ValueChangedFcn = createCallbackFcn(app, @InstrumentListValueChanged, true);
            app.InstrumentList.Value = {};
            app.PresentlyactiveLabel = uilabel(app.ActiveProgramsPanel);
            app.PresentlyactiveLabel.Text = 'Presently active';
            app.DeleteinstrumentButton = uibutton(app.ActiveProgramsPanel, 'push');
            app.DeleteinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteinstrumentButtonPushed, true);
            app.DeleteinstrumentButton.Text = 'Delete instrument';
            app.CloseGUIButton = uibutton(app.ActiveProgramsPanel, 'push');
            app.CloseGUIButton.ButtonPushedFcn = createCallbackFcn(app, @CloseGUIButtonPushed, true);
            app.CloseGUIButton.Text = 'Close GUI';
            app.OpennewDAQButton = uibutton(app.SessionTab, 'push');
            app.OpennewDAQButton.ButtonPushedFcn = createCallbackFcn(app, @OpennewDAQButtonPushed, true);
            app.OpennewDAQButton.Text = 'Open new DAQ';
            app.SavesessionButton = uibutton(app.SessionTab, 'push');
            app.SavesessionButton.ButtonPushedFcn = createCallbackFcn(app, @SavesessionButtonPushed, true);
            app.SavesessionButton.Tooltip = {'Save instrument configuration'};
            app.SavesessionButton.Text = 'Save session';
            app.LoadsessionButton = uibutton(app.SessionTab, 'push');
            app.LoadsessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsessionButtonPushed, true);
            app.LoadsessionButton.Tooltip = {'Load the instrument configuration from metadata file'};
            app.LoadsessionButton.Text = 'Load session';
            app.ShowMetadataButton = uibutton(app.SessionTab, 'push');
            app.ShowMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMetadataButtonPushed, true);
            app.ShowMetadataButton.Tooltip = {'Collect and view metadata from the current session'};
            app.ShowMetadataButton.Text = 'Show metadata';
            app.SettingsTab = uitab(app.MainTabGroup);
            app.SettingsTab.Title = 'Settings';
            app.MeasurementbasedirectoryEditFieldLabel = uilabel(app.SettingsTab);
            app.MeasurementbasedirectoryEditFieldLabel.Text = 'Measurement base directory';
            app.MeasBaseDirEditField = uieditfield(app.SettingsTab, 'text');
            app.MeasBaseDirEditField.ValueChangedFcn = createCallbackFcn(app, @MeasBaseDirEditFieldValueChanged, true);
            app.MeasBaseDirEditField.Value = 'M:\Measurement Campaigns\';
            app.MeasBaseDirBrowseButton = uibutton(app.SettingsTab, 'push');
            app.MeasBaseDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @MeasBaseDirBrowseButtonPushed, true);
            app.MeasBaseDirBrowseButton.Text = 'Browse';
            app.InstrumentcolorschemeDropDownLabel = uilabel(app.SettingsTab);
            app.InstrumentcolorschemeDropDownLabel.Text = 'Instrument color scheme';
            app.InstrumentcolorschemeDropDown = uidropdown(app.SettingsTab);
            app.InstrumentcolorschemeDropDown.Items = {'default', 'dark', 'bright'};
            app.InstrumentcolorschemeDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentcolorschemeDropDownValueChanged, true);
            app.InstrumentcolorschemeDropDown.Value = 'default';
            app.DefaultlogdirectoryEditFieldLabel = uilabel(app.SettingsTab);
            app.DefaultlogdirectoryEditFieldLabel.Text = 'Default log directory';
            app.DefaultLogDirEditField = uieditfield(app.SettingsTab, 'text');
            app.DefaultLogDirEditField.ValueChangedFcn = createCallbackFcn(app, @DefaultLogDirEditFieldValueChanged, true);
            app.DefaultLogDirBrowseButton = uibutton(app.SettingsTab, 'push');
            app.DefaultLogDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultLogDirBrowseButtonPushed, true);
            app.DefaultLogDirBrowseButton.Text = 'Browse';
            app.DAQcolorschemeDropDownLabel = uilabel(app.SettingsTab);
            app.DAQcolorschemeDropDownLabel.Text = 'DAQ color scheme';
            app.DAQcolorschemeDropDown = uidropdown(app.SettingsTab);
            app.DAQcolorschemeDropDown.Items = {'default', 'dark', 'bright'};
            app.DAQcolorschemeDropDown.ValueChangedFcn = createCallbackFcn(app, @DAQcolorschemeDropDownValueChanged, true);
            app.DAQcolorschemeDropDown.Value = 'default';
            app.AnalysisRoutineTable = uitable(app.SettingsTab);
            app.AnalysisRoutineTable.ColumnName = {'Title'; 'Function'};
            app.AnalysisRoutineTable.RowName = {};
            app.AnalysisRoutineTable.ColumnEditable = true;
            app.AnalysisRoutineTable.CellEditCallback = createCallbackFcn(app, @AnalysisRoutineTableCellEdit, true);
            app.AnalysisRoutineTable.CellSelectionCallback = createCallbackFcn(app, @AnalysisRoutineTableCellSelection, true);
            app.AnalysisRoutineTable.Tooltip = {'The list of analysis routines displayed in DAQ. Function is a MATLAB function of class constructor that creates object.'};
            app.AnalysisroutinesLabel = uilabel(app.SettingsTab);
            app.AnalysisroutinesLabel.Text = 'Analysis routines';
            app.LocalbasedirectoryEditFieldLabel = uilabel(app.SettingsTab);
            app.LocalbasedirectoryEditFieldLabel.Text = 'Local base directory';
            app.LocalbasedirEditField = uieditfield(app.SettingsTab, 'text');
            app.LocalbasedirEditField.Editable = 'off';
            app.LocalbasedirEditField.Tooltip = {'In order to change the local base directory move LocalInstrumentControlSettings to a different folder on Matalb path'};
            app.AddAnalysisRoutineButton = uibutton(app.SettingsTab, 'push');
            app.AddAnalysisRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @AddAnalysisRoutineButtonPushed, true);
            app.AddAnalysisRoutineButton.Text = 'Add';
            app.RemoveAnalysisRoutineButton = uibutton(app.SettingsTab, 'push');
            app.RemoveAnalysisRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAnalysisRoutineButtonPushed, true);
            app.RemoveAnalysisRoutineButton.Text = 'Remove';
            % Show the figure after all components are created
            app.DaqMenuFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyDaqMenu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DaqMenuFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DaqMenuFigure)
        end
    end
end