classdef GuiCeCryo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColdEdgeCryo                  matlab.ui.Figure
        V1Lamp                        matlab.ui.control.Lamp
        V1Button                      matlab.ui.control.StateButton
        V2Lamp                        matlab.ui.control.Lamp
        V2Button                      matlab.ui.control.StateButton
        V3Lamp                        matlab.ui.control.Lamp
        V3Button                      matlab.ui.control.StateButton
        V4Lamp                        matlab.ui.control.Lamp
        V4Button                      matlab.ui.control.StateButton
        V5Lamp                        matlab.ui.control.Lamp
        V5Button                      matlab.ui.control.StateButton
        V7Lamp                        matlab.ui.control.Lamp
        V7Button                      matlab.ui.control.StateButton
        RecirculatorButton            matlab.ui.control.StateButton
        CryocoolerButton              matlab.ui.control.StateButton
        Label                         matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        RecirculatorsupplyLabel       matlab.ui.control.Label
        CryostatreturnLabel           matlab.ui.control.Label
        VacuumLabel                   matlab.ui.control.Label
        PurgeLabel                    matlab.ui.control.Label
        Label_13                      matlab.ui.control.Label
        CryostatsupplyLabel           matlab.ui.control.Label
        RecirculatorreturnLabel       matlab.ui.control.Label
        RefreshpanelButton            matlab.ui.control.Button
        PressurePSIEditFieldLabel     matlab.ui.control.Label
        PressurePSIEditField          matlab.ui.control.NumericEditField
        Label_14                      matlab.ui.control.Label
        Label_15                      matlab.ui.control.Label
        Label_16                      matlab.ui.control.Label
        Label_17                      matlab.ui.control.Label
        ValvescontrolCheckBox         matlab.ui.control.CheckBox
        AbortButton                   matlab.ui.control.Button
        CooldownButton                matlab.ui.control.StateButton
        OperationinprogressLampLabel  matlab.ui.control.Label
        OperationLamp                 matlab.ui.control.Lamp
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        name = ''  % App name to clear on exit
        Listeners
    end

    methods (Access = public)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
        
        function updateGui(app)
            
            % Update access
            if app.ValvescontrolCheckBox.Value
                for i = 1:7
                    if i == 6
                        continue % no valve 6
                    end
                    button_tag = sprintf('V%iButton', i);
                    app.(button_tag).Enable = 'on';
                end
            else
                for i = 1:7
                    if i == 6
                        continue % no valve 6
                    end
                    button_tag = sprintf('V%iButton', i);
                    app.(button_tag).Enable = 'off';
                end
            end
            
            updateLinkedGuiElements(app)
        end
    end
    
    methods (Access = private)
        
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)
            for i = 1:7
                if i == 6
                    continue % no valve 6
                end
                
                prop_tag = sprintf('.Instr.valve%i', i);
                button_tag = sprintf('V%iButton', i);
                lamp_tag = sprintf('V%iLamp', i);
                
                linkGuiElement(app, app.(button_tag), prop_tag);
                linkGuiElement(app, app.(lamp_tag), prop_tag);
            end
        
            linkGuiElement(app, app.RecirculatorButton, '.Instr.recirc');
            linkGuiElement(app, app.CryocoolerButton, '.Instr.cryocooler');
            linkGuiElement(app, app.PressurePSIEditField, '.Instr.press');
            
            linkGuiElement(app, app.OperationLamp, '.Instr.operation_in_progress', ...
                'lamp_on_color', MyAppColors.warning(), 'lamp_off_color', app.OperationLamp.Color);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            initInstrGui(app);
            createLinks(app);
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Close request function: ColdEdgeCryo
        function ColdEdgeCryoCloseRequest(app, event)
            % Delete instrument object, clear global variable and delete app
            deleteInstrGui(app);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            readPropertyHedged(app.Instr,'all');
        end

        % Value changed function: ValvescontrolCheckBox
        function ValvescontrolCheckBoxValueChanged(app, event)
            updateGui(app);
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            abort(app.Instr);
        end

        % Value changed function: CooldownButton
        function CooldownButtonValueChanged(app, event)
            value = app.CooldownButton.Value;
            if value
                try
                    startCooldown(app.Instr);
                    app.CooldownButton.Text = 'Stop cooldown';
                catch ME
                    warning(ME.message);
                    app.CooldownButton.Value = false;
                end
            else
                try
                    stopCooldown(app.Instr);
                    app.CooldownButton.Text = 'Start cooldown';
                catch ME
                    warning(ME.message);
                    app.CooldownButton.Value = true;
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColdEdgeCryo = uifigure;
            app.ColdEdgeCryo.Name = 'Template';
            app.ColdEdgeCryo.CloseRequestFcn = createCallbackFcn(app, @ColdEdgeCryoCloseRequest, true);
            app.V1Lamp = uilamp(app.ColdEdgeCryo);
            app.V1Button = uibutton(app.ColdEdgeCryo, 'state');
            app.V1Button.Text = 'V1';
            app.V2Lamp = uilamp(app.ColdEdgeCryo);
            app.V2Button = uibutton(app.ColdEdgeCryo, 'state');
            app.V2Button.Text = 'V2';
            app.V3Lamp = uilamp(app.ColdEdgeCryo);
            app.V3Button = uibutton(app.ColdEdgeCryo, 'state');
            app.V3Button.Text = 'V3';
            app.V4Lamp = uilamp(app.ColdEdgeCryo);
            app.V4Button = uibutton(app.ColdEdgeCryo, 'state');
            app.V4Button.Text = 'V4';
            app.V5Lamp = uilamp(app.ColdEdgeCryo);
            app.V5Button = uibutton(app.ColdEdgeCryo, 'state');
            app.V5Button.Text = 'V5';
            app.V7Lamp = uilamp(app.ColdEdgeCryo);
            app.V7Button = uibutton(app.ColdEdgeCryo, 'state');
            app.V7Button.Text = 'V7';
            app.RecirculatorButton = uibutton(app.ColdEdgeCryo, 'state');
            app.RecirculatorButton.Text = 'Recirculator';
            app.CryocoolerButton = uibutton(app.ColdEdgeCryo, 'state');
            app.CryocoolerButton.Text = 'Cryocooler';
            app.Label = uilabel(app.ColdEdgeCryo);
            app.Label.Text = '------------------------';
            app.Label_4 = uilabel(app.ColdEdgeCryo);
            app.Label_4.Text = {'|'; '|'; '|'; '|'; ''};
            app.Label_5 = uilabel(app.ColdEdgeCryo);
            app.Label_5.Text = '------------';
            app.Label_6 = uilabel(app.ColdEdgeCryo);
            app.Label_6.Text = '------------';
            app.Label_7 = uilabel(app.ColdEdgeCryo);
            app.Label_7.Text = '--------------';
            app.Label_10 = uilabel(app.ColdEdgeCryo);
            app.Label_10.Text = {'|'; '|'; '|'; '|'; ''};
            app.RecirculatorsupplyLabel = uilabel(app.ColdEdgeCryo);
            app.RecirculatorsupplyLabel.Text = {'Recirculator '; 'supply'};
            app.CryostatreturnLabel = uilabel(app.ColdEdgeCryo);
            app.CryostatreturnLabel.Text = {'Cryostat '; 'return'};
            app.VacuumLabel = uilabel(app.ColdEdgeCryo);
            app.VacuumLabel.Text = 'Vacuum';
            app.PurgeLabel = uilabel(app.ColdEdgeCryo);
            app.PurgeLabel.Text = 'Purge';
            app.Label_13 = uilabel(app.ColdEdgeCryo);
            app.Label_13.Text = '--------------';
            app.CryostatsupplyLabel = uilabel(app.ColdEdgeCryo);
            app.CryostatsupplyLabel.Text = {'Cryostat'; 'supply'};
            app.RecirculatorreturnLabel = uilabel(app.ColdEdgeCryo);
            app.RecirculatorreturnLabel.Text = {'Recirculator '; 'return'};
            app.RefreshpanelButton = uibutton(app.ColdEdgeCryo, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.PressurePSIEditFieldLabel = uilabel(app.ColdEdgeCryo);
            app.PressurePSIEditFieldLabel.Text = 'Pressure (PSI)';
            app.PressurePSIEditField = uieditfield(app.ColdEdgeCryo, 'numeric');
            app.PressurePSIEditField.Editable = 'off';
            app.PressurePSIEditField.Value = 110.1;
            app.Label_14 = uilabel(app.ColdEdgeCryo);
            app.Label_14.Text = {'|'; '|'; '|'; '|'; ''};
            app.Label_15 = uilabel(app.ColdEdgeCryo);
            app.Label_15.Text = {'|'; '|'; '|'; '|'; ''};
            app.Label_16 = uilabel(app.ColdEdgeCryo);
            app.Label_16.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_17 = uilabel(app.ColdEdgeCryo);
            app.Label_17.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.ValvescontrolCheckBox = uicheckbox(app.ColdEdgeCryo);
            app.ValvescontrolCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValvescontrolCheckBoxValueChanged, true);
            app.ValvescontrolCheckBox.Text = 'Valves control';
            app.AbortButton = uibutton(app.ColdEdgeCryo, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.CooldownButton = uibutton(app.ColdEdgeCryo, 'state');
            app.CooldownButton.ValueChangedFcn = createCallbackFcn(app, @CooldownButtonValueChanged, true);
            app.CooldownButton.Text = 'Start cooldown';
            app.OperationinprogressLampLabel = uilabel(app.ColdEdgeCryo);
            app.OperationinprogressLampLabel.Text = 'Operation in progress';
            app.OperationLamp = uilamp(app.ColdEdgeCryo);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiCeCryo(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColdEdgeCryo)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColdEdgeCryo)
        end
    end
end