classdef GuiLakeshore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                      matlab.ui.Figure
        SensorPanel_1                   matlab.ui.container.Panel
        TLabel                          matlab.ui.control.Label
        TDisp_1                         matlab.ui.control.EditField
        SensorPanel_2                   matlab.ui.container.Panel
        TLabel_3                        matlab.ui.control.Label
        TDisp_2                         matlab.ui.control.EditField
        LoggerButton                    matlab.ui.control.Button
        SinglereadButton                matlab.ui.control.Button
        SensorPanel_3                   matlab.ui.container.Panel
        TLabel_2                        matlab.ui.control.Label
        TDisp_3                         matlab.ui.control.EditField
        SensorPanel_4                   matlab.ui.container.Panel
        TLabel_4                        matlab.ui.control.Label
        TDisp_4                         matlab.ui.control.EditField
        TemperatureunitLabel            matlab.ui.control.Label
        TUnitDropDown                   matlab.ui.control.DropDown
        HeaterPanel_1                   matlab.ui.container.Panel
        HeaterrangeLabel                matlab.ui.control.Label
        HeaterRangeDropDown_1           matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        OutModeDropDown_1               matlab.ui.control.DropDown
        SetpointEditFieldLabel          matlab.ui.control.Label
        SetpointEditField_1             matlab.ui.control.NumericEditField
        ControlinputDropDownLabel       matlab.ui.control.Label
        ControlinputDropDown_1          matlab.ui.control.DropDown
        PowerupCheckBox_1               matlab.ui.control.CheckBox
        RemainonafterpowercycleLabel_1  matlab.ui.control.Label
        HeaterPanel_2                   matlab.ui.container.Panel
        HeaterrangeLabel_2              matlab.ui.control.Label
        HeaterRangeDropDown_2           matlab.ui.control.DropDown
        ModeDropDownLabel_2             matlab.ui.control.Label
        OutModeDropDown_2               matlab.ui.control.DropDown
        SetpointEditField_2Label        matlab.ui.control.Label
        SetpointEditField_2             matlab.ui.control.NumericEditField
        ControlinputDropDown_2Label     matlab.ui.control.Label
        ControlinputDropDown_2          matlab.ui.control.DropDown
        PowerupCheckBox_2               matlab.ui.control.CheckBox
        RemainonafterpowercycleLabel_2  matlab.ui.control.Label
        HeaterPanel_3                   matlab.ui.container.Panel
        HeaterrangeLabel_3              matlab.ui.control.Label
        HeaterRangeDropDown_3           matlab.ui.control.DropDown
        ModeDropDownLabel_3             matlab.ui.control.Label
        OutModeDropDown_3               matlab.ui.control.DropDown
        SetpointEditField_3Label        matlab.ui.control.Label
        SetpointEditField_3             matlab.ui.control.NumericEditField
        ControlinputDropDown_3Label     matlab.ui.control.Label
        ControlinputDropDown_3          matlab.ui.control.DropDown
        PowerupCheckBox_3               matlab.ui.control.CheckBox
        RemainonafterpowercycleLabel_3  matlab.ui.control.Label
        HeaterPanel_4                   matlab.ui.container.Panel
        HeaterrangeLabel_4              matlab.ui.control.Label
        HeaterRangeDropDown_4           matlab.ui.control.DropDown
        ModeDropDownLabel_4             matlab.ui.control.Label
        OutModeDropDown_4               matlab.ui.control.DropDown
        SetpointEditField_4Label        matlab.ui.control.Label
        SetpointEditField_4             matlab.ui.control.NumericEditField
        ControlinputDropDown_4Label     matlab.ui.control.Label
        ControlinputDropDown_4          matlab.ui.control.DropDown
        PowerupCheckBox_4               matlab.ui.control.CheckBox
        RemainonafterpowercycleLabel_4  matlab.ui.control.Label
        OutcontrolCheckBox              matlab.ui.control.CheckBox
    end


    properties (Access = public)
        name = 'Lakeshore temperature controller';
        Instr; % MyInstrument object
        linked_elem_list={}; % Lust of elements
        Lg; % MyLogger object
        GuiLg; % Gui of MyLogger
    end

    properties (Access = private)
        Llh % Listener to new data, generated by logger
        Obdlh % Listener to logger being closed
    end
    

    methods (Access = private)
    
        function createControlLinks(app)
            for i=1:4
                tmpelem = sprintf('TDisp_%i',i);
                app.(tmpelem).Tag = 'Instr.temp_str';
                addprop(app.(tmpelem),'OutputProcessingFcn');
                app.(tmpelem).OutputProcessingFcn = @(x)x{i};
                app.linked_elem_list = [app.linked_elem_list, app.(tmpelem)];
                
                % OutputProcessingFcns should not be changed as their outputs are used 
                % also to recognize gui elements in callbacks
                tmpelem = sprintf('HeaterRangeDropDown_%i',i);
                app.(tmpelem).Tag = 'Instr.heater_rng';
                addprop(app.(tmpelem),'OutputProcessingFcn');
                app.(tmpelem).OutputProcessingFcn = @(x)x{i};
                app.linked_elem_list = [app.linked_elem_list, app.(tmpelem)];
                
                tmpelem = sprintf('SetpointEditField_%i',i);
                app.(tmpelem).Tag = 'Instr.setpoint';
                addprop(app.(tmpelem),'OutputProcessingFcn');
                app.(tmpelem).OutputProcessingFcn = @(x)x{i};
                app.linked_elem_list = [app.linked_elem_list, app.(tmpelem)];
                
                tmpelem = sprintf('OutModeDropDown_%i',i);
                app.(tmpelem).Tag = 'Instr.out_mode';
                addprop(app.(tmpelem),'OutputProcessingFcn');
                app.(tmpelem).OutputProcessingFcn = @(x)x{i}(1);
                app.linked_elem_list = [app.linked_elem_list, app.(tmpelem)];
                
                tmpelem = sprintf('ControlinputDropDown_%i',i);
                app.(tmpelem).Tag = 'Instr.out_mode';
                addprop(app.(tmpelem),'OutputProcessingFcn');
                app.(tmpelem).OutputProcessingFcn = @(x)x{i}(2);
                app.linked_elem_list = [app.linked_elem_list, app.(tmpelem)];
                
                tmpelem = sprintf('PowerupCheckBox_%i',i);
                app.(tmpelem).Tag = 'Instr.out_mode';
                addprop(app.(tmpelem),'OutputProcessingFcn');
                app.(tmpelem).OutputProcessingFcn = @(x)x{i}(3);
                app.linked_elem_list = [app.linked_elem_list, app.(tmpelem)];
            end
            
            app.TUnitDropDown.Tag = 'Instr.temp_unit';
            app.linked_elem_list = [app.linked_elem_list, app.TUnitDropDown];
        end
    
        function initDropDowns(app)
            try
                for i=1:4
                    tmpelem = sprintf('HeaterRangeDropDown_%i',i);
                    if i<=2
                        app.(tmpelem).Items=app.Instr.heater12_rng_list;
                        app.(tmpelem).ItemsData=0:length(app.Instr.heater12_rng_list)-1;
                    else
                        app.(tmpelem).Items=app.Instr.heater34_rng_list;
                        app.(tmpelem).ItemsData=0:length(app.Instr.heater34_rng_list)-1;
                    end
                    
                    tmpelem = sprintf('OutModeDropDown_%i',i);
                    app.(tmpelem).Items=app.Instr.out_mode_list;
                    app.(tmpelem).ItemsData=0:length(app.Instr.out_mode_list)-1;
                    
                    tmpelem = sprintf('ControlinputDropDown_%i',i);
                    app.(tmpelem).Items=app.Instr.inp_list;
                    app.(tmpelem).ItemsData=0:length(app.Instr.inp_list)-1;
                end
            catch
                warning('Dropdowns could not be initialized')
            end
        end
        
        function updateDisp(app)
            try 
                app.SensorPanel_1.Title = sprintf('Sensor A: %s',...
                    app.Instr.inp_sens_names{1});
                app.SensorPanel_2.Title = sprintf('Sensor B: %s',...
                    app.Instr.inp_sens_names{2});
                app.SensorPanel_3.Title = sprintf('Sensor C: %s',...
                    app.Instr.inp_sens_names{3});
                app.SensorPanel_4.Title = sprintf('Sensor D: %s',...
                    app.Instr.inp_sens_names{4});
            catch
                warning('Cannot display sensor names')
            end
        end
    
        function updateHeaterPanelsAccess(app,bool)
            elem_list = [app.HeaterPanel_1.Children;app.HeaterPanel_2.Children;...
                    app.HeaterPanel_3.Children;app.HeaterPanel_4.Children];
            for i=1:length(elem_list)
                elem = elem_list(i);
                if bool
                    elem.Enable = 'on';
                else
                    elem.Enable = 'off';
                end
            end
        end
        
        function newDataCallback(app,~,~)
            updateDisp(app);
            updateGui(app);
        end
    
        function loggerClosedCallback(app,~,~)
            % Clean up
            delete(app.Llh);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            p=inputParser();
            p.KeepUnmatched = true;
            addParameter(p,'Instr',[]);
            addParameter(p,'name',[]);
            parse(p, varargin{:});
            
            if ~ismember('Instr',p.UsingDefaults)
                app.Instr = p.Results.Instr;
            else
                % Create a dummy instrument object if it was not supplied externally
                app.Instr = MyLakeshore336('visa','');
            end
            if ~ismember('name',p.UsingDefaults)
                app.name = p.Results.name;
            end
            
            readAllHedged(app.Instr);
            
            app.MainFigure.Name = app.name;
            initDropDowns(app);
            updateHeaterPanelsAccess(app, app.OutcontrolCheckBox.Value);
            
            createControlLinks(app);
            updateGui(app);
            updateDisp(app);
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            try
                delete(app.Instr);
            catch
            end
            try
                delete(app.GuiLg);
            catch
            end
            try
                delete(app.Lg);
            catch
            end
            delete(app)
        end

        % Button pushed function: SinglereadButton
        function SinglereadCallback(app, event)
            readAllHedged(app.Instr);
            updateGui(app);
            updateDisp(app);
        end

        % Button pushed function: LoggerButton
        function LoggerButtonPushed(app, event)
            if ~(isa(app.Lg, 'MyLogger')&&isvalid(app.Lg))
                tu = app.Instr.temp_unit;
                names= app.Instr.inp_sens_names;
                headers = {['T ch A ',names{1},' [',tu,']'],...
                    ['T ch B ',names{2},' [',tu,']'],...
                    ['T ch C ',names{3},' [',tu,']'],...
                    ['T ch D ',names{4},' [',tu,']']};
                
                settings = getLocalSettings();
                base_dir = settings.measurement_base_dir;
                session_path = createSessionPath(base_dir, 'Lakeshore log');
                file_name = fullfile(session_path,'Temperature log.txt');
                
                app.Lg = MyLogger('MeasFcn', @()readAllHedged(app.Instr),...
                    'data_headers', headers,...
                    'disp_fmt', '%15s: %.3f',...
                    'save_file',file_name);
                app.GuiLg = GuiLogger('Lg',app.Lg,'name','Temperature logger');
                app.Llh = addlistener(app.Lg, 'NewData', @app.newDataCallback);
                app.Obdlh = addlistener(app.GuiLg, 'ObjectBeingDestroyed',...
                    @app.loggerClosedCallback);
            else
                % bring logger gui to front
                fig = findfigure(app.GuiLg);
                fig.Visible = 'off';
                fig.Visible = 'on';
            end
        end

        % Value changed function: TUnitDropDown
        function TUnitDropDownValueChanged(app, event)
            value = app.TUnitDropDown.Value;
            app.Instr.temp_unit = value;
            readAllHedged(app.Instr);
            updateGui(app);
            updateDisp(app);
        end

        % Value changed function: OutcontrolCheckBox
        function OutcontrolCheckBoxValueChanged(app, event)
            value = app.OutcontrolCheckBox.Value;
            updateHeaterPanelsAccess(app,value)
        end

        % Value changed function: ControlinputDropDown_1, 
        % ControlinputDropDown_2, ControlinputDropDown_3, 
        % ControlinputDropDown_4, OutModeDropDown_1, OutModeDropDown_2, 
        % OutModeDropDown_3, OutModeDropDown_4, PowerupCheckBox_1, 
        % PowerupCheckBox_2, PowerupCheckBox_3, PowerupCheckBox_4
        function OutModeCallback(app, event)
            elem = event.Source;
            dummy = {[0,1,2],[3,4,5],[6,7,8],[9,10,11]};
            ind = elem.OutputProcessingFcn(dummy); % recognize elements by their Out..Fcn
            nout = floor(ind/3)+1; % number of the output
            nvar = mod(ind,3)+1; % nvar=1,2 or 3 for out_mode_code, cntl_inp and powerup_en, respectively
            % get current mode of the channel {out_mode_code, cntl_inp, powerup_en}
            md = app.Instr.out_mode{nout};
            md(nvar) = int32(elem.Value); 
            openDevice(app.Instr);
            writeOutMode(app.Instr,nout,md(1),md(2),md(3));
            readOutMode(app.Instr);
            closeDevice(app.Instr);
            updateGui(app);
            updateDisp(app);
        end

        % Value changed function: HeaterRangeDropDown_1, 
        % HeaterRangeDropDown_2, HeaterRangeDropDown_3, 
        % HeaterRangeDropDown_4
        function HeaterRangeCallback(app, event)
            elem = event.Source;
            nout = elem.OutputProcessingFcn({1,2,3,4}); % infer the number of output from Out..Fcn
            openDevice(app.Instr);
            writeHeaterRange(app.Instr,nout,int32(elem.Value));
            readHeaterRange(app.Instr);
            closeDevice(app.Instr);
            updateGui(app);
            updateDisp(app);
        end

        % Value changed function: SetpointEditField_1, 
        % SetpointEditField_2, SetpointEditField_3, SetpointEditField_4
        function SetpointCallback(app, event)
            elem = event.Source;
            nout = elem.OutputProcessingFcn({1,2,3,4}); % infer the number of output from Out..Fcn
            openDevice(app.Instr);
            writeSetpoint(app.Instr,nout,elem.Value);
            readSetpoint(app.Instr);
            closeDevice(app.Instr);
            updateGui(app);
            updateDisp(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure;
            app.MainFigure.Name = 'TPG pressure gauge controller';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.SensorPanel_1 = uipanel(app.MainFigure);
            app.SensorPanel_1.Title = 'Sensor A';
            app.TLabel = uilabel(app.SensorPanel_1);
            app.TLabel.Text = 'T';
            app.TDisp_1 = uieditfield(app.SensorPanel_1, 'text');
            app.TDisp_1.Editable = 'off';
            app.SensorPanel_2 = uipanel(app.MainFigure);
            app.SensorPanel_2.Title = 'Sensor B';
            app.TLabel_3 = uilabel(app.SensorPanel_2);
            app.TLabel_3.Text = 'T';
            app.TDisp_2 = uieditfield(app.SensorPanel_2, 'text');
            app.TDisp_2.Editable = 'off';
            app.LoggerButton = uibutton(app.MainFigure, 'push');
            app.LoggerButton.ButtonPushedFcn = createCallbackFcn(app, @LoggerButtonPushed, true);
            app.LoggerButton.Text = 'Logger';
            app.SinglereadButton = uibutton(app.MainFigure, 'push');
            app.SinglereadButton.ButtonPushedFcn = createCallbackFcn(app, @SinglereadCallback, true);
            app.SinglereadButton.Text = 'Single read';
            app.SensorPanel_3 = uipanel(app.MainFigure);
            app.SensorPanel_3.Title = 'Sensor C';
            app.TLabel_2 = uilabel(app.SensorPanel_3);
            app.TLabel_2.Text = 'T';
            app.TDisp_3 = uieditfield(app.SensorPanel_3, 'text');
            app.TDisp_3.Editable = 'off';
            app.SensorPanel_4 = uipanel(app.MainFigure);
            app.SensorPanel_4.Title = 'Sensor D';
            app.TLabel_4 = uilabel(app.SensorPanel_4);
            app.TLabel_4.Text = 'T';
            app.TDisp_4 = uieditfield(app.SensorPanel_4, 'text');
            app.TDisp_4.Editable = 'off';
            app.TemperatureunitLabel = uilabel(app.MainFigure);
            app.TemperatureunitLabel.Text = 'Temperature unit';
            app.TUnitDropDown = uidropdown(app.MainFigure);
            app.TUnitDropDown.Items = {'K', 'C'};
            app.TUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @TUnitDropDownValueChanged, true);
            app.TUnitDropDown.Value = 'K';
            app.HeaterPanel_1 = uipanel(app.MainFigure);
            app.HeaterPanel_1.Title = 'Out 1';
            app.HeaterrangeLabel = uilabel(app.HeaterPanel_1);
            app.HeaterrangeLabel.Text = 'Heater range';
            app.HeaterRangeDropDown_1 = uidropdown(app.HeaterPanel_1);
            app.HeaterRangeDropDown_1.Items = {'Off', 'Low', 'Medium', 'High'};
            app.HeaterRangeDropDown_1.ValueChangedFcn = createCallbackFcn(app, @HeaterRangeCallback, true);
            app.HeaterRangeDropDown_1.Value = 'Medium';
            app.ModeDropDownLabel = uilabel(app.HeaterPanel_1);
            app.ModeDropDownLabel.Text = 'Mode';
            app.OutModeDropDown_1 = uidropdown(app.HeaterPanel_1);
            app.OutModeDropDown_1.Items = {'Closed loop PID', 'Option 2', 'Option 3', 'Option 4'};
            app.OutModeDropDown_1.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.OutModeDropDown_1.Value = 'Closed loop PID';
            app.SetpointEditFieldLabel = uilabel(app.HeaterPanel_1);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.SetpointEditField_1 = uieditfield(app.HeaterPanel_1, 'numeric');
            app.SetpointEditField_1.ValueDisplayFormat = '%.3f';
            app.SetpointEditField_1.ValueChangedFcn = createCallbackFcn(app, @SetpointCallback, true);
            app.SetpointEditField_1.Value = 273.234;
            app.ControlinputDropDownLabel = uilabel(app.HeaterPanel_1);
            app.ControlinputDropDownLabel.Text = 'Control input';
            app.ControlinputDropDown_1 = uidropdown(app.HeaterPanel_1);
            app.ControlinputDropDown_1.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.ControlinputDropDown_1.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.ControlinputDropDown_1.Value = 'None';
            app.PowerupCheckBox_1 = uicheckbox(app.HeaterPanel_1);
            app.PowerupCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.PowerupCheckBox_1.Text = '';
            app.RemainonafterpowercycleLabel_1 = uilabel(app.HeaterPanel_1);
            app.RemainonafterpowercycleLabel_1.Text = 'Remain on after power cycle';
            app.HeaterPanel_2 = uipanel(app.MainFigure);
            app.HeaterPanel_2.Title = 'Out 2';
            app.HeaterrangeLabel_2 = uilabel(app.HeaterPanel_2);
            app.HeaterrangeLabel_2.Text = 'Heater range';
            app.HeaterRangeDropDown_2 = uidropdown(app.HeaterPanel_2);
            app.HeaterRangeDropDown_2.Items = {'Off', 'Low', 'Medium', 'High'};
            app.HeaterRangeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @HeaterRangeCallback, true);
            app.HeaterRangeDropDown_2.Value = 'Medium';
            app.ModeDropDownLabel_2 = uilabel(app.HeaterPanel_2);
            app.ModeDropDownLabel_2.Text = 'Mode';
            app.OutModeDropDown_2 = uidropdown(app.HeaterPanel_2);
            app.OutModeDropDown_2.Items = {'Closed loop PID', 'Option 2', 'Option 3', 'Option 4'};
            app.OutModeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.OutModeDropDown_2.Value = 'Closed loop PID';
            app.SetpointEditField_2Label = uilabel(app.HeaterPanel_2);
            app.SetpointEditField_2Label.Text = 'Setpoint';
            app.SetpointEditField_2 = uieditfield(app.HeaterPanel_2, 'numeric');
            app.SetpointEditField_2.ValueDisplayFormat = '%.3f';
            app.SetpointEditField_2.ValueChangedFcn = createCallbackFcn(app, @SetpointCallback, true);
            app.SetpointEditField_2.Value = 273.234;
            app.ControlinputDropDown_2Label = uilabel(app.HeaterPanel_2);
            app.ControlinputDropDown_2Label.Text = 'Control input';
            app.ControlinputDropDown_2 = uidropdown(app.HeaterPanel_2);
            app.ControlinputDropDown_2.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.ControlinputDropDown_2.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.ControlinputDropDown_2.Value = 'None';
            app.PowerupCheckBox_2 = uicheckbox(app.HeaterPanel_2);
            app.PowerupCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.PowerupCheckBox_2.Text = '';
            app.RemainonafterpowercycleLabel_2 = uilabel(app.HeaterPanel_2);
            app.RemainonafterpowercycleLabel_2.Text = 'Remain on after power cycle';
            app.HeaterPanel_3 = uipanel(app.MainFigure);
            app.HeaterPanel_3.Title = 'Out 3';
            app.HeaterrangeLabel_3 = uilabel(app.HeaterPanel_3);
            app.HeaterrangeLabel_3.Text = 'Heater range';
            app.HeaterRangeDropDown_3 = uidropdown(app.HeaterPanel_3);
            app.HeaterRangeDropDown_3.Items = {'Off', 'Low', 'Medium', 'High'};
            app.HeaterRangeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @HeaterRangeCallback, true);
            app.HeaterRangeDropDown_3.Value = 'Medium';
            app.ModeDropDownLabel_3 = uilabel(app.HeaterPanel_3);
            app.ModeDropDownLabel_3.Text = 'Mode';
            app.OutModeDropDown_3 = uidropdown(app.HeaterPanel_3);
            app.OutModeDropDown_3.Items = {'Closed loop PID', 'Option 2', 'Option 3', 'Option 4'};
            app.OutModeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.OutModeDropDown_3.Value = 'Closed loop PID';
            app.SetpointEditField_3Label = uilabel(app.HeaterPanel_3);
            app.SetpointEditField_3Label.Text = 'Setpoint';
            app.SetpointEditField_3 = uieditfield(app.HeaterPanel_3, 'numeric');
            app.SetpointEditField_3.ValueDisplayFormat = '%.3f';
            app.SetpointEditField_3.ValueChangedFcn = createCallbackFcn(app, @SetpointCallback, true);
            app.SetpointEditField_3.Value = 273.234;
            app.ControlinputDropDown_3Label = uilabel(app.HeaterPanel_3);
            app.ControlinputDropDown_3Label.Text = 'Control input';
            app.ControlinputDropDown_3 = uidropdown(app.HeaterPanel_3);
            app.ControlinputDropDown_3.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.ControlinputDropDown_3.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.ControlinputDropDown_3.Value = 'None';
            app.PowerupCheckBox_3 = uicheckbox(app.HeaterPanel_3);
            app.PowerupCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.PowerupCheckBox_3.Text = '';
            app.RemainonafterpowercycleLabel_3 = uilabel(app.HeaterPanel_3);
            app.RemainonafterpowercycleLabel_3.Text = 'Remain on after power cycle';
            app.HeaterPanel_4 = uipanel(app.MainFigure);
            app.HeaterPanel_4.Title = 'Out 4';
            app.HeaterrangeLabel_4 = uilabel(app.HeaterPanel_4);
            app.HeaterrangeLabel_4.Text = 'Heater range';
            app.HeaterRangeDropDown_4 = uidropdown(app.HeaterPanel_4);
            app.HeaterRangeDropDown_4.Items = {'Off', 'Low', 'Medium', 'High'};
            app.HeaterRangeDropDown_4.ValueChangedFcn = createCallbackFcn(app, @HeaterRangeCallback, true);
            app.HeaterRangeDropDown_4.Value = 'Medium';
            app.ModeDropDownLabel_4 = uilabel(app.HeaterPanel_4);
            app.ModeDropDownLabel_4.Text = 'Mode';
            app.OutModeDropDown_4 = uidropdown(app.HeaterPanel_4);
            app.OutModeDropDown_4.Items = {'Closed loop PID', 'Option 2', 'Option 3', 'Option 4'};
            app.OutModeDropDown_4.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.OutModeDropDown_4.Value = 'Closed loop PID';
            app.SetpointEditField_4Label = uilabel(app.HeaterPanel_4);
            app.SetpointEditField_4Label.Text = 'Setpoint';
            app.SetpointEditField_4 = uieditfield(app.HeaterPanel_4, 'numeric');
            app.SetpointEditField_4.ValueDisplayFormat = '%.3f';
            app.SetpointEditField_4.ValueChangedFcn = createCallbackFcn(app, @SetpointCallback, true);
            app.SetpointEditField_4.Value = 273.234;
            app.ControlinputDropDown_4Label = uilabel(app.HeaterPanel_4);
            app.ControlinputDropDown_4Label.Text = 'Control input';
            app.ControlinputDropDown_4 = uidropdown(app.HeaterPanel_4);
            app.ControlinputDropDown_4.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.ControlinputDropDown_4.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.ControlinputDropDown_4.Value = 'None';
            app.PowerupCheckBox_4 = uicheckbox(app.HeaterPanel_4);
            app.PowerupCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @OutModeCallback, true);
            app.PowerupCheckBox_4.Text = '';
            app.RemainonafterpowercycleLabel_4 = uilabel(app.HeaterPanel_4);
            app.RemainonafterpowercycleLabel_4.Text = 'Remain on after power cycle';
            app.OutcontrolCheckBox = uicheckbox(app.MainFigure);
            app.OutcontrolCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutcontrolCheckBoxValueChanged, true);
            app.OutcontrolCheckBox.Text = 'Out control';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiLakeshore(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end