classdef GuiLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoggerUIFigure         matlab.ui.Figure
        RecordMenu             matlab.ui.container.Menu
        SaveMenu               matlab.ui.container.Menu
        LoadMenu               matlab.ui.container.Menu
        ClearMenu              matlab.ui.container.Menu
        LoggerMenu             matlab.ui.container.Menu
        LengthlimitMenu        matlab.ui.container.Menu
        LimNoneMenu            matlab.ui.container.Menu
        Lim1kpointsMenu        matlab.ui.container.Menu
        Lim10kpointsMenu       matlab.ui.container.Menu
        Lim100kpointsMenu      matlab.ui.container.Menu
        Lim1MpointsMenu        matlab.ui.container.Menu
        CreatenewfileMenu      matlab.ui.container.Menu
        NewfileNeverMenu       matlab.ui.container.Menu
        NewfileDailyMenu       matlab.ui.container.Menu
        NewfileWeeklyMenu      matlab.ui.container.Menu
        PlotMenu               matlab.ui.container.Menu
        LogscaleyMenu          matlab.ui.container.Menu
        ResizeableMenu         matlab.ui.container.Menu
        DisplaychannelsMenu    matlab.ui.container.Menu
        ShowallMenu            matlab.ui.container.Menu
        ShownoneMenu           matlab.ui.container.Menu
        TransferdataMenu       matlab.ui.container.Menu
        Axes                   matlab.ui.control.UIAxes
        FileEditFieldLabel     matlab.ui.control.Label
        FileEditField          matlab.ui.control.EditField
        LegendArea             matlab.ui.control.TextArea
        TimelabelsPanel        matlab.ui.container.Panel
        AddButton              matlab.ui.control.Button
        SelectedDropDownLabel  matlab.ui.control.Label
        SelectedTlDropDown     matlab.ui.control.DropDown
        DeleteTlButton         matlab.ui.control.Button
        ModifyTlButton         matlab.ui.control.Button
        DisplayTlCheckBox      matlab.ui.control.CheckBox
        MeasurementPanel       matlab.ui.container.Panel
        MeasButton             matlab.ui.control.StateButton
        IntervalsLabel         matlab.ui.control.Label
        MeasperiodsEditField   matlab.ui.control.NumericEditField
        SavecontCheckBox       matlab.ui.control.CheckBox
        PlotlimitsPanel        matlab.ui.container.Panel
        tminEditFieldLabel     matlab.ui.control.Label
        tminEditField          matlab.ui.control.EditField
        tmaxEditFieldLabel     matlab.ui.control.Label
        tmaxEditField          matlab.ui.control.EditField
        yminEditFieldLabel     matlab.ui.control.Label
        yminEditField          matlab.ui.control.NumericEditField
        ymaxEditFieldLabel     matlab.ui.control.Label
        ymaxEditField          matlab.ui.control.NumericEditField
        AutoscaleyCheckBox     matlab.ui.control.CheckBox
        AutoscaletCheckBox     matlab.ui.control.CheckBox
    end


    properties (Access = public)
        GuiSync
        
        % Logger object
        Lg
    end
    
    properties (Access = private)
        
        % Handles to the data lines plotted in Axes
        DataLines
        
        DataDisplayMenus = gobjects(0)
        DataTransferMenus = gobjects(0)
        
        axes_init_pos   % Initial axes size and position
        panel_h
        top_menu_h = 20 % pixels
    end
    
    methods (Access = public)
        function cb = publicCreateCallbackFcn(app, f)
            cb = createCallbackFcn(app, f, true);
        end
    end

    methods (Access = private)
    
        function createLinks(app)
            addLink(app.GuiSync, app.FileEditField,        'Lg.Record.file_name');
            addLink(app.GuiSync, app.SavecontCheckBox,     'Lg.Record.save_cont');
            addLink(app.GuiSync, app.SelectedTlDropDown,   'Lg.Record.TimeLabels', ...
                'elem_prop', 'Items', ...
                'OutputProcessingFcn', @app.updateTimeLabelDropDown);
            
            addLink(app.GuiSync, app.MeasperiodsEditField, 'Lg.MeasTimer.Period');
            
            addLink(app.GuiSync, app.MeasButton,           'Lg.MeasTimer.Running', ...
                'OutputProcessingFcn', @app.setMeasButton);
            
            addLink(app.GuiSync, app.tminEditField,         'Axes.XLim(1)', ...
                'OutputProcessingFcn', @datestr);  
            addLink(app.GuiSync, app.tmaxEditField,         'Axes.XLim(2)', ...
                'OutputProcessingFcn', @datestr);
            addLink(app.GuiSync, app.AutoscaletCheckBox,    'Axes.XLimMode', ...
                'map', {{'auto', 'manual'}, {true, false}});
            
            addLink(app.GuiSync, app.yminEditField,         'Axes.YLim(1)');
            addLink(app.GuiSync, app.ymaxEditField,         'Axes.YLim(2)');
            addLink(app.GuiSync, app.AutoscaleyCheckBox,    'Axes.YLimMode', ...
                'map', {{'auto', 'manual'}, {true, false}});
            
            addLink(app.GuiSync, app.LogscaleyMenu,         'Axes.YScale', ...
                'map', {{'log', 'linear'}, {'on', 'off'}});
        end
    
        % Create new listeners after the log is reloaded 
        function updateRecordLinks(app)
            reLink(app.GuiSync, app.FileEditField);
            reLink(app.GuiSync, app.SavecontCheckBox);
            reLink(app.GuiSync, app.SelectedTlDropDown);
        end
    
        function updateGui(app)
            updateDataDisp(app);
        end
    
        function newMeasCallback(app, ~, ~)
            updateDataDisp(app);
            updateAll(app.GuiSync);
        end
        
        function updateDataDisp(app)
            
            % Plot using MyLog method
            NewLines = plot(app.Lg.Record, app.Axes, ...
                'time_labels', app.DisplayTlCheckBox.Value);
            
            if ~isequal(NewLines, app.DataLines)
                app.DataLines = NewLines;
                n_lines = length(app.DataLines);
                
                % Generate new menu titles
                menu_titles = cell(1, n_lines);
                for i = 1:n_lines
                    try
                        menu_titles{i} = app.Lg.Record.data_headers{i};
                    catch
                        menu_titles{i} = sprintf('Channel %i', i);
                    end
                end
                
                % Make new menus to control the visibility of data lines

                % Delete the old menus first
                delete(app.DataDisplayMenus);
                
                % Create an empty array of graphics objects and fill it
                % with new menu items
                app.DataDisplayMenus = gobjects(n_lines);
                for i = 1:n_lines
                    app.DataDisplayMenus(i) = uimenu(app.DisplaychannelsMenu, ...
                        'Text',             menu_titles{i}, ...
                        'MenuSelectedFcn',  createChannelDisplayCallback(app, i), ...
                        'Checked',          app.DataLines(i).Visible);
                    if i==1
                        app.DataDisplayMenus(i).Separator = 'on';
                    end
                end
                
                % Make new menus for data transfer
                delete(app.DataTransferMenus);
                app.DataTransferMenus = gobjects(n_lines);
                for i = 1:n_lines
                    app.DataTransferMenus(i) = uimenu(app.TransferdataMenu, ...
                        'Text',             menu_titles{i}, ...
                        'MenuSelectedFcn',  createDataTransferCallback(app, i), ...
                        'Checked',          'off');
                end
            end
            
            app.LegendArea.Value = printReading(app.Lg);
        end
    
        function lbl = updateTimeLabelDropDown(app, ~)
            lbl = printTimeLabels(app.Lg.Record);
            
            % Use ItemsData to store indices corresponding to the menu entries
            app.SelectedTlDropDown.ItemsData = 1:length(lbl);
        end
    
        function is_on = setMeasButton(app, str)
            is_on = strcmpi(str, 'on'); 
            if is_on
                setIfChanged(app.MeasButton, 'Text', 'Stop');
            else 
                setIfChanged(app.MeasButton, 'Text', 'Start');
            end
        end
    
        % Convert input string to time value
        function t = tFromStr(app, str)
            
            % Timestamps can be datetime or numeric
            if isa(app.Lg.Record.timestamps,'datetime')
                try
                    t = datetime(str, 'Format', app.Lg.Record.datetime_fmt);
                catch ME
                    warning(['Failed to convert sting ''%s'' to datetime.\n' ...
                        'Error: ' ME.message], str);
                    t = [];
                end
            else
                try 
                    t = str2double(str);
                catch ME
                    warning(['Failed to convert sting to double..\n' ...
                        'Error: ' ME.message']);
                    t = [];
                end
            end
        end
    
        function resizeAxes(app)
            position = app.LoggerUIFigure.Position;
            
            if isempty(app.axes_init_pos)
                return
            end
            
            % Resize axes
            w = position(3)-app.axes_init_pos(1);
            h = position(4)-app.panel_h-app.top_menu_h;
            app.Axes.OuterPosition = [app.axes_init_pos(1:2), w, h];
        end
    
        function initMenus(app)
            
            % Init length limit menus
            app.LimNoneMenu.Tag         = 'Inf';
            app.Lim1kpointsMenu.Tag     = '1e3';
            app.Lim10kpointsMenu.Tag    = '1e4';
            app.Lim100kpointsMenu.Tag   = '1e5';
            app.Lim1MpointsMenu.Tag     = '1e6';
            
            % Init new file creation menus
            app.NewfileNeverMenu.Tag     = 'never';
            app.NewfileDailyMenu.Tag     = 'daily';
            app.NewfileWeeklyMenu.Tag    = 'weekly';
        end
    
        function f = createChannelDisplayCallback(app, ind)
            function channelDisplayCallback(~, Event)
                
                val = ~isequal(Event.Source.Checked, 'on');
                app.DataLines(ind).Visible = val;
                
                Event.Source.Checked = val;
                updateDataDisp(app);
            end
            
            f = @channelDisplayCallback;
        end
    
        function f = createDataTransferCallback(app, channel)
            function dataTransferCallback(~, ~)
                
                % Pick data that is currently displayed in the axes
                ind = arrayfun(@(x) ((x > app.Axes.XLim(1)) && (x < app.Axes.XLim(2))), ...
                    app.Lg.Record.timestamps);
                
                start_ind = find(ind, 1, 'first');
                stop_ind = find(ind, 1, 'last');
                
                triggerNewData(app.Lg, 'channel', channel, ...
                    'start_index', start_ind, 'stop_index', stop_ind);
            end
            
            f = @dataTransferCallback;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Logger)
            if exist('Logger', 'Var')
                
                % Start in normal mode
                app.Lg = Logger;
            else
                
                % Start in dummy mode
                disp('Logger GUI is starting in dummy mode.')
                app.LoggerUIFigure.Name = 'LogViewer';
                
                app.Lg = MyLogger();
                
                % Disable the measurement panel in dummy mode
                Elems = app.MeasurementPanel.Children;
                for i=1:length(Elems)
                    Elems(i).Enable = 'off';
                end
                
                app.LoggerMenu.Enable = 'off';
            end
           
            app.GuiSync = MyGuiSync(app, ...
                'KernelObj',            app.Lg, ...
                'createCallbackFcn',    @app.publicCreateCallbackFcn, ...
                'updateGuiFcn',         @app.updateGui);
            
            L = addlistener(app.Lg, 'NewMeasurement', @app.newMeasCallback);
            addToCleanup(app.GuiSync, L);
            
            % Add a listener to update the GUI when the axes pan and zoom
            % tools are used
            L = addlistener(app.Axes.Toolbar, 'SelectionChanged', ...
                @(~,~)updateAll(app.GuiSync));
            addToCleanup(app.GuiSync, L);
            
            % Store the initial sizes of components for the resizing of
            % axes
            app.axes_init_pos = app.Axes.OuterPosition;
            app.panel_h = app.LoggerUIFigure.OuterPosition(4)- ...
                app.Axes.OuterPosition(4)-app.top_menu_h;
            
            % Add an extended menu to the axes
            axtoolbar(app.Axes, {'export', 'datacursor', 'pan', 'zoomin', 'zoomout', 'restoreview'});
            
            initMenus(app);
            resizeAxes(app);
            updateGui(app);
            
            createLinks(app);
        end

        % Value changed function: MeasButton
        function MeasButtonValueChanged(app, event)
            value = app.MeasButton.Value;
            setMeasButton(app, value);
            if value
                app.MeasperiodsEditField.Editable = 'off';
                app.FileEditField.Editable = 'off';
                start(app.Lg);
            else
                stop(app.Lg);
                app.MeasperiodsEditField.Editable = 'on';
                app.FileEditField.Editable = 'on';
            end
            updateGui(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            addTimeLabel(app.Lg.Record);
            
            if ~isempty(app.SelectedTlDropDown.ItemsData)
                app.SelectedTlDropDown.Value = app.SelectedTlDropDown.ItemsData(end);
            end
            
            updateGui(app);
        end

        % Button pushed function: DeleteTlButton
        function DeleteTlButtonPushed(app, event)
            
            % Delete existing label
            if isempty(app.SelectedTlDropDown.Items)
                return
            end
            
            ind = app.SelectedTlDropDown.Value;
            deleteTimeLabel(app.Lg.Record, ind);
            
            updateGui(app);
        end

        % Value changed function: tminEditField
        function tminEditFieldValueChanged(app, event)
            str = app.tminEditField.Value;
            t = tFromStr(app, str);
            if ~isempty(t)
                app.Axes.XLim(1) = t;
            else
                warning('Limit value is unchanged.');
                app.tminEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: tmaxEditField
        function tmaxEditFieldValueChanged(app, event)
            str = app.tmaxEditField.Value;
            t = tFromStr(app, str);
            if ~isempty(t)
                app.Axes.XLim(2) = t;
            else
                warning('Limit value is unchanged.');
                app.tmaxEditField.Value = event.PreviousValue;
            end
        end

        % Button pushed function: ModifyTlButton
        function ModifyTlButtonPushed(app, event)
            if isempty(app.SelectedTlDropDown.Items)
                return
            end
            
            % Modify existing time label
            addTimeLabel(app.Lg.Record, 'index', app.SelectedTlDropDown.Value);
            
            updateGui(app);
        end

        % Value changed function: DisplayTlCheckBox
        function DisplayTlCheckBoxValueChanged(app, event)
            
            % Plot time labels by updating dispaly
            updateDataDisp(app);
        end

        % Size changed function: LoggerUIFigure
        function LoggerUIFigureSizeChanged(app, event)
            resizeAxes(app);
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            clear(app.Lg.Record);
            updateGui(app);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            try 
                dir = getLocalSettings('measurement_base_dir');
            catch
                dir = '';
            end
            
            [fname, path] = uigetfile([dir,'\*']);
            
            if ~isequal(fname, 0) && ~isequal(path, 0)
                app.Lg.Record = MyLog.load(fullfile(path, fname));
                
                % Update the link since the object changed
                updateRecordLinks(app);
            end
            
            % Bring gui to the front, as uidialog has pushed it to background
            app.LoggerUIFigure.Visible = 'off';
            app.LoggerUIFigure.Visible = 'on';
            
            cla(app.Axes);
            
            updateAll(app.GuiSync);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            save(app.Lg.Record);
        end

        % Menu selected function: ResizeableMenu
        function ResizeableMenuSelected(app, event)
            if isequal(app.ResizeableMenu.Checked, 'off')
                app.LoggerUIFigure.Resize = 'on';
                app.ResizeableMenu.Checked = 'on';
            else
                app.LoggerUIFigure.Resize = 'off';
                app.ResizeableMenu.Checked = 'off';
            end
        end

        % Menu selected function: ShowallMenu
        function ShowallMenuSelected(app, event)
            
            % Make all lines visible in the plot
            for i = 1:length(app.DataLines)
                app.DataLines(i).Visible = true;
            end
            
            % Check all display menus
            for i = 1:length(app.DataDisplayMenus)
                app.DataDisplayMenus(i).Checked = 'on';
            end
        end

        % Menu selected function: ShownoneMenu
        function ShownoneMenuSelected(app, event)
            
            % Hide all lines in the plot
            for i = 1:length(app.DataLines)
                app.DataLines(i).Visible = false;
            end
            
            % Uncheck all display menus
            for i = 1:length(app.DataDisplayMenus)
                app.DataDisplayMenus(i).Checked = 'off';
            end
        end

        % Menu selected function: Lim100kpointsMenu, 
        % Lim10kpointsMenu, Lim1MpointsMenu, Lim1kpointsMenu, 
        % LimNoneMenu
        function LengthLimitMenuSelected(app, event)
            app.Lg.Record.length_lim = str2double(event.Source.Tag);
            
            % Check the menu that was clicked and uncheck all the rest
            for i=1:length(app.LengthlimitMenu.Children)
                menu = app.LengthlimitMenu.Children(i);
                
                menu.Checked = isequal(event.Source, menu);
            end
        end

        % Menu selected function: NewfileDailyMenu, 
        % NewfileNeverMenu, NewfileWeeklyMenu
        function NewfileMenuSelected(app, event)
            switch event.Source.Tag
                case 'never'
                    app.Lg.FileCreationInterval = duration.empty();
                case 'daily'
                    app.Lg.FileCreationInterval = duration(24,0,0);
                case 'weekly'
                    app.Lg.FileCreationInterval = duration(7*24,0,0);
                otherwise
                    warning('Unknown duration tag ''%s''', event.Source.Tag)
            end
            
            % Check the menu that was clicked and uncheck all the rest
            for i=1:length(app.CreatenewfileMenu.Children)
                menu = app.CreatenewfileMenu.Children(i);
                
                menu.Checked = isequal(event.Source, menu);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoggerUIFigure = uifigure('Visible', 'off');
            app.LoggerUIFigure.Name = 'Logger';
            app.LoggerUIFigure.SizeChangedFcn = createCallbackFcn(app, @LoggerUIFigureSizeChanged, true);
            app.RecordMenu = uimenu(app.LoggerUIFigure);
            app.RecordMenu.Text = 'Record';
            app.SaveMenu = uimenu(app.RecordMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.RecordMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ClearMenu = uimenu(app.RecordMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.LoggerMenu = uimenu(app.LoggerUIFigure);
            app.LoggerMenu.Text = 'Logger';
            app.LengthlimitMenu = uimenu(app.LoggerMenu);
            app.LengthlimitMenu.Tooltip = {'Limit to the number of points that are retained in the measurement record'};
            app.LengthlimitMenu.Text = 'Length limit';
            app.LimNoneMenu = uimenu(app.LengthlimitMenu);
            app.LimNoneMenu.MenuSelectedFcn = createCallbackFcn(app, @LengthLimitMenuSelected, true);
            app.LimNoneMenu.Checked = 'on';
            app.LimNoneMenu.Text = 'None';
            app.Lim1kpointsMenu = uimenu(app.LengthlimitMenu);
            app.Lim1kpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @LengthLimitMenuSelected, true);
            app.Lim1kpointsMenu.Separator = 'on';
            app.Lim1kpointsMenu.Text = '1 k points';
            app.Lim10kpointsMenu = uimenu(app.LengthlimitMenu);
            app.Lim10kpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @LengthLimitMenuSelected, true);
            app.Lim10kpointsMenu.Text = '10 k points';
            app.Lim100kpointsMenu = uimenu(app.LengthlimitMenu);
            app.Lim100kpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @LengthLimitMenuSelected, true);
            app.Lim100kpointsMenu.Text = '100 k points';
            app.Lim1MpointsMenu = uimenu(app.LengthlimitMenu);
            app.Lim1MpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @LengthLimitMenuSelected, true);
            app.Lim1MpointsMenu.Text = '1 M points';
            app.CreatenewfileMenu = uimenu(app.LoggerMenu);
            app.CreatenewfileMenu.Text = 'Create new file';
            app.NewfileNeverMenu = uimenu(app.CreatenewfileMenu);
            app.NewfileNeverMenu.MenuSelectedFcn = createCallbackFcn(app, @NewfileMenuSelected, true);
            app.NewfileNeverMenu.Checked = 'on';
            app.NewfileNeverMenu.Text = 'Never';
            app.NewfileDailyMenu = uimenu(app.CreatenewfileMenu);
            app.NewfileDailyMenu.MenuSelectedFcn = createCallbackFcn(app, @NewfileMenuSelected, true);
            app.NewfileDailyMenu.Separator = 'on';
            app.NewfileDailyMenu.Text = 'Daily';
            app.NewfileWeeklyMenu = uimenu(app.CreatenewfileMenu);
            app.NewfileWeeklyMenu.MenuSelectedFcn = createCallbackFcn(app, @NewfileMenuSelected, true);
            app.NewfileWeeklyMenu.Text = 'Weekly';
            app.PlotMenu = uimenu(app.LoggerUIFigure);
            app.PlotMenu.Text = 'Plot';
            app.LogscaleyMenu = uimenu(app.PlotMenu);
            app.LogscaleyMenu.Text = 'Log scale y';
            app.ResizeableMenu = uimenu(app.PlotMenu);
            app.ResizeableMenu.MenuSelectedFcn = createCallbackFcn(app, @ResizeableMenuSelected, true);
            app.ResizeableMenu.Text = 'Resizeable';
            app.DisplaychannelsMenu = uimenu(app.LoggerUIFigure);
            app.DisplaychannelsMenu.Text = 'Display channels';
            app.ShowallMenu = uimenu(app.DisplaychannelsMenu);
            app.ShowallMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowallMenuSelected, true);
            app.ShowallMenu.Text = 'Show all';
            app.ShownoneMenu = uimenu(app.DisplaychannelsMenu);
            app.ShownoneMenu.MenuSelectedFcn = createCallbackFcn(app, @ShownoneMenuSelected, true);
            app.ShownoneMenu.Text = 'Show none';
            app.TransferdataMenu = uimenu(app.LoggerUIFigure);
            app.TransferdataMenu.Text = 'Transfer data';
            app.Axes = uiaxes(app.LoggerUIFigure);
            xlabel(app.Axes, 'Time')
            app.FileEditFieldLabel = uilabel(app.LoggerUIFigure);
            app.FileEditFieldLabel.Text = ' File';
            app.FileEditField = uieditfield(app.LoggerUIFigure, 'text');
            app.FileEditField.Value = 'M:\Measurement Campaigns\2018-07-13 Pressure Log';
            app.LegendArea = uitextarea(app.LoggerUIFigure);
            app.TimelabelsPanel = uipanel(app.LoggerUIFigure);
            app.TimelabelsPanel.Title = 'Time labels';
            app.AddButton = uibutton(app.TimelabelsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SelectedDropDownLabel = uilabel(app.TimelabelsPanel);
            app.SelectedDropDownLabel.Text = 'Selected';
            app.SelectedTlDropDown = uidropdown(app.TimelabelsPanel);
            app.SelectedTlDropDown.Items = {'24-Dec-2018 12:45:00 dummy'};
            app.SelectedTlDropDown.Value = '24-Dec-2018 12:45:00 dummy';
            app.DeleteTlButton = uibutton(app.TimelabelsPanel, 'push');
            app.DeleteTlButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTlButtonPushed, true);
            app.DeleteTlButton.Text = 'Delete';
            app.ModifyTlButton = uibutton(app.TimelabelsPanel, 'push');
            app.ModifyTlButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyTlButtonPushed, true);
            app.ModifyTlButton.Text = 'Modify';
            app.DisplayTlCheckBox = uicheckbox(app.TimelabelsPanel);
            app.DisplayTlCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayTlCheckBoxValueChanged, true);
            app.DisplayTlCheckBox.Text = 'Display';
            app.DisplayTlCheckBox.Value = true;
            app.MeasurementPanel = uipanel(app.LoggerUIFigure);
            app.MeasurementPanel.Title = 'Measurement';
            app.MeasButton = uibutton(app.MeasurementPanel, 'state');
            app.MeasButton.ValueChangedFcn = createCallbackFcn(app, @MeasButtonValueChanged, true);
            app.MeasButton.Text = 'Start';
            app.IntervalsLabel = uilabel(app.MeasurementPanel);
            app.IntervalsLabel.Text = 'Interval (s)';
            app.MeasperiodsEditField = uieditfield(app.MeasurementPanel, 'numeric');
            app.SavecontCheckBox = uicheckbox(app.MeasurementPanel);
            app.SavecontCheckBox.Text = 'Save continuously';
            app.PlotlimitsPanel = uipanel(app.LoggerUIFigure);
            app.PlotlimitsPanel.Title = 'Plot limits';
            app.tminEditFieldLabel = uilabel(app.PlotlimitsPanel);
            app.tminEditFieldLabel.Text = 't min';
            app.tminEditField = uieditfield(app.PlotlimitsPanel, 'text');
            app.tminEditField.ValueChangedFcn = createCallbackFcn(app, @tminEditFieldValueChanged, true);
            app.tminEditField.Value = '23-Dec-2018 15:59:08';
            app.tmaxEditFieldLabel = uilabel(app.PlotlimitsPanel);
            app.tmaxEditFieldLabel.Text = 't max';
            app.tmaxEditField = uieditfield(app.PlotlimitsPanel, 'text');
            app.tmaxEditField.ValueChangedFcn = createCallbackFcn(app, @tmaxEditFieldValueChanged, true);
            app.tmaxEditField.Value = '23-Dec-2018 15:59:08';
            app.yminEditFieldLabel = uilabel(app.PlotlimitsPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.PlotlimitsPanel, 'numeric');
            app.ymaxEditFieldLabel = uilabel(app.PlotlimitsPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.PlotlimitsPanel, 'numeric');
            app.AutoscaleyCheckBox = uicheckbox(app.PlotlimitsPanel);
            app.AutoscaleyCheckBox.Text = 'Autoscale y';
            app.AutoscaletCheckBox = uicheckbox(app.PlotlimitsPanel);
            app.AutoscaletCheckBox.Text = 'Autoscale t';
            % Show the figure after all components are created
            app.LoggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiLogger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoggerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoggerUIFigure)
        end
    end
end