classdef GuiNa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NetworkAnalyzerUIFigure       matlab.ui.Figure
        RefreshpanelButton            matlab.ui.control.Button
        CapturetraceButton            matlab.ui.control.Button
        StartfreqMHzEditFieldLabel    matlab.ui.control.Label
        StartfreqMHzEditField         matlab.ui.control.NumericEditField
        StopfreqMHzEditFieldLabel     matlab.ui.control.Label
        StopfreqMHzEditField          matlab.ui.control.NumericEditField
        IFBWHzEditFieldLabel          matlab.ui.control.Label
        IFBWHzEditField               matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel  matlab.ui.control.Label
        NumberofpointsEditField       matlab.ui.control.NumericEditField
        ProbepowerdBEditFieldLabel    matlab.ui.control.Label
        ProbepowerdBEditField         matlab.ui.control.NumericEditField
        SweeptypeDropDownLabel        matlab.ui.control.Label
        SweeptypeDropDown             matlab.ui.control.DropDown
        CenterfreqMHzEditFieldLabel   matlab.ui.control.Label
        CenterfreqMHzEditField        matlab.ui.control.NumericEditField
        SpanMHzEditFieldLabel         matlab.ui.control.Label
        SpanMHzEditField              matlab.ui.control.NumericEditField
        MeasPar1DropDown              matlab.ui.control.DropDown
        NtracesDropDownLabel          matlab.ui.control.Label
        NtracesDropDown               matlab.ui.control.DropDown
        Trace1Label                   matlab.ui.control.Label
        Trace1FormatDropDown          matlab.ui.control.DropDown
        Trace2Label                   matlab.ui.control.Label
        Trace2FormatDropDown          matlab.ui.control.DropDown
        NaveragesEditFieldLabel       matlab.ui.control.Label
        NaveragesEditField            matlab.ui.control.NumericEditField
        OutputButton                  matlab.ui.control.StateButton
        Trace1Axes                    matlab.ui.control.UIAxes
        Trace2Axes                    matlab.ui.control.UIAxes
        MeasPar2DropDown              matlab.ui.control.DropDown
        ParameterLabel                matlab.ui.control.Label
        DataformatLabel               matlab.ui.control.Label
        TriggerButtonGroup            matlab.ui.container.ButtonGroup
        HoldButton                    matlab.ui.control.RadioButton
        SingleButton                  matlab.ui.control.RadioButton
        ContinuousButton              matlab.ui.control.RadioButton
        DataSavePanel                 matlab.ui.container.Panel
        BasedirectoryEditFieldLabel   matlab.ui.control.Label
        BasedirectoryEditField        matlab.ui.control.EditField
        MeasurementsessionnameEditFieldLabel  matlab.ui.control.Label
        MeasurementsessionnameEditField  matlab.ui.control.EditField
        MeasurementfilenameEditFieldLabel  matlab.ui.control.Label
        MeasurementfilenameEditField  matlab.ui.control.EditField
        SavetracesButton              matlab.ui.control.Button
    end


    properties (Access = public)
        name;
        Instr; % MyNa object
        linked_elem_list = {} % list of Gui control elements 
        % which have counterpart properties
    end

    
    methods (Access = private)
        
        function updateTraceCtrlAccess(app)
            if app.Instr.trace_no > 1
                app.Trace2FormatDropDown.Enable = 'on';
                app.Trace2Label.Enable = 'on';
                app.MeasPar2DropDown.Enable = 'on';
            else 
                app.Trace2FormatDropDown.Enable = 'off';
                app.Trace2Label.Enable = 'off';
                app.MeasPar2DropDown.Enable = 'off';
            end
        end
       
        function lbl = yLabelFromForm(app, nTrace)
            fmt_tag = sprintf('form%i', nTrace);
            switch app.Instr.(fmt_tag)
                case 'MLOG'
                    lbl = 'r [dB]';
                case 'MLIN'
                    lbl = 'r';
                case 'PHAS'
                    lbl = 'Phase [rad]';
                otherwise
                    lbl = 'Y';
            end
        end
    end

    
    methods (Access = public)
        
        % creating a generic 
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end

        
        function createControlLinks(app)
            linkControlElement(app, ...
                app.StartfreqMHzEditField, 'start_freq', ...
                'input_presc',1e-6, ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.CenterfreqMHzEditField, 'cent_freq', ...
                'input_presc',1e-6, ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.StopfreqMHzEditField, 'stop_freq', ...
                'input_presc',1e-6, ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.SpanMHzEditField, 'span',...
                'input_presc',1e-6, ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.IFBWHzEditField, 'ifbw', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.NaveragesEditField, 'average_no', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.NumberofpointsEditField, 'point_no', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.MeasPar1DropDown, 'meas_par1', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.MeasPar2DropDown, 'meas_par2', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.SweeptypeDropDown, 'sweep_type', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.ProbepowerdBEditField, 'power', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.OutputButton, 'enable_out', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.NtracesDropDown, 'trace_no');
            linkControlElement(app, ...
                app.Trace1FormatDropDown, 'form1');
            linkControlElement(app, ...
                app.Trace2FormatDropDown, 'form2');
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            
            % make a numerical dropdown
            app.NtracesDropDown.ItemsData = str2double(app.NtracesDropDown.ItemsData);
         
            createControlLinks(app);
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
            updateTraceCtrlAccess(app);
        end

        % Close request function: NetworkAnalyzerUIFigure
        function NetworkAnalyzerUIFigureCloseRequest(app, event)
            try
                delete(app.Instr);
            catch
            end
            delete(app)
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            readPropertyHedged(app.Instr, 'all');
            updateGui(app);
        end

        % Button pushed function: CapturetraceButton
        function CapturetraceButtonPushed(app, event)
            openDevice(app.Instr);
            readTrace(app.Instr, 1);
            %plotTrace(app.Instr.Trace1, app.Trace1Axes);
            plot(app.Trace1Axes, app.Instr.Trace1.x, app.Instr.Trace1.y);
            ylabel(app.Trace1Axes, yLabelFromForm(app, 1));
            if app.Instr.trace_no > 1
                readTrace(app.Instr, 2);
                %plotTrace(app.Instr.Trace2, app.Trace2Axes);
                plot(app.Trace2Axes, app.Instr.Trace2.x, app.Instr.Trace2.y);
                ylabel(app.Trace2Axes, yLabelFromForm(app, 2));
            end
            closeDevice(app.Instr);
        end

        % Value changed function: NtracesDropDown
        function NtracesDropDownValueChanged(app, event)
            value = int32(app.NtracesDropDown.Value);
            
            if value==1 || value==2
                writePropertyHedged(app.Instr, 'trace_no', value);
                updateTraceCtrlAccess(app);
                
            else
                error('Unknown dropdown option');
            end
            updateGui(app);
        end

        % Selection changed function: TriggerButtonGroup
        function TriggerButtonGroupSelectionChanged(app, event)
            selectedButton = app.TriggerButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Hold'
                    app.Instr.abortSweep();
                case 'Single'
                    app.Instr.singleSweep();
                    app.HoldButton.Value = true;
                case 'Continuous'
                    app.Instr.startContSweep();
                otherwise
                    error('Trigger case unknown in callback');
            end
        end

        % Value changed function: Trace1FormatDropDown
        function Trace1FormatDropDownValueChanged(app, event)
            value = app.Trace1FormatDropDown.Value;
            openDevice(app.Instr);
            writeTraceFormat(app.Instr, 1, value);
            readProperty(app.Instr, 'all');
            closeDevice(app.Instr);
            updateGui(app);
        end

        % Value changed function: Trace2FormatDropDown
        function Trace2FormatDropDownValueChanged(app, event)
            value = app.Trace2FormatDropDown.Value;
            openDevice(app.Instr);
            writeTraceFormat(app.Instr, 2, value);
            readProperty(app.Instr, 'all');
            closeDevice(app.Instr);
            updateGui(app);
        end

        % Button pushed function: SavetracesButton
        function SavetracesButtonPushed(app, event)
            path = createSessionPath(app.BasedirectoryEditField.Value, app.MeasurementsessionnameEditField.Value);
            app.Instr.Trace1.filename = [app.MeasurementfilenameEditField.Value,'_tr1'];
            save(app.Instr.Trace1, 'save_dir', path);
            if app.Instr.trace_no > 1
                app.Instr.Trace2.filename = [app.MeasurementfilenameEditField.Value,'_tr2'];
                save(app.Instr.Trace2, 'save_dir', path);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NetworkAnalyzerUIFigure = uifigure;
            app.NetworkAnalyzerUIFigure.Name = 'Network Analyzer';
            app.NetworkAnalyzerUIFigure.CloseRequestFcn = createCallbackFcn(app, @NetworkAnalyzerUIFigureCloseRequest, true);
            app.RefreshpanelButton = uibutton(app.NetworkAnalyzerUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.CapturetraceButton = uibutton(app.NetworkAnalyzerUIFigure, 'push');
            app.CapturetraceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturetraceButtonPushed, true);
            app.CapturetraceButton.Text = 'Capture trace';
            app.StartfreqMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.StartfreqMHzEditFieldLabel.Text = 'Start freq (MHz)';
            app.StartfreqMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.StartfreqMHzEditField.Limits = [0 3000];
            app.StopfreqMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.StopfreqMHzEditFieldLabel.Text = 'Stop freq (MHz)';
            app.StopfreqMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.IFBWHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.IFBWHzEditFieldLabel.Text = 'IF BW (Hz)';
            app.IFBWHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.IFBWHzEditField.ValueDisplayFormat = '%e';
            app.NumberofpointsEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.ProbepowerdBEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.ProbepowerdBEditFieldLabel.Text = 'Probe power (dB)';
            app.ProbepowerdBEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.SweeptypeDropDownLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.SweeptypeDropDownLabel.Text = 'Sweep type';
            app.SweeptypeDropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.SweeptypeDropDown.Items = {'Linear', 'Log'};
            app.SweeptypeDropDown.ItemsData = {'LIN', 'LOG'};
            app.SweeptypeDropDown.Value = 'LIN';
            app.CenterfreqMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.CenterfreqMHzEditFieldLabel.Text = 'Center freq (MHz)';
            app.CenterfreqMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.SpanMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.SpanMHzEditFieldLabel.Text = 'Span (MHz)';
            app.SpanMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.MeasPar1DropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.MeasPar1DropDown.Items = {'S11', 'S12', 'S21', 'S22'};
            app.MeasPar1DropDown.Value = 'S21';
            app.NtracesDropDownLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.NtracesDropDownLabel.Text = 'N traces';
            app.NtracesDropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.NtracesDropDown.Items = {'1', '2'};
            app.NtracesDropDown.ItemsData = {'1', '2'};
            app.NtracesDropDown.ValueChangedFcn = createCallbackFcn(app, @NtracesDropDownValueChanged, true);
            app.NtracesDropDown.Value = '1';
            app.Trace1Label = uilabel(app.NetworkAnalyzerUIFigure);
            app.Trace1Label.Text = 'Trace 1';
            app.Trace1FormatDropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.Trace1FormatDropDown.Items = {'Log magnitude r', 'Lin magnitude r', 'Lin phase'};
            app.Trace1FormatDropDown.ItemsData = {'MLOG', 'MLIN', 'PHAS'};
            app.Trace1FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @Trace1FormatDropDownValueChanged, true);
            app.Trace1FormatDropDown.Value = 'MLOG';
            app.Trace2Label = uilabel(app.NetworkAnalyzerUIFigure);
            app.Trace2Label.Enable = 'off';
            app.Trace2Label.Text = 'Trace 2';
            app.Trace2FormatDropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.Trace2FormatDropDown.Items = {'Log magnitude r', 'Lin magnitude r', 'Lin phase'};
            app.Trace2FormatDropDown.ItemsData = {'MLOG', 'MLIN', 'PHAS'};
            app.Trace2FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @Trace2FormatDropDownValueChanged, true);
            app.Trace2FormatDropDown.Enable = 'off';
            app.Trace2FormatDropDown.Value = 'MLOG';
            app.NaveragesEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.NaveragesEditFieldLabel.Text = 'N averages';
            app.NaveragesEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.OutputButton = uibutton(app.NetworkAnalyzerUIFigure, 'state');
            app.OutputButton.Text = 'Output';
            app.Trace1Axes = uiaxes(app.NetworkAnalyzerUIFigure);
            title(app.Trace1Axes, 'Trace 1')
            xlabel(app.Trace1Axes, 'Frequency [Hz]')
            ylabel(app.Trace1Axes, 'Y')
            app.Trace2Axes = uiaxes(app.NetworkAnalyzerUIFigure);
            title(app.Trace2Axes, 'Trace 2')
            xlabel(app.Trace2Axes, 'Frequency [Hz]')
            ylabel(app.Trace2Axes, 'Y')
            app.MeasPar2DropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.MeasPar2DropDown.Items = {'S11', 'S12', 'S21', 'S22'};
            app.MeasPar2DropDown.Enable = 'off';
            app.MeasPar2DropDown.Value = 'S21';
            app.ParameterLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.ParameterLabel.Text = 'Parameter';
            app.DataformatLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.DataformatLabel.Text = 'Data format';
            app.TriggerButtonGroup = uibuttongroup(app.NetworkAnalyzerUIFigure);
            app.TriggerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TriggerButtonGroupSelectionChanged, true);
            app.TriggerButtonGroup.Title = 'Trigger';
            app.HoldButton = uiradiobutton(app.TriggerButtonGroup);
            app.HoldButton.Text = 'Hold';
            app.HoldButton.Value = true;
            app.SingleButton = uiradiobutton(app.TriggerButtonGroup);
            app.SingleButton.Text = 'Single';
            app.ContinuousButton = uiradiobutton(app.TriggerButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.DataSavePanel = uipanel(app.NetworkAnalyzerUIFigure);
            app.BasedirectoryEditFieldLabel = uilabel(app.DataSavePanel);
            app.BasedirectoryEditFieldLabel.Text = 'Base directory';
            app.BasedirectoryEditField = uieditfield(app.DataSavePanel, 'text');
            app.BasedirectoryEditField.Editable = 'off';
            app.BasedirectoryEditField.Value = 'M:\Measurement Campaigns';
            app.MeasurementsessionnameEditFieldLabel = uilabel(app.DataSavePanel);
            app.MeasurementsessionnameEditFieldLabel.Text = 'Measurement session name';
            app.MeasurementsessionnameEditField = uieditfield(app.DataSavePanel, 'text');
            app.MeasurementsessionnameEditField.Value = 'test';
            app.MeasurementfilenameEditFieldLabel = uilabel(app.DataSavePanel);
            app.MeasurementfilenameEditFieldLabel.Text = 'Measurement file name';
            app.MeasurementfilenameEditField = uieditfield(app.DataSavePanel, 'text');
            app.MeasurementfilenameEditField.Value = 'test';
            app.SavetracesButton = uibutton(app.DataSavePanel, 'push');
            app.SavetracesButton.ButtonPushedFcn = createCallbackFcn(app, @SavetracesButtonPushed, true);
            app.SavetracesButton.Text = 'Save traces';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiNa(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NetworkAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NetworkAnalyzerUIFigure)
        end
    end
end