classdef GuiNewpTlb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TLBdiodelaserUIFigure          matlab.ui.Figure
        ParametersPanel                matlab.ui.container.Panel
        WavelengthnmLabel              matlab.ui.control.Label
        WavelengthnmEditField          matlab.ui.control.NumericEditField
        PowermWEditFieldLabel          matlab.ui.control.Label
        PowermWEditField               matlab.ui.control.NumericEditField
        DiodecurrentmAEditFieldLabel   matlab.ui.control.Label
        DiodecurrentmAEditField        matlab.ui.control.NumericEditField
        DiodetemperatureCEditFieldLabel  matlab.ui.control.Label
        DiodetemperatureCEditField     matlab.ui.control.NumericEditField
        SetpointsPanel                 matlab.ui.container.Panel
        PowersetpointEditField         matlab.ui.control.NumericEditField
        ConstantpowerCheckBox          matlab.ui.control.CheckBox
        IncrementnmEditFieldLabel      matlab.ui.control.Label
        IncrementnmEditField           matlab.ui.control.NumericEditField
        CurrentsetpointEditField       matlab.ui.control.NumericEditField
        WlSetPointSpinner              matlab.ui.control.Spinner
        WavelengthtrackCheckBox        matlab.ui.control.CheckBox
        ControlmodeDropDownLabel       matlab.ui.control.Label
        ControlmodeDropDown            matlab.ui.control.DropDown
        LaseroutputButton              matlab.ui.control.StateButton
        SetmaxpowerButton              matlab.ui.control.Button
        UpdateintervalsEditFieldLabel  matlab.ui.control.Label
        UpdateintervalsEditField       matlab.ui.control.NumericEditField
    end


    properties (GetAccess = public, SetAccess = protected)
        GuiSync
        Instr
        UpdTimer
    end

    methods (Access = public)
        function updateAccess(app)
            if app.ConstantpowerCheckBox.Value
                setIfChanged(app.PowersetpointEditField, 'Enable', 'on');
                setIfChanged(app.CurrentsetpointEditField, 'Enable', 'off');
            else
                setIfChanged(app.PowersetpointEditField, 'Enable', 'off');
                setIfChanged(app.CurrentsetpointEditField, 'Enable', 'on');
            end
            
            if app.WavelengthtrackCheckBox.Value
                setIfChanged(app.WlSetPointSpinner, 'Enable', 'on');
            else
                setIfChanged(app.WlSetPointSpinner, 'Enable', 'off');
            end
            
            % Constant power mode should not be switched when output is on
            if app.Instr.enable_output
                setIfChanged(app.ConstantpowerCheckBox, 'Enable', 'off');
            else
                setIfChanged(app.ConstantpowerCheckBox, 'Enable', 'on');
            end
        end
    end

    methods (Access = private)
        
        function createLinks(app)
            addLink(app.GuiSync, app.WavelengthnmEditField,      'Instr.wavelength');
            addLink(app.GuiSync, app.PowermWEditField,           'Instr.power');
            addLink(app.GuiSync, app.DiodecurrentmAEditField,    'Instr.current');
            addLink(app.GuiSync, app.DiodetemperatureCEditField, 'Instr.temp_diode');
            addLink(app.GuiSync, app.PowersetpointEditField,     'Instr.power_sp');
            addLink(app.GuiSync, app.CurrentsetpointEditField,   'Instr.current_sp');
            addLink(app.GuiSync, app.WlSetPointSpinner,          'Instr.wavelength_sp');
            addLink(app.GuiSync, app.ConstantpowerCheckBox,      'Instr.const_power');
            addLink(app.GuiSync, app.WavelengthtrackCheckBox,    'Instr.wavelength_track');
            addLink(app.GuiSync, app.LaseroutputButton,          'Instr.enable_output');
            addLink(app.GuiSync, app.ControlmodeDropDown,        'Instr.control_mode');
            addLink(app.GuiSync, app.UpdateintervalsEditField,   'UpdTimer.Period');
        end
    
        function updTimerFcn(app, ~, ~)
            try
                
                % Clear the callback queue
                drawnow();
                
                if isa(app.Instr, 'MyNewpTlb6300')
                    
                    % Old TLB6300 communicate using GPIB and do not suffer
                    % from the race condition problem of Newport usb driver
                    sync_ready = true;
                else
                    Nuc = MyNewpUsbComm.instance();
                    sync_ready = ~Nuc.isbusy;
                end
                
                if sync_ready
                    sync(app.Instr);
                end
            catch ME
                warning(ME.message)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, ...
                'KernelObj',            Instrument, ...
                'updateGuiFcn',         @app.updateAccess);
            
            app.WlSetPointSpinner.Step = app.IncrementnmEditField.Value;
            
            app.UpdTimer = timer(); % Create timer for gui update
            addToCleanup(app.GuiSync, app.UpdTimer);     
            
            % Set up and start periodic update of the gui
            app.UpdTimer.ExecutionMode = 'fixedSpacing';
            
            % Timer period is limited by TLB6700 driver, 
            % for which reading 20 (about all) properties takes 0.2 s.
            app.UpdTimer.Period = 2; %second.
            app.UpdTimer.TimerFcn = @app.updTimerFcn;
            app.UpdTimer.BusyMode = 'drop';
            
            createLinks(app);   
            
            start(app.UpdTimer);
        end

        % Button pushed function: SetmaxpowerButton
        function SetmaxpowerButtonPushed(app, event)
            
            % setMaxOutPower works irrespectrive if the laser is in the constant power mode
            setMaxOutPower(app.Instr);
            sync(app.Instr);
        end

        % Value changed function: UpdateintervalsEditField
        function UpdateintervalsEditFieldValueChanged(app, event)
            value = app.UpdateintervalsEditField.Value;
            if value<0.2
                warning(['Timer interval below 0.2 s might result in the program '...
                    'hanging due to the finite time of gui update'])
            end
            
            stop(app.UpdTimer);
            app.UpdTimer.Period = value;
            start(app.UpdTimer);
        end

        % Value changed function: IncrementnmEditField
        function IncrementnmEditFieldValueChanged(app, event)
            app.WlSetPointSpinner.Step = app.IncrementnmEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TLBdiodelaserUIFigure = uifigure('Visible', 'off');
            app.TLBdiodelaserUIFigure.Name = 'TLB diode laser';
            app.ParametersPanel = uipanel(app.TLBdiodelaserUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.WavelengthnmLabel = uilabel(app.ParametersPanel);
            app.WavelengthnmLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.WavelengthnmEditField.ValueDisplayFormat = '%.2f';
            app.WavelengthnmEditField.Editable = 'off';
            app.WavelengthnmEditField.Value = 1550.008;
            app.PowermWEditFieldLabel = uilabel(app.ParametersPanel);
            app.PowermWEditFieldLabel.Text = 'Power (mW)';
            app.PowermWEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PowermWEditField.ValueDisplayFormat = '%.2f';
            app.PowermWEditField.Editable = 'off';
            app.PowermWEditField.Value = 23;
            app.DiodecurrentmAEditFieldLabel = uilabel(app.ParametersPanel);
            app.DiodecurrentmAEditFieldLabel.Text = 'Diode current (mA)';
            app.DiodecurrentmAEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DiodecurrentmAEditField.ValueDisplayFormat = '%.2f';
            app.DiodecurrentmAEditField.Editable = 'off';
            app.DiodecurrentmAEditField.Value = 10.4;
            app.DiodetemperatureCEditFieldLabel = uilabel(app.ParametersPanel);
            app.DiodetemperatureCEditFieldLabel.Text = 'Diode temperature (C)';
            app.DiodetemperatureCEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DiodetemperatureCEditField.ValueDisplayFormat = '%.2f';
            app.DiodetemperatureCEditField.Editable = 'off';
            app.DiodetemperatureCEditField.Value = 20;
            app.SetpointsPanel = uipanel(app.TLBdiodelaserUIFigure);
            app.SetpointsPanel.Title = 'Setpoints';
            app.PowersetpointEditField = uieditfield(app.SetpointsPanel, 'numeric');
            app.PowersetpointEditField.ValueDisplayFormat = '%.12g';
            app.PowersetpointEditField.Value = 15;
            app.ConstantpowerCheckBox = uicheckbox(app.SetpointsPanel);
            app.ConstantpowerCheckBox.Text = 'Constant power';
            app.IncrementnmEditFieldLabel = uilabel(app.SetpointsPanel);
            app.IncrementnmEditFieldLabel.Text = 'Increment (nm)';
            app.IncrementnmEditField = uieditfield(app.SetpointsPanel, 'numeric');
            app.IncrementnmEditField.ValueDisplayFormat = '%.11g';
            app.IncrementnmEditField.ValueChangedFcn = createCallbackFcn(app, @IncrementnmEditFieldValueChanged, true);
            app.IncrementnmEditField.Value = 0.05;
            app.CurrentsetpointEditField = uieditfield(app.SetpointsPanel, 'numeric');
            app.CurrentsetpointEditField.ValueDisplayFormat = '%.12g';
            app.WlSetPointSpinner = uispinner(app.SetpointsPanel);
            app.WlSetPointSpinner.ValueDisplayFormat = '%11.2f';
            app.WlSetPointSpinner.Value = 780.67;
            app.WavelengthtrackCheckBox = uicheckbox(app.SetpointsPanel);
            app.WavelengthtrackCheckBox.Text = 'Wavelength track';
            app.WavelengthtrackCheckBox.Value = true;
            app.ControlmodeDropDownLabel = uilabel(app.TLBdiodelaserUIFigure);
            app.ControlmodeDropDownLabel.Text = 'Control mode';
            app.ControlmodeDropDown = uidropdown(app.TLBdiodelaserUIFigure);
            app.ControlmodeDropDown.Items = {'Local', 'Remote'};
            app.ControlmodeDropDown.ItemsData = {'loc', 'rem'};
            app.ControlmodeDropDown.Value = 'loc';
            app.LaseroutputButton = uibutton(app.TLBdiodelaserUIFigure, 'state');
            app.LaseroutputButton.Text = 'Laser output';
            app.SetmaxpowerButton = uibutton(app.TLBdiodelaserUIFigure, 'push');
            app.SetmaxpowerButton.ButtonPushedFcn = createCallbackFcn(app, @SetmaxpowerButtonPushed, true);
            app.SetmaxpowerButton.Text = 'Set max power';
            app.UpdateintervalsEditFieldLabel = uilabel(app.TLBdiodelaserUIFigure);
            app.UpdateintervalsEditFieldLabel.Text = 'Update interval (s)';
            app.UpdateintervalsEditField = uieditfield(app.TLBdiodelaserUIFigure, 'numeric');
            app.UpdateintervalsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateintervalsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.TLBdiodelaserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiNewpTlb(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TLBdiodelaserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TLBdiodelaserUIFigure)
        end
    end
end