classdef GuiPm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TemplateUIFigure               matlab.ui.Figure
        SensorLabel                    matlab.ui.control.Label
        PoweruWEditFieldLabel          matlab.ui.control.Label
        PoweruWEditField               matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        WavelengthnmEditField          matlab.ui.control.NumericEditField
        NaveragesEditFieldLabel        matlab.ui.control.Label
        NaveragesEditField             matlab.ui.control.NumericEditField
        PanelupdateintervalsEditFieldLabel  matlab.ui.control.Label
        PanelupdateintervalsEditField  matlab.ui.control.NumericEditField
        AutopowerrangeCheckBox         matlab.ui.control.CheckBox
    end


    properties (Access = public)
        UpdTimer % Timer for gui update
        
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        name = 'Powermeter'  % App name to clear on exit;
        Listeners
    end

    methods (Access = public)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
    
        function updateSensorTitle(app)
            sens_str=regexp(app.Instr.sensor,',','split');
            app.SensorLabel.Text=['Sensor: ',sens_str{1}];
        end
    
        function updateGui(app)
            updateLinkedGuiElements(app);
            updateSensorTitle(app);
        end
    
    end
    
    methods (Access = private)
        
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)
            linkGuiElement(app, app.PoweruWEditField, 'Instr.power',...
                'input_presc',10^6);
            linkGuiElement(app, app.WavelengthnmEditField, 'Instr.wl');
            linkGuiElement(app, app.NaveragesEditField, 'Instr.average_no');
            linkGuiElement(app, app.AutopowerrangeCheckBox, 'Instr.auto_pow_rng');
            linkGuiElement(app, app.PanelupdateintervalsEditField, 'UpdTimer.Period',...
                'create_callback', false);
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            app.UpdTimer = timer(); % Create timer for gui update
            
            initInstrGui(app);
            createLinks(app);
            readPropertyHedged(app.Instr,'all');         
            
            % Set up and start periodic update of the gui
            app.UpdTimer.ExecutionMode = 'fixedSpacing';
            app.UpdTimer.Period = 1; %second.
            app.UpdTimer.TimerFcn = @(~,~)readPropertyHedged(app.Instr,'all');
            app.UpdTimer.BusyMode = 'drop';
            start(app.UpdTimer);
        end

        % Close request function: TemplateUIFigure
        function TemplateUIFigureCloseRequest(app, event)
            stop(app.UpdTimer);
            delete(app.UpdTimer);
            % Delete instrument object, clear global variable and delete app
            deleteInstrGui(app);
        end

        % Value changed function: PanelupdateintervalsEditField
        function PanelupdateintervalsEditFieldValueChanged(app, event)
            value = app.PanelupdateintervalsEditField.Value;
            stop(app.UpdTimer);
            app.UpdTimer.Period=value;
            start(app.UpdTimer);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TemplateUIFigure = uifigure;
            app.TemplateUIFigure.Name = 'Template';
            app.TemplateUIFigure.CloseRequestFcn = createCallbackFcn(app, @TemplateUIFigureCloseRequest, true);
            app.SensorLabel = uilabel(app.TemplateUIFigure);
            app.SensorLabel.Text = 'Sensor:';
            app.PoweruWEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.PoweruWEditFieldLabel.Text = 'Power (uW)';
            app.PoweruWEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.PoweruWEditField.ValueDisplayFormat = '%.3f';
            app.PoweruWEditField.Editable = 'off';
            app.PoweruWEditField.Value = 2000;
            app.WavelengthnmEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.WavelengthnmEditField.Value = 700;
            app.NaveragesEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.NaveragesEditFieldLabel.Text = 'N averages';
            app.NaveragesEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.NaveragesEditField.ValueDisplayFormat = '%.0f';
            app.NaveragesEditField.Value = 1;
            app.PanelupdateintervalsEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.PanelupdateintervalsEditFieldLabel.Text = 'Panel update interval (s)';
            app.PanelupdateintervalsEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.PanelupdateintervalsEditField.ValueChangedFcn = createCallbackFcn(app, @PanelupdateintervalsEditFieldValueChanged, true);
            app.PanelupdateintervalsEditField.Value = 1;
            app.AutopowerrangeCheckBox = uicheckbox(app.TemplateUIFigure);
            app.AutopowerrangeCheckBox.Text = 'Auto power range?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiPm(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TemplateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TemplateUIFigure)
        end
    end
end