classdef GuiRsa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TektronixRSAUIFigure         matlab.ui.Figure
        StartfreqMHzEditFieldLabel   matlab.ui.control.Label
        StartfreqMHzEditField        matlab.ui.control.NumericEditField
        StopfreqMHzEditFieldLabel    matlab.ui.control.Label
        StopfreqMHzEditField         matlab.ui.control.NumericEditField
        RBWHzEditFieldLabel          matlab.ui.control.Label
        RBWHzEditField               matlab.ui.control.NumericEditField
        CenterfreqMHzEditFieldLabel  matlab.ui.control.Label
        CenterfreqMHzEditField       matlab.ui.control.NumericEditField
        SpanMHzEditFieldLabel        matlab.ui.control.Label
        SpanMHzEditField             matlab.ui.control.NumericEditField
        CapturetraceButton           matlab.ui.control.Button
        RefreshpanelButton           matlab.ui.control.Button
        FindinstrumentButton         matlab.ui.control.Button
        NumberofpointsDropDownLabel  matlab.ui.control.Label
        NumberofpointsDropDown       matlab.ui.control.DropDown
        TracesettingsPanel           matlab.ui.container.Panel
        DetectionDropDownLabel       matlab.ui.control.Label
        DetectionDropDown            matlab.ui.control.DropDown
        TraceDropDownLabel           matlab.ui.control.Label
        TraceDropDown                matlab.ui.control.DropDown
        FunctionDropDownLabel        matlab.ui.control.Label
        FunctionDropDown             matlab.ui.control.DropDown
        NaveragesEditFieldLabel      matlab.ui.control.Label
        NaveragesEditField           matlab.ui.control.NumericEditField
        CountCheckBox                matlab.ui.control.CheckBox
        RefleveldBmEditFieldLabel    matlab.ui.control.Label
        RefleveldBmEditField         matlab.ui.control.NumericEditField
        DisplaysettingsPanel         matlab.ui.container.Panel
        YoffsetdBmEditFieldLabel     matlab.ui.control.Label
        YoffsetdBmEditField          matlab.ui.control.NumericEditField
        YscaledBmdivEditFieldLabel   matlab.ui.control.Label
        YscaledBmdivEditField        matlab.ui.control.NumericEditField
        AcquisitionButtonGroup       matlab.ui.container.ButtonGroup
        StopButton                   matlab.ui.control.RadioButton
        SingleButton                 matlab.ui.control.RadioButton
        ContinuousButton             matlab.ui.control.RadioButton
        DisplaytracesPanel           matlab.ui.container.Panel
        Tr3Button                    matlab.ui.control.StateButton
        Tr2Button                    matlab.ui.control.StateButton
        Tr1Button                    matlab.ui.control.StateButton
        AutoRBWCheckBox              matlab.ui.control.CheckBox
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        instance_name;
    end

    methods (Access = private)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end

        function updateTraceTags(app, n_tr)
            % re-link the channel property control elements to the new trace  
            app.DetectionDropDown.Tag=sprintf('det_trace%i',n_tr);
            app.FunctionDropDown.Tag=sprintf('func_trace%i',n_tr);
            app.NaveragesEditField.Tag=sprintf('average_no%i',n_tr);
            app.CountCheckBox.Tag=sprintf('cnt_trace%i',n_tr);
        end
        
        % Lineked control elements are updated by calling updateGui(app)
        function createControlLinks(app)
            linkControlElement(app, ...
                app.StartfreqMHzEditField, 'start_freq', ...
                'input_presc',1e-6, ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.CenterfreqMHzEditField, 'cent_freq', ...
                'input_presc',1e-6, ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.StopfreqMHzEditField, 'stop_freq', ...
                'input_presc',1e-6, ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.SpanMHzEditField, 'span',...
                'input_presc',1e-6, ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.AutoRBWCheckBox, 'auto_rbw', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.RBWHzEditField, 'rbw', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.RefleveldBmEditField, 'ref_level', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.YscaledBmdivEditField, 'disp_y_scale', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.YoffsetdBmEditField, 'disp_y_offset', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, ...
                app.NumberofpointsDropDown, 'point_no', ...
                'init_val_list', true,...
                'create_callback_fcn', @app.createGenericCallback);
            
            % trace-specific elemnts are initialized first with dummy tags
            linkControlElement(app, app.DetectionDropDown, 'det_trace1', ...
                'out_proc_fcn', @(x) standardizeValue(app.Instr, 'det_trace1', x),...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.FunctionDropDown, 'func_trace1', ...
                'out_proc_fcn', @(x) standardizeValue(app.Instr, 'func_trace1', x),...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.NaveragesEditField, 'average_no1', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.CountCheckBox, 'cnt_trace1', ...
                'create_callback_fcn', @app.createGenericCallback);
            % assign tags based on the trace selection
            updateTraceTags(app, str2double(app.TraceDropDown.Value));
            
            for i=1:3
                b_tag = sprintf('Tr%iButton',i);
                c_tag = sprintf('disp_trace%i',i);
                linkControlElement(app, app.(b_tag), c_tag, ...
                'create_callback_fcn', @app.createGenericCallback);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, interface, address, varargin)
            % Start the Gui with specified instrument class
            % Assign the instance name to the class variable and the instrument name to the window
            genericInitGui(app, 'MyRsa', interface, address, varargin{:});
            
            if app.Instr.init_cont
                app.ContinuousButton.Value = true;
            else
                app.StopButton.Value = true;
            end
            
            createControlLinks(app);
            updateGui(app);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Button pushed function: FindinstrumentButton
        function FindinstrumentButtonPushed(app, event)
            findInstrumentCallback(app);
        end

        % Close request function: TektronixRSAUIFigure
        function TektronixRSAUIFigureCloseRequest(app, event)
            delete(app.Instr);
            evalin('base', sprintf('clear(''%s'')',app.instance_name));
            delete(app);
        end

        % Selection changed function: AcquisitionButtonGroup
        function AcquisitionButtonGroupSelectionChanged(app, event)
            selectedButton = app.AcquisitionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Stop'
                    writePropertyHedged(app.Instr,'abort_acq','');
                case 'Single'
                    openDevice(app.Instr);
                    writeProperty(app.Instr,...
                        'abort_acq','', 'init_cont',false, 'init_acq','');
                    % Wait for the operation to be completed 
                    query(app.Instr.Device,'*OPC?');
                    closeDevice(app.Instr);
                    app.StopButton.Value = true;
                case 'Continuous'
                    writePropertyHedged(app.Instr,...
                        'abort_acq','', 'init_cont',true, 'init_acq','');
                otherwise
                    error('Trigger case unknown in callback');
            end
        end

        % Value changed function: TraceDropDown
        function TraceDropDownValueChanged(app, event)
            n_trace = str2double(app.TraceDropDown.Value);
            updateTraceTags(app, n_trace);
            updateGui(app);
        end

        % Button pushed function: CapturetraceButton
        function CapturetraceButtonPushed(app, event)
            n_trace = str2double(app.TraceDropDown.Value);
            openDevice(app.Instr);
            readSingle(app.Instr, n_trace);
            closeDevice(app.Instr);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TektronixRSAUIFigure = uifigure;
            app.TektronixRSAUIFigure.Name = 'Tektronix RSA';
            app.TektronixRSAUIFigure.CloseRequestFcn = createCallbackFcn(app, @TektronixRSAUIFigureCloseRequest, true);
            app.StartfreqMHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.StartfreqMHzEditFieldLabel.Text = 'Start freq (MHz)';
            app.StartfreqMHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.StartfreqMHzEditField.ValueDisplayFormat = '%5.6f';
            app.StopfreqMHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.StopfreqMHzEditFieldLabel.Text = 'Stop freq (MHz)';
            app.StopfreqMHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.StopfreqMHzEditField.ValueDisplayFormat = '%5.6f';
            app.RBWHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.RBWHzEditFieldLabel.Text = 'RBW (Hz)';
            app.RBWHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.RBWHzEditField.Limits = [0 Inf];
            app.RBWHzEditField.ValueDisplayFormat = '%6.3f';
            app.CenterfreqMHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.CenterfreqMHzEditFieldLabel.Text = 'Center freq (MHz)';
            app.CenterfreqMHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.CenterfreqMHzEditField.ValueDisplayFormat = '%5.6f';
            app.SpanMHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.SpanMHzEditFieldLabel.Text = 'Span (MHz)';
            app.SpanMHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.SpanMHzEditField.ValueDisplayFormat = '%5.6f';
            app.CapturetraceButton = uibutton(app.TektronixRSAUIFigure, 'push');
            app.CapturetraceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturetraceButtonPushed, true);
            app.CapturetraceButton.Text = 'Capture trace';
            app.RefreshpanelButton = uibutton(app.TektronixRSAUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.FindinstrumentButton = uibutton(app.TektronixRSAUIFigure, 'push');
            app.FindinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @FindinstrumentButtonPushed, true);
            app.FindinstrumentButton.Text = 'Find instrument';
            app.NumberofpointsDropDownLabel = uilabel(app.TektronixRSAUIFigure);
            app.NumberofpointsDropDownLabel.Text = 'Number of points';
            app.NumberofpointsDropDown = uidropdown(app.TektronixRSAUIFigure);
            app.TracesettingsPanel = uipanel(app.TektronixRSAUIFigure);
            app.TracesettingsPanel.Title = 'Trace settings';
            app.DetectionDropDownLabel = uilabel(app.TracesettingsPanel);
            app.DetectionDropDownLabel.Text = 'Detection';
            app.DetectionDropDown = uidropdown(app.TracesettingsPanel);
            app.DetectionDropDown.Items = {'Avg (VRMS)', 'Peak+', 'Peak-'};
            app.DetectionDropDown.ItemsData = {'AVERage', 'POSitive', 'NEGative'};
            app.DetectionDropDown.Value = 'AVERage';
            app.TraceDropDownLabel = uilabel(app.TracesettingsPanel);
            app.TraceDropDownLabel.Text = 'Trace';
            app.TraceDropDown = uidropdown(app.TracesettingsPanel);
            app.TraceDropDown.Items = {'1', '2', '3'};
            app.TraceDropDown.ValueChangedFcn = createCallbackFcn(app, @TraceDropDownValueChanged, true);
            app.TraceDropDown.Value = '1';
            app.FunctionDropDownLabel = uilabel(app.TracesettingsPanel);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.TracesettingsPanel);
            app.FunctionDropDown.Items = {'Avg (VRMS)', 'Hold', 'Normal'};
            app.FunctionDropDown.ItemsData = {'AVERage', 'HOLD', 'NORMal'};
            app.FunctionDropDown.Value = 'AVERage';
            app.NaveragesEditFieldLabel = uilabel(app.TracesettingsPanel);
            app.NaveragesEditFieldLabel.Text = 'N averages';
            app.NaveragesEditField = uieditfield(app.TracesettingsPanel, 'numeric');
            app.NaveragesEditField.ValueDisplayFormat = '%.0f';
            app.NaveragesEditField.Value = 1;
            app.CountCheckBox = uicheckbox(app.TracesettingsPanel);
            app.CountCheckBox.Text = 'Count';
            app.RefleveldBmEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.RefleveldBmEditFieldLabel.Text = 'Ref. level (dBm)';
            app.RefleveldBmEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.DisplaysettingsPanel = uipanel(app.TektronixRSAUIFigure);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.YoffsetdBmEditFieldLabel = uilabel(app.DisplaysettingsPanel);
            app.YoffsetdBmEditFieldLabel.Text = 'Y offset (dBm)';
            app.YoffsetdBmEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.YscaledBmdivEditFieldLabel = uilabel(app.DisplaysettingsPanel);
            app.YscaledBmdivEditFieldLabel.Text = 'Y scale (dBm/div)';
            app.YscaledBmdivEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.AcquisitionButtonGroup = uibuttongroup(app.TektronixRSAUIFigure);
            app.AcquisitionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AcquisitionButtonGroupSelectionChanged, true);
            app.AcquisitionButtonGroup.Title = 'Acquisition';
            app.StopButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            app.SingleButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.SingleButton.Text = 'Single';
            app.ContinuousButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.DisplaytracesPanel = uipanel(app.TektronixRSAUIFigure);
            app.DisplaytracesPanel.Title = 'Display traces';
            app.Tr3Button = uibutton(app.DisplaytracesPanel, 'state');
            app.Tr3Button.Text = '3';
            app.Tr2Button = uibutton(app.DisplaytracesPanel, 'state');
            app.Tr2Button.Text = '2';
            app.Tr1Button = uibutton(app.DisplaytracesPanel, 'state');
            app.Tr1Button.Text = '1';
            app.AutoRBWCheckBox = uicheckbox(app.TektronixRSAUIFigure);
            app.AutoRBWCheckBox.Text = 'Auto RBW?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiRsa(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TektronixRSAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TektronixRSAUIFigure)
        end
    end
end