classdef GuiScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TektronixDPOUIFigure          matlab.ui.Figure
        CapturetraceButton            matlab.ui.control.Button
        RefreshpanelButton            matlab.ui.control.Button
        ContinuouscaptureCheckBox     matlab.ui.control.CheckBox
        FindinstrumentButton          matlab.ui.control.Button
        AcquisitionButtonGroup        matlab.ui.container.ButtonGroup
        StopButton                    matlab.ui.control.RadioButton
        SingleButton                  matlab.ui.control.RadioButton
        ContinuousButton              matlab.ui.control.RadioButton
        ChannelconfigurationPanel     matlab.ui.container.Panel
        ScaleVDivLabel                matlab.ui.control.Label
        ScaleCH                       matlab.ui.control.NumericEditField
        VScalePlusButton              matlab.ui.control.Button
        VScaleMinusButton             matlab.ui.control.Button
        ChannelDropDownLabel          matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        CouplingDropDownLabel         matlab.ui.control.Label
        CouplingCH                    matlab.ui.control.DropDown
        OffsetEditFieldLabel          matlab.ui.control.Label
        OffsetCH                      matlab.ui.control.NumericEditField
        ImpedanceDropDownLabel        matlab.ui.control.Label
        ImpedanceCH                   matlab.ui.control.DropDown
        TriggerPanel                  matlab.ui.container.Panel
        ModeDropDownLabel             matlab.ui.control.Label
        ModeDropDown                  matlab.ui.control.DropDown
        SourceDropDownLabel           matlab.ui.control.Label
        SourceDropDown                matlab.ui.control.DropDown
        EdgeDropDownLabel             matlab.ui.control.Label
        EdgeDropDown                  matlab.ui.control.DropDown
        LevelVEditFieldLabel          matlab.ui.control.Label
        LevelVEditField               matlab.ui.control.NumericEditField
        TimescalesDivEditFieldLabel   matlab.ui.control.Label
        TimescalesDivEditField        matlab.ui.control.NumericEditField
        NumberofpointsDropDownLabel   matlab.ui.control.Label
        NumberofpointsDropDown        matlab.ui.control.DropDown
        TraceAxes                     matlab.ui.control.UIAxes
        TimeScalePlusButton           matlab.ui.control.Button
        TimeScaleMinusButton          matlab.ui.control.Button
        EnabledchannelsPanel          matlab.ui.container.Panel
        CH4Button                     matlab.ui.control.StateButton
        CH3Button                     matlab.ui.control.StateButton
        CH2Button                     matlab.ui.control.StateButton
        CH1Button                     matlab.ui.control.StateButton
        AcquisitionmodeDropDownLabel  matlab.ui.control.Label
        AcquisitionmodeDropDown       matlab.ui.control.DropDown
    end


    properties (Access = public)
        Instr % Instrument object
        linked_elem_list = {};
        instance_name = '' % App name to clear on exit;
    end

    methods (Access = private)
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
        
        % re-link the channel property control elements to the new channel 
        function updateChannelTags(app, n_ch) 
            app.CouplingCH.Tag=sprintf('cpl%i',n_ch);
            app.ImpedanceCH.Tag=sprintf('imp%i',n_ch);
            app.OffsetCH.Tag=sprintf('offset%i',n_ch);
            app.ScaleCH.Tag=sprintf('scale%i',n_ch);
        end
        
        % Plot trace 
        function updatePlot(app)
            if ~isempty(app.Instr.Trace.x) && ~isempty(app.Instr.Trace.y)
                plotTrace(app.Instr.Trace, app.TraceAxes);
                app.TraceAxes.XLabel.String = app.Instr.Trace.label_x;
                app.TraceAxes.YLabel.String = app.Instr.Trace.label_y;
            end
        end
        
        function createControlLinks(app)
            linkControlElement(app, ...
                app.ChannelDropDown, 'channel');
            linkControlElement(app, ...
                app.NumberofpointsDropDown, 'point_no', ...
                'init_val_list', true,...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app,...
                app.TimescalesDivEditField, 'time_scale', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.AcquisitionmodeDropDown, 'acq_mode', ...
                'init_val_list', true,...
                'out_proc_fcn', @(x) standardizeValue(app.Instr, 'acq_mode', x),...
                'create_callback_fcn', @app.createGenericCallback);
            
            % trigger panel
            linkControlElement(app, app.SourceDropDown, 'trig_source', ...
                'init_val_list', true,...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.ModeDropDown, 'trig_mode', ...
                'init_val_list', true,...
                'out_proc_fcn', @(x) standardizeValue(app.Instr, 'trig_mode', x),...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.EdgeDropDown, 'trig_slope', ...
                'init_val_list', true,...
                'out_proc_fcn', @(x) standardizeValue(app.Instr, 'trig_slope', x),...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.LevelVEditField, 'trig_lev', ...
                'create_callback_fcn', @app.createGenericCallback);
            
            % channel-specific elemnts are initialized first with dummy tags
            linkControlElement(app, app.CouplingCH, 'cpl1', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.ImpedanceCH, 'imp1', ...
                'out_proc_fcn', @(x) standardizeValue(app.Instr, 'imp1', x),...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.ScaleCH, 'scale1', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.OffsetCH, 'offset1', ...
                'create_callback_fcn', @app.createGenericCallback);
            % assign tags based on the channel selection
            updateChannelTags(app, app.Instr.channel);
            
            for i=1:4
                b_tag = sprintf('CH%iButton',i);
                if i<=app.Instr.N_CHANNELS
                    c_tag = sprintf('enable%i',i);
                    linkControlElement(app, app.(b_tag), c_tag, ...
                    'create_callback_fcn', @app.createGenericCallback);
                else
                    app.(b_tag).Visible = 'off';
                    app.(b_tag).Enable = 'off';
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, interface, address, varargin)
            % Start the Gui with specified instrument class
            % Assign the instance name to the class variable and the instrument name to the window
            genericInitGui(app, 'MyDpo', interface, address, varargin{:});
            
            % initialization of dropdown menus
            app.ChannelDropDown.Items = arrayfun(@(x)sprintf('%i',x),...
                1:app.Instr.N_CHANNELS,'UniformOutput',false);
            app.ChannelDropDown.ItemsData = 1:app.Instr.N_CHANNELS;
            
            if isfield(app.Instr.CommandList, 'imp1')
                % Find how the 50 Ohm impedance among the values and 
                % assign the other alias to 1 MOhm case
                v_list = stdValueList(app.Instr, 'imp1');
                if startsWith(v_list{1},'FIF','IgnoreCase',true)
                    app.ImpedanceCH.ItemsData={v_list{2}; v_list{1}};
                else
                    app.ImpedanceCH.ItemsData={v_list{1}; v_list{2}};
                end
            end
            
            if isprop(app.Instr, 'acq_state')
                if app.Instr.acq_state
                    app.ContinuousButton.Value = true;
                else
                    app.StopButton.Value = true;
                end
            elseif ismethod(app.Instr,'acquireContinuous')
                acquireContinuous(app.Instr);
                app.ContinuousButton.Value = true;
            end
            
            if isprop(app.Instr, 'ctrl_channel')
                tmp_n_ch = app.Instr.ctrl_channel;
            else
                tmp_n_ch = 1;
                writePropertyHedged(app.Instr, 'enable1',true);
            end
            writePropertyHedged(app.Instr, 'channel',tmp_n_ch);
            b_tag = sprintf('CH%iButton', tmp_n_ch);
            app.(b_tag).Enable='off';
            
            if ~ismethod(app.Instr, 'turnKnob')
                app.TimeScalePlusButton.Enable = 'off';
                app.TimeScalePlusButton.Visible = 'off';
                app.TimeScaleMinusButton.Enable = 'off';
                app.TimeScaleMinusButton.Visible = 'off';
                app.VScalePlusButton.Enable = 'off';
                app.VScalePlusButton.Visible = 'off';
                app.VScaleMinusButton.Enable = 'off';
                app.VScaleMinusButton.Visible = 'off';
            end
            
            createControlLinks(app);
            updateGui(app);
        end

        % Button pushed function: CapturetraceButton
        function CapturetraceButtonPushed(app, event)
            openDevice(app.Instr);
            readTrace(app.Instr);
            closeDevice(app.Instr);
            updatePlot(app);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Value changed function: ContinuouscaptureCheckBox
        function ContinuouscaptureCheckBoxValueChanged(app, event)
            % disable channel switching, the remaining paprameters can 
            % be changed on fly
            app.ChannelDropDown.Enable='off';
            while app.ContinuouscaptureCheckBox.Value
                openDevice(app.Instr);
                readTrace(app.Instr);
                updatePlot(app);
                % somehow need to wait for 1 trace before next acquisition
                delay = app.Instr.time_scale*10;
                pause(delay);
            end
            closeDevice(app.Instr);
            updateGui(app);
            app.ChannelDropDown.Enable='on';
        end

        % Close request function: TektronixDPOUIFigure
        function TektronixDPOUIFigureCloseRequest(app, event)
            delete(app.Instr);
            evalin('base', sprintf('clear(''%s'')',app.instance_name));
            delete(app);
        end

        % Button pushed function: FindinstrumentButton
        function FindinstrumentButtonPushed(app, event)
            findInstrumentCallback(app);
        end

        % Selection changed function: AcquisitionButtonGroup
        function AcquisitionButtonGroupSelectionChanged(app, event)
            selectedButton = app.AcquisitionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Stop'
                    if isfield(app.Instr.CommandList, 'acq_state')
                        writePropertyHedged(app.Instr, 'acq_state', false);
                    elseif ismethod(app.Instr,'stopAcquisition')
                        stopAcquisition(app.Instr);
                    end
                case 'Single'
                    acquireSingle(app.Instr);
                    app.StopButton.Value = true;
                case 'Continuous'
                    acquireContinuous(app.Instr);
                otherwise
                    error('Trigger case unknown in callback');
            end
        end

        % Button pushed function: TimeScalePlusButton
        function TimeScalePlusButtonPushed(app, event)
            turnKnob(app.Instr, 'HORZScale', -1);
            readPropertyHedged(app.Instr,'time_scale');
            updateGui(app);
        end

        % Button pushed function: TimeScaleMinusButton
        function TimeScaleMinusButtonPushed(app, event)
            turnKnob(app.Instr, 'HORZScale', 1);
            readPropertyHedged(app.Instr,'time_scale');
            updateGui(app);
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            n_ch = app.ChannelDropDown.Value;
            % re-link the channel property control elements to the new channel  
            updateChannelTags(app, n_ch);
            for i=1:4
                b_tag = sprintf('CH%iButton',i);
                if i==n_ch
                    app.(b_tag).Enable='off';
                else
                    app.(b_tag).Enable='on';
                end
            end
            enable_cmd=sprintf('enable%i',n_ch);
            writePropertyHedged(app.Instr, enable_cmd, true, 'channel', n_ch);
            updateGui(app);
        end

        % Button pushed function: VScalePlusButton
        function VScalePlusButtonPushed(app, event)
            n_ch = app.ChannelDropDown.Value;
            turnKnob(app.Instr, sprintf('VERTSCALE%i',n_ch), -1);
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Button pushed function: VScaleMinusButton
        function VScaleMinusButtonPushed(app, event)
            n_ch = app.ChannelDropDown.Value;
            turnKnob(app.Instr, sprintf('VERTSCALE%i',n_ch), 1);
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TektronixDPOUIFigure = uifigure;
            app.TektronixDPOUIFigure.Name = 'Tektronix DPO';
            app.TektronixDPOUIFigure.CloseRequestFcn = createCallbackFcn(app, @TektronixDPOUIFigureCloseRequest, true);
            app.CapturetraceButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.CapturetraceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturetraceButtonPushed, true);
            app.CapturetraceButton.Text = 'Capture trace';
            app.RefreshpanelButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.ContinuouscaptureCheckBox = uicheckbox(app.TektronixDPOUIFigure);
            app.ContinuouscaptureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuouscaptureCheckBoxValueChanged, true);
            app.ContinuouscaptureCheckBox.Text = 'Continuous capture';
            app.FindinstrumentButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.FindinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @FindinstrumentButtonPushed, true);
            app.FindinstrumentButton.Text = 'Find instrument';
            app.AcquisitionButtonGroup = uibuttongroup(app.TektronixDPOUIFigure);
            app.AcquisitionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AcquisitionButtonGroupSelectionChanged, true);
            app.AcquisitionButtonGroup.Title = 'Acquisition';
            app.StopButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            app.SingleButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.SingleButton.Text = 'Single';
            app.ContinuousButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ChannelconfigurationPanel = uipanel(app.TektronixDPOUIFigure);
            app.ChannelconfigurationPanel.Title = 'Channel configuration';
            app.ScaleVDivLabel = uilabel(app.ChannelconfigurationPanel);
            app.ScaleVDivLabel.Text = 'Scale (V/Div)';
            app.ScaleCH = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.ScaleCH.ValueDisplayFormat = '%5.2e';
            app.VScalePlusButton = uibutton(app.ChannelconfigurationPanel, 'push');
            app.VScalePlusButton.ButtonPushedFcn = createCallbackFcn(app, @VScalePlusButtonPushed, true);
            app.VScalePlusButton.Text = '+';
            app.VScaleMinusButton = uibutton(app.ChannelconfigurationPanel, 'push');
            app.VScaleMinusButton.ButtonPushedFcn = createCallbackFcn(app, @VScaleMinusButtonPushed, true);
            app.VScaleMinusButton.Text = '–';
            app.ChannelDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.ChannelconfigurationPanel);
            app.ChannelDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = '1';
            app.CouplingDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.CouplingDropDownLabel.Text = 'Coupling';
            app.CouplingCH = uidropdown(app.ChannelconfigurationPanel);
            app.CouplingCH.Items = {'DC', 'AC', 'GND'};
            app.CouplingCH.Value = 'DC';
            app.OffsetEditFieldLabel = uilabel(app.ChannelconfigurationPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetCH = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.ImpedanceDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.ImpedanceDropDownLabel.Text = 'Impedance';
            app.ImpedanceCH = uidropdown(app.ChannelconfigurationPanel);
            app.ImpedanceCH.Items = {'1 Mohm', '50 Ohm'};
            app.ImpedanceCH.ItemsData = {'MEG', 'FIFty'};
            app.ImpedanceCH.Value = 'MEG';
            app.TriggerPanel = uipanel(app.TektronixDPOUIFigure);
            app.TriggerPanel.Title = 'Trigger';
            app.ModeDropDownLabel = uilabel(app.TriggerPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.TriggerPanel);
            app.ModeDropDown.Items = {'Auto', 'Normal'};
            app.ModeDropDown.ItemsData = {'AUTO', 'NORMal'};
            app.ModeDropDown.Value = 'AUTO';
            app.SourceDropDownLabel = uilabel(app.TriggerPanel);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.TriggerPanel);
            app.SourceDropDown.Items = {'CH1', 'CH2', 'CH3', 'CH4', 'AUX', 'EXT', 'LINE'};
            app.SourceDropDown.Value = 'AUX';
            app.EdgeDropDownLabel = uilabel(app.TriggerPanel);
            app.EdgeDropDownLabel.Text = 'Edge';
            app.EdgeDropDown = uidropdown(app.TriggerPanel);
            app.EdgeDropDown.Items = {'Rise', 'Fall'};
            app.EdgeDropDown.ItemsData = {'RISe', 'FALL'};
            app.EdgeDropDown.Value = 'RISe';
            app.LevelVEditFieldLabel = uilabel(app.TriggerPanel);
            app.LevelVEditFieldLabel.Text = 'Level [V]';
            app.LevelVEditField = uieditfield(app.TriggerPanel, 'numeric');
            app.TimescalesDivEditFieldLabel = uilabel(app.TektronixDPOUIFigure);
            app.TimescalesDivEditFieldLabel.Text = 'Time scale [s/Div]';
            app.TimescalesDivEditField = uieditfield(app.TektronixDPOUIFigure, 'numeric');
            app.TimescalesDivEditField.ValueDisplayFormat = '%5.2e';
            app.NumberofpointsDropDownLabel = uilabel(app.TektronixDPOUIFigure);
            app.NumberofpointsDropDownLabel.Text = 'Number of points';
            app.NumberofpointsDropDown = uidropdown(app.TektronixDPOUIFigure);
            app.TraceAxes = uiaxes(app.TektronixDPOUIFigure);
            xlabel(app.TraceAxes, 'Time')
            ylabel(app.TraceAxes, 'Voltage')
            app.TimeScalePlusButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.TimeScalePlusButton.ButtonPushedFcn = createCallbackFcn(app, @TimeScalePlusButtonPushed, true);
            app.TimeScalePlusButton.Text = '+';
            app.TimeScaleMinusButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.TimeScaleMinusButton.ButtonPushedFcn = createCallbackFcn(app, @TimeScaleMinusButtonPushed, true);
            app.TimeScaleMinusButton.Text = '–';
            app.EnabledchannelsPanel = uipanel(app.TektronixDPOUIFigure);
            app.EnabledchannelsPanel.Title = 'Enabled channels';
            app.CH4Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH4Button.Text = 'CH4';
            app.CH3Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH3Button.Text = 'CH3';
            app.CH2Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH2Button.Text = 'CH2';
            app.CH1Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH1Button.Text = 'CH1';
            app.AcquisitionmodeDropDownLabel = uilabel(app.TektronixDPOUIFigure);
            app.AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.AcquisitionmodeDropDown = uidropdown(app.TektronixDPOUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiScope(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TektronixDPOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TektronixDPOUIFigure)
        end
    end
end