classdef GuiTekMso46 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TekScopeUIFigure              matlab.ui.Figure
        CapturetraceButton            matlab.ui.control.Button
        RefreshpanelButton            matlab.ui.control.Button
        AcquisitionButtonGroup        matlab.ui.container.ButtonGroup
        StopButton                    matlab.ui.control.RadioButton
        SingleButton                  matlab.ui.control.RadioButton
        ContinuousButton              matlab.ui.control.RadioButton
        ChannelconfigurationPanel     matlab.ui.container.Panel
        ScaleVDivLabel                matlab.ui.control.Label
        ScaleCH                       matlab.ui.control.NumericEditField
        VScalePlusButton              matlab.ui.control.Button
        VScaleMinusButton             matlab.ui.control.Button
        ChannelDropDownLabel          matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        CouplingDropDownLabel         matlab.ui.control.Label
        CouplingCH                    matlab.ui.control.DropDown
        OffsetEditFieldLabel          matlab.ui.control.Label
        OffsetCH                      matlab.ui.control.NumericEditField
        ImpedanceDropDownLabel        matlab.ui.control.Label
        ImpedanceCH                   matlab.ui.control.DropDown
        TriggerlevelVEditFieldLabel   matlab.ui.control.Label
        TriggerlevelVEditField        matlab.ui.control.NumericEditField
        StartfreqEditFieldLabel       matlab.ui.control.Label
        StartfreqEditField            matlab.ui.control.NumericEditField
        StopfreqEditFieldLabel        matlab.ui.control.Label
        StopfreqEditField             matlab.ui.control.NumericEditField
        CenterEditFieldLabel          matlab.ui.control.Label
        CenterEditField               matlab.ui.control.NumericEditField
        CapturemodeDropDownLabel      matlab.ui.control.Label
        CapturemodeDropDown           matlab.ui.control.DropDown
        SpectrumviewCheckBox          matlab.ui.control.CheckBox
        SpanEditFieldLabel            matlab.ui.control.Label
        SpanEditField                 matlab.ui.control.NumericEditField
        RBWEditFieldLabel             matlab.ui.control.Label
        RBWEditField                  matlab.ui.control.NumericEditField
        RatioEditFieldLabel           matlab.ui.control.Label
        RatioEditField                matlab.ui.control.NumericEditField
        AutoRBWLabel                  matlab.ui.control.Label
        AutoRBWDropDown               matlab.ui.control.DropDown
        NORMCheckBox                  matlab.ui.control.CheckBox
        AVGCheckBox                   matlab.ui.control.CheckBox
        Label                         matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        TriggerPanel                  matlab.ui.container.Panel
        ModeDropDownLabel             matlab.ui.control.Label
        ModeDropDown                  matlab.ui.control.DropDown
        SourceDropDownLabel           matlab.ui.control.Label
        SourceDropDown                matlab.ui.control.DropDown
        EdgeDropDownLabel             matlab.ui.control.Label
        EdgeDropDown                  matlab.ui.control.DropDown
        TimescalesDivEditFieldLabel   matlab.ui.control.Label
        TimescalesDivEditField        matlab.ui.control.NumericEditField
        TraceAxes                     matlab.ui.control.UIAxes
        TimeScalePlusButton           matlab.ui.control.Button
        TimeScaleMinusButton          matlab.ui.control.Button
        EnabledchannelsPanel          matlab.ui.container.Panel
        CH4Button                     matlab.ui.control.StateButton
        CH3Button                     matlab.ui.control.StateButton
        CH2Button                     matlab.ui.control.StateButton
        CH1Button                     matlab.ui.control.StateButton
        CH5Button                     matlab.ui.control.StateButton
        CH6Button                     matlab.ui.control.StateButton
        AcquisitionmodeDropDownLabel  matlab.ui.control.Label
        AcquisitionmodeDropDown       matlab.ui.control.DropDown
        NumberofpointsEditField       matlab.ui.control.NumericEditField
        NumberofpointsLabel           matlab.ui.control.Label
        SpectrumCheckBox              matlab.ui.control.CheckBox
        AveragingEditFieldLabel       matlab.ui.control.Label
        AveragingEditField            matlab.ui.control.NumericEditField
    end


    properties (GetAccess = public, SetAccess = protected)
        GuiSync
        Instr
    end

    methods (Access = public)
        
        % Plot trace 
        function updatePlot(app)
            if ~isempty(app.Instr.Trace.x) && ~isempty(app.Instr.Trace.y)
                plot(app.Instr.Trace, app.TraceAxes);
                app.TraceAxes.XLabel.String = app.Instr.Trace.label_x;
                app.TraceAxes.YLabel.String = app.Instr.Trace.label_y;
            end
        end
    end

    methods (Access = private)
        
        % re-link the channel property control elements to the new channel 
        function updateChannelTags(app, n_ch) 
            reLink(app.GuiSync, app.CouplingCH,  sprintf('Instr.cpl%i',n_ch));
            reLink(app.GuiSync, app.ImpedanceCH, sprintf('Instr.imp%i',n_ch));
            reLink(app.GuiSync, app.OffsetCH,    sprintf('Instr.offset%i',n_ch));
            reLink(app.GuiSync, app.ScaleCH,     sprintf('Instr.scale%i',n_ch));
            reLink(app.GuiSync, app.TriggerlevelVEditField, sprintf('Instr.trig_lev%i',n_ch));
            
            reLink(app.GuiSync, app.StartfreqEditField, sprintf('Instr.start_freq%i',n_ch));
            reLink(app.GuiSync, app.StopfreqEditField, sprintf('Instr.stop_freq%i',n_ch));
            reLink(app.GuiSync, app.CenterEditField, sprintf('Instr.center_freq%i',n_ch));
            reLink(app.GuiSync, app.SpectrumviewCheckBox, sprintf('Instr.sv_state%i',n_ch));
            reLink(app.GuiSync, app.NORMCheckBox, sprintf('Instr.sv_norm%i',n_ch));
            reLink(app.GuiSync, app.AVGCheckBox, sprintf('Instr.sv_avg%i',n_ch));
            reLink(app.GuiSync, app.EditField, sprintf('Instr.sv_avg_no%i',n_ch));
        end
        
        function createLinks(app)  
            addLink(app.GuiSync, app.ChannelDropDown,         'Instr.channel');
            addLink(app.GuiSync, app.NumberofpointsEditField,  'Instr.point_no');
            addLink(app.GuiSync, app.TimescalesDivEditField,  'Instr.time_scale');
            addLink(app.GuiSync, app.AcquisitionmodeDropDown, 'Instr.acq_mode');
            addLink(app.GuiSync, app.AveragingEditField, 'Instr.avg_point_no');
            % Trigger panel
            addLink(app.GuiSync, app.SourceDropDown,  'Instr.trig_source');
            addLink(app.GuiSync, app.ModeDropDown,    'Instr.trig_mode');
            addLink(app.GuiSync, app.EdgeDropDown,    'Instr.trig_slope');
            
            %Spectrum panel
            addLink(app.GuiSync, app.SpanEditField,  'Instr.span');
            addLink(app.GuiSync, app.RBWEditField,  'Instr.rbw');
            addLink(app.GuiSync, app.RatioEditField,  'Instr.rbwratio');
            addLink(app.GuiSync, app.AutoRBWDropDown,  'Instr.auto_rbw');
            
            % Channel-specific elemnts are initialized first with dummy tags
            addLink(app.GuiSync, app.CouplingCH,  'Instr.cpl1');
            addLink(app.GuiSync, app.ImpedanceCH, 'Instr.imp1');
            addLink(app.GuiSync, app.ScaleCH,     'Instr.scale1');
            addLink(app.GuiSync, app.OffsetCH,    'Instr.offset1');
            addLink(app.GuiSync, app.TriggerlevelVEditField, 'Instr.trig_lev1');
            
            addLink(app.GuiSync, app.StartfreqEditField, 'Instr.start_freq1');
            addLink(app.GuiSync, app.StopfreqEditField, 'Instr.stop_freq1');
            addLink(app.GuiSync, app.CenterEditField, 'Instr.center_freq1');
            addLink(app.GuiSync, app.SpectrumviewCheckBox, 'Instr.sv_state1');
            addLink(app.GuiSync, app.NORMCheckBox, 'Instr.sv_norm1');
            addLink(app.GuiSync, app.AVGCheckBox, 'Instr.sv_avg1');
            addLink(app.GuiSync, app.EditField, 'Instr.sv_avg_no1');
            
            
            % Assign tags based on the channel selection
            updateChannelTags(app, app.Instr.channel);
            
            for i=1:6
                b_tag = sprintf('CH%iButton',i);
                if i<=app.Instr.channel_no
                    c_tag = sprintf('Instr.enable%i',i);
                    addLink(app.GuiSync, app.(b_tag), c_tag);
                else
                    app.(b_tag).Visible = 'off';
                    app.(b_tag).Enable = 'off';
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, 'KernelObj', Instrument);
            
            L = addlistener(app.Instr, 'NewData', @(~,~)updatePlot(app));
            addToCleanup(app.GuiSync, L);
            
            
            % Initialize the trigger panel
            try
                if app.Instr.acq_state
                    app.ContinuousButton.Value = true;
                else
                    app.StopButton.Value = true;
                end
            catch ME
                warning(ME.message)
            end
            
            % Initialize the channel panel
            try
                app.ChannelDropDown.Items = arrayfun(@(x)sprintf('%i',x),...
                    1:app.Instr.channel_no,'UniformOutput',false);
                app.ChannelDropDown.ItemsData = 1:app.Instr.channel_no;
            catch ME
                warning(ME.message)
            end
            
            if ~ismethod(app.Instr, 'turnKnob')
                app.TimeScalePlusButton.Enable = 'off';
                app.TimeScalePlusButton.Visible = 'off';
                app.TimeScaleMinusButton.Enable = 'off';
                app.TimeScaleMinusButton.Visible = 'off';
                app.VScalePlusButton.Enable = 'off';
                app.VScalePlusButton.Visible = 'off';
                app.VScaleMinusButton.Enable = 'off';
                app.VScaleMinusButton.Visible = 'off';
            end

            createLinks(app);
            
            % disable coupling change if Impedance is set to 50
            if strcmp(app.ImpedanceCH.Value,'50.0000')
                app.CouplingCH.Enable=0;
            else
                app.CouplingCH.Enable=1;
            end
            %%changed from ctrl_channel to channel
            % disable the selected channel source. The disabling and
            % enabling should be after the createLinks(app), otherwise
            % there will be a problem creating links. 
            b_tag = sprintf('CH%iButton', app.Instr.channel);
            app.(b_tag).Enable = 'off';
        end

        % Button pushed function: CapturetraceButton
        function CapturetraceButtonPushed(app, event)
            if app.SpectrumCheckBox.Value==1
                readTrace_SV(app.Instr,app.CapturemodeDropDown.Value,app.ChannelDropDown.Value);
            else
                readTrace(app.Instr);
            end
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            sync(app.Instr);
        end

        % Selection changed function: AcquisitionButtonGroup
        function AcquisitionButtonGroupSelectionChanged(app, event)
            selectedButton = app.AcquisitionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Stop'
                    if isfield(app.Instr.CommandList, 'acq_state')
                        app.Instr.acq_state = false;
                    elseif ismethod(app.Instr,'stopAcquisition')
                        stopAcquisition(app.Instr);
                    end
                case 'Single'
                    acquireSingle(app.Instr);
                    app.StopButton.Value = true;
                case 'Continuous'
                    acquireContinuous(app.Instr);
                otherwise
                    error('Trigger case unknown in callback');
            end
        end

        % Button pushed function: TimeScalePlusButton
        function TimeScalePlusButtonPushed(app, event)
            turnKnob(app.Instr, 'HORZScale', -1);
            sync(app.Instr);
        end

        % Button pushed function: TimeScaleMinusButton
        function TimeScaleMinusButtonPushed(app, event)
            turnKnob(app.Instr, 'HORZScale', 1);
            sync(app.Instr);
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            n_ch = app.ChannelDropDown.Value;
            
            % re-link the channel property control elements to the new channel  
            updateChannelTags(app, n_ch);
            
            % disable coupling change if Impedance is set to 50
            if strcmp(app.ImpedanceCH.Value,'50.0000')
                app.CouplingCH.Enable=0;
            else
                app.CouplingCH.Enable=1;
            end
            
            for i=1:6
                b_tag = sprintf('CH%iButton',i);
                if i==n_ch
                    app.(b_tag).Enable = 'off';
                else
                    app.(b_tag).Enable = 'on';
                end
            end
            enable_cmd = sprintf('enable%i',n_ch);
            
            app.Instr.(enable_cmd) = true;
            app.Instr.channel = n_ch;
        end

        % Button pushed function: VScalePlusButton
        function VScalePlusButtonPushed(app, event)
            n_ch = app.ChannelDropDown.Value;
            turnKnob(app.Instr, sprintf('VERTSCALE%i',n_ch), -1);
            sync(app.Instr);
        end

        % Button pushed function: VScaleMinusButton
        function VScaleMinusButtonPushed(app, event)
            n_ch = app.ChannelDropDown.Value;
            turnKnob(app.Instr, sprintf('VERTSCALE%i',n_ch), 1);
            sync(app.Instr);
        end

        % Value changed function: ImpedanceCH
        function ImpedanceCHValueChanged(app, event)
            % disable coupling change if Impedance is set to 50
            if strcmp(app.ImpedanceCH.Value,'50.0000')
                app.CouplingCH.Enable=0;
            else
                app.CouplingCH.Enable=1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TekScopeUIFigure = uifigure('Visible', 'off');
            app.TekScopeUIFigure.Name = 'Tektronix DPO';
            app.CapturetraceButton = uibutton(app.TekScopeUIFigure, 'push');
            app.CapturetraceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturetraceButtonPushed, true);
            app.CapturetraceButton.Text = 'Capture trace';
            app.RefreshpanelButton = uibutton(app.TekScopeUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.AcquisitionButtonGroup = uibuttongroup(app.TekScopeUIFigure);
            app.AcquisitionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AcquisitionButtonGroupSelectionChanged, true);
            app.AcquisitionButtonGroup.Title = 'Acquisition';
            app.StopButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            app.SingleButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.SingleButton.Text = 'Single';
            app.ContinuousButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ChannelconfigurationPanel = uipanel(app.TekScopeUIFigure);
            app.ChannelconfigurationPanel.Title = 'Channel configuration';
            app.ScaleVDivLabel = uilabel(app.ChannelconfigurationPanel);
            app.ScaleVDivLabel.Text = 'Scale (V/Div)';
            app.ScaleCH = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.ScaleCH.Limits = [0.0005 10];
            app.ScaleCH.ValueDisplayFormat = '%.4g';
            app.ScaleCH.Value = 0.5;
            app.VScalePlusButton = uibutton(app.ChannelconfigurationPanel, 'push');
            app.VScalePlusButton.ButtonPushedFcn = createCallbackFcn(app, @VScalePlusButtonPushed, true);
            app.VScalePlusButton.Text = '+';
            app.VScaleMinusButton = uibutton(app.ChannelconfigurationPanel, 'push');
            app.VScaleMinusButton.ButtonPushedFcn = createCallbackFcn(app, @VScaleMinusButtonPushed, true);
            app.VScaleMinusButton.Text = '–';
            app.ChannelDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.ChannelconfigurationPanel);
            app.ChannelDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = '1';
            app.CouplingDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.CouplingDropDownLabel.Text = 'Coupling';
            app.CouplingCH = uidropdown(app.ChannelconfigurationPanel);
            app.CouplingCH.Items = {'AC', 'DC'};
            app.CouplingCH.ItemsData = {'ac', 'dc'};
            app.CouplingCH.Value = 'dc';
            app.OffsetEditFieldLabel = uilabel(app.ChannelconfigurationPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetCH = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.OffsetCH.Limits = [-10 10];
            app.ImpedanceDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.ImpedanceDropDownLabel.Text = 'Impedance';
            app.ImpedanceCH = uidropdown(app.ChannelconfigurationPanel);
            app.ImpedanceCH.Items = {'50', '1M'};
            app.ImpedanceCH.ItemsData = {'50.0000', '1.0000e+6'};
            app.ImpedanceCH.ValueChangedFcn = createCallbackFcn(app, @ImpedanceCHValueChanged, true);
            app.ImpedanceCH.Value = '50.0000';
            app.TriggerlevelVEditFieldLabel = uilabel(app.ChannelconfigurationPanel);
            app.TriggerlevelVEditFieldLabel.Text = 'Trigger level [V]';
            app.TriggerlevelVEditField = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.StartfreqEditFieldLabel = uilabel(app.ChannelconfigurationPanel);
            app.StartfreqEditFieldLabel.Text = 'Start freq';
            app.StartfreqEditField = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.StartfreqEditField.Editable = 'off';
            app.StopfreqEditFieldLabel = uilabel(app.ChannelconfigurationPanel);
            app.StopfreqEditFieldLabel.Text = 'Stop freq';
            app.StopfreqEditField = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.StopfreqEditField.Editable = 'off';
            app.CenterEditFieldLabel = uilabel(app.ChannelconfigurationPanel);
            app.CenterEditFieldLabel.Text = 'Center';
            app.CenterEditField = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.CapturemodeDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.CapturemodeDropDownLabel.Text = 'Capture mode';
            app.CapturemodeDropDown = uidropdown(app.ChannelconfigurationPanel);
            app.CapturemodeDropDown.Items = {'NORM', 'AVG'};
            app.CapturemodeDropDown.ItemsData = {'NORMal', 'AVErage'};
            app.CapturemodeDropDown.Value = 'NORMal';
            app.SpectrumviewCheckBox = uicheckbox(app.ChannelconfigurationPanel);
            app.SpectrumviewCheckBox.Text = 'Spectrum view?';
            app.SpanEditFieldLabel = uilabel(app.ChannelconfigurationPanel);
            app.SpanEditFieldLabel.Text = 'Span';
            app.SpanEditField = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.RBWEditFieldLabel = uilabel(app.ChannelconfigurationPanel);
            app.RBWEditFieldLabel.Text = 'RBW';
            app.RBWEditField = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.RatioEditFieldLabel = uilabel(app.ChannelconfigurationPanel);
            app.RatioEditFieldLabel.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.AutoRBWLabel = uilabel(app.ChannelconfigurationPanel);
            app.AutoRBWLabel.Text = 'Auto RBW?';
            app.AutoRBWDropDown = uidropdown(app.ChannelconfigurationPanel);
            app.AutoRBWDropDown.Items = {'MAN', 'AUTO'};
            app.AutoRBWDropDown.ItemsData = {'manual', 'automatic'};
            app.AutoRBWDropDown.Value = 'manual';
            app.NORMCheckBox = uicheckbox(app.ChannelconfigurationPanel);
            app.NORMCheckBox.Text = 'NORM';
            app.AVGCheckBox = uicheckbox(app.ChannelconfigurationPanel);
            app.AVGCheckBox.Text = 'AVG';
            app.Label = uilabel(app.ChannelconfigurationPanel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.EditField.Limits = [2 512];
            app.EditField.Value = 16;
            app.TriggerPanel = uipanel(app.TekScopeUIFigure);
            app.TriggerPanel.Title = 'Trigger';
            app.ModeDropDownLabel = uilabel(app.TriggerPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.TriggerPanel);
            app.ModeDropDown.Items = {'Auto', 'Normal'};
            app.ModeDropDown.ItemsData = {'auto', 'normal'};
            app.ModeDropDown.Value = 'auto';
            app.SourceDropDownLabel = uilabel(app.TriggerPanel);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.TriggerPanel);
            app.SourceDropDown.Items = {'CH1', 'CH2', 'CH3', 'CH4', 'LINE', 'AUX'};
            app.SourceDropDown.ItemsData = {'ch1', 'ch2', 'ch3', 'ch4', 'line', 'auxiliary'};
            app.SourceDropDown.Value = 'ch1';
            app.EdgeDropDownLabel = uilabel(app.TriggerPanel);
            app.EdgeDropDownLabel.Text = 'Edge';
            app.EdgeDropDown = uidropdown(app.TriggerPanel);
            app.EdgeDropDown.Items = {'RISE', 'FALL', 'EITHER'};
            app.EdgeDropDown.ItemsData = {'rise', 'fall', 'either'};
            app.EdgeDropDown.Value = 'rise';
            app.TimescalesDivEditFieldLabel = uilabel(app.TekScopeUIFigure);
            app.TimescalesDivEditFieldLabel.Text = 'Time scale [s/Div]';
            app.TimescalesDivEditField = uieditfield(app.TekScopeUIFigure, 'numeric');
            app.TimescalesDivEditField.ValueDisplayFormat = '%.4g';
            app.TraceAxes = uiaxes(app.TekScopeUIFigure);
            xlabel(app.TraceAxes, 'Time')
            ylabel(app.TraceAxes, 'Voltage')
            app.TimeScalePlusButton = uibutton(app.TekScopeUIFigure, 'push');
            app.TimeScalePlusButton.ButtonPushedFcn = createCallbackFcn(app, @TimeScalePlusButtonPushed, true);
            app.TimeScalePlusButton.Text = '+';
            app.TimeScaleMinusButton = uibutton(app.TekScopeUIFigure, 'push');
            app.TimeScaleMinusButton.ButtonPushedFcn = createCallbackFcn(app, @TimeScaleMinusButtonPushed, true);
            app.TimeScaleMinusButton.Text = '–';
            app.EnabledchannelsPanel = uipanel(app.TekScopeUIFigure);
            app.EnabledchannelsPanel.Title = 'Enabled channels';
            app.CH4Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH4Button.Text = 'CH4';
            app.CH3Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH3Button.Text = 'CH3';
            app.CH2Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH2Button.Text = 'CH2';
            app.CH1Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH1Button.Text = 'CH1';
            app.CH5Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH5Button.Text = 'CH5';
            app.CH6Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH6Button.Text = 'CH6';
            app.AcquisitionmodeDropDownLabel = uilabel(app.TekScopeUIFigure);
            app.AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.AcquisitionmodeDropDown = uidropdown(app.TekScopeUIFigure);
            app.AcquisitionmodeDropDown.Items = {};
            app.AcquisitionmodeDropDown.Value = {};
            app.NumberofpointsEditField = uieditfield(app.TekScopeUIFigure, 'numeric');
            app.NumberofpointsEditField.Limits = [1000 62500000];
            app.NumberofpointsEditField.ValueDisplayFormat = '%.4g';
            app.NumberofpointsEditField.Value = 62500;
            app.NumberofpointsLabel = uilabel(app.TekScopeUIFigure);
            app.NumberofpointsLabel.Text = 'Number of points';
            app.SpectrumCheckBox = uicheckbox(app.TekScopeUIFigure);
            app.SpectrumCheckBox.Text = 'Spectrum?';
            app.AveragingEditFieldLabel = uilabel(app.TekScopeUIFigure);
            app.AveragingEditFieldLabel.Text = 'Averaging';
            app.AveragingEditField = uieditfield(app.TekScopeUIFigure, 'numeric');
            app.AveragingEditField.Limits = [2 10240];
            app.AveragingEditField.Value = 10;
            % Show the figure after all components are created
            app.TekScopeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiTekMso46(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TekScopeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TekScopeUIFigure)
        end
    end
end