classdef GuiTekRsa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TektronixRSAUIFigure        matlab.ui.Figure
        StartfreqHzEditFieldLabel   matlab.ui.control.Label
        StartfreqHzEditField        matlab.ui.control.NumericEditField
        StopfreqHzEditFieldLabel    matlab.ui.control.Label
        StopfreqHzEditField         matlab.ui.control.NumericEditField
        RBWHzEditFieldLabel         matlab.ui.control.Label
        RBWHzEditField              matlab.ui.control.NumericEditField
        CenterfreqHzEditFieldLabel  matlab.ui.control.Label
        CenterfreqHzEditField       matlab.ui.control.NumericEditField
        SpanHzEditFieldLabel        matlab.ui.control.Label
        SpanHzEditField             matlab.ui.control.NumericEditField
        CapturetraceButton          matlab.ui.control.Button
        RefreshpanelButton          matlab.ui.control.Button
        TracesettingsPanel          matlab.ui.container.Panel
        DetectionDropDownLabel      matlab.ui.control.Label
        DetectionDropDown           matlab.ui.control.DropDown
        TraceDropDownLabel          matlab.ui.control.Label
        TraceDropDown               matlab.ui.control.DropDown
        FunctionDropDownLabel       matlab.ui.control.Label
        FunctionDropDown            matlab.ui.control.DropDown
        NaveragesEditFieldLabel     matlab.ui.control.Label
        NaveragesEditField          matlab.ui.control.NumericEditField
        CountCheckBox               matlab.ui.control.CheckBox
        NpointsDropDownLabel        matlab.ui.control.Label
        NpointsDropDown             matlab.ui.control.DropDown
        RefleveldBmEditFieldLabel   matlab.ui.control.Label
        RefleveldBmEditField        matlab.ui.control.NumericEditField
        DisplaysettingsPanel        matlab.ui.container.Panel
        YoffsetdBmEditFieldLabel    matlab.ui.control.Label
        YoffsetdBmEditField         matlab.ui.control.NumericEditField
        YscaledBmdivEditFieldLabel  matlab.ui.control.Label
        YscaledBmdivEditField       matlab.ui.control.NumericEditField
        AcquisitionButtonGroup      matlab.ui.container.ButtonGroup
        StopButton                  matlab.ui.control.RadioButton
        SingleButton                matlab.ui.control.RadioButton
        ContinuousButton            matlab.ui.control.RadioButton
        DisplaytracesPanel          matlab.ui.container.Panel
        Tr3Button                   matlab.ui.control.StateButton
        Tr2Button                   matlab.ui.control.StateButton
        Tr1Button                   matlab.ui.control.StateButton
        AutoRBWCheckBox             matlab.ui.control.CheckBox
        ActualRBWHzEditFieldLabel   matlab.ui.control.Label
        ActualRBWHzEditField        matlab.ui.control.NumericEditField
    end


    properties (GetAccess = public, SetAccess = protected)
        GuiSync
        Instr
    end
    
    methods (Access = private)

        function updateTraceTags(app, n_tr)
            
            % re-link the channel property control elements to the new trace  
            reLink(app.GuiSync, app.DetectionDropDown,  sprintf('Instr.det_trace%i',n_tr));
            reLink(app.GuiSync, app.FunctionDropDown,   sprintf('Instr.func_trace%i',n_tr));
            reLink(app.GuiSync, app.NaveragesEditField, sprintf('Instr.average_no%i',n_tr));
            reLink(app.GuiSync, app.CountCheckBox,      sprintf('Instr.cnt_trace%i',n_tr));
        end
        
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)  
            addLink(app.GuiSync, app.StartfreqHzEditField,  'Instr.start_freq');
            addLink(app.GuiSync, app.CenterfreqHzEditField, 'Instr.cent_freq');
            addLink(app.GuiSync, app.StopfreqHzEditField,   'Instr.stop_freq');
            addLink(app.GuiSync, app.SpanHzEditField,       'Instr.span');
            addLink(app.GuiSync, app.AutoRBWCheckBox,       'Instr.auto_rbw');
            addLink(app.GuiSync, app.RBWHzEditField,        'Instr.rbw');
            addLink(app.GuiSync, app.ActualRBWHzEditField,  'Instr.rbw_act');
            addLink(app.GuiSync, app.RefleveldBmEditField,  'Instr.ref_level');
            addLink(app.GuiSync, app.YscaledBmdivEditField, 'Instr.disp_y_scale');
            addLink(app.GuiSync, app.YoffsetdBmEditField,   'Instr.disp_y_offset');
            addLink(app.GuiSync, app.NpointsDropDown,'Instr.point_no');
            
            % trace-specific elements are initialized first with dummy tags
            addLink(app.GuiSync, app.DetectionDropDown, 'Instr.det_trace1');
            addLink(app.GuiSync, app.FunctionDropDown, 'Instr.func_trace1');
            addLink(app.GuiSync, app.NaveragesEditField, 'Instr.average_no1');
            addLink(app.GuiSync, app.CountCheckBox, 'Instr.cnt_trace1');
            
            % assign tags based on the trace selection
            updateTraceTags(app, str2double(app.TraceDropDown.Value));
            
            for i=1:3
                b_tag = sprintf('Tr%iButton',i);
                c_tag = sprintf('Instr.disp_trace%i',i);
                addLink(app.GuiSync, app.(b_tag), c_tag);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, 'KernelObj', Instrument);
            
            if app.Instr.init_cont
                app.ContinuousButton.Value = true;
            else
                app.StopButton.Value = true;
            end
            
            createLinks(app);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            sync(app.Instr)
        end

        % Selection changed function: AcquisitionButtonGroup
        function AcquisitionButtonGroupSelectionChanged(app, event)
            selectedButton = app.AcquisitionButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Stop'
                    abortAcq(app.Instr);
                case 'Single'
                    abortAcq(app.Instr);
                    app.Instr.init_cont = false;
                    initAcq(app.Instr);
                        
                    % Wait for the operation to be completed 
                    opc(app.Instr);

                    app.StopButton.Value = true;
                case 'Continuous'
                    abortAcq(app.Instr);
                    app.Instr.init_cont = true;
                    initAcq(app.Instr);
                otherwise
                    error('Trigger case is unknown in callback');
            end
        end

        % Value changed function: TraceDropDown
        function TraceDropDownValueChanged(app, event)
            n_trace = str2double(app.TraceDropDown.Value);
            updateTraceTags(app, n_trace);
        end

        % Button pushed function: CapturetraceButton
        function CapturetraceButtonPushed(app, event)
            n_trace = str2double(app.TraceDropDown.Value);
            readTrace(app.Instr, n_trace);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TektronixRSAUIFigure = uifigure('Visible', 'off');
            app.TektronixRSAUIFigure.Name = 'Tektronix RSA';
            app.StartfreqHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.StartfreqHzEditFieldLabel.Text = 'Start freq (Hz)';
            app.StartfreqHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.StartfreqHzEditField.ValueDisplayFormat = '%.12g';
            app.StopfreqHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.StopfreqHzEditFieldLabel.Text = 'Stop freq (Hz)';
            app.StopfreqHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.StopfreqHzEditField.ValueDisplayFormat = '%.12g';
            app.RBWHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.RBWHzEditFieldLabel.Text = 'RBW (Hz)';
            app.RBWHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.RBWHzEditField.Limits = [0 Inf];
            app.RBWHzEditField.ValueDisplayFormat = '%.5g';
            app.CenterfreqHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.CenterfreqHzEditFieldLabel.Text = 'Center freq (Hz)';
            app.CenterfreqHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.CenterfreqHzEditField.ValueDisplayFormat = '%.12g';
            app.SpanHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.SpanHzEditFieldLabel.Text = 'Span (Hz)';
            app.SpanHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.SpanHzEditField.ValueDisplayFormat = '%.12g';
            app.CapturetraceButton = uibutton(app.TektronixRSAUIFigure, 'push');
            app.CapturetraceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturetraceButtonPushed, true);
            app.CapturetraceButton.Text = 'Capture trace';
            app.RefreshpanelButton = uibutton(app.TektronixRSAUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.TracesettingsPanel = uipanel(app.TektronixRSAUIFigure);
            app.TracesettingsPanel.Title = 'Trace settings';
            app.DetectionDropDownLabel = uilabel(app.TracesettingsPanel);
            app.DetectionDropDownLabel.Text = 'Detection';
            app.DetectionDropDown = uidropdown(app.TracesettingsPanel);
            app.DetectionDropDown.Items = {'Avg (VRMS)', 'Peak+', 'Peak-'};
            app.DetectionDropDown.ItemsData = {'average', 'positive', 'negative'};
            app.DetectionDropDown.Value = 'average';
            app.TraceDropDownLabel = uilabel(app.TracesettingsPanel);
            app.TraceDropDownLabel.Text = 'Trace';
            app.TraceDropDown = uidropdown(app.TracesettingsPanel);
            app.TraceDropDown.Items = {'1', '2', '3'};
            app.TraceDropDown.ValueChangedFcn = createCallbackFcn(app, @TraceDropDownValueChanged, true);
            app.TraceDropDown.Value = '1';
            app.FunctionDropDownLabel = uilabel(app.TracesettingsPanel);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.TracesettingsPanel);
            app.FunctionDropDown.Items = {'Avg (VRMS)', 'Hold', 'Normal'};
            app.FunctionDropDown.ItemsData = {'average', 'hold', 'normal'};
            app.FunctionDropDown.Value = 'average';
            app.NaveragesEditFieldLabel = uilabel(app.TracesettingsPanel);
            app.NaveragesEditFieldLabel.Text = 'N averages';
            app.NaveragesEditField = uieditfield(app.TracesettingsPanel, 'numeric');
            app.NaveragesEditField.ValueDisplayFormat = '%.0f';
            app.NaveragesEditField.Value = 1;
            app.CountCheckBox = uicheckbox(app.TracesettingsPanel);
            app.CountCheckBox.Text = 'Count';
            app.NpointsDropDownLabel = uilabel(app.TracesettingsPanel);
            app.NpointsDropDownLabel.Text = 'N points';
            app.NpointsDropDown = uidropdown(app.TracesettingsPanel);
            app.RefleveldBmEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.RefleveldBmEditFieldLabel.Text = 'Ref. level (dBm)';
            app.RefleveldBmEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.DisplaysettingsPanel = uipanel(app.TektronixRSAUIFigure);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.YoffsetdBmEditFieldLabel = uilabel(app.DisplaysettingsPanel);
            app.YoffsetdBmEditFieldLabel.Text = 'Y offset (dBm)';
            app.YoffsetdBmEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.YscaledBmdivEditFieldLabel = uilabel(app.DisplaysettingsPanel);
            app.YscaledBmdivEditFieldLabel.Text = 'Y scale (dBm/div)';
            app.YscaledBmdivEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.AcquisitionButtonGroup = uibuttongroup(app.TektronixRSAUIFigure);
            app.AcquisitionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AcquisitionButtonGroupSelectionChanged, true);
            app.AcquisitionButtonGroup.Title = 'Acquisition';
            app.StopButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            app.SingleButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.SingleButton.Text = 'Single';
            app.ContinuousButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.DisplaytracesPanel = uipanel(app.TektronixRSAUIFigure);
            app.DisplaytracesPanel.Title = 'Display traces';
            app.Tr3Button = uibutton(app.DisplaytracesPanel, 'state');
            app.Tr3Button.Text = '3';
            app.Tr2Button = uibutton(app.DisplaytracesPanel, 'state');
            app.Tr2Button.Text = '2';
            app.Tr1Button = uibutton(app.DisplaytracesPanel, 'state');
            app.Tr1Button.Text = '1';
            app.AutoRBWCheckBox = uicheckbox(app.TektronixRSAUIFigure);
            app.AutoRBWCheckBox.Text = 'Auto RBW?';
            app.ActualRBWHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.ActualRBWHzEditFieldLabel.Text = 'Actual RBW (Hz)';
            app.ActualRBWHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.ActualRBWHzEditField.Limits = [0 Inf];
            app.ActualRBWHzEditField.ValueDisplayFormat = '%.5g';
            app.ActualRBWHzEditField.Editable = 'off';
            % Show the figure after all components are created
            app.TektronixRSAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiTekRsa(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TektronixRSAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TektronixRSAUIFigure)
        end
    end
end