classdef GuiTekRsaFvt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TektronixRSAUIFigure           matlab.ui.Figure
        StarttimesEditFieldLabel       matlab.ui.control.Label
        StarttimesEditField            matlab.ui.control.NumericEditField
        TimelengthsEditFieldLabel      matlab.ui.control.Label
        TimelengthsEditField           matlab.ui.control.NumericEditField
        MeasurementfreqHzEditFieldLabel  matlab.ui.control.Label
        MeasurementfreqHzEditField     matlab.ui.control.NumericEditField
        MeasurementBWHzEditFieldLabel  matlab.ui.control.Label
        MeasurementBWHzEditField       matlab.ui.control.NumericEditField
        CapturetraceButton             matlab.ui.control.Button
        RefreshpanelButton             matlab.ui.control.Button
        DisplaysettingsPanel           matlab.ui.container.Panel
        YoffsetHzEditFieldLabel        matlab.ui.control.Label
        YoffsetHzEditField             matlab.ui.control.NumericEditField
        YextentHzEditFieldLabel        matlab.ui.control.Label
        YextentHzEditField             matlab.ui.control.NumericEditField
    end


    properties (GetAccess = public, SetAccess = protected)
        GuiSync
        Instr
    end
    
    methods (Access = private)

        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)  
            addLink(app.GuiSync, app.StarttimesEditField,  'Instr.disp_x_start');
            addLink(app.GuiSync, app.MeasurementfreqHzEditField, 'Instr.measuremnt_freq');
            addLink(app.GuiSync, app.TimelengthsEditField,   'Instr.disp_x_length');
            addLink(app.GuiSync, app.MeasurementBWHzEditField,'Instr.measurement_BW');
            addLink(app.GuiSync, app.YextentHzEditField, 'Instr.disp_y_scale');
            addLink(app.GuiSync, app.YoffsetHzEditField,   'Instr.disp_y_offset');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, 'KernelObj', Instrument);
            createLinks(app);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            sync(app.Instr)
        end

        % Button pushed function: CapturetraceButton
        function CapturetraceButtonPushed(app, event)
            readTrace(app.Instr);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TektronixRSAUIFigure = uifigure('Visible', 'off');
            app.TektronixRSAUIFigure.Name = 'Tektronix RSA';
            app.StarttimesEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.StarttimesEditFieldLabel.Text = 'Start time (s)';
            app.StarttimesEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.StarttimesEditField.ValueDisplayFormat = '%.12g';
            app.TimelengthsEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.TimelengthsEditFieldLabel.Text = 'Time length (s)';
            app.TimelengthsEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.TimelengthsEditField.ValueDisplayFormat = '%.12g';
            app.MeasurementfreqHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.MeasurementfreqHzEditFieldLabel.Text = 'Measurement freq (Hz)';
            app.MeasurementfreqHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.MeasurementfreqHzEditField.ValueDisplayFormat = '%.12g';
            app.MeasurementBWHzEditFieldLabel = uilabel(app.TektronixRSAUIFigure);
            app.MeasurementBWHzEditFieldLabel.Text = 'Measurement BW (Hz)';
            app.MeasurementBWHzEditField = uieditfield(app.TektronixRSAUIFigure, 'numeric');
            app.MeasurementBWHzEditField.ValueDisplayFormat = '%.12g';
            app.CapturetraceButton = uibutton(app.TektronixRSAUIFigure, 'push');
            app.CapturetraceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturetraceButtonPushed, true);
            app.CapturetraceButton.Text = 'Capture trace';
            app.RefreshpanelButton = uibutton(app.TektronixRSAUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.DisplaysettingsPanel = uipanel(app.TektronixRSAUIFigure);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.YoffsetHzEditFieldLabel = uilabel(app.DisplaysettingsPanel);
            app.YoffsetHzEditFieldLabel.Text = 'Y offset (Hz)';
            app.YoffsetHzEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.YextentHzEditFieldLabel = uilabel(app.DisplaysettingsPanel);
            app.YextentHzEditFieldLabel.Text = 'Y extent (Hz)';
            app.YextentHzEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            % Show the figure after all components are created
            app.TektronixRSAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiTekRsaFvt(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TektronixRSAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TektronixRSAUIFigure)
        end
    end
end