classdef GuiTekScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TekScopeUIFigure              matlab.ui.Figure
        CapturetraceButton            matlab.ui.control.Button
        RefreshpanelButton            matlab.ui.control.Button
        AcquisitionButtonGroup        matlab.ui.container.ButtonGroup
        StopButton                    matlab.ui.control.RadioButton
        SingleButton                  matlab.ui.control.RadioButton
        ContinuousButton              matlab.ui.control.RadioButton
        ChannelconfigurationPanel     matlab.ui.container.Panel
        ScaleVDivLabel                matlab.ui.control.Label
        ScaleCH                       matlab.ui.control.NumericEditField
        VScalePlusButton              matlab.ui.control.Button
        VScaleMinusButton             matlab.ui.control.Button
        ChannelDropDownLabel          matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        CouplingDropDownLabel         matlab.ui.control.Label
        CouplingCH                    matlab.ui.control.DropDown
        OffsetEditFieldLabel          matlab.ui.control.Label
        OffsetCH                      matlab.ui.control.NumericEditField
        ImpedanceDropDownLabel        matlab.ui.control.Label
        ImpedanceCH                   matlab.ui.control.DropDown
        TriggerPanel                  matlab.ui.container.Panel
        ModeDropDownLabel             matlab.ui.control.Label
        ModeDropDown                  matlab.ui.control.DropDown
        SourceDropDownLabel           matlab.ui.control.Label
        SourceDropDown                matlab.ui.control.DropDown
        EdgeDropDownLabel             matlab.ui.control.Label
        EdgeDropDown                  matlab.ui.control.DropDown
        LevelVEditFieldLabel          matlab.ui.control.Label
        LevelVEditField               matlab.ui.control.NumericEditField
        TimescalesDivEditFieldLabel   matlab.ui.control.Label
        TimescalesDivEditField        matlab.ui.control.NumericEditField
        NumberofpointsDropDownLabel   matlab.ui.control.Label
        NumberofpointsDropDown        matlab.ui.control.DropDown
        TraceAxes                     matlab.ui.control.UIAxes
        TimeScalePlusButton           matlab.ui.control.Button
        TimeScaleMinusButton          matlab.ui.control.Button
        EnabledchannelsPanel          matlab.ui.container.Panel
        CH4Button                     matlab.ui.control.StateButton
        CH3Button                     matlab.ui.control.StateButton
        CH2Button                     matlab.ui.control.StateButton
        CH1Button                     matlab.ui.control.StateButton
        AcquisitionmodeDropDownLabel  matlab.ui.control.Label
        AcquisitionmodeDropDown       matlab.ui.control.DropDown
    end


    properties (GetAccess = public, SetAccess = protected)
        GuiSync
        Instr
    end

    methods (Access = public)
        function cb = publicCreateCallbackFcn(app, f)
            cb = createCallbackFcn(app, f, true);
        end
        
        % Plot trace 
        function updatePlot(app)
            if ~isempty(app.Instr.Trace.x) && ~isempty(app.Instr.Trace.y)
                plot(app.Instr.Trace, app.TraceAxes);
                app.TraceAxes.XLabel.String = app.Instr.Trace.label_x;
                app.TraceAxes.YLabel.String = app.Instr.Trace.label_y;
            end
        end
    end

    methods (Access = private)
        
        % re-link the channel property control elements to the new channel 
        function updateChannelTags(app, n_ch) 
            reLink(app.GuiSync, app.CouplingCH,  sprintf('Instr.cpl%i',n_ch));
            reLink(app.GuiSync, app.ImpedanceCH, sprintf('Instr.imp%i',n_ch));
            reLink(app.GuiSync, app.OffsetCH,    sprintf('Instr.offset%i',n_ch));
            reLink(app.GuiSync, app.ScaleCH,     sprintf('Instr.scale%i',n_ch));
        end
        
        function createLinks(app)  
            addLink(app.GuiSync, app.ChannelDropDown,         'Instr.channel');
            addLink(app.GuiSync, app.NumberofpointsDropDown,  'Instr.point_no');
            addLink(app.GuiSync, app.TimescalesDivEditField,  'Instr.time_scale');
            addLink(app.GuiSync, app.AcquisitionmodeDropDown, 'Instr.acq_mode');
            
            % Trigger panel
            addLink(app.GuiSync, app.SourceDropDown,  'Instr.trig_source');
            addLink(app.GuiSync, app.ModeDropDown,    'Instr.trig_mode');
            addLink(app.GuiSync, app.EdgeDropDown,    'Instr.trig_slope');
            addLink(app.GuiSync, app.LevelVEditField, 'Instr.trig_lev');
            
            % Channel-specific elemnts are initialized first with dummy tags
            addLink(app.GuiSync, app.CouplingCH,  'Instr.cpl1');
            addLink(app.GuiSync, app.ImpedanceCH, 'Instr.imp1');
            addLink(app.GuiSync, app.ScaleCH,     'Instr.scale1');
            addLink(app.GuiSync, app.OffsetCH,    'Instr.offset1');
            
            % Assign tags based on the channel selection
            updateChannelTags(app, app.Instr.channel);
            
            for i=1:4
                b_tag = sprintf('CH%iButton',i);
                if i<=app.Instr.channel_no
                    c_tag = sprintf('Instr.enable%i',i);
                    addLink(app.GuiSync, app.(b_tag), c_tag);
                else
                    app.(b_tag).Visible = 'off';
                    app.(b_tag).Enable = 'off';
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, ...
                'KernelObj',            Instrument, ...
                'createCallbackFcn',    @app.publicCreateCallbackFcn);
            
            L = addlistener(app.Instr, 'NewData', @(~,~)updatePlot(app));
            addToCleanup(app.GuiSync, L);
            
            
            % Initialize the trigger panel
            try
                if app.Instr.acq_state
                    app.ContinuousButton.Value = true;
                else
                    app.StopButton.Value = true;
                end
            catch ME
                warning(ME.message)
            end
            
            % Initialize the channel panel
            try
                app.ChannelDropDown.Items = arrayfun(@(x)sprintf('%i',x),...
                    1:app.Instr.channel_no,'UniformOutput',false);
                app.ChannelDropDown.ItemsData = 1:app.Instr.channel_no;
                
                current_n_ch = app.Instr.ctrl_channel;
                b_tag = sprintf('CH%iButton', current_n_ch);
                app.(b_tag).Enable = 'off';
                
                app.Instr.ctrl_channel = current_n_ch;
            catch ME
                warning(ME.message)
            end
            
            if ~ismethod(app.Instr, 'turnKnob')
                app.TimeScalePlusButton.Enable = 'off';
                app.TimeScalePlusButton.Visible = 'off';
                app.TimeScaleMinusButton.Enable = 'off';
                app.TimeScaleMinusButton.Visible = 'off';
                app.VScalePlusButton.Enable = 'off';
                app.VScalePlusButton.Visible = 'off';
                app.VScaleMinusButton.Enable = 'off';
                app.VScaleMinusButton.Visible = 'off';
            end

            createLinks(app);
        end

        % Button pushed function: CapturetraceButton
        function CapturetraceButtonPushed(app, event)
            readTrace(app.Instr);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            sync(app.Instr);
        end

        % Selection changed function: AcquisitionButtonGroup
        function AcquisitionButtonGroupSelectionChanged(app, event)
            selectedButton = app.AcquisitionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Stop'
                    if isfield(app.Instr.CommandList, 'acq_state')
                        writePropertyHedged(app.Instr, 'acq_state', false);
                    elseif ismethod(app.Instr,'stopAcquisition')
                        stopAcquisition(app.Instr);
                    end
                case 'Single'
                    acquireSingle(app.Instr);
                    app.StopButton.Value = true;
                case 'Continuous'
                    acquireContinuous(app.Instr);
                otherwise
                    error('Trigger case unknown in callback');
            end
        end

        % Button pushed function: TimeScalePlusButton
        function TimeScalePlusButtonPushed(app, event)
            turnKnob(app.Instr, 'HORZScale', -1);
            readPropertyHedged(app.Instr,'time_scale');
        end

        % Button pushed function: TimeScaleMinusButton
        function TimeScaleMinusButtonPushed(app, event)
            turnKnob(app.Instr, 'HORZScale', 1);
            readPropertyHedged(app.Instr,'time_scale');
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            n_ch = app.ChannelDropDown.Value;
            
            % re-link the channel property control elements to the new channel  
            updateChannelTags(app, n_ch);
            for i=1:4
                b_tag = sprintf('CH%iButton',i);
                if i==n_ch
                    app.(b_tag).Enable = 'off';
                else
                    app.(b_tag).Enable = 'on';
                end
            end
            enable_cmd = sprintf('enable%i',n_ch);
            
            app.Instr.(enable_cmd) = true;
            app.Instr.channel = n_ch;
        end

        % Button pushed function: VScalePlusButton
        function VScalePlusButtonPushed(app, event)
            n_ch = app.ChannelDropDown.Value;
            turnKnob(app.Instr, sprintf('VERTSCALE%i',n_ch), -1);
            sync(app.Instr);
        end

        % Button pushed function: VScaleMinusButton
        function VScaleMinusButtonPushed(app, event)
            n_ch = app.ChannelDropDown.Value;
            turnKnob(app.Instr, sprintf('VERTSCALE%i',n_ch), 1);
            sync(app.Instr);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TekScopeUIFigure = uifigure('Visible', 'off');
            app.TekScopeUIFigure.Name = 'Tektronix DPO';
            app.CapturetraceButton = uibutton(app.TekScopeUIFigure, 'push');
            app.CapturetraceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturetraceButtonPushed, true);
            app.CapturetraceButton.Text = 'Capture trace';
            app.RefreshpanelButton = uibutton(app.TekScopeUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.AcquisitionButtonGroup = uibuttongroup(app.TekScopeUIFigure);
            app.AcquisitionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AcquisitionButtonGroupSelectionChanged, true);
            app.AcquisitionButtonGroup.Title = 'Acquisition';
            app.StopButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            app.SingleButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.SingleButton.Text = 'Single';
            app.ContinuousButton = uiradiobutton(app.AcquisitionButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ChannelconfigurationPanel = uipanel(app.TekScopeUIFigure);
            app.ChannelconfigurationPanel.Title = 'Channel configuration';
            app.ScaleVDivLabel = uilabel(app.ChannelconfigurationPanel);
            app.ScaleVDivLabel.Text = 'Scale (V/Div)';
            app.ScaleCH = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.ScaleCH.ValueDisplayFormat = '%.4g';
            app.VScalePlusButton = uibutton(app.ChannelconfigurationPanel, 'push');
            app.VScalePlusButton.ButtonPushedFcn = createCallbackFcn(app, @VScalePlusButtonPushed, true);
            app.VScalePlusButton.Text = '+';
            app.VScaleMinusButton = uibutton(app.ChannelconfigurationPanel, 'push');
            app.VScaleMinusButton.ButtonPushedFcn = createCallbackFcn(app, @VScaleMinusButtonPushed, true);
            app.VScaleMinusButton.Text = '–';
            app.ChannelDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.ChannelconfigurationPanel);
            app.ChannelDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = '1';
            app.CouplingDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.CouplingDropDownLabel.Text = 'Coupling';
            app.CouplingCH = uidropdown(app.ChannelconfigurationPanel);
            app.CouplingCH.Items = {'DC', 'AC', 'GND'};
            app.CouplingCH.Value = 'DC';
            app.OffsetEditFieldLabel = uilabel(app.ChannelconfigurationPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetCH = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.ImpedanceDropDownLabel = uilabel(app.ChannelconfigurationPanel);
            app.ImpedanceDropDownLabel.Text = 'Impedance';
            app.ImpedanceCH = uidropdown(app.ChannelconfigurationPanel);
            app.ImpedanceCH.Items = {'1 Mohm', '50 Ohm'};
            app.ImpedanceCH.ItemsData = {'MEG', 'FIFty'};
            app.ImpedanceCH.Value = 'MEG';
            app.TriggerPanel = uipanel(app.TekScopeUIFigure);
            app.TriggerPanel.Title = 'Trigger';
            app.ModeDropDownLabel = uilabel(app.TriggerPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.TriggerPanel);
            app.ModeDropDown.Items = {'Auto', 'Normal'};
            app.ModeDropDown.ItemsData = {'AUTO', 'NORMal'};
            app.ModeDropDown.Value = 'AUTO';
            app.SourceDropDownLabel = uilabel(app.TriggerPanel);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.TriggerPanel);
            app.SourceDropDown.Items = {'CH1', 'CH2', 'CH3', 'CH4', 'AUX', 'EXT', 'LINE'};
            app.SourceDropDown.Value = 'AUX';
            app.EdgeDropDownLabel = uilabel(app.TriggerPanel);
            app.EdgeDropDownLabel.Text = 'Edge';
            app.EdgeDropDown = uidropdown(app.TriggerPanel);
            app.EdgeDropDown.Items = {'Rise', 'Fall'};
            app.EdgeDropDown.ItemsData = {'RISe', 'FALL'};
            app.EdgeDropDown.Value = 'RISe';
            app.LevelVEditFieldLabel = uilabel(app.TriggerPanel);
            app.LevelVEditFieldLabel.Text = 'Level [V]';
            app.LevelVEditField = uieditfield(app.TriggerPanel, 'numeric');
            app.TimescalesDivEditFieldLabel = uilabel(app.TekScopeUIFigure);
            app.TimescalesDivEditFieldLabel.Text = 'Time scale [s/Div]';
            app.TimescalesDivEditField = uieditfield(app.TekScopeUIFigure, 'numeric');
            app.TimescalesDivEditField.ValueDisplayFormat = '%.4g';
            app.NumberofpointsDropDownLabel = uilabel(app.TekScopeUIFigure);
            app.NumberofpointsDropDownLabel.Text = 'Number of points';
            app.NumberofpointsDropDown = uidropdown(app.TekScopeUIFigure);
            app.TraceAxes = uiaxes(app.TekScopeUIFigure);
            xlabel(app.TraceAxes, 'Time')
            ylabel(app.TraceAxes, 'Voltage')
            app.TimeScalePlusButton = uibutton(app.TekScopeUIFigure, 'push');
            app.TimeScalePlusButton.ButtonPushedFcn = createCallbackFcn(app, @TimeScalePlusButtonPushed, true);
            app.TimeScalePlusButton.Text = '+';
            app.TimeScaleMinusButton = uibutton(app.TekScopeUIFigure, 'push');
            app.TimeScaleMinusButton.ButtonPushedFcn = createCallbackFcn(app, @TimeScaleMinusButtonPushed, true);
            app.TimeScaleMinusButton.Text = '–';
            app.EnabledchannelsPanel = uipanel(app.TekScopeUIFigure);
            app.EnabledchannelsPanel.Title = 'Enabled channels';
            app.CH4Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH4Button.Text = 'CH4';
            app.CH3Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH3Button.Text = 'CH3';
            app.CH2Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH2Button.Text = 'CH2';
            app.CH1Button = uibutton(app.EnabledchannelsPanel, 'state');
            app.CH1Button.Text = 'CH1';
            app.AcquisitionmodeDropDownLabel = uilabel(app.TekScopeUIFigure);
            app.AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.AcquisitionmodeDropDown = uidropdown(app.TekScopeUIFigure);
            % Show the figure after all components are created
            app.TekScopeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiTekScope(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TekScopeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TekScopeUIFigure)
        end
    end
end