classdef GuiThorlabsPm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TemplateUIFigure               matlab.ui.Figure
        SensorLabel                    matlab.ui.control.Label
        PoweruWEditFieldLabel          matlab.ui.control.Label
        PoweruWEditField               matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        WavelengthnmEditField          matlab.ui.control.NumericEditField
        NaveragesEditFieldLabel        matlab.ui.control.Label
        NaveragesEditField             matlab.ui.control.NumericEditField
        PanelupdateintervalsEditFieldLabel  matlab.ui.control.Label
        PanelupdateintervalsEditField  matlab.ui.control.NumericEditField
        AutopowerrangeCheckBox         matlab.ui.control.CheckBox
    end


    properties (Access = public)
        UpdTimer % Timer for gui update
        
        GuiSync
        Instr
    end

    methods (Access = public)
        function updateSensorTitle(app)
            sens_str = regexp(app.Instr.sensor,',','split');
            app.SensorLabel.Text = ['Sensor: ', sens_str{1}];
        end
    end
    
    methods (Access = private)
        function createLinks(app)
            addLink(app.GuiSync, app.PoweruWEditField,               'Instr.power',...
                'input_prescaler', 10^6);
            addLink(app.GuiSync, app.WavelengthnmEditField,          'Instr.wl');
            addLink(app.GuiSync, app.NaveragesEditField,             'Instr.average_no');
            addLink(app.GuiSync, app.AutopowerrangeCheckBox,         'Instr.auto_pow_rng');
            addLink(app.GuiSync, app.PanelupdateintervalsEditField,  'UpdTimer.Period');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, ...
                'KernelObj',        Instrument, ...
                'updateGuiFcn',     @app.updateSensorTitle);
            
             % Set up and start periodic update of the gui
            app.UpdTimer = timer(); 
            
            addToCleanup(app.GuiSync, app.UpdTimer);
            
            app.UpdTimer.ExecutionMode = 'fixedSpacing';
            app.UpdTimer.Period = 1; %second.
            app.UpdTimer.TimerFcn = @(~,~)sync(app.Instr);
            app.UpdTimer.BusyMode = 'drop';
            start(app.UpdTimer);
            
            createLinks(app); 
        end

        % Value changed function: PanelupdateintervalsEditField
        function PanelupdateintervalsEditFieldValueChanged(app, event)
            value = app.PanelupdateintervalsEditField.Value;
            stop(app.UpdTimer);
            app.UpdTimer.Period = value;
            start(app.UpdTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TemplateUIFigure = uifigure('Visible', 'off');
            app.TemplateUIFigure.Name = 'Template';
            app.SensorLabel = uilabel(app.TemplateUIFigure);
            app.SensorLabel.Text = 'Sensor:';
            app.PoweruWEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.PoweruWEditFieldLabel.Text = 'Power (uW)';
            app.PoweruWEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.PoweruWEditField.ValueDisplayFormat = '%.3f';
            app.PoweruWEditField.Editable = 'off';
            app.PoweruWEditField.Value = 2000;
            app.WavelengthnmEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.WavelengthnmEditField.Value = 700;
            app.NaveragesEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.NaveragesEditFieldLabel.Text = 'N averages';
            app.NaveragesEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.NaveragesEditField.ValueDisplayFormat = '%.0f';
            app.NaveragesEditField.Value = 1;
            app.PanelupdateintervalsEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.PanelupdateintervalsEditFieldLabel.Text = 'Panel update interval (s)';
            app.PanelupdateintervalsEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.PanelupdateintervalsEditField.ValueChangedFcn = createCallbackFcn(app, @PanelupdateintervalsEditFieldValueChanged, true);
            app.PanelupdateintervalsEditField.Value = 1;
            app.AutopowerrangeCheckBox = uicheckbox(app.TemplateUIFigure);
            app.AutopowerrangeCheckBox.Text = 'Auto power range?';
            % Show the figure after all components are created
            app.TemplateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiThorlabsPm(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TemplateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TemplateUIFigure)
        end
    end
end