classdef GuiTpg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure         matlab.ui.Figure
        GaugePanel1        matlab.ui.container.Panel
        PEditFieldLabel    matlab.ui.control.Label
        PDisp1             matlab.ui.control.EditField
        GaugePanel2        matlab.ui.container.Panel
        PEditField_2Label  matlab.ui.control.Label
        PDisp2             matlab.ui.control.EditField
        LoggerButton       matlab.ui.control.Button
        SinglereadButton   matlab.ui.control.Button
    end


    properties (Access = public)
        name = 'TPG pressure monitor';
        Instr; % MyInstrument object
        linked_elem_list={}; % Lust of elements
        Lg; % MyLogger object
        GuiLg; % Gui of MyLogger
    end

    properties (Access = private)
        Llh % Listener to new data, generated by logger
        Obdlh % Listener to logger being closed
    end
    

    methods (Access = private)
    
        function createControlLinks(app)
            app.PDisp1.Tag = 'Instr.pressure_str1';
            app.linked_elem_list = [app.linked_elem_list, app.PDisp1];
            
            app.PDisp2.Tag = 'Instr.pressure_str2';
            app.linked_elem_list = [app.linked_elem_list, app.PDisp2];
        end
        
        % overload standard updateGui
        function updateGui(app)
            updateLinkedGuiElements(app);
            try 
                app.GaugePanel1.Title = sprintf('Gauge 1: %s, %s',...
                    app.Instr.gauge_id1, app.Instr.stat1);
            catch
                app.GaugePanel1.Title = 'Gauge 1: display error';
                warning('Cannot display status of gauge 1')
            end
            try
                app.GaugePanel2.Title = sprintf('Gauge 2: %s, %s',...
                    app.Instr.gauge_id2, app.Instr.stat2);
            catch
                app.GaugePanel2.Title = 'Gauge 2: display error';
                warning('Cannot display status of gauge 2')
            end
        end
        
        function newDataCallback(app,~,~)
            updateGui(app);
        end
    
        function loggerClosedCallback(app,~,~)
            % Clean up
            delete(app.Llh);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            p=inputParser();
            p.KeepUnmatched = true;
            addParameter(p,'Instr',[]);
            addParameter(p,'name',[]);
            parse(p, varargin{:});
            
            if ~ismember('Instr',p.UsingDefaults)
                app.Instr = p.Results.Instr;
            else
                % Create a dummy instrument object if it was not supplied externally
                app.Instr = MyTpg('visa','');
            end
            if ~ismember('name',p.UsingDefaults)
                app.name = p.Results.name;
            end
            
            readAllHedged(app.Instr);
            
            app.MainFigure.Name = app.name;
            createControlLinks(app);
            updateGui(app);
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            try
                delete(app.Instr);
            catch
            end
            try
                delete(app.GuiLg);
            catch
            end
            try
                delete(app.Lg);
            catch
            end
            delete(app)
        end

        % Button pushed function: SinglereadButton
        function SinglereadCallback(app, event)
            readAllHedged(app.Instr);
            updateGui(app);
        end

        % Button pushed function: LoggerButton
        function LoggerButtonPushed(app, event)
            if ~(isa(app.Lg, 'MyLogger')&&isvalid(app.Lg))
                pu = app.Instr.pressure_unit;
                
                settings = getLocalSettings();
                base_dir = settings.measurement_base_dir;
                session_path = createSessionPath(base_dir, 'Pressure log');
                file_name = fullfile(session_path,'Pressure log.txt');
                
                app.Lg = MyLogger('MeasFcn', @()readAllHedged(app.Instr),...
                    'data_headers', {['P ch1 [',pu,']'],['P ch2 [',pu,']']},...
                    'save_file',file_name);
                app.GuiLg = GuiLogger('Lg',app.Lg,'name','Pressure logger');
                app.Llh = addlistener(app.Lg, 'NewData', @app.newDataCallback);
                app.Obdlh = addlistener(app.GuiLg, 'ObjectBeingDestroyed',...
                    @app.loggerClosedCallback);
            else
                % bring logger gui to front
                fig = findfigure(app.GuiLg);
                fig.Visible = 'off';
                fig.Visible = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure;
            app.MainFigure.Name = 'TPG pressure gauge controller';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.GaugePanel1 = uipanel(app.MainFigure);
            app.GaugePanel1.Title = 'Gauge 1';
            app.PEditFieldLabel = uilabel(app.GaugePanel1);
            app.PEditFieldLabel.Text = 'P';
            app.PDisp1 = uieditfield(app.GaugePanel1, 'text');
            app.PDisp1.Editable = 'off';
            app.GaugePanel2 = uipanel(app.MainFigure);
            app.GaugePanel2.Title = 'Gauge 2';
            app.PEditField_2Label = uilabel(app.GaugePanel2);
            app.PEditField_2Label.Text = 'P';
            app.PDisp2 = uieditfield(app.GaugePanel2, 'text');
            app.PDisp2.Editable = 'off';
            app.LoggerButton = uibutton(app.MainFigure, 'push');
            app.LoggerButton.ButtonPushedFcn = createCallbackFcn(app, @LoggerButtonPushed, true);
            app.LoggerButton.Text = 'Logger';
            app.SinglereadButton = uibutton(app.MainFigure, 'push');
            app.SinglereadButton.ButtonPushedFcn = createCallbackFcn(app, @SinglereadCallback, true);
            app.SinglereadButton.Text = 'Single read';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiTpg(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end