classdef GuiZiRingdown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZiRingdownUIFigure           matlab.ui.Figure
        AuxiliaryoutputPanel         matlab.ui.container.Panel
        AuxOutEnableCheckBox         matlab.ui.control.CheckBox
        OnlevelVEditFieldLabel       matlab.ui.control.Label
        OnlevelVEditField            matlab.ui.control.NumericEditField
        OfflevelVEditFieldLabel      matlab.ui.control.Label
        OfflevelVEditField           matlab.ui.control.NumericEditField
        OntimesEditFieldLabel        matlab.ui.control.Label
        OntimesEditField             matlab.ui.control.NumericEditField
        OfftimesEditFieldLabel       matlab.ui.control.Label
        OfftimesEditField            matlab.ui.control.NumericEditField
        AuxoutonLampLabel            matlab.ui.control.Label
        AuxoutonLamp                 matlab.ui.control.Lamp
        AuxOutInfoLabel              matlab.ui.control.Label
        TracepostprocessingPanel     matlab.ui.container.Panel
        NaverageEditFieldLabel       matlab.ui.control.Label
        NaverageEditField            matlab.ui.control.NumericEditField
        CompletedaveragesEditFieldLabel  matlab.ui.control.Label
        CompletedaveragesEditField   matlab.ui.control.NumericEditField
        AutosaveCheckBox             matlab.ui.control.CheckBox
        DownsampledrateptsLabel      matlab.ui.control.Label
        DownsampledrateEditField     matlab.ui.control.NumericEditField
        DownsamplingfactorEditFieldLabel  matlab.ui.control.Label
        DownsamplingfactorEditField  matlab.ui.control.NumericEditField
        RingdownPanel                matlab.ui.container.Panel
        EnabletriggeringButton       matlab.ui.control.StateButton
        TriggerthresholdVEditFieldLabel  matlab.ui.control.Label
        TriggerthresholdVEditField   matlab.ui.control.NumericEditField
        RecordingLampLabel           matlab.ui.control.Label
        RecordingLamp                matlab.ui.control.Lamp
        DriveLampLabel               matlab.ui.control.Label
        DriveLamp                    matlab.ui.control.Lamp
        RecordtimesEditFieldLabel    matlab.ui.control.Label
        RecordtimesEditField         matlab.ui.control.NumericEditField
        TimeelapsedsEditFieldLabel   matlab.ui.control.Label
        TimeelapsedsEditField        matlab.ui.control.NumericEditField
        DemodulationoscillatorEditFieldLabel  matlab.ui.control.Label
        CurrentOscEditField          matlab.ui.control.NumericEditField
        DemodulatorspectrumPanel     matlab.ui.container.Panel
        DemodUIAxes                  matlab.ui.control.UIAxes
        ylimitmodeLabel              matlab.ui.control.Label
        YLimModeDropDown             matlab.ui.control.DropDown
        flimitmodeLabel              matlab.ui.control.Label
        XLimModeDropDown             matlab.ui.control.DropDown
        yminEditFieldLabel           matlab.ui.control.Label
        yminEditField                matlab.ui.control.NumericEditField
        ymaxEditFieldLabel           matlab.ui.control.Label
        ymaxEditField                matlab.ui.control.NumericEditField
        fmaxHzEditFieldLabel         matlab.ui.control.Label
        fmaxHzEditField              matlab.ui.control.NumericEditField
        FFTRBWHzEditFieldLabel       matlab.ui.control.Label
        FFTRBWHzEditField            matlab.ui.control.NumericEditField
        FFTlengthptEditFieldLabel    matlab.ui.control.Label
        FFTlengthptEditField         matlab.ui.control.NumericEditField
        TraceUIAxes                  matlab.ui.control.UIAxes
        AdaptiveFrequencyPanel       matlab.ui.container.Panel
        AdaptivedemodCheckBox        matlab.ui.control.CheckBox
        OscfollowingLampLabel        matlab.ui.control.Label
        OscfollowingLamp             matlab.ui.control.Lamp
    end


    properties (Access = public)
        Instr       % Instrument object
        GuiSync
    end
    
    properties (Access = private)
        
        % variable that is used to determine the axes scale during the first update
        init_axes = true
    end
    
    methods (Access = private)
        
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)
            addLink(app.GuiSync, app.RecordingLamp, '.Instr.recording');
            addLink(app.GuiSync, app.DriveLamp, '.Instr.drive_on');
            addLink(app.GuiSync, app.EnabletriggeringButton, '.Instr.enable_acq');
            addLink(app.GuiSync, app.TriggerthresholdVEditField, '.Instr.trig_threshold');
            addLink(app.GuiSync, app.RecordtimesEditField, '.Instr.record_time');
            addLink(app.GuiSync, app.CurrentOscEditField, '.Instr.current_osc');
            addLink(app.GuiSync, app.TimeelapsedsEditField, '.Instr.elapsed_t');
            
            addLink(app.GuiSync, app.NaverageEditField, '.Instr.n_avg');
            addLink(app.GuiSync, app.CompletedaveragesEditField, '.Instr.avg_count');
            
            addLink(app.GuiSync, app.FFTlengthptEditField, '.Instr.fft_length');
            addLink(app.GuiSync, app.FFTRBWHzEditField, '.Instr.fft_rbw');
            addLink(app.GuiSync, app.DownsamplingfactorEditField, '.Instr.downsample_n');
            addLink(app.GuiSync, app.DownsampledrateEditField, '.Instr.downsampled_rate');
            
            addLink(app.GuiSync, app.AdaptivedemodCheckBox, '.Instr.adaptive_meas_osc');
            addLink(app.GuiSync, app.OscfollowingLamp, '.Instr.ad_osc_following');
            
            addLink(app.GuiSync, app.AutosaveCheckBox, '.Instr.auto_save');
            
            % Auxiliary output
            addLink(app.GuiSync, app.AuxOutEnableCheckBox, '.Instr.enable_aux_out');
            addLink(app.GuiSync, app.OnlevelVEditField, '.Instr.aux_out_on_lev');
            addLink(app.GuiSync, app.OfflevelVEditField, '.Instr.aux_out_off_lev');
            addLink(app.GuiSync, app.OntimesEditField, '.Instr.aux_out_on_t');
            addLink(app.GuiSync, app.OfftimesEditField, '.Instr.aux_out_off_t');
            addLink(app.GuiSync, app.AuxoutonLamp, '.Instr.aux_out_on');
            
            % Demodulator axes limits
            addLink(app.GuiSync, app.yminEditField, '.DemodUIAxes.YLim(1)');
            addLink(app.GuiSync, app.ymaxEditField, '.DemodUIAxes.YLim(2)');
            addLink(app.GuiSync, app.YLimModeDropDown, '.DemodUIAxes.YLimMode');
            
            % X limits are symmetric
            addLink(app.GuiSync, app.fmaxHzEditField, '.DemodUIAxes.XLim(2)');
            addLink(app.GuiSync, app.XLimModeDropDown, '.DemodUIAxes.XLimMode');
        end
        
        % Switch on/off access to those gui elements that cannot/should not 
        % be changed during the acquisition of ringdown
        function updateAccess(app, state)
            switch lower(state)
                case 'recording'
                    app.OntimesEditField.Editable='off';
                    app.OfftimesEditField.Editable='off';
                    app.AuxOutEnableCheckBox.Enable='off';
                    
                    app.DownsamplingfactorEditField.Editable = 'off';
                    app.DownsampledrateEditField.Editable = 'off';
                case 'driving'
                    app.OntimesEditField.Editable='on';
                    app.OfftimesEditField.Editable='on';
                    app.AuxOutEnableCheckBox.Enable='on';
                    
                    app.DownsamplingfactorEditField.Editable = 'on';
                    app.DownsampledrateEditField.Editable = 'on';
                otherwise
                    error('Unknown state name %s supplied to updateAccess', state)
            end
        end
    
        function updatePlot(app)
            
            % Plot the trace using its class method
            plot(app.Instr.Trace, app.TraceUIAxes);
            
            % Set x limits manually to beautify dynamic updating
            dt=1e-9;
            app.TraceUIAxes.XLim=[-dt, app.Instr.elapsed_t+dt];
            
            plot(app.Instr.DemodSpectrum, app.DemodUIAxes);
            
            if app.init_axes && ~isempty(app.Instr.DemodSpectrum.y)
                app.init_axes=false;
                % Set limits converting to 'double'
                app.DemodUIAxes.YLim=double([0.1*min(app.Instr.DemodSpectrum.y), 10*max(app.Instr.DemodSpectrum.y)]);
            end
            
            % Update the plot-related controls
            updateElement(app.GuiSync, app.yminEditField);
            updateElement(app.GuiSync, app.ymaxEditField);
            updateElement(app.GuiSync, app.fmaxHzEditField);
            updateElement(app.GuiSync, app.YLimModeDropDown);
            
            % Elapsed time indicator
            updateElement(app.GuiSync, app.TimeelapsedsEditField);
            
            % Adaptive oscillator indicator
            updateElement(app.GuiSync, app.OscfollowingLamp);
        end
        
        function updateGui(app)
            fmax=app.Instr.demod_rate/2;
            app.DemodUIAxes.XLim=[-fmax, fmax];

            updateLinkedGuiElements(app)
            updatePlot(app)
        end
        
        function newDataCallback(app, ~, ~)
            updateAccess(app, 'driving');
            updateGui(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, ...
                'KernelObj',     Instrument, ...
                'updateGuiFcn',  app.updateGui);
            createLinks(app);
            
            L = addlistener(app.Instr, 'NewDemodSample', @(~,~)updatePlot(app));
            addToCleanup(app.GuiSync, L);
            
            L = addlistener(app.Instr, 'NewData', @app.newDataCallback);
            addToCleanup(app.GuiSync, L);
            
            L = addlistener(app.Instr, 'RecordingStarted', @(~,~)updateAccess(app, 'recording'));
            addToCleanup(app.GuiSync, L);
            
            updateGui(app);
            
            % Start continuous data acquisition
            startPoll(app.Instr);
        end

        % Value changed function: EnabletriggeringButton
        function EnabletriggeringButtonValueChanged(app, event)
            value = app.EnabletriggeringButton.Value;
            if value && ~app.Instr.recording
                app.Instr.drive_on=true;
                resetAveraging(app.Instr); % Set the averaging counter to 0
            else
                app.Instr.drive_on=false;
            end
            app.Instr.enable_acq=value;
            updateGui(app);
        end

        % Value changed function: fmaxHzEditField
        function fmaxHzEditFieldValueChanged(app, event)
            value = app.fmaxHzEditField.Value;
            app.DemodUIAxes.XLim(1)=-abs(value);
            app.DemodUIAxes.XLim(2)=abs(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZiRingdownUIFigure = uifigure('Visible', 'off');
            app.ZiRingdownUIFigure.Name = 'Template';
            app.AuxiliaryoutputPanel = uipanel(app.ZiRingdownUIFigure);
            app.AuxiliaryoutputPanel.Title = 'Auxiliary output';
            app.AuxOutEnableCheckBox = uicheckbox(app.AuxiliaryoutputPanel);
            app.AuxOutEnableCheckBox.Text = 'Enable';
            app.OnlevelVEditFieldLabel = uilabel(app.AuxiliaryoutputPanel);
            app.OnlevelVEditFieldLabel.Text = 'On level (V)';
            app.OnlevelVEditField = uieditfield(app.AuxiliaryoutputPanel, 'numeric');
            app.OfflevelVEditFieldLabel = uilabel(app.AuxiliaryoutputPanel);
            app.OfflevelVEditFieldLabel.Text = 'Off level (V)';
            app.OfflevelVEditField = uieditfield(app.AuxiliaryoutputPanel, 'numeric');
            app.OntimesEditFieldLabel = uilabel(app.AuxiliaryoutputPanel);
            app.OntimesEditFieldLabel.Text = 'On time (s)';
            app.OntimesEditField = uieditfield(app.AuxiliaryoutputPanel, 'numeric');
            app.OfftimesEditFieldLabel = uilabel(app.AuxiliaryoutputPanel);
            app.OfftimesEditFieldLabel.Text = 'Off time (s)';
            app.OfftimesEditField = uieditfield(app.AuxiliaryoutputPanel, 'numeric');
            app.AuxoutonLampLabel = uilabel(app.AuxiliaryoutputPanel);
            app.AuxoutonLampLabel.Text = 'Aux out on';
            app.AuxoutonLamp = uilamp(app.AuxiliaryoutputPanel);
            app.AuxOutInfoLabel = uilabel(app.AuxiliaryoutputPanel);
            app.AuxOutInfoLabel.Text = {'If disabled – aux out always ''on'''; 'If enabled, switch between ''on'' and ''off'' states'; 'periodically starting from ''on'' state '};
            app.TracepostprocessingPanel = uipanel(app.ZiRingdownUIFigure);
            app.TracepostprocessingPanel.Title = 'Trace postprocessing';
            app.NaverageEditFieldLabel = uilabel(app.TracepostprocessingPanel);
            app.NaverageEditFieldLabel.Text = 'N average';
            app.NaverageEditField = uieditfield(app.TracepostprocessingPanel, 'numeric');
            app.NaverageEditField.Value = 1;
            app.CompletedaveragesEditFieldLabel = uilabel(app.TracepostprocessingPanel);
            app.CompletedaveragesEditFieldLabel.Text = 'Completed averages';
            app.CompletedaveragesEditField = uieditfield(app.TracepostprocessingPanel, 'numeric');
            app.CompletedaveragesEditField.ValueDisplayFormat = '%.0f';
            app.CompletedaveragesEditField.Editable = 'off';
            app.AutosaveCheckBox = uicheckbox(app.TracepostprocessingPanel);
            app.AutosaveCheckBox.Text = 'Auto save each trace';
            app.DownsampledrateptsLabel = uilabel(app.TracepostprocessingPanel);
            app.DownsampledrateptsLabel.Text = 'Downsampled rate (pt/s)';
            app.DownsampledrateEditField = uieditfield(app.TracepostprocessingPanel, 'numeric');
            app.DownsampledrateEditField.ValueDisplayFormat = '%.0f';
            app.DownsamplingfactorEditFieldLabel = uilabel(app.TracepostprocessingPanel);
            app.DownsamplingfactorEditFieldLabel.Text = 'Downsampling factor';
            app.DownsamplingfactorEditField = uieditfield(app.TracepostprocessingPanel, 'numeric');
            app.DownsamplingfactorEditField.ValueDisplayFormat = '%.0f';
            app.RingdownPanel = uipanel(app.ZiRingdownUIFigure);
            app.RingdownPanel.Title = 'Ringdown';
            app.EnabletriggeringButton = uibutton(app.RingdownPanel, 'state');
            app.EnabletriggeringButton.ValueChangedFcn = createCallbackFcn(app, @EnabletriggeringButtonValueChanged, true);
            app.EnabletriggeringButton.Text = 'Enable triggering';
            app.TriggerthresholdVEditFieldLabel = uilabel(app.RingdownPanel);
            app.TriggerthresholdVEditFieldLabel.Text = 'Trigger threshold (V)';
            app.TriggerthresholdVEditField = uieditfield(app.RingdownPanel, 'numeric');
            app.TriggerthresholdVEditField.Value = 1;
            app.RecordingLampLabel = uilabel(app.RingdownPanel);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.RingdownPanel);
            app.DriveLampLabel = uilabel(app.RingdownPanel);
            app.DriveLampLabel.Text = 'Drive';
            app.DriveLamp = uilamp(app.RingdownPanel);
            app.RecordtimesEditFieldLabel = uilabel(app.RingdownPanel);
            app.RecordtimesEditFieldLabel.Text = 'Record time (s)';
            app.RecordtimesEditField = uieditfield(app.RingdownPanel, 'numeric');
            app.RecordtimesEditField.Value = 150;
            app.TimeelapsedsEditFieldLabel = uilabel(app.RingdownPanel);
            app.TimeelapsedsEditFieldLabel.Text = 'Time elapsed (s)';
            app.TimeelapsedsEditField = uieditfield(app.RingdownPanel, 'numeric');
            app.TimeelapsedsEditField.ValueDisplayFormat = '%.1f';
            app.TimeelapsedsEditField.Editable = 'off';
            app.DemodulationoscillatorEditFieldLabel = uilabel(app.RingdownPanel);
            app.DemodulationoscillatorEditFieldLabel.Text = 'Demodulation oscillator';
            app.CurrentOscEditField = uieditfield(app.RingdownPanel, 'numeric');
            app.CurrentOscEditField.ValueDisplayFormat = '%.0f';
            app.CurrentOscEditField.Editable = 'off';
            app.DemodulatorspectrumPanel = uipanel(app.ZiRingdownUIFigure);
            app.DemodulatorspectrumPanel.Title = 'Demodulator spectrum';
            app.DemodUIAxes = uiaxes(app.DemodulatorspectrumPanel);
            xlabel(app.DemodUIAxes, 'Frequency (Hz)')
            ylabel(app.DemodUIAxes, 'PSD')
            app.DemodUIAxes.XLim = [0 1];
            app.DemodUIAxes.YScale = 'log';
            app.ylimitmodeLabel = uilabel(app.DemodulatorspectrumPanel);
            app.ylimitmodeLabel.Text = 'y limit mode';
            app.YLimModeDropDown = uidropdown(app.DemodulatorspectrumPanel);
            app.YLimModeDropDown.Items = {'auto', 'manual'};
            app.YLimModeDropDown.Value = 'manual';
            app.flimitmodeLabel = uilabel(app.DemodulatorspectrumPanel);
            app.flimitmodeLabel.Text = 'f limit mode';
            app.XLimModeDropDown = uidropdown(app.DemodulatorspectrumPanel);
            app.XLimModeDropDown.Items = {'auto', 'manual'};
            app.XLimModeDropDown.Value = 'manual';
            app.yminEditFieldLabel = uilabel(app.DemodulatorspectrumPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.DemodulatorspectrumPanel, 'numeric');
            app.ymaxEditFieldLabel = uilabel(app.DemodulatorspectrumPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.DemodulatorspectrumPanel, 'numeric');
            app.fmaxHzEditFieldLabel = uilabel(app.DemodulatorspectrumPanel);
            app.fmaxHzEditFieldLabel.Text = 'f max (Hz)';
            app.fmaxHzEditField = uieditfield(app.DemodulatorspectrumPanel, 'numeric');
            app.fmaxHzEditField.ValueChangedFcn = createCallbackFcn(app, @fmaxHzEditFieldValueChanged, true);
            app.FFTRBWHzEditFieldLabel = uilabel(app.DemodulatorspectrumPanel);
            app.FFTRBWHzEditFieldLabel.Text = 'FFT RBW (Hz)';
            app.FFTRBWHzEditField = uieditfield(app.DemodulatorspectrumPanel, 'numeric');
            app.FFTRBWHzEditField.Value = 10;
            app.FFTlengthptEditFieldLabel = uilabel(app.DemodulatorspectrumPanel);
            app.FFTlengthptEditFieldLabel.Text = 'FFT length (pt)';
            app.FFTlengthptEditField = uieditfield(app.DemodulatorspectrumPanel, 'numeric');
            app.FFTlengthptEditField.Value = 16;
            app.TraceUIAxes = uiaxes(app.ZiRingdownUIFigure);
            xlabel(app.TraceUIAxes, 'Time (s)')
            ylabel(app.TraceUIAxes, 'Magnitude r (V)')
            app.TraceUIAxes.XLim = [0 1];
            app.TraceUIAxes.YScale = 'log';
            app.AdaptiveFrequencyPanel = uipanel(app.ZiRingdownUIFigure);
            app.AdaptiveFrequencyPanel.Title = 'Adaptive demodulation frequency';
            app.AdaptivedemodCheckBox = uicheckbox(app.AdaptiveFrequencyPanel);
            app.AdaptivedemodCheckBox.Text = 'Enable';
            app.OscfollowingLampLabel = uilabel(app.AdaptiveFrequencyPanel);
            app.OscfollowingLampLabel.Text = 'Osc following';
            app.OscfollowingLamp = uilamp(app.AdaptiveFrequencyPanel);
            % Show the figure after all components are created
            app.ZiRingdownUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiZiRingdown(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZiRingdownUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZiRingdownUIFigure)
        end
    end
end