classdef GuiZiScopeFt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZiScopeFt                      matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        ScopenumberEditFieldLabel      matlab.ui.control.Label
        ScopenumberEditField           matlab.ui.control.NumericEditField
        SignalinputEditFieldLabel      matlab.ui.control.Label
        SignalinputEditField           matlab.ui.control.NumericEditField
        PlotPanel                      matlab.ui.container.Panel
        ylimitmodeLabel                matlab.ui.control.Label
        YLimModeDropDown               matlab.ui.control.DropDown
        yminEditFieldLabel             matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        ymaxEditField                  matlab.ui.control.NumericEditField
        LogYCheckBox                   matlab.ui.control.CheckBox
        LogXCheckBox                   matlab.ui.control.CheckBox
        AcquisitionPanel               matlab.ui.container.Panel
        ScopelengthptEditFieldLabel    matlab.ui.control.Label
        ScopelengthptEditField         matlab.ui.control.NumericEditField
        FFTRBWHzEditFieldLabel         matlab.ui.control.Label
        FFTRBWHzEditField              matlab.ui.control.NumericEditField
        SamplingrateptsEditFieldLabel  matlab.ui.control.Label
        SamplingrateptsEditField       matlab.ui.control.NumericEditField
        FFTlengthptEditFieldLabel      matlab.ui.control.Label
        FFTlengthptEditField           matlab.ui.control.NumericEditField
        AveragingPanel                 matlab.ui.container.Panel
        AvgtypeDropDownLabel           matlab.ui.control.Label
        AvgtypeDropDown                matlab.ui.control.DropDown
        navgEditFieldLabel             matlab.ui.control.Label
        navgEditField                  matlab.ui.control.NumericEditField
        CompletedEditFieldLabel        matlab.ui.control.Label
        CompletedEditField             matlab.ui.control.NumericEditField
        RestartaveragingButton         matlab.ui.control.Button
        TracetoDAQButton               matlab.ui.control.Button
        nchEditFieldLabel              matlab.ui.control.Label
        nchEditField                   matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        name = ''  % App name to clear on exit
        Listeners = struct()
    end
    
    properties (Access = private)
        % variable that is used to determine the axes scale during the first update
        init_axes = true
    end
    
    
    methods (Access = public)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
    
    end
    
    methods (Access = private)
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)
            linkGuiElement(app, app.ScopenumberEditField, '.Instr.n_scope');
            linkGuiElement(app, app.nchEditField, '.Instr.n_ch');
            linkGuiElement(app, app.SignalinputEditField, '.Instr.signal_in');
            linkGuiElement(app, app.AvgtypeDropDown, '.Instr.Trace.avg_type');
            linkGuiElement(app, app.navgEditField, '.Instr.Trace.n_avg');
            linkGuiElement(app, app.CompletedEditField, '.Instr.Trace.avg_count');
            linkGuiElement(app, app.FFTRBWHzEditField, '.Instr.fft_rbw');
            linkGuiElement(app, app.FFTlengthptEditField, '.Instr.Trace.x', ...
                'out_proc_fcn', @(x)length(x));
            
            linkGuiElement(app, app.SamplingrateptsEditField, '.Instr.scope_rate');
            linkGuiElement(app, app.ScopelengthptEditField, '.Instr.n_pt');
            
            % Axes limits
            linkGuiElement(app, app.yminEditField, '.UIAxes.YLim(1)');
            linkGuiElement(app, app.ymaxEditField, '.UIAxes.YLim(2)');
            linkGuiElement(app, app.YLimModeDropDown, '.UIAxes.YLimMode');
            linkGuiElement(app, app.LogYCheckBox, '.UIAxes.YScale', ...
                'in_proc_fcn', @(x)select(x, 'log', 'linear'), ...
                'out_proc_fcn', @(x)strcmpi(x,'log'));
            linkGuiElement(app, app.LogXCheckBox, '.UIAxes.XScale', ...
                'in_proc_fcn', @(x)select(x, 'log', 'linear'), ...
                'out_proc_fcn', @(x)strcmpi(x,'log'));
        end
    
        function updatePlot(app)
            % Plot the trace using its class method
            plot(app.Instr.Trace, app.UIAxes);
            % Set x limits manually to beautify dynamic updating
            fmax=app.Instr.scope_rate/2;
            app.UIAxes.XLim=[0, fmax];
            
            if app.init_axes && ~isempty(app.Instr.Trace.y)
                app.init_axes=false;
                % Set limits converting 'single' trace y to 'double'
                app.UIAxes.YLim=double([min(app.Instr.Trace.y),max(app.Instr.Trace.y)]);
            end
            
            updateLinkedElement(app, app.CompletedEditField);
            updateLinkedElement(app, app.FFTRBWHzEditField);
            updateLinkedElement(app, app.ScopelengthptEditField);
            updateLinkedElement(app, app.SamplingrateptsEditField);
            updateLinkedElement(app, app.FFTlengthptEditField);
            
            % Update the plot-related controls
            updateLinkedElement(app, app.yminEditField);
            updateLinkedElement(app, app.ymaxEditField);
            updateLinkedElement(app, app.LogXCheckBox);
            updateLinkedElement(app, app.LogYCheckBox);
            updateLinkedElement(app, app.YLimModeDropDown);
        end
        
        function updateGui(app)
            updateLinkedGuiElements(app)
            updatePlot(app)
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            createLinks(app);
            
            app.Listeners.NewWave=addlistener(app.Instr,'NewWave',@(~,~)updatePlot(app));
            app.Listeners.NewData=addlistener(app.Instr,'NewData',@(~,~)updateGui(app));
            app.Listeners.NewSetting=addlistener(app.Instr,'NewSetting',@(~,~)updateGui(app));
            
            app.Instr.Trace.avg_type='exp';
            
            updateGui(app);
            
            % Start continuous data acquisition
            startPoll(app.Instr);
        end

        % Button pushed function: TracetoDAQButton
        function TracetoDAQButtonPushed(app, event)
            % Send the present trace to Daq
            triggerNewData(app.Instr);
        end

        % Button pushed function: RestartaveragingButton
        function RestartaveragingButtonPushed(app, event)
            clearData(app.Instr.Trace);
        end

        % Close request function: ZiScopeFt
        function ZiScopeFtCloseRequest(app, event)
            % Delete instrument object, clear global variable and delete app
            deleteInstrGui(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZiScopeFt = uifigure;
            app.ZiScopeFt.Name = 'Zi scope Fourier transform';
            app.ZiScopeFt.CloseRequestFcn = createCallbackFcn(app, @ZiScopeFtCloseRequest, true);
            app.UIAxes = uiaxes(app.ZiScopeFt);
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'PSD (V^2/Hz)')
            app.UIAxes.YScale = 'log';
            app.ScopenumberEditFieldLabel = uilabel(app.ZiScopeFt);
            app.ScopenumberEditFieldLabel.Text = 'Scope number';
            app.ScopenumberEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.ScopenumberEditField.Editable = 'off';
            app.ScopenumberEditField.Value = 1;
            app.SignalinputEditFieldLabel = uilabel(app.ZiScopeFt);
            app.SignalinputEditFieldLabel.Text = 'Signal input';
            app.SignalinputEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.SignalinputEditField.Editable = 'off';
            app.SignalinputEditField.Value = 1;
            app.PlotPanel = uipanel(app.ZiScopeFt);
            app.PlotPanel.Title = 'Plot';
            app.ylimitmodeLabel = uilabel(app.PlotPanel);
            app.ylimitmodeLabel.Text = 'y limit mode';
            app.YLimModeDropDown = uidropdown(app.PlotPanel);
            app.YLimModeDropDown.Items = {'auto', 'manual'};
            app.YLimModeDropDown.Value = 'manual';
            app.yminEditFieldLabel = uilabel(app.PlotPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.PlotPanel, 'numeric');
            app.yminEditField.Value = 0.1;
            app.ymaxEditFieldLabel = uilabel(app.PlotPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.PlotPanel, 'numeric');
            app.ymaxEditField.Value = 1;
            app.LogYCheckBox = uicheckbox(app.PlotPanel);
            app.LogYCheckBox.Text = 'Log Y';
            app.LogXCheckBox = uicheckbox(app.PlotPanel);
            app.LogXCheckBox.Text = 'Log X';
            app.AcquisitionPanel = uipanel(app.ZiScopeFt);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.ScopelengthptEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.ScopelengthptEditFieldLabel.Text = 'Scope length (pt)';
            app.ScopelengthptEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.ScopelengthptEditField.ValueDisplayFormat = '%.0f';
            app.ScopelengthptEditField.Value = 5000000;
            app.FFTRBWHzEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FFTRBWHzEditFieldLabel.Text = 'FFT RBW (Hz)';
            app.FFTRBWHzEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FFTRBWHzEditField.Editable = 'off';
            app.SamplingrateptsEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.SamplingrateptsEditFieldLabel.Text = 'Sampling rate (pt/s)';
            app.SamplingrateptsEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FFTlengthptEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FFTlengthptEditFieldLabel.Text = 'FFT length (pt)';
            app.FFTlengthptEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FFTlengthptEditField.ValueDisplayFormat = '%.0f';
            app.FFTlengthptEditField.Editable = 'off';
            app.AveragingPanel = uipanel(app.ZiScopeFt);
            app.AveragingPanel.Title = 'Averaging';
            app.AvgtypeDropDownLabel = uilabel(app.AveragingPanel);
            app.AvgtypeDropDownLabel.Text = 'Avg type';
            app.AvgtypeDropDown = uidropdown(app.AveragingPanel);
            app.AvgtypeDropDown.Items = {'linear', 'exp'};
            app.AvgtypeDropDown.ItemsData = {'lin', 'exp'};
            app.AvgtypeDropDown.Value = 'lin';
            app.navgEditFieldLabel = uilabel(app.AveragingPanel);
            app.navgEditFieldLabel.Text = 'n avg';
            app.navgEditField = uieditfield(app.AveragingPanel, 'numeric');
            app.navgEditField.ValueDisplayFormat = '%.0f';
            app.navgEditField.Value = 10000;
            app.CompletedEditFieldLabel = uilabel(app.AveragingPanel);
            app.CompletedEditFieldLabel.Text = 'Completed';
            app.CompletedEditField = uieditfield(app.AveragingPanel, 'numeric');
            app.CompletedEditField.ValueDisplayFormat = '%.0f';
            app.CompletedEditField.Editable = 'off';
            app.RestartaveragingButton = uibutton(app.AveragingPanel, 'push');
            app.RestartaveragingButton.ButtonPushedFcn = createCallbackFcn(app, @RestartaveragingButtonPushed, true);
            app.RestartaveragingButton.Text = 'Restart averaging';
            app.TracetoDAQButton = uibutton(app.AveragingPanel, 'push');
            app.TracetoDAQButton.ButtonPushedFcn = createCallbackFcn(app, @TracetoDAQButtonPushed, true);
            app.TracetoDAQButton.Text = 'Trace to DAQ';
            app.nchEditFieldLabel = uilabel(app.ZiScopeFt);
            app.nchEditFieldLabel.Text = 'n ch';
            app.nchEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.nchEditField.Editable = 'off';
            app.nchEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiZiScopeFt(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZiScopeFt)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZiScopeFt)
        end
    end
end