classdef aGuiTemplate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TemplateUIFigure      matlab.ui.Figure
        RefreshpanelButton    matlab.ui.control.Button
        FindinstrumentButton  matlab.ui.control.Button
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        instance_name = '' ; % App name to clear on exit;
    end

    methods (Access = private)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
        
        % Lineked control elements are updated by calling updateGui(app)
        function createControlLinks(app)
            linkControlElement(app, app.element, 'instr_property');
            %....
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, interface, address, varargin)
            % Start the Gui with specified instrument class
            % Assign the instance name to the class variable and the instrument name to the window
            genericInitGui(app, 'MyDpo', interface, address, varargin{:});
            
            % initialization of dropdown menus (convert text to numbers if necessary)
            app.DropDown.ItemsData = str2double(app.DropDown.Items);
         
            createControlLinks(app);
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Button pushed function: FindinstrumentButton
        function FindinstrumentButtonPushed(app, event)
            findInstrumentCallback(app);
        end

        % Close request function: TemplateUIFigure
        function TemplateUIFigureCloseRequest(app, event)
            delete(app.Instr);
            evalin('base', sprintf('clear(''%s'')',app.instance_name));
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TemplateUIFigure = uifigure;
            app.TemplateUIFigure.Name = 'Template';
            app.TemplateUIFigure.CloseRequestFcn = createCallbackFcn(app, @TemplateUIFigureCloseRequest, true);
            app.RefreshpanelButton = uibutton(app.TemplateUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.FindinstrumentButton = uibutton(app.TemplateUIFigure, 'push');
            app.FindinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @FindinstrumentButtonPushed, true);
            app.FindinstrumentButton.Text = 'Find instrument';
        end
    end

    methods (Access = public)

        % Construct app
        function app = aGuiTemplate(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TemplateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TemplateUIFigure)
        end
    end
end