classdef GuiAmplModCal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlphacalibrationUIFigure        matlab.ui.Figure
        CalibrateButton                 matlab.ui.control.Button
        ClearpeaksButton                matlab.ui.control.Button
        PeakthresholdcursorCheckBox     matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
        ModulationfrequencyHzEditFieldLabel  matlab.ui.control.Label
        ModulationfrequencyHzEditField  matlab.ui.control.NumericEditField
        ModulationdepthalphaEditFieldLabel  matlab.ui.control.Label
        ModulationdepthalphaEditField   matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        GuiSync
        AlphaCal
    end

    methods (Access = private)
        function createLinks(app)
            addLink(app.GuiSync, app.ModulationdepthalphaEditField,  'AlphaCal.alpha');
            addLink(app.GuiSync, app.ModulationfrequencyHzEditField, 'AlphaCal.mod_freq');
            addLink(app.GuiSync, app.PeakthresholdcursorCheckBox,    'AlphaCal.enable_cursor');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AlphaCalObj)
            app.AlphaCal = AlphaCalObj;
            app.GuiSync = MyGuiSync(app, 'KernelObj', AlphaCalObj);
            
            createLinks(app); 
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            calcAlpha(app.AlphaCal);
        end

        % Button pushed function: ClearpeaksButton
        function ClearpeaksButtonPushed(app, event)
            clearPeakDisp(app.AlphaCal);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlphacalibrationUIFigure = uifigure('Visible', 'off');
            app.AlphacalibrationUIFigure.Name = 'Alpha calibration';
            app.CalibrateButton = uibutton(app.AlphacalibrationUIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ClearpeaksButton = uibutton(app.AlphacalibrationUIFigure, 'push');
            app.ClearpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ClearpeaksButtonPushed, true);
            app.ClearpeaksButton.Text = 'Clear peaks';
            app.PeakthresholdcursorCheckBox = uicheckbox(app.AlphacalibrationUIFigure);
            app.PeakthresholdcursorCheckBox.Text = 'Peak threshold cursor';
            app.Image = uiimage(app.AlphacalibrationUIFigure);
            app.Image.ImageSource = 'alpha_def.png';
            app.ModulationfrequencyHzEditFieldLabel = uilabel(app.AlphacalibrationUIFigure);
            app.ModulationfrequencyHzEditFieldLabel.Text = 'Modulation frequency (Hz)';
            app.ModulationfrequencyHzEditField = uieditfield(app.AlphacalibrationUIFigure, 'numeric');
            app.ModulationdepthalphaEditFieldLabel = uilabel(app.AlphacalibrationUIFigure);
            app.ModulationdepthalphaEditFieldLabel.Text = 'Modulation depth alpha';
            app.ModulationdepthalphaEditField = uieditfield(app.AlphacalibrationUIFigure, 'numeric');
            app.ModulationdepthalphaEditField.Editable = 'off';
            % Show the figure after all components are created
            app.AlphacalibrationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiAmplModCal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlphacalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlphacalibrationUIFigure)
        end
    end
end