classdef GuiAmplNoiseCal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationinfrequencyunitsUIFigure  matlab.ui.Figure
        ModulationdepthalphaEditFieldLabel  matlab.ui.control.Label
        ModulationdepthalphaEditField   matlab.ui.control.NumericEditField
        CalculatecfButton               matlab.ui.control.Button
        ConvertspectrumButton           matlab.ui.control.Button
        ConversionfactorEditFieldLabel  matlab.ui.control.Label
        ConversionfactorEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        GuiSync
        Anc
    end

    methods (Access = private)
        function createLinks(app)         
            addLink(app.GuiSync, app.ModulationdepthalphaEditField,  'Anc.alpha');
            addLink(app.GuiSync, app.ConversionfactorEditField,      'Anc.cf');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AncObj)
            app.Anc = AncObj;
            app.GuiSync = MyGuiSync(app, 'KernelObj', AncObj);
            createLinks(app); 
        end

        % Button pushed function: CalculatecfButton
        function CalculatecfButtonPushed(app, event)
            calcConvFactor(app.Anc);
        end

        % Button pushed function: ConvertspectrumButton
        function ConvertspectrumButtonPushed(app, event)
            convertSpectrum(app.Anc);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationinfrequencyunitsUIFigure = uifigure('Visible', 'off');
            app.CalibrationinfrequencyunitsUIFigure.Name = 'Calibration in frequency units';
            app.ModulationdepthalphaEditFieldLabel = uilabel(app.CalibrationinfrequencyunitsUIFigure);
            app.ModulationdepthalphaEditFieldLabel.Text = 'Modulation depth alpha';
            app.ModulationdepthalphaEditField = uieditfield(app.CalibrationinfrequencyunitsUIFigure, 'numeric');
            app.CalculatecfButton = uibutton(app.CalibrationinfrequencyunitsUIFigure, 'push');
            app.CalculatecfButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatecfButtonPushed, true);
            app.CalculatecfButton.Tooltip = {'Calculate conversion factor from S_V to S_\omega'};
            app.CalculatecfButton.Text = 'Calculate cf';
            app.ConvertspectrumButton = uibutton(app.CalibrationinfrequencyunitsUIFigure, 'push');
            app.ConvertspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertspectrumButtonPushed, true);
            app.ConvertspectrumButton.Text = 'Convert spectrum';
            app.ConversionfactorEditFieldLabel = uilabel(app.CalibrationinfrequencyunitsUIFigure);
            app.ConversionfactorEditFieldLabel.Text = 'Conversion factor';
            app.ConversionfactorEditField = uieditfield(app.CalibrationinfrequencyunitsUIFigure, 'numeric');
            app.ConversionfactorEditField.Editable = 'off';
            app.ConversionfactorEditField.Tooltip = {'RIN = cf*S_V'};
            app.ConversionfactorEditField.Value = 1.446e-12;
            % Show the figure after all components are created
            app.CalibrationinfrequencyunitsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiAmplNoiseCal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationinfrequencyunitsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationinfrequencyunitsUIFigure)
        end
    end
end