classdef DiodeLaserScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiodelaserscanUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ScanTab                         matlab.ui.container.Tab
        SignalAxes                      matlab.ui.control.UIAxes
        ScopechannelsPanel              matlab.ui.container.Panel
        SignalDropDownLabel             matlab.ui.control.Label
        SignalDropDown                  matlab.ui.control.DropDown
        WavelengthoutDropDownLabel      matlab.ui.control.Label
        WavelengthoutDropDown           matlab.ui.control.DropDown
        ScanPanel                       matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        StartwavelengthnmEditFieldLabel  matlab.ui.control.Label
        StartwlnmEditField              matlab.ui.control.NumericEditField
        StopwavelengthnmEditFieldLabel  matlab.ui.control.Label
        StopwlnmEditField               matlab.ui.control.NumericEditField
        LasertostartwlButton            matlab.ui.control.Button
        SpeednmsEditFieldLabel          matlab.ui.control.Label
        SpeednmsEditField               matlab.ui.control.NumericEditField
        StopButton                      matlab.ui.control.Button
        MaxspeedLabel                   matlab.ui.control.Label
        RequireswavelengthtrackonLabel  matlab.ui.control.Label
        SelectinstrumentsTab            matlab.ui.container.Tab
        ScopeDropDownLabel              matlab.ui.control.Label
        ScopeDropDown                   matlab.ui.control.DropDown
        LaserDropDownLabel              matlab.ui.control.Label
        LaserDropDown                   matlab.ui.control.DropDown
        SelectinstrumentfromInstrumentListLabel  matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
    end


    properties (Access = public)
        name = 'DiodeLaserScan';
        Laser % Diode laser instrument object
        Scope % Oscilloscope instrument object
        linked_elem_list={}
        ConstructionParser
        
        SigVsTimeTrace % Signal versus time trace
        WlVsTimeTrace % Wavelength vs time trace
        Trace % Signal versus Wavelength. This is the trace that is transferred to Daq
    end
    
    properties (SetAccess = private, GetAccess = public)
        InstrumentList; % Private copy of InstrumentList
        ScanTimer;
    end
    
    events
        NewData
    end
    
    methods (Access = public)
        
        % Send headers to Collector
        function Hdr=readHeader(app)
            Hdr=MyMetadata();
            field_name=genvarname(app.name);
            addField(Hdr, field_name);
            
            try
                addParam(Hdr, field_name, 'laser', app.Laser.name);
            catch
                addParam(Hdr, field_name, 'laser', '');
            end
            try
                addParam(Hdr, field_name, 'scope', app.Scope.name);
            catch
                addParam(Hdr, field_name, 'scope', '');
            end
        end
    
    end

    methods (Access = private)
        
        % Lineked control elements are updated by calling updateGui(app)
        function createControlLinks(app)
            app.StartwlnmEditField.Tag = 'Laser.scan_start_wl';
            app.linked_elem_list = [app.linked_elem_list, app.StartwlnmEditField];
            
            app.StopwlnmEditField.Tag = 'Laser.scan_stop_wl';
            app.linked_elem_list = [app.linked_elem_list, app.StopwlnmEditField];
            
            app.SpeednmsEditField.Tag = 'Laser.scan_speed';
            app.linked_elem_list = [app.linked_elem_list, app.SpeednmsEditField];
        end
    
        function p = createConstructionParser(app)
            p=inputParser();
            % Ignore unmatched parameters
            p.KeepUnmatched = true;
            addParameter(p,'Laser','');
            addParameter(p,'Scope','');
            addParameter(p,'name','',@ischar);
            app.ConstructionParser=p;
        end
    
        function parseClassInputs(app,varargin)
            p=app.ConstructionParser;
            parse(p,varargin{:});
            
            app.name=p.Results.name;
            
            if ~ismember('Scope',p.UsingDefaults)
                app.ScopeDropDown.Value=p.Results.Scope;
                try
                    app.Scope = runInstrumentWithGui(p.Results.Scope);
                catch
                    warning('Could not run %s',entry_name)
                end
            end
            if ~ismember('Laser',p.UsingDefaults)
                app.LaserDropDown.Value=p.Results.Laser;
                try
                    app.Laser = runInstrumentWithGui(p.Results.Laser);
                catch
                    warning('Could not run %s',entry_name)
                end
            end
        end
    
        function acquireScanResult(app)
            % Unblock the scan panel
            updateScanPanelAccess(app,false);
            L=app.Laser;
            S=app.Scope;
            try
                % Read signal vs time
                sig_ch = app.SignalDropDown.Value;
                writePropertyHedged(S,'channel',sig_ch);
                openDevice(S);
                readTrace(S);
                app.SigVsTimeTrace = copy(S.Trace);
                % Read wavelength voltage vs time
                wl_ch = app.WavelengthoutDropDown.Value;
                writePropertyHedged(S,'channel',wl_ch);
                readTrace(S);
                closeDevice(S);
                app.WlVsTimeTrace = copy(S.Trace);
            catch
                warning('Cannot acquire scope traces.');
                return
            end
            try
                tag=sprintf('scale%i',sig_ch);
                % For Dpo scopes v<v_min_lim if the trace is aquired partially
                v_min_lim=-5*S.(tag);
                v_max_lim=5*S.(tag);
                sel_ind=arrayfun(@(x)(x>v_min_lim && x<v_max_lim),app.SigVsTimeTrace.y);
                app.Trace.y=app.SigVsTimeTrace.y(sel_ind);
                
                % Calibrate wavelength from voltage in the auxiliary channel
                v_min=min(app.WlVsTimeTrace.y(sel_ind));
                v_max=max(app.WlVsTimeTrace.y(sel_ind));
                sc=(L.scan_stop_wl-L.scan_start_wl)/(v_max-v_min);
                app.Trace.x=(app.WlVsTimeTrace.y(sel_ind)-v_min)*sc+L.scan_start_wl;
            catch
                warning('Cannot calibrate wavelength from voltage.');
                return
            end
            try
                plotTrace(app.Trace, app.SignalAxes);
            catch
                warning('Cannot plot trace.');
            end
            % Trigger event that Daq and Collector listen to to transfer data
            notify(app,'NewData');
        end
    
        function updateInstrDropDowns(app)
            try
                app.InstrumentList = getLocalSettings('InstrumentList');
            catch
                warning('Could not get local InstrumentList. Gui drop-downs are not updated');
                return
            end
            menu = [{'Select instrument ...'};fieldnames(app.InstrumentList)];
            app.ScopeDropDown.Items = menu;
            app.LaserDropDown.Items = menu;
        end
        
        function updateScopeChannelDropDowns(app)
            try
                ch_arr=1:app.Scope.N_CHANNELS;
                app.SignalDropDown.Items=arrayfun(@(x)sprintf('%i',x),ch_arr,...
                    'UniformOutput',false);
                app.SignalDropDown.ItemsData=ch_arr;
                
                app.WavelengthoutDropDown.Items=arrayfun(@(x)sprintf('%i',x),ch_arr,...
                    'UniformOutput',false);
                app.WavelengthoutDropDown.ItemsData=ch_arr;
            catch
            end
        end
    
        function updateScanPanelAccess(app, is_scanning)
            if is_scanning
                app.StartButton.Enable='off';
                app.StartwlnmEditField.Enable='off';
                app.StopwlnmEditField.Enable='off';
                app.LasertostartwlButton.Enable='off';
                app.SpeednmsEditField.Enable='off';
                app.ScopeDropDown.Enable='off';
                app.LaserDropDown.Enable='off';
            else
                app.StartButton.Enable='on';
                app.StartwlnmEditField.Enable='on';
                app.StopwlnmEditField.Enable='on';
                app.LasertostartwlButton.Enable='on';
                app.SpeednmsEditField.Enable='on';
                app.ScopeDropDown.Enable='on';
                app.LaserDropDown.Enable='on';
            end
        end
            
        function updateGui(app)
            try
                updateLinkedGuiElements(app);
            catch
            end
            % Update display of the laser maximum scan speed
            try
                app.MaxspeedLabel.Text=sprintf('Max speed: %.1f nm/s',app.Laser.scan_speed_max);
            catch
                app.MaxspeedLabel.Text='Max speed:';
            end
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            updateInstrDropDowns(app);
            createConstructionParser(app);
            %Loads parsed variables into class properties
            parseClassInputs(app,varargin{:});
            createControlLinks(app);
            
            % Create an empty signal-vs-wavelength trace
            app.Trace=MyTrace('name_x','Wavelength',...
                'unit_x','nm','name_y','Voltage','unit_y','V');
            
            %Fill dopdowns with local instrument names
            updateScopeChannelDropDowns(app);
            updateGui(app);
            
            app.ScanTimer=timer();
            app.ScanTimer.ExecutionMode='singleShot';
            app.ScanTimer.TimerFcn=@(~,~)acquireScanResult(app);
        end

        % Close request function: DiodelaserscanUIFigure
        function DiodelaserscanUIFigureCloseRequest(app, event)
            % Do not delete laser and scope objects on exit but leave them running
            % Clean up variable to which app handle is assigned in the global workspace
            try
                evalin('base', sprintf('clear(''%s'')', app.name));
            catch
            end
            try 
                stop(app.ScanTimer);
                delete(app.ScanTimer);
            catch
            end
            delete(app)
        end

        % Value changed function: SpeednmsEditField, 
        % StartwlnmEditField, StopwlnmEditField
        function LaserParameterChanged(app, event)
            val = event.Value;
            prop = sscanf(event.Source.Tag, 'Laser.%s');
            try
                writePropertyHedged(app.Laser, prop, val);
            catch
                warning('Property ''%s'' cannot be set',prop);
            end
            updateGui(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            L=app.Laser;
            S=app.Scope;
            try
                % Estimated scan time (s)
                scan_time = (L.scan_stop_wl-L.scan_start_wl)/L.scan_speed; 
                
                % Adjust scope span accordingly
                % time_scale (s/div), overall scan length is 10 divisions
                writePropertyHedged(S,'time_scale',scan_time/10);
                if S.time_scale*10<scan_time
                    % Some scopes do not accept arbitrary time scale.
                    % If time scale was rounded to smaller one, increase by the factor of 2
                    writePropertyHedged(S,'time_scale',2*scan_time/10);
                end
            catch
                warning('Cannot configure scan timescale');
                return
            end
            try
                configSingleScan(L)
                % Initite laser scan and trigger 
                % signal acquisition by the the scope 
                startScan(L);
                % Trigger the scope for single acquisition
                acquireSingle(S);
            catch
                warning('Cannot configure or initiate laser scan')
                return
            end
            % Start timer that executes only once when the nominal 
            % scan time is elapced
            app.ScanTimer.StartDelay=round(scan_time,3);
            start(app.ScanTimer);
            updateScanPanelAccess(app,true);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
                stopScan(app.Laser);
                stop(app.ScanTimer);
                updateScanPanelAccess(app,false);
            catch
                warning('Unable to stop laser scan')
            end
        end

        % Button pushed function: LasertostartwlButton
        function LasertostartwlButtonPushed(app, event)
            writePropertyHedged(app.Laser,'wavelength_sp', app.Laser.scan_start_wl);
        end

        % Value changed function: ScopeDropDown
        function ScopeDropDownValueChanged(app, event)
            entry_name = app.ScopeDropDown.Value;
            if ismember(entry_name,fieldnames(app.InstrumentList))
                try
                    app.Scope = runInstrumentWithGui(entry_name);
                catch
                    warning('Could not run %s',entry_name)
                end
            end
            updateScopeChannelDropDowns(app);
            updateGui(app);
        end

        % Value changed function: LaserDropDown
        function LaserDropDownValueChanged(app, event)
            entry_name = app.LaserDropDown.Value;
            if ismember(entry_name,fieldnames(app.InstrumentList))
                try
                    app.Laser = runInstrumentWithGui(entry_name);
                catch
                    warning('Could not run %s',entry_name)
                end
            end
            updateGui(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            updateGui(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiodelaserscanUIFigure = uifigure;
            app.DiodelaserscanUIFigure.Name = 'Diode laser scan';
            app.DiodelaserscanUIFigure.CloseRequestFcn = createCallbackFcn(app, @DiodelaserscanUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.DiodelaserscanUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.SignalAxes = uiaxes(app.ScanTab);
            xlabel(app.SignalAxes, 'Wavelength (nm)')
            ylabel(app.SignalAxes, 'V')
            app.ScopechannelsPanel = uipanel(app.ScanTab);
            app.ScopechannelsPanel.Title = 'Scope channels';
            app.SignalDropDownLabel = uilabel(app.ScopechannelsPanel);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.ScopechannelsPanel);
            app.SignalDropDown.Items = {'1', '2'};
            app.SignalDropDown.Value = '1';
            app.WavelengthoutDropDownLabel = uilabel(app.ScopechannelsPanel);
            app.WavelengthoutDropDownLabel.Text = 'Wavelength out';
            app.WavelengthoutDropDown = uidropdown(app.ScopechannelsPanel);
            app.WavelengthoutDropDown.Items = {'1', '2'};
            app.WavelengthoutDropDown.Value = '2';
            app.ScanPanel = uipanel(app.ScanTab);
            app.ScanPanel.Title = 'Scan';
            app.StartButton = uibutton(app.ScanPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StartwavelengthnmEditFieldLabel = uilabel(app.ScanPanel);
            app.StartwavelengthnmEditFieldLabel.Text = 'Start wavelength (nm)';
            app.StartwlnmEditField = uieditfield(app.ScanPanel, 'numeric');
            app.StartwlnmEditField.ValueDisplayFormat = '%.12g';
            app.StartwlnmEditField.ValueChangedFcn = createCallbackFcn(app, @LaserParameterChanged, true);
            app.StopwavelengthnmEditFieldLabel = uilabel(app.ScanPanel);
            app.StopwavelengthnmEditFieldLabel.Text = 'Stop wavelength (nm)';
            app.StopwlnmEditField = uieditfield(app.ScanPanel, 'numeric');
            app.StopwlnmEditField.ValueDisplayFormat = '%.12g';
            app.StopwlnmEditField.ValueChangedFcn = createCallbackFcn(app, @LaserParameterChanged, true);
            app.LasertostartwlButton = uibutton(app.ScanPanel, 'push');
            app.LasertostartwlButton.ButtonPushedFcn = createCallbackFcn(app, @LasertostartwlButtonPushed, true);
            app.LasertostartwlButton.Text = 'Laser to start wl';
            app.SpeednmsEditFieldLabel = uilabel(app.ScanPanel);
            app.SpeednmsEditFieldLabel.Text = 'Speed (nm/s)';
            app.SpeednmsEditField = uieditfield(app.ScanPanel, 'numeric');
            app.SpeednmsEditField.ValueDisplayFormat = '%.12g';
            app.SpeednmsEditField.ValueChangedFcn = createCallbackFcn(app, @LaserParameterChanged, true);
            app.StopButton = uibutton(app.ScanPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.MaxspeedLabel = uilabel(app.ScanPanel);
            app.MaxspeedLabel.Text = 'Max speed: 0.0 nm/s';
            app.RequireswavelengthtrackonLabel = uilabel(app.ScanPanel);
            app.RequireswavelengthtrackonLabel.Text = 'Requires wavelength track on';
            app.SelectinstrumentsTab = uitab(app.TabGroup);
            app.SelectinstrumentsTab.Title = 'Select instruments';
            app.ScopeDropDownLabel = uilabel(app.SelectinstrumentsTab);
            app.ScopeDropDownLabel.Text = 'Scope';
            app.ScopeDropDown = uidropdown(app.SelectinstrumentsTab);
            app.ScopeDropDown.Items = {'Select instrument ...'};
            app.ScopeDropDown.ValueChangedFcn = createCallbackFcn(app, @ScopeDropDownValueChanged, true);
            app.ScopeDropDown.Value = 'Select instrument ...';
            app.LaserDropDownLabel = uilabel(app.SelectinstrumentsTab);
            app.LaserDropDownLabel.Text = 'Laser';
            app.LaserDropDown = uidropdown(app.SelectinstrumentsTab);
            app.LaserDropDown.Items = {'Select instrument ...'};
            app.LaserDropDown.ValueChangedFcn = createCallbackFcn(app, @LaserDropDownValueChanged, true);
            app.LaserDropDown.Value = 'Select instrument ...';
            app.SelectinstrumentfromInstrumentListLabel = uilabel(app.SelectinstrumentsTab);
            app.SelectinstrumentfromInstrumentListLabel.Text = 'Select instrument from InstrumentList';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Label = uilabel(app.HelpTab);
            app.Label.Text = {'This program scans wavelength of ECDL laser and records transmission vs wavelength'; 'Photodiode and Wavelength output of ECDL need to be connected to oscilloscope channels'; ''; 'It is preferable to perform laser scan in constant current mode, or otherwise set scan speed to be <1% of max scan speed '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = DiodeLaserScan(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiodelaserscanUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiodelaserscanUIFigure)
        end
    end
end