classdef GuiDrivenMechDispCal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MechanicaldisplacementcalibrationUIFigure  matlab.ui.Figure
        CalibrateButton                 matlab.ui.control.Button
        ClearpeaksButton                matlab.ui.control.Button
        PeakthresholdcursorCheckBox     matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
        MechanicalfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MechanicalfrequencyHzEditField  matlab.ui.control.NumericEditField
        QuadratureangledegEditFieldLabel  matlab.ui.control.Label
        QuadratureangledegEditField     matlab.ui.control.NumericEditField
        OpticalwavelengthnmEditFieldLabel  matlab.ui.control.Label
        OpticalwavelengthnmEditField    matlab.ui.control.NumericEditField
        OscillationamplitudenmEditFieldLabel  matlab.ui.control.Label
        OscillationamplitudenmEditField  matlab.ui.control.NumericEditField
        CalibrationFactormV2Label       matlab.ui.control.Label
        CalibrationFactormV2EditField   matlab.ui.control.NumericEditField
        ConfintmV2EditFieldLabel        matlab.ui.control.Label
        ConfintmV2EditField             matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        GuiSync
        BetaCal
    end

    methods (Access = private)
        function createLinks(app)
            addLink(app.GuiSync, app.QuadratureangledegEditField,   'BetaCal.theta');
            addLink(app.GuiSync, app.MechanicalfrequencyHzEditField, 'BetaCal.mod_freq');
            addLink(app.GuiSync, app.OpticalwavelengthnmEditField, 'BetaCal.lambda');
            addLink(app.GuiSync, app.OscillationamplitudenmEditField,   'BetaCal.x0');
            addLink(app.GuiSync, app.CalibrationFactormV2EditField,    'BetaCal.disp_cal');
            addLink(app.GuiSync, app.ConfintmV2EditField,    'BetaCal.disp_cal_err');
            addLink(app.GuiSync, app.PeakthresholdcursorCheckBox,    'BetaCal.enable_cursor');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BetaCalObj)
            app.BetaCal = BetaCalObj;
            app.GuiSync = MyGuiSync(app, 'KernelObj', BetaCalObj);
%             app.CalibrationFactormV2Label.Interpreter = 'latex';
%             app.CalibrationFactormV2Label.Text = 'Calibration factor $(m/V)^2$';
            set(app.UIAxes, 'yscale', 'log');
            set(app.UIAxes, 'ylim', [1e-7 2]);
            createLinks(app); 
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            calcBeta(app.BetaCal);
            if (app.OpticalwavelengthnmEditField.Value~= 0) && (app.OscillationamplitudenmEditField.Value ~=0)
                %Update Bessel functions plot
                app.UIAxes.ColorOrderIndex = 1; %Reset the plot series colors;
                sb_pow = app.BetaCal.sb_pow;
                sb_n = app.BetaCal.sb_n;
                beta_cal = app.BetaCal.beta;
                theta = pi*app.BetaCal.theta/180;
                beta_ax = linspace(0.1,8,1000);
                
                %Rescale the constant normalization factor
                
                [~,max_ind] = max(sb_pow);
                sb_max = sb_n(max_ind);
                norm_fac = (besselj(sb_max, beta_cal)^2)*(sin(theta + sb_max*pi/2)^2);
                sb_pow = norm_fac*sb_pow;
                
                %Prepare plot axes
                
                cla(app.UIAxes);                
                set(app.UIAxes, 'ylim', [0.1*min(sb_pow)/max(sin(theta)^2,cos(theta)^2), 1.5*max(sb_pow)/min(sin(theta)^2,cos(theta)^2)]);
                set(app.UIAxes, 'xlim', [0.1, max(3,2*beta_cal)]);
                hold(app.UIAxes, 'on');                
                
                for n = 1:max(sb_n) %Plot of Bessel functions
                    plot(app.UIAxes,beta_ax, besselj(n,beta_ax).^2, 'LineWidth', 1.5);
                end
                
                app.UIAxes.ColorOrderIndex = 1; %Reset the plot series colors;
                for n = 1:max(sb_n) %Plot modulation index vs. peak values
                    if(any(sb_n == n))
                        if mod(n,2) ~= 0
                            plot(app.UIAxes, beta_cal, sb_pow(sb_n == n)/(cos(theta)^2), 'o',...
                                'Markersize', 5, 'LineWidth', 1);
                        else
                            plot(app.UIAxes, beta_cal, sb_pow(sb_n == n)/(sin(theta)^2), 'o',...
                                'Markersize', 5, 'LineWidth', 1);
                        end
                    end
                end
                
                hold(app.UIAxes, 'off');
                xline(app.UIAxes,beta_cal, '--', 'LineWidth', 1);
           end

        end

        % Button pushed function: ClearpeaksButton
        function ClearpeaksButtonPushed(app, event)
            clearPeakDisp(app.BetaCal);

        end

        % Value changed function: OscillationamplitudenmEditField
        function OscillationamplitudenmEditFieldValueChanged(app, event)
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MechanicaldisplacementcalibrationUIFigure = uifigure('Visible', 'off');
            app.MechanicaldisplacementcalibrationUIFigure.Name = 'Mechanical displacement calibration';
            app.CalibrateButton = uibutton(app.MechanicaldisplacementcalibrationUIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ClearpeaksButton = uibutton(app.MechanicaldisplacementcalibrationUIFigure, 'push');
            app.ClearpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ClearpeaksButtonPushed, true);
            app.ClearpeaksButton.Text = 'Clear peaks';
            app.PeakthresholdcursorCheckBox = uicheckbox(app.MechanicaldisplacementcalibrationUIFigure);
            app.PeakthresholdcursorCheckBox.Text = 'Peak threshold cursor';
            app.Image = uiimage(app.MechanicaldisplacementcalibrationUIFigure);
            app.Image.ImageSource = 'sidebands_power_def.png';
            app.MechanicalfrequencyHzEditFieldLabel = uilabel(app.MechanicaldisplacementcalibrationUIFigure);
            app.MechanicalfrequencyHzEditFieldLabel.Text = 'Mechanical frequency (Hz)';
            app.MechanicalfrequencyHzEditField = uieditfield(app.MechanicaldisplacementcalibrationUIFigure, 'numeric');
            app.QuadratureangledegEditFieldLabel = uilabel(app.MechanicaldisplacementcalibrationUIFigure);
            app.QuadratureangledegEditFieldLabel.Text = 'Quadrature angle (deg)';
            app.QuadratureangledegEditField = uieditfield(app.MechanicaldisplacementcalibrationUIFigure, 'numeric');
            app.QuadratureangledegEditField.ValueDisplayFormat = '%11.2g';
            app.QuadratureangledegEditField.Editable = 'off';
            app.OpticalwavelengthnmEditFieldLabel = uilabel(app.MechanicaldisplacementcalibrationUIFigure);
            app.OpticalwavelengthnmEditFieldLabel.Text = 'Optical wavelength (nm)';
            app.OpticalwavelengthnmEditField = uieditfield(app.MechanicaldisplacementcalibrationUIFigure, 'numeric');
            app.OpticalwavelengthnmEditField.Value = 780;
            app.OscillationamplitudenmEditFieldLabel = uilabel(app.MechanicaldisplacementcalibrationUIFigure);
            app.OscillationamplitudenmEditFieldLabel.Text = 'Oscillation amplitude (nm)';
            app.OscillationamplitudenmEditField = uieditfield(app.MechanicaldisplacementcalibrationUIFigure, 'numeric');
            app.OscillationamplitudenmEditField.ValueChangedFcn = createCallbackFcn(app, @OscillationamplitudenmEditFieldValueChanged, true);
            app.OscillationamplitudenmEditField.Editable = 'off';
            app.CalibrationFactormV2Label = uilabel(app.MechanicaldisplacementcalibrationUIFigure);
            app.CalibrationFactormV2Label.Text = 'Calibration Factor (m/V)^2';
            app.CalibrationFactormV2EditField = uieditfield(app.MechanicaldisplacementcalibrationUIFigure, 'numeric');
            app.CalibrationFactormV2EditField.Editable = 'off';
            app.ConfintmV2EditFieldLabel = uilabel(app.MechanicaldisplacementcalibrationUIFigure);
            app.ConfintmV2EditFieldLabel.Text = '95% Conf. int. (m/V)^2';
            app.ConfintmV2EditField = uieditfield(app.MechanicaldisplacementcalibrationUIFigure, 'numeric');
            app.ConfintmV2EditField.Editable = 'off';
            app.UIAxes = uiaxes(app.MechanicaldisplacementcalibrationUIFigure);
            xlabel(app.UIAxes, '\beta')
            ylabel(app.UIAxes, 'J_n^2(\beta)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.MechanicaldisplacementcalibrationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiDrivenMechDispCal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MechanicaldisplacementcalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MechanicaldisplacementcalibrationUIFigure)
        end
    end
end