classdef GuiFreqNoiseCal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationinfrequencyunitsUIFigure  matlab.ui.Figure
        ModulationdepthbetaEditFieldLabel  matlab.ui.control.Label
        ModulationdepthbetaEditField    matlab.ui.control.NumericEditField
        CalculatecfButton               matlab.ui.control.Button
        SpectrumtypeDropDownLabel       matlab.ui.control.Label
        SpectrumtypeDropDown            matlab.ui.control.DropDown
        ConvertspectrumButton           matlab.ui.control.Button
        ConversionfactorEditFieldLabel  matlab.ui.control.Label
        ConversionfactorEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        GuiSync
        Fnc
    end

    methods (Access = private)
        function createLinks(app)
            addLink(app.GuiSync, app.SpectrumtypeDropDown,           'Fnc.spectrum_type');           
            addLink(app.GuiSync, app.ModulationdepthbetaEditField,   'Fnc.beta');
            addLink(app.GuiSync, app.ConversionfactorEditField,      'Fnc.cf');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FncObj)
            app.Fnc = FncObj;
            app.GuiSync = MyGuiSync(app, 'KernelObj', FncObj);
            createLinks(app); 
        end

        % Button pushed function: CalculatecfButton
        function CalculatecfButtonPushed(app, event)
            calcConvFactor(app.Fnc);
        end

        % Button pushed function: ConvertspectrumButton
        function ConvertspectrumButtonPushed(app, event)
            convertSpectrum(app.Fnc);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationinfrequencyunitsUIFigure = uifigure('Visible', 'off');
            app.CalibrationinfrequencyunitsUIFigure.Name = 'Calibration in frequency units';
            app.ModulationdepthbetaEditFieldLabel = uilabel(app.CalibrationinfrequencyunitsUIFigure);
            app.ModulationdepthbetaEditFieldLabel.Text = 'Modulation depth beta';
            app.ModulationdepthbetaEditField = uieditfield(app.CalibrationinfrequencyunitsUIFigure, 'numeric');
            app.CalculatecfButton = uibutton(app.CalibrationinfrequencyunitsUIFigure, 'push');
            app.CalculatecfButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatecfButtonPushed, true);
            app.CalculatecfButton.Tooltip = {'Calculate conversion factor from S_V to S_\omega'};
            app.CalculatecfButton.Text = 'Calculate cf';
            app.SpectrumtypeDropDownLabel = uilabel(app.CalibrationinfrequencyunitsUIFigure);
            app.SpectrumtypeDropDownLabel.Text = 'Spectrum type';
            app.SpectrumtypeDropDown = uidropdown(app.CalibrationinfrequencyunitsUIFigure);
            app.SpectrumtypeDropDown.Items = {'S_\omega', 'S_f', 'sqrt(S_f)'};
            app.SpectrumtypeDropDown.Value = 'sqrt(S_f)';
            app.ConvertspectrumButton = uibutton(app.CalibrationinfrequencyunitsUIFigure, 'push');
            app.ConvertspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertspectrumButtonPushed, true);
            app.ConvertspectrumButton.Text = 'Convert spectrum';
            app.ConversionfactorEditFieldLabel = uilabel(app.CalibrationinfrequencyunitsUIFigure);
            app.ConversionfactorEditFieldLabel.Text = 'Conversion factor';
            app.ConversionfactorEditField = uieditfield(app.CalibrationinfrequencyunitsUIFigure, 'numeric');
            app.ConversionfactorEditField.Editable = 'off';
            app.ConversionfactorEditField.Value = 1.446e-12;
            % Show the figure after all components are created
            app.CalibrationinfrequencyunitsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiFreqNoiseCal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationinfrequencyunitsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationinfrequencyunitsUIFigure)
        end
    end
end