classdef MyPeakInspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PeakInspectorUIFigure       matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        TracepathEditFieldLabel     matlab.ui.control.Label
        TracePathEditField          matlab.ui.control.EditField
        FitpathEditFieldLabel       matlab.ui.control.Label
        FitPathEditField            matlab.ui.control.EditField
        LoadTraceButton             matlab.ui.control.Button
        PreviousPeakButton          matlab.ui.control.Button
        NextPeakButton              matlab.ui.control.Button
        LoadfitsDropDown            matlab.ui.control.DropDown
        LoadFitsButton              matlab.ui.control.Button
        LoadPeaksButton             matlab.ui.control.Button
        PeakpathEditFieldLabel      matlab.ui.control.Label
        PeakPathEditField           matlab.ui.control.EditField
        FitLabel1                   matlab.ui.control.Label
        FitLabel2                   matlab.ui.control.Label
        FitLabel3                   matlab.ui.control.Label
        PanButton                   matlab.ui.control.StateButton
        ZoomButton                  matlab.ui.control.StateButton
        RestoreButton               matlab.ui.control.Button
        SaveplotButton              matlab.ui.control.Button
        CurrentpeakSpinnerLabel     matlab.ui.control.Label
        CurrentPeakSpinner          matlab.ui.control.Spinner
        DisplaywidthEditFieldLabel  matlab.ui.control.Label
        DispWidthEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        PeakFitParams % Description
        
        Fits % Description
        PeakFinder % Description
    end
    
    properties (Access = private)
        peak_handle% Description
        peak_color='r';
        fit_colors={'r','m','c'}
        trace_color='b';
        fit_label % Description
    end
    
    properties (Dependent=true)
        fit_names;
        peak_no;
        disp_width;
    end
    
    methods (Access = private)
        
        function exitLoad(app)
            app.LoadTraceButton.BackgroundColor=[0.94,0.94,0.94];
            app.LoadTraceButton.Text='Load trace';
        end
        
        function plotPeaks(app)
            delete(app.peak_handle);
            app.peak_handle=plot(app.UIAxes,...
                [app.PeakFinder.Peaks.Location],...
                [app.PeakFinder.Peaks.Value],...
                'Marker','o',...
                'LineStyle','none',...
                'Color',app.peak_color);
        end
        
        function updateFits(app)
            %We now extract the x and y data from the trace that was fitted to
            [x_peak,y_peak]=extractPeak(app.PeakFinder,app.peak_no,app.disp_width);
            
            %We clear the axis
            cla(app.UIAxes);
            %We loop over the open fits to update their coefficients and x,y data
            for i=1:length(app.fit_names)
                fname=app.fit_names{i};
                %We extract the coefficients of each parameter from the table,
                %for the correct peak number, then load it into the fit object
                coeffs=cellfun(@(x) app.PeakFitParams.(fname).(x)(app.peak_no),...
                    app.Fits.(fname).fit_params);
                setFitParams(app.Fits.(fname),coeffs);
                app.Fits.(fname).Data.x=x_peak;
                app.Fits.(fname).Data.y=y_peak;
                
                %Now plot the fit
                plotFit(app.Fits.(fname),'Color',app.fit_colors{i});
            end
            annotateFits(app);
            plot(app.UIAxes,x_peak,y_peak,...
                'Color',app.trace_color,...
                'LineStyle','none',...
                'Marker','.',...
                'MarkerSize',12);
        end
        
        
        function annotateFits(app)
            if ismember('Lorentzian',app.fit_names)
                str=sprintf(['Lorentzian\n',...
                    'Linewidth    %3.3e\n',...
                    'Adj. r^2         %3.3f'],...
                    app.PeakFitParams.Lorentzian.b(app.peak_no),...
                    app.PeakFitParams.Lorentzian.adjrsquare(app.peak_no));
                app.FitLabel1.Text=str;
            end
            
            if ismember('LorentzianGrad',app.fit_names)
                str=sprintf(['Lorentzian\n',...
                    'Linewidth    %3.3e\n',...
                    'Adj. r^2         %3.3f'],...
                    app.PeakFitParams.LorentzianGrad.b(app.peak_no),...
                    app.PeakFitParams.LorentzianGrad.adjrsquare(app.peak_no));
                app.FitLabel1.Text=str;
            end
            
            
            if ismember('DoubleLorentzian',app.fit_names)
                tmp=app.PeakFitParams.DoubleLorentzian;
                n=app.peak_no;
                str=sprintf(['Double Lorentzian\n',...
                    'Linewidth1       %3.3e\n',...
                    'Linewidth2       %3.3e\n',...
                    'Mode splitting   %3.3e\n',...
                    'Adj. r^2            %3.3f'],...
                    tmp.b(n),tmp.e(n),tmp.f(n)-tmp.c(n),tmp.adjrsquare(n));
                app.FitLabel3.Text=str;
            end
            
            if ismember('Gorodetsky2000',app.fit_names)
                str=sprintf(['Gorodetsky2000\n',...
                    'k_0          %3.3e\n',...
                    'k_ex        %3.3e\n',...
                    'gamma    %3.3e\n',...
                    'Adj. r^2     %3.3f'],...
                    app.PeakFitParams.Gorodetsky2000.k0(app.peak_no),...
                    app.PeakFitParams.Gorodetsky2000.kex(app.peak_no),...
                    app.PeakFitParams.Gorodetsky2000.gamma(app.peak_no),...
                    app.PeakFitParams.Gorodetsky2000.adjrsquare(app.peak_no));
                app.FitLabel2.Text=str;
            end
            
            if ismember('Gorodetsky2000Plus',app.fit_names)
                str=sprintf(['Gorodetsky2000Plus\n',...
                    'k_0          %3.3e\n',...
                    'k_ex        %3.3e\n',...
                    'gamma    %3.3e\n',...
                    'Adj. r^2     %3.3f'],...
                    app.PeakFitParams.Gorodetsky2000Plus.k0(app.peak_no),...
                    app.PeakFitParams.Gorodetsky2000Plus.kex(app.peak_no),...
                    app.PeakFitParams.Gorodetsky2000Plus.gamma(app.peak_no),...
                    app.PeakFitParams.Gorodetsky2000Plus.adjrsquare(app.peak_no));
                app.FitLabel2.Text=str;
            end
            
        end
        
        
        function savePlot(app)
            f=figure;
            ax=axes(f);
            hold(ax,'on');
            for i=1:length(app.fit_names)
                fname=app.fit_names{i};
                app.Fits.(fname).plot_handle=ax;
                plotFit(app.Fits.(fname),'Color',app.fit_colors{i});
            end
            
            [x_peak,y_peak]=extractPeak(app.PeakFinder,app.peak_no,app.disp_width);
            plot(ax,x_peak,y_peak,...
                'Color',app.trace_color,...
                'LineStyle','none',...
                'Marker','.',...
                'MarkerSize',12);
            save_path=uiputfile;
            savefig(f,[save_path,'.fig']);
            close(f);
        end
        
    end
    
    methods
        function fit_names=get.fit_names(app)
            fit_names=fieldnames(app.Fits);
        end
        
        function peak_no=get.peak_no(app)
            peak_no=app.CurrentPeakSpinner.Value;
        end
        
        function set.peak_no(app,peak_no)
            app.CurrentPeakSpinner.Value=peak_no;
            updateFits(app);
        end
    
        function disp_width=get.disp_width(app)
            disp_width=app.DispWidthEditField.Value;
        end
    
        function set.disp_width(app,disp_width)
            app.DispWidthEditField.Value=disp_width;
            updateFits(app);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PeakFinder=MyPeakFinder();
            app.Fits=struct();
            hold(app.UIAxes,'on');
        end

        % Button pushed function: LoadTraceButton
        function loadTraceCallback(app, event)
            [fname,path]=uigetfile('*.*');
            if fname==0
                warning('No file was selected');
                return
            end
            app.LoadTraceButton.BackgroundColor=[1,0,0];
            app.LoadTraceButton.Text='Loading..';
            
            loadTrace(app.PeakFinder,[path,fname]);
            plot(app.PeakFinder.Trace,app.UIAxes);
            app.TracePathEditField.Value=[path,fname];
            app.LoadPeaksButton.Enable='on';
            app.LoadfitsDropDown.Enable='on';
            exitLoad(app);
        end

        % Button pushed function: LoadFitsButton
        function loadFitsCallback(app, event)
            fit_name=app.LoadfitsDropDown.Value;
            
            [fname,path]=uigetfile('*.*');
            if fname==0
                warning('No file was selected');
                return
            end
            
            app.LoadFitsButton.BackgroundColor=[1,0,0];
            app.LoadFitsButton.Text='Loading..';
            
            %Sets up loading of fits
            opts=detectImportOptions([path,fname]);
            opts.LeadingDelimitersRule='ignore';
            opts.ExtraColumnsRule='ignore';
            opts.ConsecutiveDelimitersRule='join';
            opts.MissingRule='omitvar';
            
            %Read the file containing the fit parameters
            app.PeakFitParams.(fit_name)=readtable([path,fname],opts);
            
            %Insantiate the fit class
            app.Fits.(fit_name)=launchFit(fit_name,...
                'enable_gui',false,'plot_handle',app.UIAxes);
            
            %Set up for renaming the table headers
            fit_params=app.Fits.(fit_name).fit_params;
            table_headers=fieldnames(app.PeakFitParams.(fit_name));
            
            %Rename table headers appropriately for the fit parameters
            for i=1:length(fit_params)
                ind=cellfun(@(x) ~isempty(regexp(x,['_',fit_params{i},'_'],'once')),...
                    table_headers);
                app.PeakFitParams.(fit_name).Properties.VariableNames{ind}=...
                    fit_params{i};
            end
            

            updateFits(app);
            
            app.LoadFitsButton.BackgroundColor=[0.94,0.94,0.94];
            app.LoadFitsButton.Text='Load fits';
        end

        % Button pushed function: LoadPeaksButton
        function loadPeaksCallback(app, event)
            [fname,path]=uigetfile('*.*');
            if fname==0
                warning('No file was selected');
                return
            end
            loadPeaks(app.PeakFinder,[path,fname]);
            %Set limit to peak number spinner
            app.CurrentPeakSpinner.Limits=[1,length(app.PeakFinder.Peaks)];
            plotPeaks(app);
            app.LoadFitsButton.Enable='on';
            app.PeakPathEditField.Value=[path,fname];
        end

        % Button pushed function: NextPeakButton
        function nextPeakCallback(app, event)
            if app.peak_no<length(app.PeakFinder.Peaks)
                app.peak_no=app.peak_no+1;
            end
        end

        % Button pushed function: PreviousPeakButton
        function prevPeakCallback(app, event)
            if app.peak_no>1
                app.peak_no=app.peak_no-1;
            end
        end

        % Value changed function: ZoomButton
        function zoomCallback(app, event)
            value = app.ZoomButton.Value;
            if value
                app.PanButton.Value=false;
                zoom(app.UIAxes,'on')
            else
                zoom(app.UIAxes,'off')
            end
        end

        % Value changed function: PanButton
        function panCallback(app, event)
            value = app.PanButton.Value;
            if value
                pan(app.UIAxes,'on')
                app.ZoomButton.Value=false;
            else
                pan(app.UIAxes,'off')
            end
        end

        % Button pushed function: RestoreButton
        function restoreCallback(app, event)
            axis(app.UIAxes,'tight');
        end

        % Button pushed function: SaveplotButton
        function savePlotCallback(app, event)
            savePlot(app);
        end

        % Close request function: PeakInspectorUIFigure
        function closeFigure(app, event)
            structfun(@(x) delete(x), app.Fits);
            delete(app.PeakFinder);
            delete(app)
        end

        % Value changed function: CurrentPeakSpinner
        function CurrentPeakSpinnerValueChanged(app, event)
            updateFits(app);
        end

        % Value changed function: DispWidthEditField
        function DispWidthEditFieldValueChanged(app, event)
            updateFits(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PeakInspectorUIFigure = uifigure;
            app.PeakInspectorUIFigure.Name = 'Peak Inspector';
            app.PeakInspectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFigure, true);
            app.UIAxes = uiaxes(app.PeakInspectorUIFigure);
            app.TracepathEditFieldLabel = uilabel(app.PeakInspectorUIFigure);
            app.TracepathEditFieldLabel.Text = 'Trace path';
            app.TracePathEditField = uieditfield(app.PeakInspectorUIFigure, 'text');
            app.TracePathEditField.Editable = 'off';
            app.FitpathEditFieldLabel = uilabel(app.PeakInspectorUIFigure);
            app.FitpathEditFieldLabel.Enable = 'off';
            app.FitpathEditFieldLabel.Text = 'Fit path';
            app.FitPathEditField = uieditfield(app.PeakInspectorUIFigure, 'text');
            app.FitPathEditField.Editable = 'off';
            app.FitPathEditField.Enable = 'off';
            app.LoadTraceButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.LoadTraceButton.ButtonPushedFcn = createCallbackFcn(app, @loadTraceCallback, true);
            app.LoadTraceButton.Text = 'Load trace';
            app.PreviousPeakButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.PreviousPeakButton.ButtonPushedFcn = createCallbackFcn(app, @prevPeakCallback, true);
            app.PreviousPeakButton.Text = 'Previous peak';
            app.NextPeakButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.NextPeakButton.ButtonPushedFcn = createCallbackFcn(app, @nextPeakCallback, true);
            app.NextPeakButton.Text = 'Next peak';
            app.LoadfitsDropDown = uidropdown(app.PeakInspectorUIFigure);
            app.LoadfitsDropDown.Items = {'Lorentzian', 'LorentzianGrad', 'DoubleLorentzian', 'Gorodetsky2000', 'Gorodetsky2000Plus'};
            app.LoadfitsDropDown.Enable = 'off';
            app.LoadfitsDropDown.Value = 'Lorentzian';
            app.LoadFitsButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.LoadFitsButton.ButtonPushedFcn = createCallbackFcn(app, @loadFitsCallback, true);
            app.LoadFitsButton.Enable = 'off';
            app.LoadFitsButton.Text = 'Load fits';
            app.LoadPeaksButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.LoadPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @loadPeaksCallback, true);
            app.LoadPeaksButton.Enable = 'off';
            app.LoadPeaksButton.Text = 'Load peaks';
            app.PeakpathEditFieldLabel = uilabel(app.PeakInspectorUIFigure);
            app.PeakpathEditFieldLabel.Enable = 'off';
            app.PeakpathEditFieldLabel.Text = 'Peak path';
            app.PeakPathEditField = uieditfield(app.PeakInspectorUIFigure, 'text');
            app.PeakPathEditField.Enable = 'off';
            app.FitLabel1 = uilabel(app.PeakInspectorUIFigure);
            app.FitLabel1.Text = '';
            app.FitLabel2 = uilabel(app.PeakInspectorUIFigure);
            app.FitLabel2.Text = '';
            app.FitLabel3 = uilabel(app.PeakInspectorUIFigure);
            app.FitLabel3.Text = '';
            app.PanButton = uibutton(app.PeakInspectorUIFigure, 'state');
            app.PanButton.ValueChangedFcn = createCallbackFcn(app, @panCallback, true);
            app.PanButton.Text = 'Pan';
            app.ZoomButton = uibutton(app.PeakInspectorUIFigure, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @zoomCallback, true);
            app.ZoomButton.Text = 'Zoom';
            app.RestoreButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @restoreCallback, true);
            app.RestoreButton.Text = 'Restore';
            app.SaveplotButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.SaveplotButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotCallback, true);
            app.SaveplotButton.Text = 'Save plot';
            app.CurrentpeakSpinnerLabel = uilabel(app.PeakInspectorUIFigure);
            app.CurrentpeakSpinnerLabel.Text = 'Current peak';
            app.CurrentPeakSpinner = uispinner(app.PeakInspectorUIFigure);
            app.CurrentPeakSpinner.Limits = [1 Inf];
            app.CurrentPeakSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentPeakSpinnerValueChanged, true);
            app.CurrentPeakSpinner.Value = 1;
            app.DisplaywidthEditFieldLabel = uilabel(app.PeakInspectorUIFigure);
            app.DisplaywidthEditFieldLabel.Text = 'Display width';
            app.DispWidthEditField = uieditfield(app.PeakInspectorUIFigure, 'numeric');
            app.DispWidthEditField.Limits = [0 Inf];
            app.DispWidthEditField.ValueChangedFcn = createCallbackFcn(app, @DispWidthEditFieldValueChanged, true);
            app.DispWidthEditField.Value = 16;
        end
    end

    methods (Access = public)

        % Construct app
        function app = MyPeakInspector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PeakInspectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PeakInspectorUIFigure)
        end
    end
end