classdef MyTraceAdder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TraceadderUIFigure  matlab.ui.Figure
        Selecttrace1Button  matlab.ui.control.Button
        Selecttrace2Button  matlab.ui.control.Button
        Trace1Label         matlab.ui.control.Label
        Trace2Label         matlab.ui.control.Label
        OperationDropDown   matlab.ui.control.DropDown
        CalculateButton     matlab.ui.control.Button
        Name1Label          matlab.ui.control.Label
        Name1EditField      matlab.ui.control.EditField
        Name2Label          matlab.ui.control.Label
        Name2EditField      matlab.ui.control.EditField
    end

    
    properties (Access = public)
        data_name   char 
        
        Data        MyTrace
        
        Data1       MyTrace
        Data2       MyTrace
    end
    
    events
        NewProcessedData
    end
    
    methods (Access = public)
                
        %Triggered for transferring of the fit trace to DAQ
        function triggerNewProcessedData(app, varargin)
            EventData = MyNewDataEvent(varargin{:});
            notify(app, 'NewProcessedData', EventData);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            p = inputParser();
            addParameter(p, 'Data', MyTrace.empty());
            
            % This class does not use axes but we keep it in the parser
            % scheme for compatibility with other analysis routines
            addParameter(p, 'Axes', [], @isaxes);
            addParameter(p, 'enable_gui', true);
            parse(p, varargin{:});
            
            app.Data = p.Results.Data;
        end

        % Button pushed function: Selecttrace1Button
        function Selecttrace1ButtonPushed(app, event)
            if ~isempty(app.Data)
                app.Data1 = app.Data;
                app.Name1EditField.Value = app.data_name;
            end
        end

        % Button pushed function: Selecttrace2Button
        function Selecttrace2ButtonPushed(app, event)
            if ~isempty(app.Data)
                app.Data2 = app.Data;
                app.Name2EditField.Value = app.data_name;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            switch app.OperationDropDown.Value
                case '+'
                    Res = app.Data1 + app.Data2;
                    Res.UserMetadata = app.Data1.UserMetadata;
                    triggerNewProcessedData(app, 'traces', {Res}, 'trace_tags', {'_add'});
                case '-'
                    Res = app.Data1 - app.Data2;
                    Res.UserMetadata = app.Data1.UserMetadata;
                    triggerNewProcessedData(app, 'traces', {Res}, 'trace_tags', {'_sub'});
                otherwise
                    error('Unknown trace operation')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TraceadderUIFigure = uifigure('Visible', 'off');
            app.TraceadderUIFigure.Name = 'Trace adder';
            app.Selecttrace1Button = uibutton(app.TraceadderUIFigure, 'push');
            app.Selecttrace1Button.ButtonPushedFcn = createCallbackFcn(app, @Selecttrace1ButtonPushed, true);
            app.Selecttrace1Button.Text = 'Select trace 1';
            app.Selecttrace2Button = uibutton(app.TraceadderUIFigure, 'push');
            app.Selecttrace2Button.ButtonPushedFcn = createCallbackFcn(app, @Selecttrace2ButtonPushed, true);
            app.Selecttrace2Button.Text = 'Select trace 2';
            app.Trace1Label = uilabel(app.TraceadderUIFigure);
            app.Trace1Label.Text = 'Trace 1';
            app.Trace2Label = uilabel(app.TraceadderUIFigure);
            app.Trace2Label.Text = 'Trace 2 =';
            app.OperationDropDown = uidropdown(app.TraceadderUIFigure);
            app.OperationDropDown.Items = {'+', '-'};
            app.OperationDropDown.Value = '-';
            app.CalculateButton = uibutton(app.TraceadderUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Name1Label = uilabel(app.TraceadderUIFigure);
            app.Name1Label.Text = 'Name 1';
            app.Name1EditField = uieditfield(app.TraceadderUIFigure, 'text');
            app.Name1EditField.Editable = 'off';
            app.Name2Label = uilabel(app.TraceadderUIFigure);
            app.Name2Label.Text = 'Name 2';
            app.Name2EditField = uieditfield(app.TraceadderUIFigure, 'text');
            app.Name2EditField.Editable = 'off';
            % Show the figure after all components are created
            app.TraceadderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyTraceAdder(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TraceadderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TraceadderUIFigure)
        end
    end
end