classdef GuiPhaseModCal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BetacalibrationUIFigure         matlab.ui.Figure
        CalibrateButton                 matlab.ui.control.Button
        ClearpeaksButton                matlab.ui.control.Button
        PeakthresholdcursorCheckBox     matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
        ModulationdepthbetaEditFieldLabel  matlab.ui.control.Label
        ModulationdepthbetaEditField    matlab.ui.control.NumericEditField
        ModulationfrequencyHzEditFieldLabel  matlab.ui.control.Label
        ModulationfrequencyHzEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        GuiSync
        BetaCal
    end

    methods (Access = private)
        function createLinks(app)
            addLink(app.GuiSync, app.ModulationdepthbetaEditField,   'BetaCal.beta');
            addLink(app.GuiSync, app.ModulationfrequencyHzEditField, 'BetaCal.mod_freq');
            addLink(app.GuiSync, app.PeakthresholdcursorCheckBox,    'BetaCal.enable_cursor');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BetaCalObj)
            app.BetaCal = BetaCalObj;
            app.GuiSync = MyGuiSync(app, 'KernelObj', BetaCalObj);
            
            createLinks(app); 
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            calcBeta(app.BetaCal);
        end

        % Button pushed function: ClearpeaksButton
        function ClearpeaksButtonPushed(app, event)
            clearPeakDisp(app.BetaCal);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BetacalibrationUIFigure = uifigure('Visible', 'off');
            app.BetacalibrationUIFigure.Name = 'Beta calibration';
            app.CalibrateButton = uibutton(app.BetacalibrationUIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ClearpeaksButton = uibutton(app.BetacalibrationUIFigure, 'push');
            app.ClearpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ClearpeaksButtonPushed, true);
            app.ClearpeaksButton.Text = 'Clear peaks';
            app.PeakthresholdcursorCheckBox = uicheckbox(app.BetacalibrationUIFigure);
            app.PeakthresholdcursorCheckBox.Text = 'Peak threshold cursor';
            app.Image = uiimage(app.BetacalibrationUIFigure);
            app.Image.ImageSource = 'beta_def.png';
            app.ModulationfrequencyHzEditFieldLabel = uilabel(app.BetacalibrationUIFigure);
            app.ModulationfrequencyHzEditFieldLabel.Text = 'Modulation frequency (Hz)';
            app.ModulationfrequencyHzEditField = uieditfield(app.BetacalibrationUIFigure, 'numeric');
            app.ModulationdepthbetaEditFieldLabel = uilabel(app.BetacalibrationUIFigure);
            app.ModulationdepthbetaEditFieldLabel.Text = 'Modulation depth beta';
            app.ModulationdepthbetaEditField = uieditfield(app.BetacalibrationUIFigure, 'numeric');
            app.ModulationdepthbetaEditField.Editable = 'off';
            % Show the figure after all components are created
            app.BetacalibrationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiPhaseModCal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BetacalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BetacalibrationUIFigure)
        end
    end
end