classdef Guig0Cal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationofoptomechanicalcouplingUIFigure  matlab.ui.Figure
        MethodButtonGroup             matlab.ui.container.ButtonGroup
        IntegrationButton             matlab.ui.control.RadioButton
        FitButton                     matlab.ui.control.RadioButton
        AnalyzeButton                 matlab.ui.control.Button
        FittedvaluesPanel             matlab.ui.container.Panel
        FrequencyHzEditFieldLabel     matlab.ui.control.Label
        FrequencyHzEditField          matlab.ui.control.NumericEditField
        LinewidthHzEditFieldLabel     matlab.ui.control.Label
        LinewidthHzEditField          matlab.ui.control.NumericEditField
        QEditFieldLabel               matlab.ui.control.Label
        QEditField                    matlab.ui.control.NumericEditField
        CorrectionfordynamicbackactionPanel  matlab.ui.container.Panel
        CorrectforDBACheckBox         matlab.ui.control.CheckBox
        ReferenceQEditFieldLabel      matlab.ui.control.Label
        ReferenceQEditField           matlab.ui.control.NumericEditField
        CalibrationparametersPanel    matlab.ui.container.Panel
        TemperatureKEditFieldLabel    matlab.ui.control.Label
        TemperatureKEditField         matlab.ui.control.NumericEditField
        ModulationdepthbetaEditFieldLabel  matlab.ui.control.Label
        ModulationdepthbetaEditField  matlab.ui.control.NumericEditField
        g02piHzEditFieldLabel         matlab.ui.control.Label
        g02piHzEditField              matlab.ui.control.NumericEditField
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        GuiSync
        g0Cal
    end

    methods (Access = private)
        function createLinks(app)
            addLink(app.GuiSync, app.g02piHzEditField,               'g0Cal.g0l');
            
            addLink(app.GuiSync, app.ModulationdepthbetaEditField,   'g0Cal.beta');
            addLink(app.GuiSync, app.TemperatureKEditField,          'g0Cal.T');
            addLink(app.GuiSync, app.ReferenceQEditField,            'g0Cal.ref_Q');
            addLink(app.GuiSync, app.CorrectforDBACheckBox,          'g0Cal.correct_dba');
            
            addLink(app.GuiSync, app.FrequencyHzEditField,           'g0Cal.freq');
            addLink(app.GuiSync, app.LinewidthHzEditField,           'g0Cal.lw');
            addLink(app.GuiSync, app.QEditField,                     'g0Cal.Q');
        end
    
        % Apdate the accessibility of elements and values that are not
        % handled by the links of GuiSync directly
        function updateGui(app)
            if isequal(app.g0Cal.method, 'fit')
                setIfChanged(app.FittedvaluesPanel.Children, 'Enable', 'on');
                setIfChanged(app.CorrectforDBACheckBox, 'Enable', 'on');
                
                if app.g0Cal.correct_dba
                    setIfChanged(app.ReferenceQEditField, 'Enable', 'on');
                    setIfChanged(app.ReferenceQEditFieldLabel, 'Enable', 'on');
                else
                    setIfChanged(app.ReferenceQEditField, 'Enable', 'off');
                    setIfChanged(app.ReferenceQEditFieldLabel, 'Enable', 'off');
                end
            else
                setIfChanged(app.FittedvaluesPanel.Children, 'Enable', 'off');
                setIfChanged(app.CorrectionfordynamicbackactionPanel.Children, 'Enable', 'off');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, g0CalObj)
            app.g0Cal = g0CalObj;
            app.GuiSync = MyGuiSync(app, 'KernelObj', g0CalObj, 'updateGuiFcn', @app.updateGui);
            
            createLinks(app); 
            updateGui(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            calcg0(app.g0Cal);
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodButtonGroup.SelectedObject;
            switch selectedButton
                case app.IntegrationButton
                    app.g0Cal.method = 'integration';
                case app.FitButton
                    app.g0Cal.method = 'fit';
            end
            
            updateGui(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationofoptomechanicalcouplingUIFigure = uifigure('Visible', 'off');
            app.CalibrationofoptomechanicalcouplingUIFigure.Name = 'Calibration of optomechanical coupling';
            app.MethodButtonGroup = uibuttongroup(app.CalibrationofoptomechanicalcouplingUIFigure);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.IntegrationButton = uiradiobutton(app.MethodButtonGroup);
            app.IntegrationButton.Text = 'Integration';
            app.IntegrationButton.Value = true;
            app.FitButton = uiradiobutton(app.MethodButtonGroup);
            app.FitButton.Text = 'Fit';
            app.AnalyzeButton = uibutton(app.MethodButtonGroup, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.FittedvaluesPanel = uipanel(app.CalibrationofoptomechanicalcouplingUIFigure);
            app.FittedvaluesPanel.Title = 'Fitted values';
            app.FrequencyHzEditFieldLabel = uilabel(app.FittedvaluesPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.FittedvaluesPanel, 'numeric');
            app.FrequencyHzEditField.Editable = 'off';
            app.LinewidthHzEditFieldLabel = uilabel(app.FittedvaluesPanel);
            app.LinewidthHzEditFieldLabel.Text = 'Linewidth (Hz)';
            app.LinewidthHzEditField = uieditfield(app.FittedvaluesPanel, 'numeric');
            app.LinewidthHzEditField.Editable = 'off';
            app.QEditFieldLabel = uilabel(app.FittedvaluesPanel);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.FittedvaluesPanel, 'numeric');
            app.QEditField.Editable = 'off';
            app.CorrectionfordynamicbackactionPanel = uipanel(app.CalibrationofoptomechanicalcouplingUIFigure);
            app.CorrectionfordynamicbackactionPanel.Title = 'Correction for dynamic backaction';
            app.CorrectforDBACheckBox = uicheckbox(app.CorrectionfordynamicbackactionPanel);
            app.CorrectforDBACheckBox.Tooltip = {''};
            app.CorrectforDBACheckBox.Text = 'Correct for DBA';
            app.ReferenceQEditFieldLabel = uilabel(app.CorrectionfordynamicbackactionPanel);
            app.ReferenceQEditFieldLabel.Text = 'Reference Q';
            app.ReferenceQEditField = uieditfield(app.CorrectionfordynamicbackactionPanel, 'numeric');
            app.CalibrationparametersPanel = uipanel(app.CalibrationofoptomechanicalcouplingUIFigure);
            app.CalibrationparametersPanel.Title = 'Calibration parameters';
            app.TemperatureKEditFieldLabel = uilabel(app.CalibrationparametersPanel);
            app.TemperatureKEditFieldLabel.Text = 'Temperature (K)';
            app.TemperatureKEditField = uieditfield(app.CalibrationparametersPanel, 'numeric');
            app.TemperatureKEditField.Value = 300;
            app.ModulationdepthbetaEditFieldLabel = uilabel(app.CalibrationparametersPanel);
            app.ModulationdepthbetaEditFieldLabel.Text = 'Modulation depth beta';
            app.ModulationdepthbetaEditField = uieditfield(app.CalibrationparametersPanel, 'numeric');
            app.g02piHzEditFieldLabel = uilabel(app.CalibrationofoptomechanicalcouplingUIFigure);
            app.g02piHzEditFieldLabel.Text = 'g0/2pi (Hz)';
            app.g02piHzEditField = uieditfield(app.CalibrationofoptomechanicalcouplingUIFigure, 'numeric');
            app.g02piHzEditField.Value = 1000000;
            app.Image = uiimage(app.CalibrationofoptomechanicalcouplingUIFigure);
            app.Image.ImageSource = 'g0_cal.png';
            % Show the figure after all components are created
            app.CalibrationofoptomechanicalcouplingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Guig0Cal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationofoptomechanicalcouplingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationofoptomechanicalcouplingUIFigure)
        end
    end
end