classdef MyPeakInspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PeakInspectorUIFigure      matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        TracepathEditFieldLabel    matlab.ui.control.Label
        TracePathEditField         matlab.ui.control.EditField
        FitpathEditFieldLabel      matlab.ui.control.Label
        FitPathEditField           matlab.ui.control.EditField
        LoadTraceButton            matlab.ui.control.Button
        PreviousPeakButton         matlab.ui.control.Button
        NextPeakButton             matlab.ui.control.Button
        CurrentpeakEditFieldLabel  matlab.ui.control.Label
        CurrentPeakEditField       matlab.ui.control.NumericEditField
        LoadfitsDropDown           matlab.ui.control.DropDown
        LoadFitsButton             matlab.ui.control.Button
        LoadPeaksButton            matlab.ui.control.Button
        PeakpathEditFieldLabel     matlab.ui.control.Label
        PeakPathEditField          matlab.ui.control.EditField
    end

    
    properties (Access = public)
        PeakFitParams % Description
        
        Fits % Description
        PeakFinder % Description
    end
    
    properties (Access = private)
        peak_handle% Description
        peak_color='r';
        fit_colors={'r','m','c'}
        trace_color='b';
    end
    
    properties (Dependent=true)
        fit_names;
        peak_no;
    end
    
    methods (Access = private)
        
        function exitLoad(app)
            app.LoadTraceButton.BackgroundColor=[0.94,0.94,0.94];
            app.LoadTraceButton.Text='Load trace';
        end
        
        function plotPeaks(app)
            delete(app.peak_handle);
            app.peak_handle=plot(app.UIAxes,...
                [app.PeakFinder.Peaks.Location],...
                [app.PeakFinder.Peaks.Value],...
                'Marker','o',...
                'LineStyle','none',...
                'Color',app.peak_color);
        end
        
        function updateFits(app)           
            %We now extract the x and y data from the trace that was fitted to
            [x_peak,y_peak]=extractPeak(app.PeakFinder,app.peak_no);
            
            %We clear the axis
            cla(app.UIAxes);
            %We loop over the open fits to update their coefficients and x,y data
            for i=1:length(app.fit_names)
                fname=app.fit_names{i};
                %We extract the coefficients of each parameter from the table,
                %for the correct peak number, then load it into the fit object
                coeffs=cellfun(@(x) app.PeakFitParams.(fname).(x)(app.peak_no),...
                    app.Fits.(fname).fit_params);
                setFitParams(app.Fits.(fname),coeffs);
                app.Fits.(fname).Data.x=x_peak;
                app.Fits.(fname).Data.y=y_peak;

                %Now plot the fit
                plotFit(app.Fits.(fname),'Color',app.fit_colors{i});
            end
            
            plot(app.UIAxes,x_peak,y_peak,app.trace_color);
        end
    end
    
    methods
        function fit_names=get.fit_names(app)
            fit_names=fieldnames(app.Fits);
        end
        
        function peak_no=get.peak_no(app)
            peak_no=app.CurrentPeakEditField.Value;
        end
    
        function set.peak_no(app,peak_no)
            app.CurrentPeakEditField.Value=peak_no;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PeakFinder=MyPeakFinder();
            app.Fits=struct();
            hold(app.UIAxes,'on');
        end

        % Button pushed function: LoadTraceButton
        function loadTraceCallback(app, event)
            [fname,path]=uigetfile('*.*');
            if fname==0
                warning('No file was selected');
                return
            end
            app.LoadTraceButton.BackgroundColor=[1,0,0];
            app.LoadTraceButton.Text='Loading..';
            
            loadTrace(app.PeakFinder,[path,fname]);
            plotTrace(app.PeakFinder.Trace,app.UIAxes);
            app.TracePathEditField.Value=[path,fname];
            app.LoadPeaksButton.Enable='on';
            exitLoad(app);
        end

        % Button pushed function: LoadFitsButton
        function loadFitsCallback(app, event)
            fit_name=app.LoadfitsDropDown.Value;
            
            [fname,path]=uigetfile('*.*');
            if fname==0
                warning('No file was selected');
                return
            end
            
            app.LoadFitsButton.BackgroundColor=[1,0,0];
            app.LoadFitsButton.Text='Loading..';
            
            %Sets up loading of fits
            opts=detectImportOptions([path,fname]);
            opts.LeadingDelimitersRule='ignore';
            opts.ExtraColumnsRule='ignore';
            opts.ConsecutiveDelimitersRule='join';
            opts.MissingRule='omitvar';
            
            %Read the file containing the fit parameters
            app.PeakFitParams.(fit_name)=readtable([path,fname],opts);
            
            %Insantiate the fit class
            app.Fits.(fit_name)=MyFit('fit_name',fit_name,...
                'enable_gui',false,'plot_handle',app.UIAxes);
            
            %Set up for renaming the table headers
            fit_params=app.Fits.(fit_name).fit_params;
            table_headers=fieldnames(app.PeakFitParams.(fit_name));
            
            %Rename table headers appropriately for the fit parameters
            for i=1:length(fit_params)
                ind=cellfun(@(x) ~isempty(regexp(x,['_',fit_params{i},'_'],'once')),...
                    table_headers);
                app.PeakFitParams.(fit_name).Properties.VariableNames{ind}=...
                    fit_params{i};
            end
            
            updateFits(app);
            
            app.LoadFitsButton.BackgroundColor=[0.94,0.94,0.94];
            app.LoadFitsButton.Text='Load fits';
        end

        % Button pushed function: LoadPeaksButton
        function loadPeaksCallback(app, event)
            [fname,path]=uigetfile('*.*');
            if fname==0
                warning('No file was selected');
                return
            end
            loadPeaks(app.PeakFinder,[path,fname]);
            plotPeaks(app);
            app.LoadFitsButton.Enable='on';
            app.PeakPathEditField.Value=[path,fname];
        end

        % Button pushed function: NextPeakButton
        function nextPeakCallback(app, event)
            if app.peak_no<length(app.PeakFinder.Peaks)
                app.peak_no=app.peak_no+1;
            end
            updateFits(app);
        end

        % Button pushed function: PreviousPeakButton
        function prevPeakCallback(app, event)
            if app.peak_no>1
                app.peak_no=app.peak_no-1;
            end
            updateFits(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PeakInspectorUIFigure = uifigure;
            app.PeakInspectorUIFigure.Name = 'Peak Inspector';
            app.UIAxes = uiaxes(app.PeakInspectorUIFigure);
            app.TracepathEditFieldLabel = uilabel(app.PeakInspectorUIFigure);
            app.TracepathEditFieldLabel.Text = 'Trace path';
            app.TracePathEditField = uieditfield(app.PeakInspectorUIFigure, 'text');
            app.TracePathEditField.Editable = 'off';
            app.FitpathEditFieldLabel = uilabel(app.PeakInspectorUIFigure);
            app.FitpathEditFieldLabel.Enable = 'off';
            app.FitpathEditFieldLabel.Text = 'Fit path';
            app.FitPathEditField = uieditfield(app.PeakInspectorUIFigure, 'text');
            app.FitPathEditField.Editable = 'off';
            app.FitPathEditField.Enable = 'off';
            app.LoadTraceButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.LoadTraceButton.ButtonPushedFcn = createCallbackFcn(app, @loadTraceCallback, true);
            app.LoadTraceButton.Text = 'Load trace';
            app.PreviousPeakButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.PreviousPeakButton.ButtonPushedFcn = createCallbackFcn(app, @prevPeakCallback, true);
            app.PreviousPeakButton.Text = 'Previous peak';
            app.NextPeakButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.NextPeakButton.ButtonPushedFcn = createCallbackFcn(app, @nextPeakCallback, true);
            app.NextPeakButton.Text = 'Next peak';
            app.CurrentpeakEditFieldLabel = uilabel(app.PeakInspectorUIFigure);
            app.CurrentpeakEditFieldLabel.Text = 'Current peak';
            app.CurrentPeakEditField = uieditfield(app.PeakInspectorUIFigure, 'numeric');
            app.CurrentPeakEditField.Limits = [1 Inf];
            app.CurrentPeakEditField.Editable = 'off';
            app.CurrentPeakEditField.Value = 1;
            app.LoadfitsDropDown = uidropdown(app.PeakInspectorUIFigure);
            app.LoadfitsDropDown.Items = {'Lorentzian', 'Double lorentzian'};
            app.LoadfitsDropDown.Enable = 'off';
            app.LoadfitsDropDown.Value = 'Lorentzian';
            app.LoadFitsButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.LoadFitsButton.ButtonPushedFcn = createCallbackFcn(app, @loadFitsCallback, true);
            app.LoadFitsButton.Enable = 'off';
            app.LoadFitsButton.Text = 'Load fits';
            app.LoadPeaksButton = uibutton(app.PeakInspectorUIFigure, 'push');
            app.LoadPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @loadPeaksCallback, true);
            app.LoadPeaksButton.Enable = 'off';
            app.LoadPeaksButton.Text = 'Load peaks';
            app.PeakpathEditFieldLabel = uilabel(app.PeakInspectorUIFigure);
            app.PeakpathEditFieldLabel.Enable = 'off';
            app.PeakpathEditFieldLabel.Text = 'Peak path';
            app.PeakPathEditField = uieditfield(app.PeakInspectorUIFigure, 'text');
            app.PeakPathEditField.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MyPeakInspector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PeakInspectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PeakInspectorUIFigure)
        end
    end
end