classdef appDesignerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        Panel       matlab.ui.container.Panel
        Button_3    matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        Button      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStartup(app)
            % Put a layout in the panel
            g = uix.GridFlex( 'Parent', app.Panel, ...
                'Units', 'normalized', ...
                'Position', [0, 0, 1, 1], ...
                'Spacing', 5, ...
                'Padding', 5 );
            uix.BoxPanel( 'Parent', g, 'Title', 'Panel 1' );
            uix.BoxPanel( 'Parent', g, 'Title', 'Panel 2' );
            uix.BoxPanel( 'Parent', g, 'Title', 'Panel 3' );
            uix.BoxPanel( 'Parent', g, 'Title', 'Panel 4' );
            g.Heights = [-1, -1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDesignerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStartup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end