classdef GuiDummyInstrument < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        cmd1EditFieldLabel      matlab.ui.control.Label
        cmd1EditField           matlab.ui.control.NumericEditField
        cmd2EditFieldLabel      matlab.ui.control.Label
        cmd2EditField           matlab.ui.control.NumericEditField
        cmd31EditFieldLabel     matlab.ui.control.Label
        cmd31EditField          matlab.ui.control.NumericEditField
        cmd32EditFieldLabel     matlab.ui.control.Label
        cmd32EditField          matlab.ui.control.NumericEditField
        cmd34EditFieldLabel     matlab.ui.control.Label
        cmd34EditField          matlab.ui.control.NumericEditField
        ReadtraceButton         matlab.ui.control.Button
        TracetypeDropDownLabel  matlab.ui.control.Label
        TracetypeDropDown       matlab.ui.control.DropDown
        ReadntracesButton       matlab.ui.control.Button
        nEditFieldLabel         matlab.ui.control.Label
        nEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        GuiSync
        Instr
    end
    
    methods (Access = private)
        function createLinks(app)  
            addLink(app.GuiSync, app.cmd1EditField,  'Instr.cmd1');
            addLink(app.GuiSync, app.cmd2EditField,  'Instr.cmd2');
            addLink(app.GuiSync, app.cmd31EditField,  'Instr.cmd3(1)');
            addLink(app.GuiSync, app.cmd32EditField,  'Instr.cmd3(2)');
            addLink(app.GuiSync, app.cmd34EditField,  'Instr.cmd3(4)');
            addLink(app.GuiSync, app.TracetypeDropDown,  'Instr.trace_type');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, 'KernelObj', Instrument);
            
            createLinks(app);
        end

        % Button pushed function: ReadtraceButton
        function ReadtraceButtonPushed(app, event)
            readTrace(app.Instr);
        end

        % Button pushed function: ReadntracesButton
        function ReadntracesButtonPushed(app, event)
            readTraces(app.Instr, app.nEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.cmd1EditFieldLabel = uilabel(app.UIFigure);
            app.cmd1EditFieldLabel.Text = 'cmd1';
            app.cmd1EditField = uieditfield(app.UIFigure, 'numeric');
            app.cmd2EditFieldLabel = uilabel(app.UIFigure);
            app.cmd2EditFieldLabel.Text = 'cmd2';
            app.cmd2EditField = uieditfield(app.UIFigure, 'numeric');
            app.cmd2EditField.Editable = 'off';
            app.cmd31EditFieldLabel = uilabel(app.UIFigure);
            app.cmd31EditFieldLabel.Text = 'cmd3 (1)';
            app.cmd31EditField = uieditfield(app.UIFigure, 'numeric');
            app.cmd32EditFieldLabel = uilabel(app.UIFigure);
            app.cmd32EditFieldLabel.Text = 'cmd3 (2)';
            app.cmd32EditField = uieditfield(app.UIFigure, 'numeric');
            app.cmd34EditFieldLabel = uilabel(app.UIFigure);
            app.cmd34EditFieldLabel.Text = 'cmd3 (4)';
            app.cmd34EditField = uieditfield(app.UIFigure, 'numeric');
            app.ReadtraceButton = uibutton(app.UIFigure, 'push');
            app.ReadtraceButton.ButtonPushedFcn = createCallbackFcn(app, @ReadtraceButtonPushed, true);
            app.ReadtraceButton.Text = 'Read trace';
            app.TracetypeDropDownLabel = uilabel(app.UIFigure);
            app.TracetypeDropDownLabel.Text = 'Trace type';
            app.TracetypeDropDown = uidropdown(app.UIFigure);
            app.TracetypeDropDown.Items = {'zero', 'exp', 'lorentz'};
            app.TracetypeDropDown.Value = 'zero';
            app.ReadntracesButton = uibutton(app.UIFigure, 'push');
            app.ReadntracesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadntracesButtonPushed, true);
            app.ReadntracesButton.Text = 'Read n traces';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiDummyInstrument(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end