classdef DataAcquisitionMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataacquisitionmenuUIFigure    matlab.ui.Figure
        MainTabGroup                   matlab.ui.container.TabGroup
        ProgramTab                     matlab.ui.container.Tab
        RunButton                      matlab.ui.control.Button
        InstrumentmanagerButton        matlab.ui.control.Button
        ReloadlistButton               matlab.ui.control.Button
        ProgramsListBoxLabel           matlab.ui.control.Label
        ProgramListBox                 matlab.ui.control.ListBox
        RuninbackgroundButton          matlab.ui.control.Button
        StartnewDAQButton              matlab.ui.control.Button
        AnalysisroutinesTab            matlab.ui.container.Tab
        RemoveAnalysisRoutineButton    matlab.ui.control.Button
        AddAnalysisRoutineButton       matlab.ui.control.Button
        AnalysisRoutineTable           matlab.ui.control.Table
        AnalysisroutinesLabel          matlab.ui.control.Label
        SettingsTab                    matlab.ui.container.Tab
        MeasurementbasedirectoryEditFieldLabel  matlab.ui.control.Label
        MeasBaseDirEditField           matlab.ui.control.EditField
        MeasBaseDirBrowseButton        matlab.ui.control.Button
        InstrumentcolorschemeDropDownLabel  matlab.ui.control.Label
        InstrumentcolorschemeDropDown  matlab.ui.control.DropDown
        DefaultlogdirectoryEditFieldLabel  matlab.ui.control.Label
        DefaultLogDirEditField         matlab.ui.control.EditField
        DefaultLogDirBrowseButton      matlab.ui.control.Button
        DAQcolorschemeDropDownLabel    matlab.ui.control.Label
        DAQcolorschemeDropDown         matlab.ui.control.DropDown
        CollectorTab                   matlab.ui.container.Tab
        CollectHeadersButton           matlab.ui.control.Button
        InstrumentList                 matlab.ui.control.ListBox
        InstrumentPanel                matlab.ui.container.Panel
        HeaderFlagCheckBox             matlab.ui.control.CheckBox
        DeleteinstrumentButton         matlab.ui.control.Button
        SavesessionButton              matlab.ui.control.Button
        LoadsessionButton              matlab.ui.control.Button
        LocalbasedirectoryEditFieldLabel  matlab.ui.control.Label
        LocalbasedirEditField          matlab.ui.control.EditField
        InfoLabel                      matlab.ui.control.Label
        HeaderTextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        ProgList     MyProgramDescriptor
        Collector
    end
    
    properties (Access = private)
        Listeners = struct
    end
    
    methods (Access = private)

        function updateProgramList(app)
            
            % Reload the list of local instrument control programs
            FullProgList = getIcPrograms();
            ind = [FullProgList.enabled];
            
            app.ProgList = FullProgList(ind);
            
            app.ProgramListBox.Items = {app.ProgList.title};
            app.ProgramListBox.ItemsData = 1:length(app.ProgList);
            
            if isempty(app.ProgramListBox.Value) && ~isempty(app.ProgList)
                app.ProgramListBox.Value = 1;
            end
            
            % Enable state of the run buttons depends on the current selection in the list
            updateRunButtonsAccess(app);
        end
        
        function updateHeaderTextArea(app)
            switch app.MainTabGroup.SelectedTab
                case app.ProgramTab
                    ind = app.ProgramListBox.Value;
                    
                    if ~isempty(ind)
                        app.HeaderTextArea.Value = app.ProgList(ind).info;
                    else
                        app.HeaderTextArea.Value = '';
                    end
                case app.SettingsTab
                    app.HeaderTextArea.Value = '';
            end
        end
        
        function updateInstrList(app)
            app.InstrumentList.Items = app.Collector.running_instruments;
            
            if ~isempty(app.InstrumentList.Items)
                if isempty(app.InstrumentList.Value)
                    
                    % Set the selection to the first element if none is
                    % selected
                    app.InstrumentList.Value = app.InstrumentList.Items{1};
                end
                name = app.InstrumentList.Value;
                
                app.InstrumentList.Value = name;
                app.InstrumentPanel.Title = name;
                app.DeleteinstrumentButton.Enable = 'on';
                
                app.HeaderFlagCheckBox.Enable = 'on';
                app.HeaderFlagCheckBox.Value = getInstrumentProp(app.Collector, ...
                    name, 'collect_header');
            else
                app.InstrumentPanel.Title = 'Instrument Name';
                app.HeaderFlagCheckBox.Enable = 'off';
                app.DeleteinstrumentButton.Enable = 'off';
            end
        end
    
        function updateLocalSettingsDisp(app)
            S = getLocalSettings();
                    
            try
                app.MeasBaseDirEditField.Value = S.measurement_base_dir;
            catch
            end
            
            try
                app.DefaultLogDirEditField.Value = S.default_log_dir;
            catch
            end
            
            try
                app.InstrumentcolorschemeDropDown.Value = S.ColorScheme.name;
            catch
            end
            
            try
                app.DAQcolorschemeDropDown.Value = S.DaqColorScheme.name;
            catch
            end
        end
        
        function updateAnalysisRoutineDisp(app)
            S = getLocalSettings();
            
            try
                
                % Ensure column format for proper conversion to cell
                List = S.AnalysisRoutineList(:);
                
                % Display the values of structure fields in the table
                app.AnalysisRoutineTable.Data = struct2cell(List)';
            catch
            end
        end
    
        function saveAnalysisRoutineList(app)
            
            % Convert the table data to structure array
            S = cell2struct(app.AnalysisRoutineTable.Data', {'title', 'function'});
            
            % Save the array
            setLocalSettings('AnalysisRoutineList', S);
        end
        
        % Delete all the stored listeners
        function deleteListeners(app)
            fn = fieldnames(app.Listeners);
            for i = 1:length(fn)
                obj_name = fn{i};
                
                event_names = fieldnames(app.Listeners.(obj_name));
                for j = 1:length(event_names)
                    delete(app.Listeners.(obj_name).(event_names{j}));
                end
            end
        end
    
        function updateRunButtonsAccess(app)
            ind = app.ProgramListBox.Value;
            
            if ~isempty(ind)
                
                if isempty(app.ProgList(ind).run_expr)
                    app.RunButton.Enable = 'off';
                else
                    app.RunButton.Enable = 'on';
                end
                
                if isempty(app.ProgList(ind).run_bg_expr)
                    app.RuninbackgroundButton.Enable = 'off';
                else
                    app.RuninbackgroundButton.Enable = 'on';
                end
            else
                app.RunButton.Enable = 'off';
                app.RuninbackgroundButton.Enable = 'off';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            runSingletonApp(app);
            
            % Get the unique instance of Collector and initialize its GUI tab
            app.Collector = MyCollector.instance();
            updateInstrList(app);
            
            app.Listeners.Collector.InstrChange = ...
                addlistener(app.Collector, 'InstrProps', 'PostSet', @(~,~) updateInstrList(app));
            
            % The line below throws an error if the local directory is not set up
            app.LocalbasedirEditField.Value = getLocalBaseDir();
            
            updateProgramList(app);
        end

        % Close request function: DataacquisitionmenuUIFigure
        function DataacquisitionmenuUIFigureCloseRequest(app, event)
            deleteListeners(app);
            delete(app);
        end

        % Selection change function: MainTabGroup
        function MainTabGroupSelectionChanged(app, event)
            selectedTab = app.MainTabGroup.SelectedTab;
            
            switch selectedTab
                case app.ProgramTab
                    updateProgramList(app);
                case app.SettingsTab
                    updateLocalSettingsDisp(app);
                case app.AnalysisroutinesTab
                    updateAnalysisRoutineDisp(app);
                case app.CollectorTab
                    updateInstrList(app);
            end
            app.HeaderTextArea.Value = '';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            ind = app.ProgramListBox.Value;
            if ~isempty(ind)
                eval([app.ProgList(ind).run_expr,';']);
            end
        end

        % Button pushed function: ReloadlistButton
        function ReloadlistButtonPushed(app, event)
            updateProgramList(app);
        end

        % Button pushed function: InstrumentmanagerButton
        function InstrumentmanagerButtonPushed(app, event)
            InstrumentManager();
        end

        % Button pushed function: MeasBaseDirBrowseButton
        function MeasBaseDirBrowseButtonPushed(app, event)
            dir_name = uigetdir();
            if dir_name~=0
                
                % if a new file was choosen
                app.MeasBaseDirEditField.Value = dir_name;
                setLocalSettings('measurement_base_dir', dir_name);
            end
            
            % Bring focus back to the app window
            setFocus(app);
        end

        % Value changed function: MeasBaseDirEditField
        function MeasBaseDirEditFieldValueChanged(app, event)
            value = app.MeasBaseDirEditField.Value;
            setLocalSettings('measurement_base_dir', value);
        end

        % Value changed function: InstrumentList
        function InstrListChangeCallback(app, event)
            updateInstrList(app);
        end

        % Button pushed function: CollectHeadersButton
        function CollectHeadersCallback(app, event)
            Mdt = acquireHeaders(app.Collector, 'add_collector_metadata', true);
            app.HeaderTextArea.Value = mdt2str(Mdt);
        end

        % Value changed function: HeaderFlagCheckBox
        function HeaderFlagCallback(app, event)
            value = app.HeaderFlagCheckBox.Value;
            instr_name = app.InstrumentList.Value;
            setInstrumentProp(app.Collector, instr_name, 'collect_header', value);
        end

        % Value changed function: ProgramListBox
        function ProgramListBoxValueChanged(app, event)
            ind = app.ProgramListBox.Value;
            if ~isempty(ind)
                app.HeaderTextArea.Value = app.ProgList(ind).info;
            else
                app.HeaderTextArea.Value = '';
            end

            updateRunButtonsAccess(app);
        end

        % Button pushed function: RuninbackgroundButton
        function RuninbackgroundButtonPushed(app, event)
            ind = app.ProgramListBox.Value;
            if ~isempty(ind)
                eval([app.ProgList(ind).run_bg_expr,';']);
            end
        end

        % Button pushed function: DeleteinstrumentButton
        function DeleteinstrumentButtonPushed(app, event)
            name = app.InstrumentList.Value;
            if ~isempty(name)
                Instr = getInstrument(app.Collector, name);
                delete(Instr);
            end
            updateInstrList(app);
        end

        % Value changed function: InstrumentcolorschemeDropDown
        function InstrumentcolorschemeDropDownValueChanged(app, event)
            S = struct();
            S.name = lower(app.InstrumentcolorschemeDropDown.Value);
            
            switch S.name
                case 'default'
                    S.colorSchemeFcn = @(x)MyAppColors.applyScheme(x, 'default');
                case 'dark'
                    S.colorSchemeFcn = @(x)MyAppColors.applyScheme(x, 'dark');
                case 'bright'
                    S.colorSchemeFcn = @(x)MyAppColors.applyScheme(x, 'bright');
                otherwise
                    error('Unknown color scheme %s', value)
            end
            
            try
                
                % Recolor running apps, except MyDaqs
                app_names = app.Collector.running_apps;
                
                for i = 1:length(app_names)
                    RunningApp = getApp(app.Collector, app_names{i});
                    if ~isa(RunningApp, 'MyDaq')
                        S.colorSchemeFcn(findFigure(RunningApp));
                    end
                end
            catch ME
                warning(['Could not recolor the running apps. Error: ' ME.message]);
            end
            
            try
                
                % Recolor the GUIs of all running instruments
                instr_names = app.Collector.running_instruments;
                
                for i = 1:length(instr_names)
                    Instr = getInstrument(app.Collector, instr_names{i});
                    Fig = findFigure(Instr);
                    if ~isempty(Fig)
                        S.colorSchemeFcn(Fig);
                    end
                end
            catch ME
                warning(['Could not recolor the instrument GUIs. Error: ' ME.message]);
            end
            
            % Save the new color scheme
            setLocalSettings('ColorScheme', S);
        end

        % Value changed function: DAQcolorschemeDropDown
        function DAQcolorschemeDropDownValueChanged(app, event)
             S = struct();
             S.name = lower(app.DAQcolorschemeDropDown.Value);
             
             switch S.name
                 case 'default'
                     S.colorSchemeFcn = @(x)MyAppColors.applyScheme(x, 'default');
                 case 'dark'
                     S.colorSchemeFcn = @(x)MyAppColors.applyScheme(x, 'dark');
                 case 'bright'
                     S.colorSchemeFcn = @(x)MyAppColors.applyScheme(x, 'bright');
                 otherwise
                     error('Unknown color scheme %s', value)
            end
            
            % Save the new color scheme
            setLocalSettings('DaqColorScheme', S);
            
            try
                
                % Recolor running MyDaqs
                app_names = app.Collector.running_apps;
                
                for i = 1:length(app_names)
                    RunningApp = getApp(app.Collector, app_names{i});
                    if isa(RunningApp, 'MyDaq')
                        
                        % Here the function applyLocalColorScheme is a method of
                        % MyDaq in this case
                        applyLocalColorScheme(RunningApp);
                    end
                end
            catch ME
                warning(['Could not recolor MyDaq apps. Error: ' ME.message]);
            end
        end

        % Value changed function: DefaultLogDirEditField
        function DefaultLogDirEditFieldValueChanged(app, event)
            value = app.DefaultLogDirEditField.Value;
            setLocalSettings('default_log_dir', value);
        end

        % Button pushed function: DefaultLogDirBrowseButton
        function DefaultLogDirBrowseButtonPushed(app, event)
            dir_name = uigetdir();
            if dir_nam ~= 0
                
                % if a new file was choosen
                app.DefaultLogDirEditField.Value = dir_name;
                setLocalSettings('default_log_dir', dir_name);
            end
            
            % Bring focus back to the app window
            setFocus(app);
        end

        % Button pushed function: LoadsessionButton
        function LoadsessionButtonPushed(app, event)
            try
                default_dir = getLocalSettings('measurement_base_dir');
            catch
                default_dir = '';
            end
            
            [file_name, path] = uigetfile('*.*', '', default_dir);
            
            if file_name ~= 0
                runSession(fullfile(path, file_name));
            end
        end

        % Button pushed function: SavesessionButton
        function SavesessionButtonPushed(app, event)
            try
                default_dir = getLocalSettings('measurement_base_dir');
            catch
                default_dir = '';
            end
            
            [file_name, path] = uiputfile('*.*', 'Save information about session', ...
                fullfile(default_dir, 'MeasurementSession.txt'));
            
            if file_name ~= 0
                
                % Acquire measurement headers and save them in the file
                % chosen by the user
                Mdt = acquireHeaders(app.Collector, 'add_collector_metadata', true);
                
                save(Mdt, fullfile(path,file_name), 'overwrite', true);
                
                disp(['Session info is saved to ' fullfile(path,file_name)])
            end
            
            setFocus(app.DataacquisitionmenuUIFigure);
        end

        % Button pushed function: AddAnalysisRoutineButton
        function AddAnalysisRoutineButtonPushed(app, event)
            if isempty(app.AnalysisRoutineTable.Data)
                app.AnalysisRoutineTable.Data = {'AnalysisRoutine', 'FunctionName'};
            else
                title = matlab.lang.makeUniqueStrings('AnalysisRoutine', ...
                    app.AnalysisRoutineTable.Data(:, 1));
                
                func_name = matlab.lang.makeUniqueStrings('FunctionName', ...
                    app.AnalysisRoutineTable.Data(:, 2));
                
                app.AnalysisRoutineTable.Data(end+1, :) = {title, func_name};
            end
            
            saveAnalysisRoutineList(app);
        end

        % Button pushed function: RemoveAnalysisRoutineButton
        function RemoveAnalysisRoutineButtonPushed(app, event)
            try 
                sel_ind = app.AnalysisRoutineTable.UserData.selected_indices;
                app.AnalysisRoutineTable.Data(sel_ind(:, 1), :) = [];
                
                saveAnalysisRoutineList(app);
            catch
                
                % The statements above can fail for a few reasons,
                % including the selection being empty or the table data
                % being empty, we do nothing in such case. 
            end
        end

        % Cell selection callback: AnalysisRoutineTable
        function AnalysisRoutineTableCellSelection(app, event)
            
            % Store the selected indices under UserData
            app.AnalysisRoutineTable.UserData.selected_indices = event.Indices;
        end

        % Cell edit callback: AnalysisRoutineTable
        function AnalysisRoutineTableCellEdit(app, event)
            saveAnalysisRoutineList(app);
        end

        % Button pushed function: StartnewDAQButton
        function StartnewDAQButtonPushed(app, event)
            MyDaq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataacquisitionmenuUIFigure = uifigure('Visible', 'off');
            app.DataacquisitionmenuUIFigure.Name = 'Data acquisition menu';
            app.DataacquisitionmenuUIFigure.CloseRequestFcn = createCallbackFcn(app, @DataacquisitionmenuUIFigureCloseRequest, true);
            app.MainTabGroup = uitabgroup(app.DataacquisitionmenuUIFigure);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MainTabGroupSelectionChanged, true);
            app.ProgramTab = uitab(app.MainTabGroup);
            app.ProgramTab.Title = 'Programs';
            app.RunButton = uibutton(app.ProgramTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Start selected instrument'};
            app.RunButton.Text = 'Run';
            app.InstrumentmanagerButton = uibutton(app.ProgramTab, 'push');
            app.InstrumentmanagerButton.ButtonPushedFcn = createCallbackFcn(app, @InstrumentmanagerButtonPushed, true);
            app.InstrumentmanagerButton.Text = 'Instrument manager';
            app.ReloadlistButton = uibutton(app.ProgramTab, 'push');
            app.ReloadlistButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadlistButtonPushed, true);
            app.ReloadlistButton.Text = 'Reload list';
            app.ProgramsListBoxLabel = uilabel(app.ProgramTab);
            app.ProgramsListBoxLabel.Text = 'Programs';
            app.ProgramListBox = uilistbox(app.ProgramTab);
            app.ProgramListBox.Items = {};
            app.ProgramListBox.ValueChangedFcn = createCallbackFcn(app, @ProgramListBoxValueChanged, true);
            app.ProgramListBox.Value = {};
            app.RuninbackgroundButton = uibutton(app.ProgramTab, 'push');
            app.RuninbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @RuninbackgroundButtonPushed, true);
            app.RuninbackgroundButton.Tooltip = {'Start selected instrument without GUI'};
            app.RuninbackgroundButton.Text = 'Run in background';
            app.StartnewDAQButton = uibutton(app.ProgramTab, 'push');
            app.StartnewDAQButton.ButtonPushedFcn = createCallbackFcn(app, @StartnewDAQButtonPushed, true);
            app.StartnewDAQButton.Text = 'Start new DAQ';
            app.AnalysisroutinesTab = uitab(app.MainTabGroup);
            app.AnalysisroutinesTab.Title = 'Analysis routines';
            app.RemoveAnalysisRoutineButton = uibutton(app.AnalysisroutinesTab, 'push');
            app.RemoveAnalysisRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAnalysisRoutineButtonPushed, true);
            app.RemoveAnalysisRoutineButton.Text = 'Remove';
            app.AddAnalysisRoutineButton = uibutton(app.AnalysisroutinesTab, 'push');
            app.AddAnalysisRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @AddAnalysisRoutineButtonPushed, true);
            app.AddAnalysisRoutineButton.Text = 'Add';
            app.AnalysisRoutineTable = uitable(app.AnalysisroutinesTab);
            app.AnalysisRoutineTable.ColumnName = {'Title'; 'Function'};
            app.AnalysisRoutineTable.RowName = {};
            app.AnalysisRoutineTable.ColumnEditable = true;
            app.AnalysisRoutineTable.CellEditCallback = createCallbackFcn(app, @AnalysisRoutineTableCellEdit, true);
            app.AnalysisRoutineTable.CellSelectionCallback = createCallbackFcn(app, @AnalysisRoutineTableCellSelection, true);
            app.AnalysisRoutineTable.Tooltip = {'The list of analysis routines displayed in DAQ. Function is a MATLAB function of class constructor that creates object.'};
            app.AnalysisroutinesLabel = uilabel(app.AnalysisroutinesTab);
            app.AnalysisroutinesLabel.Text = 'Analysis routines';
            app.SettingsTab = uitab(app.MainTabGroup);
            app.SettingsTab.Title = 'Settings';
            app.MeasurementbasedirectoryEditFieldLabel = uilabel(app.SettingsTab);
            app.MeasurementbasedirectoryEditFieldLabel.Text = 'Measurement base directory';
            app.MeasBaseDirEditField = uieditfield(app.SettingsTab, 'text');
            app.MeasBaseDirEditField.ValueChangedFcn = createCallbackFcn(app, @MeasBaseDirEditFieldValueChanged, true);
            app.MeasBaseDirEditField.Value = 'M:\Measurement Campaigns\';
            app.MeasBaseDirBrowseButton = uibutton(app.SettingsTab, 'push');
            app.MeasBaseDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @MeasBaseDirBrowseButtonPushed, true);
            app.MeasBaseDirBrowseButton.Text = 'Browse';
            app.InstrumentcolorschemeDropDownLabel = uilabel(app.SettingsTab);
            app.InstrumentcolorschemeDropDownLabel.Text = 'Instrument color scheme';
            app.InstrumentcolorschemeDropDown = uidropdown(app.SettingsTab);
            app.InstrumentcolorschemeDropDown.Items = {'default', 'dark', 'bright'};
            app.InstrumentcolorschemeDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentcolorschemeDropDownValueChanged, true);
            app.InstrumentcolorschemeDropDown.Value = 'default';
            app.DefaultlogdirectoryEditFieldLabel = uilabel(app.SettingsTab);
            app.DefaultlogdirectoryEditFieldLabel.Text = 'Default log directory';
            app.DefaultLogDirEditField = uieditfield(app.SettingsTab, 'text');
            app.DefaultLogDirEditField.ValueChangedFcn = createCallbackFcn(app, @DefaultLogDirEditFieldValueChanged, true);
            app.DefaultLogDirBrowseButton = uibutton(app.SettingsTab, 'push');
            app.DefaultLogDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultLogDirBrowseButtonPushed, true);
            app.DefaultLogDirBrowseButton.Text = 'Browse';
            app.DAQcolorschemeDropDownLabel = uilabel(app.SettingsTab);
            app.DAQcolorschemeDropDownLabel.Text = 'DAQ color scheme';
            app.DAQcolorschemeDropDown = uidropdown(app.SettingsTab);
            app.DAQcolorschemeDropDown.Items = {'default', 'dark', 'bright'};
            app.DAQcolorschemeDropDown.ValueChangedFcn = createCallbackFcn(app, @DAQcolorschemeDropDownValueChanged, true);
            app.DAQcolorschemeDropDown.Value = 'default';
            app.CollectorTab = uitab(app.MainTabGroup);
            app.CollectorTab.Title = 'Collector';
            app.CollectHeadersButton = uibutton(app.CollectorTab, 'push');
            app.CollectHeadersButton.ButtonPushedFcn = createCallbackFcn(app, @CollectHeadersCallback, true);
            app.CollectHeadersButton.Text = 'Collect headers';
            app.InstrumentList = uilistbox(app.CollectorTab);
            app.InstrumentList.Items = {};
            app.InstrumentList.ValueChangedFcn = createCallbackFcn(app, @InstrListChangeCallback, true);
            app.InstrumentList.Value = {};
            app.InstrumentPanel = uipanel(app.CollectorTab);
            app.InstrumentPanel.Title = 'Instrument name';
            app.HeaderFlagCheckBox = uicheckbox(app.InstrumentPanel);
            app.HeaderFlagCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeaderFlagCallback, true);
            app.HeaderFlagCheckBox.Text = 'Collect header';
            app.DeleteinstrumentButton = uibutton(app.InstrumentPanel, 'push');
            app.DeleteinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteinstrumentButtonPushed, true);
            app.DeleteinstrumentButton.Text = 'Delete instrument';
            app.SavesessionButton = uibutton(app.CollectorTab, 'push');
            app.SavesessionButton.ButtonPushedFcn = createCallbackFcn(app, @SavesessionButtonPushed, true);
            app.SavesessionButton.Text = 'Save session';
            app.LoadsessionButton = uibutton(app.CollectorTab, 'push');
            app.LoadsessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsessionButtonPushed, true);
            app.LoadsessionButton.Text = 'Load session';
            app.LocalbasedirectoryEditFieldLabel = uilabel(app.DataacquisitionmenuUIFigure);
            app.LocalbasedirectoryEditFieldLabel.Text = 'Local base directory';
            app.LocalbasedirEditField = uieditfield(app.DataacquisitionmenuUIFigure, 'text');
            app.LocalbasedirEditField.Editable = 'off';
            app.InfoLabel = uilabel(app.DataacquisitionmenuUIFigure);
            app.InfoLabel.Text = 'Info';
            app.HeaderTextArea = uitextarea(app.DataacquisitionmenuUIFigure);
            app.HeaderTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.DataacquisitionmenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAcquisitionMenu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataacquisitionmenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataacquisitionmenuUIFigure)
        end
    end
end