classdef FindInstrumentDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FindInstrument                matlab.ui.Figure
        InterfaceDropDownLabel        matlab.ui.control.Label
        InterfaceDropDown             matlab.ui.control.DropDown
        AdaptorDropDownLabel          matlab.ui.control.Label
        AdaptorDropDown               matlab.ui.control.DropDown
        ConstructornameDropDownLabel  matlab.ui.control.Label
        ConstructornameDropDown       matlab.ui.control.DropDown
        RefreshButton                 matlab.ui.control.Button
        IDNButton                     matlab.ui.control.Button
        ResponseEditFieldLabel        matlab.ui.control.Label
        ResponseEditField             matlab.ui.control.EditField
        ContinueButton                matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        SearchstatusLabel             matlab.ui.control.Label
        ErrorwarningTextAreaLabel     matlab.ui.control.Label
        ErrorwarningTextArea          matlab.ui.control.TextArea
    end


    properties (Access = public)
        Ret % variable with the value passed by reference to return the choosen device
    end

    properties (Constant = true, Access = private)
        DEFAULT_TIMEOUT = 1; % timeout for communication
        NORMAL_COLOR = [0.47, 0.67, 0.19];
        ERROR_COLOR = [1, 0, 0];
        WARNING_COLOR = [0.93, 0.69, 0.13];
    end
   
    methods (Access = private)
    
        function initVisaMenus(app)
            app.AdaptorDropDown.Enable = 'on';
            app.ConstructornameDropDownLabel.Text = 'Constructor name';
            ad = instrhwinfo('visa');
            app.AdaptorDropDown.Items = ad.InstalledAdaptors;
            
            if ismember('ni',ad.InstalledAdaptors)
               app.AdaptorDropDown.Value = 'ni'; 
            elseif isempty(ad.InstalledAdaptors)
                app.ConstructornameDropDown.Items = {};
                app.ErrorwarningTextArea.Value = sprintf('No VISA adaptors installed');
                app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
            end
        end
        
        function findVisaGpibInstruments(app)
            % works with either 'visa' of 'gpib' values of InterfaceDropDown
            interface = app.InterfaceDropDown.Value;
            adaptor = app.AdaptorDropDown.Value;
            if isempty(adaptor)
                setSearchIndicator(app,'completed');
                app.ConstructornameDropDown.Items = {};
                app.ErrorwarningTextArea.Value = sprintf('Instruments could not be found, no installed %s adaptors', upper(interface));
                app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
                return
            end
            try
                setSearchIndicator(app,'searching');
                instr_list = instrhwinfo(interface, adaptor);
                app.ConstructornameDropDown.Items = instr_list.ObjectConstructorName;
                setSearchIndicator(app,'completed');
            catch
                app.ConstructornameDropDown.Items = {};
                setSearchIndicator(app,'failed');
                app.ErrorwarningTextArea.Value = sprintf('Could not find VISA or GPIB instruments with %s adaptor',adaptor);
                app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
            end
        end
        
        function findSerialInstruments(app)
            try
                setSearchIndicator(app,'searching');
                instr_list = instrhwinfo('serial');
                app.ConstructornameDropDown.Items = instr_list.ObjectConstructorName;
                setSearchIndicator(app,'completed');
            catch
                app.ConstructornameDropDown.Items = {};
                setSearchIndicator(app,'failed');
                app.ErrorwarningTextArea.Value = 'Could not find serial instruments';
                app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
            end
        end
        
        function findTcpipUdpInstruments(app)
            % works with either 'visa' of 'gpib' values of InterfaceDropDown
            interface = app.InterfaceDropDown.Value;
            try
                setSearchIndicator(app,'searching');
                instr_list = instrhwinfo(interface);
                app.ConstructornameDropDown.Items = instr_list.LocalHost;
                setSearchIndicator(app,'completed');
            catch
                app.ConstructornameDropDown.Items = {};
                setSearchIndicator(app,'failed');
                app.ErrorwarningTextArea.Value = 'Could not find TCPIP or UDP instruments';
                app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
            end
        end
        
        function setSearchIndicator(app, status)
            switch status
                case 'searching'
                    app.SearchstatusLabel.Text = 'Searching ...';
                    app.SearchstatusLabel.FontColor = app.WARNING_COLOR;
                case 'completed'
                    app.SearchstatusLabel.Text = 'Search completed';
                    app.SearchstatusLabel.FontColor = app.NORMAL_COLOR;
                case 'failed'
                    app.SearchstatusLabel.Text = 'Search unsuccessful';
                    app.SearchstatusLabel.FontColor = app.ERROR_COLOR;
                otherwise
                    error('Unknown search status')
            end
        end
        
        function clearRespFields(app)
            app.ErrorwarningTextArea.Value = '';
            app.ResponseEditField.Value = '';
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            initVisaMenus(app);
            clearRespFields(app);
            
            if isempty(varargin)
                app.Ret = MyRefVar('');
            else
                if isequal(class(varargin{1}),'MyRefVar')
                    app.Ret = varargin{1};
                else
                    app.ErrorwarningTextArea.Value = ...
                        ['The input variable class needs to be MyRefVar.',... 
                        ' The selected value will not be returned'];
                    app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
                    app.Ret = MyRefVar('');
                end
            end
        end

        % Value changed function: InterfaceDropDown
        function InterfaceDropDownValueChanged(app, event)
            interface = lower(app.InterfaceDropDown.Value);
            clearRespFields(app);
            switch interface
                case {'visa', 'gpib'}
                    ad = instrhwinfo(interface);
                    app.AdaptorDropDown.Items = ad.InstalledAdaptors;
                    app.AdaptorDropDown.Enable = 'on';
                    app.AdaptorDropDownLabel.Enable = 'on';
                    app.ConstructornameDropDownLabel.Text = 'Constructor name';
                    findVisaGpibInstruments(app);
                case 'serial'
                    app.AdaptorDropDown.Enable = 'off';
                    app.AdaptorDropDownLabel.Enable = 'off';
                    app.ConstructornameDropDownLabel.Text = 'Constructor name';
                    findSerialInstruments(app);
                case {'tcpip', 'udp'}
                    app.AdaptorDropDown.Enable = 'off';
                    app.AdaptorDropDownLabel.Enable = 'off';
                    app.ConstructornameDropDownLabel.Text = 'LocalHost';
                    findTcpipUdpInstruments(app);
            end
        end

        % Value changed function: AdaptorDropDown
        function AdaptorDropDownValueChanged(app, event)
            findVisaGpibInstruments(app);
            clearRespFields(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            interface = lower(app.InterfaceDropDown.Value);
            switch interface
                case {'visa', 'gpib'}
                    findVisaGpibInstruments(app);
                case 'serial'
                    findSerialInstruments(app);
                case {'tcpip', 'udp'}
                    findTcpipUdpInstruments(app);
            end
        end

        % Button pushed function: IDNButton
        function IDNButtonPushed(app, event)
            interface = lower(app.InterfaceDropDown.Value);
            clearRespFields(app);
            switch interface
                case {'visa', 'gpib', 'serial'}
                    ocn = app.ConstructornameDropDown.Value;
                    if isempty(ocn)
                        app.ErrorwarningTextArea.Value = 'No constructor name given';
                        app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
                        return
                    end
                    
                    device = eval(ocn);
                    device.Timeout = app.DEFAULT_TIMEOUT; % s
                    
                    try
                        fopen(device);
                    catch 
                        app.ErrorwarningTextArea.Value = 'Cannot open device';
                        app.ErrorwarningTextArea.FontColor = app.ERROR_COLOR;
                        return
                    end
                    
                    [resp_str,~,msg] = query(device,'*IDN?');
                    fclose(device);
                    delete(device);
                    
                    app.ResponseEditField.Value = resp_str;
                    app.ErrorwarningTextArea.Value = msg;
                    app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
    
                case {'tcpip', 'udp'}
                    app.ErrorwarningTextArea.Value = '*IDN? for TCPIP and UDP connection is not presently supported';
                    app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
            end
        end

        % Callback function: CancelButton, FindInstrument
        function FindInstrumentCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.Ret.value = app.ConstructornameDropDown.Value;
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FindInstrument = uifigure;
            app.FindInstrument.Name = 'Find Instrument';
            app.FindInstrument.CloseRequestFcn = createCallbackFcn(app, @FindInstrumentCloseRequest, true);
            app.InterfaceDropDownLabel = uilabel(app.FindInstrument);
            app.InterfaceDropDownLabel.Text = 'Interface';
            app.InterfaceDropDown = uidropdown(app.FindInstrument);
            app.InterfaceDropDown.Items = {'VISA', 'GPIB', 'Serial', 'TCPIP', 'UDP'};
            app.InterfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @InterfaceDropDownValueChanged, true);
            app.InterfaceDropDown.Value = 'VISA';
            app.AdaptorDropDownLabel = uilabel(app.FindInstrument);
            app.AdaptorDropDownLabel.Text = 'Adaptor';
            app.AdaptorDropDown = uidropdown(app.FindInstrument);
            app.AdaptorDropDown.Items = {'ni', 'tektronix', 'agilent'};
            app.AdaptorDropDown.ValueChangedFcn = createCallbackFcn(app, @AdaptorDropDownValueChanged, true);
            app.AdaptorDropDown.Value = 'ni';
            app.ConstructornameDropDownLabel = uilabel(app.FindInstrument);
            app.ConstructornameDropDownLabel.Text = 'Constructor name';
            app.ConstructornameDropDown = uidropdown(app.FindInstrument);
            app.ConstructornameDropDown.Items = {};
            app.ConstructornameDropDown.Value = {};
            app.RefreshButton = uibutton(app.FindInstrument, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.IDNButton = uibutton(app.FindInstrument, 'push');
            app.IDNButton.ButtonPushedFcn = createCallbackFcn(app, @IDNButtonPushed, true);
            app.IDNButton.Text = '*IDN?';
            app.ResponseEditFieldLabel = uilabel(app.FindInstrument);
            app.ResponseEditFieldLabel.Text = 'Response';
            app.ResponseEditField = uieditfield(app.FindInstrument, 'text');
            app.ResponseEditField.Editable = 'off';
            app.ContinueButton = uibutton(app.FindInstrument, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.CancelButton = uibutton(app.FindInstrument, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @FindInstrumentCloseRequest, true);
            app.CancelButton.Text = 'Cancel';
            app.SearchstatusLabel = uilabel(app.FindInstrument);
            app.SearchstatusLabel.Text = 'Search status';
            app.ErrorwarningTextAreaLabel = uilabel(app.FindInstrument);
            app.ErrorwarningTextAreaLabel.Text = 'Error/warning';
            app.ErrorwarningTextArea = uitextarea(app.FindInstrument);
            app.ErrorwarningTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FindInstrumentDlg(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FindInstrument)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FindInstrument)
        end
    end
end