classdef InstrListManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManagethelistofinstrumentsUIFigure  matlab.ui.Figure
        InstrTable              matlab.ui.control.Table
        AddinstrumentButton     matlab.ui.control.Button
        DeleteinstrumentButton  matlab.ui.control.Button
        SavelistButton          matlab.ui.control.Button
        InstrAddressListlocationEditFieldLabel  matlab.ui.control.Label
        InstrAddressListlocationEditField  matlab.ui.control.EditField
        BrowseButton            matlab.ui.control.Button
        CreatenewlistButton     matlab.ui.control.Button
        StatusLabel             matlab.ui.control.Label
        ReloadlistButton        matlab.ui.control.Button
    end


    properties (Access = public)
        file_name; % path to 'InstrAddressList.mat      
        cell_sel = {}; % selected cells
        
        NORMAL_COLOR = [0.47, 0.67, 0.19];
        ERROR_COLOR = [1, 0, 0];
        WARNING_COLOR = [0.93, 0.69, 0.13];
        
        EDIT_BG_COLOR = [1,1,0.9]; % yellow if changes unsaved
        STD_BG_COLOR = [1,1,1]; % white
    end

    methods (Access = private)
    
        function updateTable(app)
            try
                load(app.file_name,'InstrAddressList');
                app.InstrTable.Data =  [fieldnames(InstrAddressList) ...
                    struct2cell(InstrAddressList)];
                app.InstrAddressListlocationEditField.Value = app.file_name;
                app.StatusLabel.Text = 'Instruments file loaded';
                app.StatusLabel.FontColor = app.NORMAL_COLOR;
                app.InstrTable.BackgroundColor = app.STD_BG_COLOR;
            catch
                app.file_name = app.InstrAddressListlocationEditField.Value;
                app.StatusLabel.Text = ...
                    'Instruments file is not found or is not of expected format';
                app.StatusLabel.FontColor = app.ERROR_COLOR;
            end
        end
        
        function setTableStatus(app, status)
            switch status
                case 'edits_unsaved'
                    app.InstrTable.BackgroundColor = app.EDIT_BG_COLOR;
                    app.StatusLabel.Text = 'List of instruments contains unsaved changes';
                    app.StatusLabel.FontColor = app.WARNING_COLOR;
                case 'edits_saved'
                    app.InstrTable.BackgroundColor = app.STD_BG_COLOR;
                    app.StatusLabel.Text = 'Changes saved';
                    app.StatusLabel.FontColor = app.NORMAL_COLOR;
                otherwise
                    error('Unknown table status')
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.file_name = which('InstrAddressList.mat');
            app.InstrTable.Data = {};
            updateTable(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile();
            app.file_name = fullfile(path, file);
            updateTable(app);
        end

        % Button pushed function: CreatenewlistButton
        function CreatenewlistButtonPushed(app, event)
            path = uigetdir();
            InstrAddressList = struct();
            app.file_name = fullfile(path, 'InstrAddressList.mat');
            save(app.file_name, 'InstrAddressList');
        end

        % Button pushed function: AddinstrumentButton
        function AddinstrumentButtonPushed(app, event)
            newaddr = MyRefVar('');
            waitfor(FindInstrumentDlg(newaddr));
            disp(newaddr);
            if ~isequal(newaddr.value,'')
                app.InstrTable.Data = [app.InstrTable.Data; {'placeholder', newaddr.value}];
                setTableStatus(app, 'edits_unsaved');
            end
        end

        % Cell edit callback: InstrTable
        function InstrTableCellEdit(app, event)
            setTableStatus(app, 'edits_unsaved');
        end

        % Button pushed function: SavelistButton
        function SavelistButtonPushed(app, event)
            InstrAddressList = struct();
            data_size = size(app.InstrTable.Data);
            for i=1:data_size(1)
                InstrAddressList.(app.InstrTable.Data{i,1}) = app.InstrTable.Data{i,2};
            end
            save(app.file_name, 'InstrAddressList');            
            setTableStatus(app, 'edits_saved');
        end

        % Button pushed function: ReloadlistButton
        function ReloadlistButtonPushed(app, event)
            updateTable(app);
        end

        % Button pushed function: DeleteinstrumentButton
        function DeleteinstrumentButtonPushed(app, event)
            del_row_ind = unique(app.cell_sel(:,1));
            app.InstrTable.Data(del_row_ind, :) = [];
            setTableStatus(app, 'edits_unsaved');
        end

        % Cell selection callback: InstrTable
        function InstrTableCellSelection(app, event)
            app.cell_sel = event.Indices;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManagethelistofinstrumentsUIFigure = uifigure;
            app.ManagethelistofinstrumentsUIFigure.Name = 'Manage the list of instruments';
            app.InstrTable = uitable(app.ManagethelistofinstrumentsUIFigure);
            app.InstrTable.ColumnName = {'name'; 'address'};
            app.InstrTable.RowName = {};
            app.InstrTable.ColumnEditable = [true false];
            app.InstrTable.CellEditCallback = createCallbackFcn(app, @InstrTableCellEdit, true);
            app.InstrTable.CellSelectionCallback = createCallbackFcn(app, @InstrTableCellSelection, true);
            app.AddinstrumentButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.AddinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @AddinstrumentButtonPushed, true);
            app.AddinstrumentButton.Text = 'Add instrument';
            app.DeleteinstrumentButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.DeleteinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteinstrumentButtonPushed, true);
            app.DeleteinstrumentButton.Text = 'Delete instrument';
            app.SavelistButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.SavelistButton.ButtonPushedFcn = createCallbackFcn(app, @SavelistButtonPushed, true);
            app.SavelistButton.Text = 'Save list';
            app.InstrAddressListlocationEditFieldLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.InstrAddressListlocationEditFieldLabel.Text = 'InstrAddressList location';
            app.InstrAddressListlocationEditField = uieditfield(app.ManagethelistofinstrumentsUIFigure, 'text');
            app.InstrAddressListlocationEditField.Editable = 'off';
            app.BrowseButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.CreatenewlistButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.CreatenewlistButton.ButtonPushedFcn = createCallbackFcn(app, @CreatenewlistButtonPushed, true);
            app.CreatenewlistButton.Text = 'Create new list';
            app.StatusLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.StatusLabel.Text = 'Status';
            app.ReloadlistButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.ReloadlistButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadlistButtonPushed, true);
            app.ReloadlistButton.Text = 'Reload list';
        end
    end

    methods (Access = public)

        % Construct app
        function app = InstrListManager

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManagethelistofinstrumentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManagethelistofinstrumentsUIFigure)
        end
    end
end