classdef Setup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure         matlab.ui.Figure
        BaseDirEditField     matlab.ui.control.EditField
        MeasDirEditField     matlab.ui.control.EditField
        InfoLabel            matlab.ui.control.Label
        InfoLabel2           matlab.ui.control.Label
        CreatethesettingsfileandbasedirectoryLabel  matlab.ui.control.Label
        BrowseBaseDirButton  matlab.ui.control.Button
        BrowseMeasDirButton  matlab.ui.control.Button
        ContinueButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerFigure(app.MainUIFigure);
            
            try
                Settings = getLocalSettings();
                
                % Disable all UI components if the file with settings is found
                cdrn = app.MainUIFigure.Children;
                set(findall(cdrn, '-property', 'Enable'), 'Enable', 'off');
                disp('The local base directory is already set up.')
                
                uialert(app.MainUIFigure , ...
                    ['Local base directory already exists. ' ...
                    'Please remove LocalInstrumentControlSettings.m from Matlab path ' ...
                    'if you wish to repeat the setup.'], ...
                    'Local directory is already set up', 'Icon', 'info');
                
                try
                    app.BaseDirEditField.Value = getLocalBaseDir();
                    app.MeasDirEditField.Value = Settings.measurement_base_dir;
                catch
                end
            catch
            end
        end

        % Button pushed function: BrowseBaseDirButton
        function BrowseBaseDirButtonPushed(app, event)
            dir = uigetdir();
            if dir~=0
                % if a new file was choosen
                app.BaseDirEditField.Value = dir;
            end
            % Bring focus back to the window
            app.MainUIFigure.Visible = 'off';
            app.MainUIFigure.Visible = 'on';
        end

        % Button pushed function: BrowseMeasDirButton
        function BrowseMeasDirButtonPushed(app, event)
            dir = uigetdir();
            if dir~=0
                % if a new file was choosen
                app.MeasDirEditField.Value = dir;
            end
            % Bring focus back to the window
            app.MainUIFigure.Visible = 'off';
            app.MainUIFigure.Visible = 'on';
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            if isfolder(app.BaseDirEditField.Value)
                file_name = fullfile(app.BaseDirEditField.Value,...
                    'LocalInstrumentControlSettings.mat');
                
                % Create an empty instrument list
                InstrumentList = MyInstrumentDescriptor.empty();
                save(file_name,'InstrumentList');
                if isfolder(app.MeasDirEditField.Value)
                    
                    % append the measurement base directory if specified
                    measurement_base_dir = app.MeasDirEditField.Value;
                    save(file_name,'measurement_base_dir','-append');
                end
                
                % Add the local instrument control directory on Matlab path
                addpath(app.BaseDirEditField.Value);
                savepath();
                
                % Copy default run files to the local base directory
                try
                    ic_dir = getInstrumentControlDir();
                    
                    copyfile([ic_dir,'\Local\sourceRunLogViewer.m'], ...
                        fullfile(app.BaseDirEditField.Value, 'runLogViewer.m'));
                catch
                    warning(['Could not copy the default run files to the local base directory.' ...
                        'You can copy them manually from instrument-control\Local.'])
                end
                
                % Set color schemes
                try
                    S = struct();
                    S.name = 'dark';
                    S.colorSchemeFcn = MyAppColors.createColorSchemeFcn(S.name);
                    setLocalSettings('ColorScheme', S);
                    
                    S.name = 'bright';
                    S.colorSchemeFcn = MyAppColors.createColorSchemeFcn(S.name);
                    setLocalSettings('DaqColorScheme', S);
                catch
                    disp('Keeping default color scheme')
                end
                
                msg = 'Would you like to create Desktop shortcuts (Windows only)?';
                title = 'Desktop shortcuts';
                sel = uiconfirm(app.MainUIFigure, msg, title, 'Options', {'yes','no'},...
                           'DefaultOption', 1, 'CancelOption', 2);
                
                if isequal(sel, 'yes')
                    try
                        
                        % Create a symbolic link, works for windows only 
                        % Thanks to https://ch.mathworks.com/matlabcentral/fileexchange/34948-createlinks
                        try
                            path = getSpecialFolderPath('Desktop');
                        catch
                            disp(['Could not get the user Desktop directory. ' ...
                                'Try creating a shortcut in the instrument control ' ...
                                'local base directory instead.'])
                            path = app.BaseDirEditField.Value;
                        end
                        
                        Asvr = actxserver('WScript.Shell');
                        
                        % Create icon for the Daq menu
                        Sc = Asvr.CreateShortcut(fullfile(path, 'DAQ menu.lnk'));
                        Sc.TargetPath = which('MyDaqMenu');
                        Sc.IconLocation = fullfile(ic_dir,'Resources','IconDaqMenu.ico');
                        Save(Sc);
                        
                        % Create icon for the Daq
                        % Create icon for the Daq menu
                        Sc = Asvr.CreateShortcut(fullfile(path, 'DAQ.lnk'));
                        Sc.TargetPath = which('MyDaq');
                        Sc.IconLocation = fullfile(ic_dir,'Resources','IconDaq.ico');
                        Save(Sc);
                    catch ME
                        disp(['Shortcut could not be created. Error: ' ME.message])
                    end
                end
                
                delete(app)
            else
                warning('''%s'' is not a valid folder name.', app.BaseDirEditField.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Setup Instrument Control';
            app.BaseDirEditField = uieditfield(app.MainUIFigure, 'text');
            app.MeasDirEditField = uieditfield(app.MainUIFigure, 'text');
            app.InfoLabel = uilabel(app.MainUIFigure);
            app.InfoLabel.Text = {'This is a dialog to create the file LocalInstrumentControlSettings.mat, storing the local setting for'; 'this computer. The file location will define the local base directory, which is also intended to contain'; '''run'' files – functions with no arguments that start programs using instruments. '; ''; 'Please chose the local base directory, in which the settings file will be created: '};
            app.InfoLabel2 = uilabel(app.MainUIFigure);
            app.InfoLabel2.Text = {'The local base directory needs to be present on Matlab path, so that the file with settings could be'; 'loaded using the load() command. After being created, the local base directory is free to be renamed'; 'or moved in the file browser, as soon as it stays on the Matlab path.'; ''; 'Also you can indicate the directory, in subfolders of which to store the measurements data by default.'; 'This setting is optional and can be changed later at any point.'};
            app.CreatethesettingsfileandbasedirectoryLabel = uilabel(app.MainUIFigure);
            app.CreatethesettingsfileandbasedirectoryLabel.Text = 'Create the settings file and base directory:';
            app.BrowseBaseDirButton = uibutton(app.MainUIFigure, 'push');
            app.BrowseBaseDirButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBaseDirButtonPushed, true);
            app.BrowseBaseDirButton.Text = 'Browse';
            app.BrowseMeasDirButton = uibutton(app.MainUIFigure, 'push');
            app.BrowseMeasDirButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseMeasDirButtonPushed, true);
            app.BrowseMeasDirButton.Text = 'Browse';
            app.ContinueButton = uibutton(app.MainUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Setup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end