classdef GenRunFileDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptionsforRunfileUIFigure   matlab.ui.Figure
        ShowinDAQCheckBox           matlab.ui.control.CheckBox
        TitleinmenusEditFieldLabel  matlab.ui.control.Label
        TitleinmenusEditField       matlab.ui.control.EditField
        ContinueButton              matlab.ui.control.Button
    end


    properties (Access = private)
        Ret % 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if isempty(varargin)
                app.Ret = MyRefVar('');
            else
                if isequal(class(varargin{1}),'MyRefVar')
                    app.Ret = varargin{1};
                else
                    app.ErrorwarningTextArea.Value = ...
                        ['The input variable class needs to be MyRefVar.',... 
                        ' The selected value will not be returned'];
                    app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
                    app.Ret = MyRefVar('');
                end
            end
        end

        % Close request function: OptionsforRunfileUIFigure
        function OptionsforRunfileUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Assign value for returning
            app.Ret.value=struct();
            app.Ret.value.show_in_daq = logical(app.ShowinDAQCheckBox.Value);
            app.Ret.value.menu_title = app.TitleinmenusEditField.Value;
            % Delete app
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptionsforRunfileUIFigure = uifigure;
            app.OptionsforRunfileUIFigure.Name = 'Options for ''Run'' file';
            app.OptionsforRunfileUIFigure.CloseRequestFcn = createCallbackFcn(app, @OptionsforRunfileUIFigureCloseRequest, true);
            app.ShowinDAQCheckBox = uicheckbox(app.OptionsforRunfileUIFigure);
            app.ShowinDAQCheckBox.Text = 'Show in DAQ?';
            app.TitleinmenusEditFieldLabel = uilabel(app.OptionsforRunfileUIFigure);
            app.TitleinmenusEditFieldLabel.Text = 'Title in menus';
            app.TitleinmenusEditField = uieditfield(app.OptionsforRunfileUIFigure, 'text');
            app.ContinueButton = uibutton(app.OptionsforRunfileUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GenRunFileDlg(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptionsforRunfileUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptionsforRunfileUIFigure)
        end
    end
end